classdef analysis_CrossSignalMetrics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ClearGroupsButton              matlab.ui.control.Button
        GroupswithinselectionTextArea  matlab.ui.control.TextArea
        GroupswithinselectionTextAreaLabel  matlab.ui.control.Label
        SelectthegroupnumbertouseSpinner  matlab.ui.control.Spinner
        SelectthegroupnumbertouseSpinnerLabel  matlab.ui.control.Label
        SelectthelevelofgroupinformationtouseDropDown  matlab.ui.control.DropDown
        SelectthelevelofgroupinformationtouseDropDownLabel  matlab.ui.control.Label
        Spinner                        matlab.ui.control.Spinner
        SpinnerLabel                   matlab.ui.control.Label
        FindMatchesButton              matlab.ui.control.Button
        SelecttheanalysistoperformDropDown  matlab.ui.control.DropDown
        SelecttheanalysistoperformDropDownLabel  matlab.ui.control.Label
        WhatlevelshouldbeusedtofindamatchDropDown  matlab.ui.control.DropDown
        WhatlevelshouldbeusedtofindamatchDropDownLabel  matlab.ui.control.Label
        SelectthesignalstoprocessListBoxLabel  matlab.ui.control.Label
        Version                        matlab.ui.control.Label
        SelectthebasesignalsListBox    matlab.ui.control.ListBox
        Switch                         matlab.ui.control.Switch
        SaveResultsandExitButton       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Image                          matlab.ui.control.Image
        CrossSignalAnalysisLabel       matlab.ui.control.Label
        ProcessButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        BAR_App   % Main app object
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of
            %           the error and organize the errors available.
            %         - newText, a string that may be inserted into the
            %                    message.
            % Remarks
            % - This method will print messages to the log of the BAR App.
            %   The error codes are unique to each message so they can be
            %   found and referenced easier.
            % - The method is public and is acessible outside of the BAR
            %   App. It is used in some of the example analysis and process
            %   scripts.
            % Future Work
            % - This log should be printed and saved to a file location
            %   each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text within the log.
            text = app.BAR_App.Log.Value;

            % Match the error code with the message and update the text for
            % the log.
            switch code
                case '001'
                    text{length(text) + 1, 1} = 'CSA-001 matching started';
                case '002'
                    text{length(text) + 1, 1} = 'CSA-002 matching complete';
                case '003'
                    text{length(text) + 1, 1} = ['CSA-003 analysis failed on: ' newText];
                case '004'
                    text{length(text) + 1, 1} = ['CSA-004 ' newText];
                case '005'
                    text{length(text) + 1, 1} = 'CSA-005 this feature is not available';
                case '006'
                    text{length(text) + 1, 1} = 'CSA-006 no matches available to process';
            end

            % Update the log.
            app.BAR_App.Log.Value = text;

            % Scroll to the bottom so the most recent message is visible.
            scroll(app.BAR_App.Log,'bottom')
            drawnow
        end

        function updatelist(app)
            % updatelist(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the signal list within the app with the
            %   currently selected data type.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu


            % Get the current data from the app.
            data = getappdata(app.UITable, 'data');
            % Find which data type was selected.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Initialize an empty list.
            sigsAll = [];

            % Check that the type is present.
            if isfield(data, type)
                % Get the file names.
                files = fieldnames(data.(type));
                % Iterate through the file names.
                for i = 1:length(files)
                    % Get the object names and remove the informational ones.
                    objs = fieldnames(data.(type).(files{i}));
                    objs(strcmp(objs, 'groups')) = [];
                    objs(strcmp(objs, 'meta')) = [];
                    % Iterate through the objects
                    for j = 1:length(objs)
                        if isempty(sigsAll)
                            sigsAll = fieldnames(data.(type).(files{i}).(objs{j}).data);
                        else
                            % Append the signals to the list.
                            sigsAll = [sigsAll; fieldnames(data.(type).(files{i}).(objs{j}).data)];
                        end
                    end
                end
                % Only the unique names will be used and add an 'all' option to the top
                sigsAll = ['all'; unique(sigsAll)];
                % Set the unique signals to the list box.
                app.SelectthebasesignalsListBox.Items = sigsAll;

            else
                % Empty the list box if no data is present for the selected type.
                app.SelectthebasesignalsListBox.Items = {''};
            end
            

        end

        function [file, obj, score] = findMatch(app, groups, level, data)
            % [file, obj, score] = findMatch(app, groups, level, data)
            % inputs  - app, required mlapp object.
            %         - groups, the group information to find a match for.
            %         - level, the level to find the match in, 'file' or 'object'.
            %         - data, the data structure to find the match in.
            % Remarks
            % - This method will search a BAR App data structure at a given level for matches with given group information. All matches will be
            %   found.
            % - Matches are determined based on the number of group elements that match. The minimum number is set in the app interface.
            % - To increase speed it is assumed that all matches will always be found within the same item. If the number of matches count is met or
            %   exceeded the matching algorithm will assume those are the only matches and exit.
            % - The method assumes that one file will match one file while multiple objects may match a file.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            switch level
                % If file is selected search the file level group
                % information for matches.
                case 'file'
                    % Get the file names.
                    files = fieldnames(data.raw);
                    % Initialize an empty array for the matches. This is
                    % usefull for troubleshooting.
                    m = [];
                    % Iterate through the file level.
                    for j = 1:length(files)
                        % Start the number of matches at 0.
                        m(j, 1) = 0;
                        % This will add 1 to the count for each match
                        % present.
                        for ii = 1:length(groups)
                            % This statement will consider numbers by converting any potential numbers as text to doubles. This would match text like
                            % 006 with the number 6.
                            % If the current group is not empty and any of the object's group information matches the current group, or if the current
                            % group is a number and any of the object's groups convert to numbers and match the current group converted to a number.
                            if ~isempty(groups{ii}) && (any(strcmp(data.raw.(files{j}).groups, groups{ii})) || ( ~isempty(str2num(groups{ii})) && any(cell2mat(cellfun(@str2num, data.raw.(files{j}).groups, 'UniformOutput', false)) == str2num(groups{ii}))))
                                m(j, 1) = m(j, 1) + 1;
                            end
                        end
                    end
                    % Zero anything less than the spinner value. This may not be necessary.
                    m(m < app.Spinner.Value) = 0;
                    % Find the index of all the maximums.
                    [m_max, I] = max(m, [], 'all');
                    % Check that the max score was not 0.
                    if m_max ~= 0
                        % Find the row and column indexes that represent the file number and object number.
                        [ind_f, ind_o] = find(m == m_max);
                        % Get the file name.
                        file = files(ind_f);
                        % This is a file match so there is no object name.
                        obj = [];
                        % Save the max score for display.
                        score = m_max;
                    else
                        % If the max was 0 return empty arrays.
                        file = [];
                        obj = [];
                        score = m_max;
                    end
                    % If file is selected search the file level group information for matches.
                case 'object'
                    % Get the file names.
                    files = fieldnames(data.raw);
                    % Initialize an empty array for the matches. This is
                    % usefull for troubleshooting.
                    m = [];
                    % Iterate through the file level.
                    for j = 1:length(files)
                        % Get the object names and remove the informational ones.
                        objs = fieldnames(data.raw.(files{j}));
                        objs(strcmp(objs, 'meta')) = [];
                        objs(strcmp(objs, 'groups')) = [];
                        % Iterate through the current object level.
                        for i = 1:length(objs)
                            % Start the number of matches at 0.
                            m(j, i) = 0;
                            % Add 1 for each group in the object that matches the input groups.
                            for ii = 1:length(groups)
                                % If the current group is not empty and any of the object's group information matches the current group, or if the
                                % current group is a number and any of the object's groups convert to numbers and match the current group converted
                                % to a number.
                                if ~isempty(groups{ii}) && (any(strcmp(data.raw.(files{j}).(objs{i}).groups, groups{ii})) || ( ~isempty(str2num(groups{ii})) && any(cell2mat(cellfun(@str2num, data.raw.(files{j}).(objs{i}).groups, 'UniformOutput', false)) == str2num(groups{ii}))))
                                    m(j, i) = m(j, i) + 1;
                                end
                            end
                        end
                        % Check if the user indicated there will be duplicat matches. If no remove the items to save future processing time.
                        if strcmp(app.ArethereduplicatematchesSwitch.Value, 'No')
                            % If the any of the scores for the current object match or are above the user selected value the loop will exit. This is done
                            % to save time and assumes any matches for a file would be contained within a single file.
                            if any(any(m >= app.Spinner.Value))
                                break
                            end
                        end
                    end
                    % Zero anything less than the spinner value. This may not be necessary.
                    m(m < app.Spinner.Value) = 0;
                    % Find the index of all the maximums.
                    [m_max, ~] = max(m, [], 'all');
                    % Check that the max score was not 0.
                    if m_max ~= 0
                        % Find the row and column indexes that represent the file number and object number.
                        [ind_f, ind_o] = find(m == m_max);
                        % Get the file name.
                        file = files(ind_f);
                        % Iterate through all the matches.
                        for i = 1:length(ind_f)
                            % Get the object names and remove the informational ones.
                            objs = fieldnames(data.raw.(files{ind_f(i)}));
                            objs(strcmp(objs, 'meta')) = [];
                            objs(strcmp(objs, 'groups')) = [];
                            % Find the name of the object that matched.
                            obj{i, 1} = objs{ind_o(i)};
                            % Save the score.
                            score(i, 1) = m_max;
                        end
                    else
                        % If no match was found create empty place holders.
                        file = [];
                        obj = [];
                        score = m_max;
                    end
            end
        end

        function identifyOptions(app)
            % identifyOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Analysis folder for valid files that would be used to analyze data within the app. These files need to have
            %   a name analysis_CSM_*.m. The results will be saved to the configuration file and made selectable in the app.
            % - The goal of this function was to make the addition of new functions easier.
            % - The idea of processing is to take an input and create a new ouput of similar size and scale.
            % Future Work
            % - None
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Load the existing configuration so it can be updated.
            config = loadConfig(app.BAR_App);

            % Empty the available treatment files from the saved configuration.
            config.options.crossSignalMetrics = [];
            ind = 1;

            for j = 1:length(app.BAR_App.FilesAnalysis.Value)

                % Use the directory entered into the app to find the processing scripts.
                m_files = dir(app.BAR_App.FilesAnalysis.Value{j});

                % Look for files within the directory. If the file names contain the word 'CSM' and a third part save them within the app and
                % configuration.
                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'analysis') && contains(m_files(i).name, 'CSM') && ~contains(m_files(i).name, 'asv')
                        [~, name] = fileparts(m_files(i).name);
                        parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                        if ~isempty(parts{3})
                            % The third part of the file name is used for the options.
                            config.options.crossSignalMetrics{ind,1} = parts{3}{1};
                            ind = ind + 1;
                        end
                    end
                end
            end

            % If there are no available options or if the directory is misleading nothing will be found. The object in the app can't be set to empty.
            if ~isempty(config.options.crossSignalMetrics)
                app.SelecttheanalysistoperformDropDown.Items = config.options.crossSignalMetrics;
            end

            % Save the configuration with the updated directory and or file extensions and formats.
            saveConfig(app.BAR_App, config)

        end

        
        function identifyGroups(app, level, number)
            % identifyGroups(app, level, number)
            % inputs  - app, required mlapp object.
            %         - level, indicates if groups should be retrieved from the file or object level.
            %         - number, indicates would number of the group information should be retreived.
            % Remarks
            % - This method finds the groups that can be used to run the cross signal metrics by groups.
            % Future Work
            % - There is no error handling in this code. It assumes the groups will be present at the level and number entered.
            % Jul 2023 - Created by Ben Senderling, bsender@bu.edu

            % Get the data from the app.
            data = getappdata(app.UITable, 'data');

            % Empty the text display in case no groups are found with the current selection.
            app.GroupswithinselectionTextArea.Value = {''};

            % Find if the raw or processed data should be used.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Initialize the cell array for the group information.
            groups = {};

            % Get all the file names.
            files = fieldnames(data.(type));
            % Iterate through the files.
            for ind_file = 1:length(files)
                % If the file level was selected.
                if strcmp(level, 'File')
                    if isfield(data.(type).(files{ind_file}), 'groups') && length(data.(type).(files{ind_file}).groups) >= number
                        % Add the string from the cell number selected to the groups.
                        groups = [groups; data.(type).(files{ind_file}).groups{number}];
                    end
                else
                    % If the object level was selected.
                    % Get all the object names.
                    objs = fieldnames(data.(type).(files{ind_file}));
                    objs(strcmp(objs, 'groups')) = [];
                    objs(strcmp(objs, 'meta')) = [];
                    % Iterate through the object names.
                    for ind_obj = 1:length(objs)
                        if isfield(data.(type).(files{ind_file}).(objs{ind_obj}), 'groups') && length(data.(type).(files{ind_file}).(objs{ind_obj}).groups) >= number
                            % Add the string from the cell number selected to the groups.
                            groups = [groups; data.(type).(files{ind_file}).(objs{ind_obj}).groups(number)];
                        end
                    end
                end
            end

            % There will be duplicates since information was pulled from every file. We only need the unique ones.
            groups = unique(groups);

            % Display the information in the app.
            if ~isempty(groups)
                app.GroupswithinselectionTextArea.Value = groups;
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % StartupFcn(app, mainapp, data, analysis)
            % inputs  - app, required mlapp object.
            %         - mlapp, BAR App objects.
            %         - data, BAR App data structure.
            %         - analysis, string of the analysis name.
            % Remarks
            % - This is the startup method that initiallized the app.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            % Save the data into the module.
            setappdata(app.UITable, 'data', data)
            % Initialize the signal list in the app.
            updatelist(app)
            % Find any mini-modules.
            identifyOptions(app)
            % Update the group selection
            identifyGroups(app, app.SelectthelevelofgroupinformationtouseDropDown.Value, app.SelectthegroupnumbertouseSpinner.Value)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.BAR_App.Version.Text;

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % ProcessButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will use the matched items only to perform the selected analysis. Items without matches are skipped.
            % Future Work
            % - None.
            % Jul 2023 - Created by Ben Senderling, bsender@bu.edu

            % Get the data from the app.
            data = getappdata(app.UITable, 'data');
            % Get the selected level of data to process.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Find the load function that matches the selected file extension and equipment type.
            % Get the load functions for the first directory.
            filesAnalysis = dir(app.BAR_App.FilesAnalysis.Value{1});
            % Get the load functions for the additional directories if there are more.
            if length(app.BAR_App.FilesAnalysis.Value) > 1
                for i = 2:length(app.BAR_App.FilesAnalysis.Value)
                    filesAnalysis = [filesAnalysis; dir(app.BAR_App.FilesAnalysis.Value{i})];
                end
            end
            % Find the index where the filename contains the target file extension and the equipment type and is not an asv-file.
            ind3 = find(contains({filesAnalysis.name}', app.SelecttheanalysistoperformDropDown.Value) & ~contains({filesAnalysis.name}', 'asv'), 1);

            % Exit if no file was found.
            if isempty(ind3)
                return
            end

            % Find the index of '.m' which will be present in all m- and mlapp-files.
            ind4 = strfind(filesAnalysis(ind3).name, '.m');

            % Get the matches identified by the app.
            matches = app.UITable.Data;
            if isempty(matches)
                printLog(app, '006', [])
                return
            end
            matches(strcmp(matches.item2,'no match found'),:) = [];

            % Get the user selected signals.
            sigs = app.SelectthebasesignalsListBox.Value;

            % If 'all' was selected or nothing was selected get all the signal names from the list.
            if any(contains(sigs, 'all')) || isempty(sigs)
                sigs = app.SelectthebasesignalsListBox.Items;
                % Remove the all option where ever it may be in the list.
                sigs(strcmp(sigs, 'all')) = [];
            elseif isempty(sigs)
                return
            end

            groups = app.GroupswithinselectionTextArea.Value;
            number = app.SelectthegroupnumbertouseSpinner.Value;
            for ind_group = 1:length(groups)

                % Iterate through the signals.
                for ind_sig = 1:length(sigs)

                    % Initialize the signal arrays.
                    signal1 = [];
                    signal2 = [];

                    % Initialize an index for the array.
                    ind = 1;

                    % Iterate through the files.
                    for ind_matches = 1:height(matches)

                        file1 = matches{ind_matches, 1};
                        file2 = matches{ind_matches, 3};

                        objs = fieldnames(data.(type).(file1{1}));
                        objs(strcmp(objs, 'groups')) = [];
                        objs(strcmp(objs, 'meta')) = [];

                        if ~((strcmp(app.SelectthelevelofgroupinformationtouseDropDown.Value, 'File') && strcmp(data.(type).(file1{1}).groups{number}, groups{ind_group}) && strcmp(data.(type).(file2{1}).groups{number}, groups{ind_group})) || isempty(groups) || strcmp(app.SelectthelevelofgroupinformationtouseDropDown.Value, 'Object'))
                            continue
                        end
                        % Iterate through the objects.
                        for ind_obj = 1:length(objs)

                            % The signal has to be present for both files to be processed.
                            if isfield(data.(type).(file1{1}), objs{ind_obj}) && isfield(data.(type).(file2{1}), objs{ind_obj}) && ...
                                    isfield(data.(type).(file1{1}).(objs{ind_obj}).data, sigs{ind_sig}) && isfield(data.(type).(file2{1}).(objs{ind_obj}).data, sigs{ind_sig})

                                if ~((strcmp(app.SelectthelevelofgroupinformationtouseDropDown.Value, 'File') && strcmp(data.(type).(file1{1}).groups{number}, groups{ind_group}) && strcmp(data.(type).(file2{1}).groups{number}, groups{ind_group})) || isempty(groups)) &&...
                                    ~((strcmp(app.SelectthelevelofgroupinformationtouseDropDown.Value, 'Object') && strcmp(data.(type).(file1{1}).(objs{ind_obj}).groups{number}, groups{ind_group}) && strcmp(data.(type).(file2{1}).(objs{ind_obj}).groups{number}, groups{ind_group})) || isempty(groups))
                                    continue
                                end

                                % Add the new signal and pad the existing array with NaNs if the sizes do not match.
                                a = size(signal1, 1);
                                b = size(mean(data.(type).(file1{1}).(objs{ind_obj}).data.(sigs{ind_sig}), 2), 1);
                                if a == 0 || a == b
                                    signal1(:, ind) = mean(data.(type).(file1{1}).(objs{ind_obj}).data.(sigs{ind_sig}), 2);
                                elseif a > b
                                    signal1(:, ind) = [mean(data.(type).(file1{1}).(objs{ind_obj}).data.(sigs{ind_sig}), 2); NaN*ones(a - b, 1)];
                                elseif a < b
                                    signal1 = [signal1; NaN*ones(b - a, size(signal1, 2))];
                                    signal1(:, ind) = mean(data.(type).(file1{1}).(objs{ind_obj}).data.(sigs{ind_sig}), 2);
                                end

                                % Add the new signal and pad the existing array with NaNs if the sizes do not match.
                                c = size(signal2, 1);
                                d = size(mean(data.(type).(file2{1}).(objs{ind_obj}).data.(sigs{ind_sig}), 2), 1);
                                if c == 0 || c == d
                                    signal2(:, ind) = mean(data.(type).(file2{1}).(objs{ind_obj}).data.(sigs{ind_sig}), 2);
                                elseif c > d
                                    signal2(:, ind) = [mean(data.(type).(file2{1}).(objs{ind_obj}).data.(sigs{ind_sig}), 2); NaN*ones(c - d, 1)];
                                elseif c < d
                                    signal2 = [signal2; NaN*ones(d - c, size(signal2, 2))];
                                    signal2(:, ind) = mean(data.(type).(file2{1}).(objs{ind_obj}).data.(sigs{ind_sig}), 2);
                                end
                            else
                                continue
                            end

                            % Increase the index for the table structure.
                            ind = ind + 1;
                        end

                        % Every 10 files print an update message.
                        if rem(ind_matches, 10) == 0
                            printLog(app, '001', ['finished ' num2str(ind_matches) ' of ' num2str(height(matches)) ' files for group ' groups{ind_group}])
                        end
                    end
                    try

                        if ~(isempty(signal1) || isempty(signal2))

                            % Run the analysis method.
                            if isempty(groups)
                                plotName = sigs{ind_sig};
                            else
                                plotName = [sigs{ind_sig} '_' groups{ind_group}];
                            end
                            results = feval(filesAnalysis(ind3).name(1:ind4 - 1), app.BAR_App, signal1, signal2, plotName);

                            for res_ana = {'ana', 'res'}
                                if isfield(results, res_ana{1})
                                    ana = fieldnames(results.ana);
                                    for ind_ana = 1:length(ana)
                                        data.ana.(sigs{ind_sig}).data.(ana{ind_ana}) = results.(ana{ind_ana});
                                    end
                                end
                            end
                        end

                        % The result will be saved to the analysis type. Usually this would be an array or some other type that would not be
                        % % exported but might be used in figures.
                        % data.ana.CustomApp.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).mean = resultMean;
                        %
                        % % The result will be saved to the results type.
                        % data.res.CustomApp.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).mean = resultMean;
                        %
                        % % Save results to the table structure.
                        % dataTable.File{ind, 1} = files{i};
                        % dataTable.Object{ind, 1} = objs{j};
                        % dataTable.Signal{ind, 1} = sigs{k};
                        % dataTable.Dimension{ind, 1} = ii;
                        % dataTable.Mean(ind, 1) = resultMean;

                    catch ME
                        % If there is an error set all the same reults to NaNs.
                        % data.ana.CustomApp.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).mean = NaN;
                        %
                        % data.res.CustomApp.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).mean = NaN;
                        %
                        % dataTable.File{ind, 1} = files{i};
                        % dataTable.Object{ind, 1} = objs{j};
                        % dataTable.Signal{ind, 1} = sigs{k};
                        % dataTable.Dimension{ind, 1} = ii;
                        % dataTable.Mean(ind, 1) = resultMean;
                        %
                        % % Print a message saying which data failed.
                        % printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])
                        %
                        % % Get MATLAB's last error message and display it.
                        % printLog(app, '004', ME.message)

                    end
                end
            end
            
        % Print a message complete message to the log.
        printLog(app, '002')
        % Save the data to the module.
        % setappdata(app.UITable, 'data', data)
        % Sent the table structure to the module's table and set the column names to the fieldnames.
        % app.UITable.Data = struct2table(dataTable);
        % app.UITable.ColumnName = fieldnames(dataTable);

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This method will send the data back to the BAR App and save
            %   it.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the module.
            data = getappdata(app.UITable, 'data');
            % The analysis name that will be used to save the data.
            analysis = 'CrossSignalMetrics';
            % Save the data using a BAR_App method.
            analysisComplete(app.BAR_App, data, analysis, 0)
            % Close the app.
            delete(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            updatelist(app)
        end

        % Button pushed function: FindMatchesButton
        function FindMatchesButtonPushed(app, event)
            % FindMatchesButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information from the app at the button press.
            % Remarks
            % - This method will iterate through the data and run the matching algorithm on each level.
            % Future Work
            % -
            % Jun 2023 - Created by Ben Senderling, bsender@bu.edu

            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Print a message to the log the method has started.
            printLog(app, '001', [])

            % Empty the table.
            app.UITable.Data = [];

            % This is the first data set that will be used as the base.
            data1 = getappdata(app.UITable, 'data');
            % This is the second data set that will be inserted into the base.
            data2 = data1;

            % Initialize the match structure.
            matches = struct;
            % Create a starting index. This is usefull for concidering both files and objects at different levels of the loop.
            ind = 1;

            % This is a section for file-file matches.
            if strcmp(app.WhatlevelshouldbeusedtofindamatchDropDown.Value, 'File')

                % Get the starting list of files.
                files1 = fieldnames(data1.(type));
                ind_file = 1;
                while ind_file <= length(files1)
                    % Get the current list of files.
                    files1 = fieldnames(data1.(type));
                    % Get the group information for the current file.
                    groups = data1.(type).(files1{ind_file}).groups;
                    % Remove the current file to avoid self matches.
                    data2.(type) = rmfield(data2.(type), files1{ind_file});
                    % Run the match algorithm
                    [file2, ~, score] = findMatch(app, groups, 'file', data2);
                    % If a match was found.
                    if ~isempty(file2)
                        % Store the concatenated group information so it can be displayed in the table.
                        matches.file1{ind, 1} = files1{ind_file};
                        matches.item1{ind, 1} = strjoin(groups);
                        matches.file2{ind, 1} = file2{1};
                        matches.item2{ind, 1} = strjoin(data2.(type).(file2{1}).groups);
                        matches.score{ind, 1} = score;
                        ind = ind + 1;

                        % Remove the matched file.
                        data1.(type) = rmfield(data1.(type), file2{1});
                        data2.(type) = rmfield(data2.(type), file2{1});
                    else
                        % If no match was found create a placeholder to communicate that.
                        matches.file1{ind, 1} = files1{ind_file};
                        matches.item1{ind, 1} = strjoin(groups);
                        matches.file2{ind, 1} = [];
                        matches.item2{ind, 1} = 'no match found';
                        matches.score{ind, 1} = [];
                        ind = ind + 1;
                    end
                    % Update the list of files in case some were removed.
                    files1 = fieldnames(data1.(type));
                    % Increment the file count.
                    ind_file = ind_file + 1;

                    % Every 10 file print an update to the log.
                    if rem(ind_file, 10) == 0
                        printLog(app, '004', ['matched ' num2str(ind_file) ' of ' num2str(length(files1))])
                    end

                end
                % This is a section for file-object matches.
            elseif sstrcmp(app.WhatlevelshouldbeusedtofindamatchDropDown.Value, 'Object')

                printLog(app, '005', [])

            end

            % Set the match results as the data for the table.
            app.UITable.Data = struct2table(matches);
            app.UITable.ColumnName = fieldnames(matches);
            % Save the matched results in the app.
            setappdata(app.UITable, 'data1matched', data1);
            % Print a merge complete message to the log.
            printLog(app, '002', [])

        end

        % Value changed function: 
        % SelectthelevelofgroupinformationtouseDropDown
        function SelectthelevelofgroupinformationtouseDropDownValueChanged(app, event)
            level = app.SelectthelevelofgroupinformationtouseDropDown.Value;
            number = app.SelectthegroupnumbertouseSpinner.Value;
            identifyGroups(app, level, number)
        end

        % Value changed function: SelectthegroupnumbertouseSpinner
        function SelectthegroupnumbertouseSpinnerValueChanged(app, event)
            number = app.SelectthegroupnumbertouseSpinner.Value;
            level = app.SelectthelevelofgroupinformationtouseDropDown.Value;
            identifyGroups(app, level, number)
        end

        % Button pushed function: ClearGroupsButton
        function ClearGroupsButtonPushed(app, event)
            app.GroupswithinselectionTextArea.Value = {''};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.CrossSignalAnalysisLabel = uilabel(app.UIFigure);
            app.CrossSignalAnalysisLabel.Text = 'Cross Signal Analysis';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Raw';
            app.SelectthebasesignalsListBox = uilistbox(app.UIFigure);
            app.SelectthebasesignalsListBox.Items = {};
            app.SelectthebasesignalsListBox.Multiselect = 'on';
            app.SelectthebasesignalsListBox.Value = {};
            app.Version = uilabel(app.UIFigure);
            app.SelectthesignalstoprocessListBoxLabel = uilabel(app.UIFigure);
            app.SelectthesignalstoprocessListBoxLabel.Text = 'Select the base signals';
            app.WhatlevelshouldbeusedtofindamatchDropDownLabel = uilabel(app.UIFigure);
            app.WhatlevelshouldbeusedtofindamatchDropDownLabel.Text = 'What level should be used to find a match?';
            app.WhatlevelshouldbeusedtofindamatchDropDown = uidropdown(app.UIFigure);
            app.WhatlevelshouldbeusedtofindamatchDropDown.Items = {'File', 'Object', 'Signal'};
            app.WhatlevelshouldbeusedtofindamatchDropDown.Value = 'File';
            app.SelecttheanalysistoperformDropDownLabel = uilabel(app.UIFigure);
            app.SelecttheanalysistoperformDropDownLabel.Text = 'Select the analysis to perform.';
            app.SelecttheanalysistoperformDropDown = uidropdown(app.UIFigure);
            app.SelecttheanalysistoperformDropDown.Items = {};
            app.SelecttheanalysistoperformDropDown.Value = {};
            app.FindMatchesButton = uibutton(app.UIFigure, 'push');
            app.FindMatchesButton.ButtonPushedFcn = createCallbackFcn(app, @FindMatchesButtonPushed, true);
            app.FindMatchesButton.Text = 'Find Matches';
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'What is the minimum number of tags that should be common between sources to form a match?';
            app.Spinner = uispinner(app.UIFigure);
            app.SelectthelevelofgroupinformationtouseDropDownLabel = uilabel(app.UIFigure);
            app.SelectthelevelofgroupinformationtouseDropDownLabel.Text = 'Select the level of group information to use.';
            app.SelectthelevelofgroupinformationtouseDropDown = uidropdown(app.UIFigure);
            app.SelectthelevelofgroupinformationtouseDropDown.Items = {'File', 'Object'};
            app.SelectthelevelofgroupinformationtouseDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectthelevelofgroupinformationtouseDropDownValueChanged, true);
            app.SelectthelevelofgroupinformationtouseDropDown.Value = 'File';
            app.SelectthegroupnumbertouseSpinnerLabel = uilabel(app.UIFigure);
            app.SelectthegroupnumbertouseSpinnerLabel.Text = 'Select the group number to use.';
            app.SelectthegroupnumbertouseSpinner = uispinner(app.UIFigure);
            app.SelectthegroupnumbertouseSpinner.Limits = [1 Inf];
            app.SelectthegroupnumbertouseSpinner.RoundFractionalValues = 'on';
            app.SelectthegroupnumbertouseSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectthegroupnumbertouseSpinnerValueChanged, true);
            app.SelectthegroupnumbertouseSpinner.Value = 1;
            app.GroupswithinselectionTextAreaLabel = uilabel(app.UIFigure);
            app.GroupswithinselectionTextAreaLabel.Text = 'Groups within selection.';
            app.GroupswithinselectionTextArea = uitextarea(app.UIFigure);
            app.GroupswithinselectionTextArea.Editable = 'off';
            app.ClearGroupsButton = uibutton(app.UIFigure, 'push');
            app.ClearGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGroupsButtonPushed, true);
            app.ClearGroupsButton.Text = 'Clear Groups';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_CrossSignalMetrics(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end