classdef analysis_DFA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CreatefiguresSwitch             matlab.ui.control.Switch
        CreatefiguresSwitchLabel        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MonoTab                         matlab.ui.container.Tab
        Leaveat0tousedefaultofN18Label  matlab.ui.control.Label
        ProcessButton                   matlab.ui.control.Button
        ofpointsforlinearregionSpinner  matlab.ui.control.Spinner
        ofpointsforlinearregionSpinnerLabel  matlab.ui.control.Label
        MaximumboxsizeasfractionofdatalengthSpinner  matlab.ui.control.Spinner
        MaximumboxsizeasfractionofdatalengthSpinnerLabel  matlab.ui.control.Label
        MinimumBoxSizeSpinner           matlab.ui.control.Spinner
        MinimumBoxSizeSpinnerLabel      matlab.ui.control.Label
        MultiTab                        matlab.ui.container.Tab
        NoiselevelEditField             matlab.ui.control.NumericEditField
        NoiselevelEditFieldLabel        matlab.ui.control.Label
        mSpinner                        matlab.ui.control.Spinner
        mSpinnerLabel                   matlab.ui.control.Label
        maxqSpinner                     matlab.ui.control.Spinner
        maxqSpinnerLabel                matlab.ui.control.Label
        NSpinner                        matlab.ui.control.Spinner
        NSpinnerLabel                   matlab.ui.control.Label
        MinimumSpinner                  matlab.ui.control.Spinner
        MinimumSpinnerLabel             matlab.ui.control.Label
        MaximumSpinner                  matlab.ui.control.Spinner
        MaximumSpinnerLabel             matlab.ui.control.Label
        ScaleParametersLabel            matlab.ui.control.Label
        ProcessButton_2                 matlab.ui.control.Button
        SkipexistingSwitch              matlab.ui.control.Switch
        SkipexistingSwitchLabel         matlab.ui.control.Label
        SaveperiodicallySwitch          matlab.ui.control.Switch
        SaveperiodicallySwitchLabel     matlab.ui.control.Label
        Version                         matlab.ui.control.Label
        SelectthesignalstoprocessListBox  matlab.ui.control.ListBox
        SelectthesignalstoprocessListBoxLabel  matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        SaveResultsandExitButton        matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        DetrendedFluctuationAnalysisLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        DAMVi_App % Main app object
        Data % Container for data from DAMVi
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of the error and organize the errors available.
            %         - newText, a string that may be inserted into the message.
            % Remarks
            % - This method will print messages to the log of the BAR App. The error codes are unique to each message so they can be found and
            %   referenced easier.
            % - The method is public and is acessible outside of the BAR App. It is used in some of the example analysis and process scripts.
            % Future Work
            % - This log should be printed and saved to a file location each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text of the log.
            text = app.DAMVi_App.Log.Value;
            % Match the code with the message.
            switch code
                case '001'
                    text{length(text) + 1,1} = ['DFA-001 ' newText];
                case '002'
                    text{length(text) + 1,1} = ['DFA-002 analysis complete'];
                case '003'
                    text{length(text) + 1,1} = ['DFA-003 analysis failed on: ' newText];
                case '004'
                    text{length(text) + 1,1} = ['DFA-004 ' newText];
                case '005'
                    text{length(text) + 1,1} = 'DFA-004 more than 10% of files have failed and the method has stopped';
            end
            % Create a date-time for the message.
            text{end, 1} = [char(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z')) ' | ' text{end, 1}];
            % Add the message to the log.
            app.DAMVi_App.Log.Value = text;
            % Scroll down so the message is visible.
            scroll(app.DAMVi_App.Log,'bottom')
            % Make sure the app updates.
            drawnow
            % Write to the log file.
            writeLog(app.DAMVi_App, text{end, 1})

        end

        function updatelist(app)
            % updatelist(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the signal list within the app with the currently selected data type.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Find which data type was selected.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Initialize the list.
            sigsAll = {};

            % Get the file names.
            files = fieldnames(app.Data.(type));
            % Iterate through the file names.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(app.Data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects
                for j = 1:length(objs)
                    % Append the signals to the list.
                    sigsAll = [sigsAll; fieldnames(app.Data.(type).(files{i}).(objs{j}).data)];
                end
            end

            % Set the unique signals to the list box. Adding 'all' this way ensures it is at the top of the list. Before the list was initialized with
            % all. Unique would move it to the end of the list.
            sigsAll = ['all'; unique(sigsAll)];
            app.SelectthesignalstoprocessListBox.Items = sigsAll;

        end

        function processData(app, dfa)
            % processData(app, event)
            % inputs  - app, required mlapp object.
            %         - dfa, a tag of either 'mono' or 'multi' to specifcy the DFA method.
            % Remarks
            % - This method starts the analysis method. It pulls primary inputs from the user interface or uses default values.
            % Future Work
            % - None.
            % Nov 2023 - Created by Ben Senderling, bms322@drexel.edu

            % Empty the current table.
            app.UITable.Data = [];

            % Get the selected level of data to process.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Initialize the table to view the results.
            dataTable = struct;
            % Starting index for the table.
            ind = 1;
            % A flag that will prevent skipped data from being saved.
            flagSave = 0;
            % A flag that will be used to stop processing if too many are failing with an error that is not accounted for.
            flagError = 0;

            % Get the user selected signals.
            sigsSelected = app.SelectthesignalstoprocessListBox.Value;

            % Get the field names of all the files that were loaded.
            files = fieldnames(app.Data.(type));
            % Iterate through the files.
            for i = 1:length(files)
                % Get the fieldnames for all the signals that were contained within that file. This could be different for each file so it is within
                % the loop.
                objs = fieldnames(app.Data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects.
                for j = 1:length(objs)
                    % Get the signals from the object.
                    sigs = fieldnames(app.Data.(type).(files{i}).(objs{j}).data);
                    % Iterate through the signals.
                    for ind_sig = 1:length(sigs)

                        if strcmp(objs{j}, 's20006940')
                            disp('here')
                        end
                        % Check if 'all' or nothing was selected or the current signal matches the selection.
                        if any(contains(sigsSelected, 'all')) || isempty(sigsSelected) || any(strcmp(app.SelectthesignalstoprocessListBox.Value, sigs{ind_sig}))
                            % Get the number of dimensions.
                            if iscell(app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig}))
                                m = numel(app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig}));
                            else
                                if size(app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig}), 1) == 1
                                    m = 1;
                                elseif size(app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig}), 2) == 1
                                    m = 1;
                                else
                                    m = size(app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig}), 1);
                                end
                            end
                            % Iterate through the dimensions.
                            for ii = 1:m

                                if iscell(app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig}))
                                    dat = app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig}){ii};
                                else
                                    if size(app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig}), 1) == 1
                                        dat = app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig})';
                                    elseif size(app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig}), 2) == 1
                                        dat = app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig});
                                    else
                                        dat = app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig})(:,ii);
                                    end

                                end


                                try
                                    if ~isempty(dat)
                                        if (~(strcmp(app.SkipexistingSwitch.Value, 'Yes') &&...
                                                (isfield(app.Data, 'res') && isfield(app.Data.res, 'DFA') && isfield(app.Data.res.DFA, files{i}) && isfield(app.Data.res.DFA.(files{i}), objs{j}) && isfield(app.Data.res.DFA.(files{i}).(objs{j}).data, sigs{ind_sig}) && ...
                                                ((isfield(app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}), 'a') && length(app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).a) >= ii) || ...
                                                 (isfield(app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}), 'hqMin') && length(app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqMin) >= ii) ...
                                                ) ...
                                                ) ...
                                              ) ...
                                            ) || isnan(app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).a(ii))
                                            
                                            % Both DFA and MFDFA have built in methods that create plots. That code is not used but the same code is
                                            % used elsewhere. This allows the plots to be included along other plots.
                                            plotOption = 0;
                                            switch dfa
                                                % Run mono-fractal DFA
                                                case 'mono'
                                                    % Set the scale parameters using the graphical inputs.
                                                    n_length = app.ofpointsforlinearregionSpinner.Value;
                                                    n_max = length(dat)/app.MaximumboxsizeasfractionofdatalengthSpinner.Value;
                                                    n_min = app.MinimumBoxSizeSpinner.Value;

                                                    % Run the DFA method on the data.
                                                    [a, r2, out_a, out_l] = DFA(dat, n_min, n_max, n_length, plotOption);
                                                    % The arrays used for the plot are saved to analysis results.
                                                    app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).out_a{1, ii} = out_a;
                                                    app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).out_l{1, ii} = out_l;
                                                    % The alpha and goodness of fit are saved to the results as well as the scalar inputs.
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).DfaScaleMin(1, ii) = n_min;
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).DfaScaleMax(1, ii) = n_max;
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).DfaScaleN(1, ii) = n_length;
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).a(1, ii) = a;
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).r2(1, ii) = r2;

                                                    % Set the values in the table so they are visible.
                                                    dataTable.File{ind, 1} = files{i};
                                                    dataTable.Object{ind, 1} = objs{j};
                                                    dataTable.Signal{ind, 1} = sigs{ind_sig};
                                                    dataTable.Dimension(ind, 1) = ii;
                                                    dataTable.Alpha(ind, 1) = a;
                                                    dataTable.R2(ind, 1) = r2;

                                                case 'multi'
                                                    % Set the scale parameters using the graphical inputs.
                                                    exponents = linspace(log2(app.MinimumSpinner.Value), log2(app.MaximumSpinner.Value), app.NSpinner.Value);
                                                    scale = round(2.^exponents);
                                                    % Set the q-orders.
                                                    q = linspace(-app.maxqSpinner.Value, app.maxqSpinner.Value, 101);
                                                    % Set the order of the polynomial fit.
                                                    m = app.mSpinner.Value;

                                                    % Run MFDFA.
                                                    [Hq, tq, hq, Dq, Fq, qRegLine] = MFDFA1(dat, scale, q, m, plotOption, app.NoiselevelEditField.Value);

                                                    % All of the direct output are saved to analysis.
                                                    app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).Hq{1, ii} = Hq;
                                                    app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).tq{1, ii} = tq;
                                                    app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hq{1, ii} = hq;
                                                    app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).Dq{1, ii} = Dq;
                                                    app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).Fq{1, ii} = Fq;
                                                    %
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).MfdfaScaleMin(1, ii) = app.MinimumSpinner.Value;
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).MfdfaScaleMax(1, ii) = app.MaximumSpinner.Value;
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).MfdfaScaleN(1, ii) = app.NSpinner.Value;
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).q(1, ii) = app.maxqSpinner.Value;
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).m(1, ii) = app.mSpinner.Value;
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).NoiseLevel(1, ii) = app.NoiselevelEditField.Value;
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqMin(1, ii) = min(hq);
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqMax(1, ii) = max(hq);
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqRange(1, ii) = max(hq) - min(hq);
                                                    app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqAsymetry(1, ii) = (hq(Dq == max(Dq)) - min(hq)) - (max(hq) - hq(Dq == max(Dq)));
                                                    %app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{k}).a(1, ii) = a;
                                                    %app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{k}).r2(1, ii) = r2;

                                                    % Set the values in the table so they are visible.
                                                    dataTable.File{ind, 1} = files{i};
                                                    dataTable.Object{ind, 1} = objs{j};
                                                    dataTable.Signal{ind, 1} = sigs{ind_sig};
                                                    dataTable.Dimension(ind, 1) = ii;
                                                    dataTable.hqMin(ind, 1) = min(hq);
                                                    dataTable.hqMax(ind, 1) = max(hq);
                                                    dataTable.hqRange(ind, 1) = max(hq) - min(hq);
                                            end

                                            if strcmp(app.CreatefiguresSwitch.Value, 'Yes')
                                                % Create the plot.
                                                switch dfa
                                                    case 'mono'
                                                        H = plot_DFA(dat, a, r2, out_a, out_l);
                                                    case 'multi'
                                                        H = plot_MFDFA(dat, q, qRegLine, scale, Hq, tq, hq, Dq, Fq);
                                                end
                                                % Make sure the directory to save it exists.
                                                if ~isfield(app.Data.(type).(files{i}), 'groups') || any(contains(app.Data.(type).(files{i}).groups, ':'))
                                                    % Create the folder using the filename replacement.
                                                    pathName = [app.DAMVi_App.Database.Value '\Figures\DFA\' files{i} '\' objs{j} '\' sigs{ind_sig} '\' num2str(ii)];
                                                else
                                                    % Create the folder using the file group information.
                                                    pathName = [app.DAMVi_App.Database.Value '\Figures\DFA\' strjoin(app.Data.(type).(files{i}).groups, '\') '\' objs{j} '\' sigs{ind_sig} '\' num2str(ii)];
                                                end
                                                mkdir(pathName)
                                                switch dfa
                                                    case 'mono'
                                                        saveas(H, [pathName '\DFA.jpg'])
                                                        close(H)
                                                    case 'multi'
                                                        saveas(H, [pathName '\MFDFA.jpg'])
                                                        close(H)
                                                end
                                            end

                                            % Change the save flag so this data saves.
                                            flagSave = 1;

                                        else
                                            % Set the values in the table so they are visible.
                                            dataTable.File{ind, 1} = files{i};
                                            dataTable.Object{ind, 1} = objs{j};
                                            dataTable.Signal{ind, 1} = sigs{ind_sig};
                                            dataTable.Dimension(ind, 1) = ii;

                                            switch dfa
                                                case 'mono'
                                                    dataTable.Alpha(ind, 1) = app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).a(1, ii);
                                                    dataTable.r2(ind, 1) = app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).r2(1, ii);
                                                case 'multi'
                                                    dataTable.hqMin(ind, 1) = app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqMin(1, ii);
                                                    dataTable.hqMax(ind, 1) = app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqMax(1, ii);
                                                    dataTable.hqRange(ind, 1) = app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqRange(1, ii);
                                            end

                                            % Change the save flag so this existing data does not save.
                                            flagSave = 0;

                                        end

                                    else % The data is empty so fill in NaNs.

                                        dataTable.File{ind, 1} = files{i};
                                        dataTable.Object{ind, 1} = objs{j};
                                        dataTable.Signal{ind, 1} = sigs{ind_sig};
                                        dataTable.Dimension(ind, 1) = ii;

                                        % If an error occurs set all the same values to NaNs.
                                        switch dfa
                                            case 'mono'
                                                % Set the scale parameters using the graphical inputs.
                                                n_length = app.ofpointsforlinearregionSpinner.Value;
                                                n_max = length(app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig})(:, ii))/app.MaximumboxsizeasfractionofdatalengthSpinner.Value;
                                                n_min = app.MinimumBoxSizeSpinner.Value;

                                                app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).out_a{1, ii} = NaN;
                                                app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).out_l{1, ii} = NaN;
                                                % The alpha and goodness of fit are saved to the results.
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).n_min(1, ii) = n_min;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).n_max(1, ii) = n_max;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).n_length(1, ii) = n_length;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).a(1, ii) = NaN;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).r2(1, ii) = NaN;

                                                dataTable.Alpha(ind, 1) = NaN;
                                                dataTable.R2(ind, 1) = NaN;
                                            case 'multi'
                                                % All of the direct output are saved to analysis.
                                                app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).Hq{1, ii} = NaN;
                                                app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).tq{1, ii} = NaN;
                                                app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hq{1, ii} = NaN;
                                                app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).Dq{1, ii} = NaN;
                                                app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).Fq{1, ii} = NaN;

                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).MfdfaScaleMin(1, ii) = app.MinimumSpinner.Value;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).MfdfaScaleMax(1, ii) = app.MaximumSpinner.Value;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).MfdfaScaleN(1, ii) = app.NSpinner.Value;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).q(1, ii) = app.maxqSpinner.Value;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).m(1, ii) = app.mSpinner.Value;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).NoiseLevel(1, ii) = app.NoiselevelEditField.Value;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqMin(1, ii) = NaN;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqMax(1, ii) = NaN;
                                                app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqRange(1, ii) = NaN;

                                                dataTable.hqMin(ind, 1) = NaN;
                                                dataTable.hqMax(ind, 1) = NaN;
                                                dataTable.hqRange(ind, 1) = NaN;

                                        end

                                    end

                                catch ME % An error occured so fill in NaNs.

                                    dataTable.File{ind, 1} = files{i};
                                    dataTable.Object{ind, 1} = objs{j};
                                    dataTable.Signal{ind, 1} = sigs{ind_sig};
                                    dataTable.Dimension(ind, 1) = ii;

                                    % If an error occurs set all the same values to NaNs.
                                    switch dfa
                                        case 'mono'
                                            % Set the scale parameters using the graphical inputs.
                                            n_length = app.ofpointsforlinearregionSpinner.Value;
                                            n_max = length(app.Data.(type).(files{i}).(objs{j}).data.(sigs{ind_sig})(:, ii))/app.MaximumboxsizeasfractionofdatalengthSpinner.Value;
                                            n_min = app.MinimumBoxSizeSpinner.Value;

                                            app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).out_a{1, ii} = NaN;
                                            app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).out_l{1, ii} = NaN;
                                            % The alpha and goodness of fit are saved to the results.
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).n_min(1, ii) = n_min;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).n_max(1, ii) = n_max;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).n_length(1, ii) = n_length;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).a(1, ii) = NaN;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).r2(1, ii) = NaN;

                                            dataTable.Alpha(ind, 1) = NaN;
                                            dataTable.R2(ind, 1) = NaN;
                                        case 'multi'
                                            % All of the direct output are saved to analysis.
                                            app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).Hq{1, ii} = NaN;
                                            app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).tq{1, ii} = NaN;
                                            app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hq{1, ii} = NaN;
                                            app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).Dq{1, ii} = NaN;
                                            app.Data.ana.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).Fq{1, ii} = NaN;

                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).MfdfaScaleMin(1, ii) = app.MinimumSpinner.Value;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).MfdfaScaleMax(1, ii) = app.MaximumSpinner.Value;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).MfdfaScaleN(1, ii) = app.NSpinner.Value;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).q(1, ii) = app.maxqSpinner.Value;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).m(1, ii) = app.mSpinner.Value;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).NoiseLevel(1, ii) = app.NoiselevelEditField.Value;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqMin(1, ii) = NaN;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqMax(1, ii) = NaN;
                                            app.Data.res.DFA.(files{i}).(objs{j}).data.(sigs{ind_sig}).hqRange(1, ii) = NaN;

                                    end


                                    % Print a message with the file and object and signal information for the one that failed.
                                    printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{ind_sig}])

                                    % Get MATLAB's last error message and display it.
                                    printLog(app, '004', ME.message)

                                    % Up the error count.
                                    flagError = flagError + 1;
                                end
                                if ind > 100 && flagError/ind > 0.10
                                    printLog(app, '005')
                                    return
                                end

                                % Increase the index for the table.
                                ind = ind + 1;

                                % Every 10 results print an update to the BAR App log.
                                if rem(ind, 1000) == 0
                                    printLog(app, '001', ['finished ' num2str(ind) ' results from ' num2str(height(files)) ' files'])
                                    % Sent the table structure to the module's table and set the column names to the fieldnames.
                                    app.UITable.Data = struct2table(dataTable);
                                    app.UITable.ColumnName = fieldnames(dataTable);
                                    if strcmp(app.SaveperiodicallySwitch.Value, 'Yes') && flagSave
                                        analysisComplete(app.DAMVi_App, app.Data, 'DFA', 1)
                                    end
                                end

                            end
                        end
                    end
                end


            end

            % Print a message that the analysis is complete.
            printLog(app, '002')
            % Put the table data into the module and update the column headers with the field names.
            app.UITable.Data = struct2table(dataTable);
            app.UITable.ColumnName = fieldnames(dataTable);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % StartupFcn(app, mainapp, data, analysis)
            % inputs  - app, required mlapp object.
            %         - mlapp, BAR App objects.
            %         - data, BAR App data structure.
            %         - analysis, string of the analysis name.
            % Remarks
            % - This is the startup method that initiallized the app.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.DAMVi_App = mainapp;
            % Save the data into the module.
            app.Data = data;
            % Initialize the signal list in the app.
            updatelist(app)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.DAMVi_App.Version.Text;

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % ProcessButton(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information about the app state.
            % Remarks
            % - This is a pass-through method that tells the processData method to use mon-fractal DFA.
            % Future Work
            % - None.
            % Nov 2023 - Created by Ben Senderling, bms322@drexel.edu

            dfa = 'mono';
            processData(app, dfa)


        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will send the data back to the BAR App and save it.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % The analysis name that will be used to save the data.
            analysis = 'DFA';
            % Save the data using a BAR_App method.
            analysisComplete(app.DAMVi_App, app.Data, analysis, 0)
            % Close the app.
            delete(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            updatelist(app)
        end

        % Button pushed function: ProcessButton_2
        function ProcessButton_2Pushed(app, event)
            % ProcessButton_2Pushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information about the app state.
            % Remarks
            % - This is a pass-through method that tells the processData method to use multi-fractal DFA.
            % Future Work
            % - None.
            % Nov 2023 - Created by Ben Senderling, bms322@drexel.edu

            dfa = 'multi';
            processData(app, dfa)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.DetrendedFluctuationAnalysisLabel = uilabel(app.UIFigure);
            app.DetrendedFluctuationAnalysisLabel.Text = 'Detrended Fluctuation Analysis';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Raw';
            app.SelectthesignalstoprocessListBoxLabel = uilabel(app.UIFigure);
            app.SelectthesignalstoprocessListBoxLabel.Text = 'Select the signals to process';
            app.SelectthesignalstoprocessListBox = uilistbox(app.UIFigure);
            app.SelectthesignalstoprocessListBox.Items = {};
            app.SelectthesignalstoprocessListBox.Multiselect = 'on';
            app.SelectthesignalstoprocessListBox.Value = {};
            app.Version = uilabel(app.UIFigure);
            app.SaveperiodicallySwitchLabel = uilabel(app.UIFigure);
            app.SaveperiodicallySwitchLabel.Text = 'Save periodically?';
            app.SaveperiodicallySwitch = uiswitch(app.UIFigure, 'slider');
            app.SaveperiodicallySwitch.Items = {'Yes', 'No'};
            app.SaveperiodicallySwitch.Value = 'Yes';
            app.SkipexistingSwitchLabel = uilabel(app.UIFigure);
            app.SkipexistingSwitchLabel.Text = 'Skip existing?';
            app.SkipexistingSwitch = uiswitch(app.UIFigure, 'slider');
            app.SkipexistingSwitch.Items = {'Yes', 'No'};
            app.SkipexistingSwitch.Value = 'Yes';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MonoTab = uitab(app.TabGroup);
            app.MonoTab.Title = 'Mono';
            app.MinimumBoxSizeSpinnerLabel = uilabel(app.MonoTab);
            app.MinimumBoxSizeSpinnerLabel.Text = 'Minimum Box Size';
            app.MinimumBoxSizeSpinner = uispinner(app.MonoTab);
            app.MinimumBoxSizeSpinner.Limits = [10 Inf];
            app.MinimumBoxSizeSpinner.RoundFractionalValues = 'on';
            app.MinimumBoxSizeSpinner.Value = 10;
            app.MaximumboxsizeasfractionofdatalengthSpinnerLabel = uilabel(app.MonoTab);
            app.MaximumboxsizeasfractionofdatalengthSpinnerLabel.Text = 'Maximum box size as fraction of data length';
            app.MaximumboxsizeasfractionofdatalengthSpinner = uispinner(app.MonoTab);
            app.MaximumboxsizeasfractionofdatalengthSpinner.Limits = [1 Inf];
            app.MaximumboxsizeasfractionofdatalengthSpinner.RoundFractionalValues = 'on';
            app.MaximumboxsizeasfractionofdatalengthSpinner.Value = 8;
            app.ofpointsforlinearregionSpinnerLabel = uilabel(app.MonoTab);
            app.ofpointsforlinearregionSpinnerLabel.Text = '# of points for linear region';
            app.ofpointsforlinearregionSpinner = uispinner(app.MonoTab);
            app.ofpointsforlinearregionSpinner.Limits = [18 Inf];
            app.ofpointsforlinearregionSpinner.Value = 18;
            app.ProcessButton = uibutton(app.MonoTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.Leaveat0tousedefaultofN18Label = uilabel(app.MonoTab);
            app.Leaveat0tousedefaultofN18Label.Text = 'Leave at 0 to use default of N/18.';
            app.MultiTab = uitab(app.TabGroup);
            app.MultiTab.Title = 'Multi';
            app.ProcessButton_2 = uibutton(app.MultiTab, 'push');
            app.ProcessButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_2Pushed, true);
            app.ProcessButton_2.Text = 'Process';
            app.ScaleParametersLabel = uilabel(app.MultiTab);
            app.ScaleParametersLabel.Text = 'Scale Parameters';
            app.MaximumSpinnerLabel = uilabel(app.MultiTab);
            app.MaximumSpinnerLabel.Text = 'Maximum';
            app.MaximumSpinner = uispinner(app.MultiTab);
            app.MaximumSpinner.Limits = [10 Inf];
            app.MaximumSpinner.RoundFractionalValues = 'on';
            app.MaximumSpinner.Value = 1024;
            app.MinimumSpinnerLabel = uilabel(app.MultiTab);
            app.MinimumSpinnerLabel.Text = 'Minimum';
            app.MinimumSpinner = uispinner(app.MultiTab);
            app.MinimumSpinner.Limits = [10 Inf];
            app.MinimumSpinner.RoundFractionalValues = 'on';
            app.MinimumSpinner.Value = 16;
            app.NSpinnerLabel = uilabel(app.MultiTab);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.MultiTab);
            app.NSpinner.Limits = [1 Inf];
            app.NSpinner.RoundFractionalValues = 'on';
            app.NSpinner.Value = 19;
            app.maxqSpinnerLabel = uilabel(app.MultiTab);
            app.maxqSpinnerLabel.Text = '| max(q) |';
            app.maxqSpinner = uispinner(app.MultiTab);
            app.maxqSpinner.Limits = [1 5];
            app.maxqSpinner.RoundFractionalValues = 'on';
            app.maxqSpinner.Value = 5;
            app.mSpinnerLabel = uilabel(app.MultiTab);
            app.mSpinnerLabel.Text = 'm';
            app.mSpinner = uispinner(app.MultiTab);
            app.mSpinner.Limits = [1 Inf];
            app.mSpinner.RoundFractionalValues = 'on';
            app.mSpinner.Value = 1;
            app.NoiselevelEditFieldLabel = uilabel(app.MultiTab);
            app.NoiselevelEditFieldLabel.Text = 'Noise level';
            app.NoiselevelEditField = uieditfield(app.MultiTab, 'numeric');
            app.NoiselevelEditField.Limits = [0.0001 10000];
            app.NoiselevelEditField.Value = 1;
            app.CreatefiguresSwitchLabel = uilabel(app.UIFigure);
            app.CreatefiguresSwitchLabel.Text = 'Create figures';
            app.CreatefiguresSwitch = uiswitch(app.UIFigure, 'slider');
            app.CreatefiguresSwitch.Items = {'Yes', 'No'};
            app.CreatefiguresSwitch.Value = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_DFA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end