classdef analysis_Entropy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SkipExistingSwitch           matlab.ui.control.Switch
        SkipExistingSwitchLabel      matlab.ui.control.Label
        SavePeriodicallySwitch       matlab.ui.control.Switch
        SavePeriodicallySwitchLabel  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        GeneralParametersTab         matlab.ui.container.Tab
        MinimumDataLengthSpinner     matlab.ui.control.Spinner
        MinimumDataLengthSpinner_3Label  matlab.ui.control.Label
        rEditField                   matlab.ui.control.EditField
        rEditField_2Label_2          matlab.ui.control.Label
        mEditField                   matlab.ui.control.EditField
        mEditField_2Label_2          matlab.ui.control.Label
        Label2                       matlab.ui.control.Label
        SampleTab                    matlab.ui.container.Tab
        ProcessButton                matlab.ui.control.Button
        MultiscaleTab                matlab.ui.container.Tab
        ScaleFactorsEditField        matlab.ui.control.EditField
        ScaleFactorsEditFieldLabel   matlab.ui.control.Label
        Label2_2                     matlab.ui.control.Label
        ProcessButton_MSE            matlab.ui.control.Button
        Version                      matlab.ui.control.Label
        SelectthesignalstoprocessListBox  matlab.ui.control.ListBox
        SelectthesignalstoprocessListBoxLabel  matlab.ui.control.Label
        Switch_2                     matlab.ui.control.Switch
        SaveResultsandExitButton     matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        Image                        matlab.ui.control.Image
        EntropyAnalysisLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        DAMVi_App   % Main app object
        Data % Description
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of the error and organize the errors available.
            %         - newText, a string that may be inserted into the message.
            % Remarks
            % - This method will print messages to the log of the BAR App. The error codes are unique to each message so they can be found and
            %   referenced easier.
            % - The method is public and is acessible outside of the BAR App. It is used in some of the example analysis and process scripts.
            % Future Work
            % - This log should be printed and saved to a file location each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text within the log.
            text = app.DAMVi_App.Log.Value;

            % Match the error code with the message and update the text for
            % the log.
            switch code
                case '001'
                    text{length(text) + 1, 1} = ['ENT-001 ' newText];
                case '002'
                    text{length(text) + 1, 1} = 'ENT-002 analysis complete';
                case '003'
                    text{length(text) + 1, 1} = ['ENT-003 analysis failed on: ' newText];
                case '004'
                    text{length(text) + 1, 1} = ['ENT-004 ' newText];
                case '005'
                    text{length(text) + 1, 1} = 'ENT-005 existing analysis, more than 10% have failed';
                case '006'
                    text{length(text) + 1, 1} = 'ENT-006 not enough parameters entered';
            end

            % Create a date-time for the message.
            text{end, 1} = [char(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z')) ' | ' text{end, 1}];

            % Update the log.
            app.DAMVi_App.Log.Value = text;

            % Scroll to the bottom so the most recent message is visible.
            scroll(app.DAMVi_App.Log,'bottom')
            drawnow
        end

        function updatelist(app)
            % updatelist(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the signal list within the app with the currently selected data type.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Find which data type was selected.
            switch app.Switch_2.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end
            % Initialize the list with an 'all' option.
            sigsAll = {'all'};
            % Get the file names.
            files = fieldnames(app.Data.(type));
            % Iterate through the file names.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(app.Data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects
                for j = 1:length(objs)
                    % Append the signals to the list.
                    sigsAll = [sigsAll; fieldnames(app.Data.(type).(files{i}).(objs{j}).data)];
                end
            end
            % Set the unique signals to the list box.
            app.SelectthesignalstoprocessListBox.Items = unique(sigsAll);

        end

        function processData(app, entropy)
            % processData(app, entropy)
            % inputs  - app, required mlapp object.
            %         - entropy, a string of the type of Entropy analysis to run.
            % Remarks
            % - This method starts the analysis method. It pulls much of the inputs from the app interface. A different button is in each tab and each
            %   maps to this analysis method.
            % Future Work
            % - A couple other methods could be added as options.
            % Mar 2023 - Created by Ben Senderling, bsender@bu.edu

            % Empty the current table.
            app.UITable.Data = [];

            % Get the selected level of data to process.
            switch app.Switch_2.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Initialize an index for the array.
            ind = 1;
            % Initialize a variable to count the fails.
            fails = 0;
            % A flag that will prevent skipped data from being saved.
            flagSave = 0;
            
            % This tag is used for a switch/case later.
            switch entropy
                case 'sample'
                    entropy = 'EntropySample';
                case 'multiscale'
                    entropy = 'EntropyMultiscale';
            end

            % Get the user selected signals.
            sigsSelected = app.SelectthesignalstoprocessListBox.Value;

            % The module
            switch entropy

                case {'EntropySample', 'EntropyMultiscale'}
                    mEntropy = splitInput(app, split(app.mEditField.Value, ','));
                    rEntropy = splitInput(app, split(app.rEditField.Value, ','));
                    if strcmp(entropy, 'EntropyMultiscale')
                        scale = splitInput(app, split(app.ScaleFactorsEditField.Value, ','));
                    end

                    % Get the field names of all the files that were loaded.
                    files = fieldnames(app.Data.(type));

                    for ind_files = 1:length(files)
                        % Get the fieldnames for all the signals that were contained within that file. This could be different for each file so it is
                        % within the loop.
                        objs = fieldnames(app.Data.(type).(files{ind_files}));
                        objs(strcmp(objs, 'groups')) = [];
                        objs(strcmp(objs, 'meta')) = [];
                        % Iterate through the objects.
                        for ind_objs = 1:length(objs)
                            % Get the user selected signals.
                            sigs = fieldnames(app.Data.(type).(files{ind_files}).(objs{ind_objs}).data);
                            % Iterate through the signals.
                            for ind_sig = 1:length(sigs)
                                % Check if 'all' or nothing was selected or the current signal matches the selection.
                                if any(contains(sigsSelected, 'all')) || isempty(sigsSelected) || any(strcmp(app.SelectthesignalstoprocessListBox.Value, sigs{ind_sig}))
                                    % Get the number of dimensions to use.
                                    % Get the number of dimensions.
                                    if iscell(app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}))
                                        m = numel(app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}));
                                    else
                                        if size(app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}), 1) == 1
                                            m = 1;
                                        elseif size(app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}), 2) == 1
                                            m = 1;
                                        else
                                            m = size(app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}), 1);
                                        end
                                    end

                                    % Iterate through the dimensions.
                                    for ind_dim = 1:m

                                        if iscell(app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}))
                                            dat = app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}){ind_dim};
                                        else
                                            if size(app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}), 1) == 1
                                                dat = app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig})';
                                            elseif size(app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}), 2) == 1
                                                dat = app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig});
                                            else
                                                dat = app.Data.(type).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig})(:,ii);
                                            end

                                        end

                                        ind_SE = 1;

                                        if ~isempty(dat)

                                            if (~(strcmp(app.SkipExistingSwitch.Value, 'Yes') &&...
                                                    (isfield(app.Data, 'res') && isfield(app.Data.res, entropy) && isfield(app.Data.res.(entropy), files{ind_files}) && isfield(app.Data.res.(entropy).(files{ind_files}), objs{ind_objs}) && isfield(app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data, sigs{ind_sig}) && ...
                                                    ((isfield(app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}), 'SE') && size(app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SE, 1) >= ind_SE && size(app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SE, 2) >= ind_dim) || ...
                                                    (isfield(app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}), 'RCMSE') && size(app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).RCMSE, 1) >= ind_SE && size(app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).RCMSE, 2) >= ind_dim) ...
                                                    ) ...
                                                    ) ...
                                                 ) ...
                                               )

                                                switch entropy
                                                    case 'EntropySample'

                                                        for jj = 1:length(mEntropy)

                                                            for kk = 1:length(rEntropy)

                                                                % Save results to the table structure.
                                                                dataTable.File{ind, 1} = files{ind_files};
                                                                dataTable.Object{ind, 1} = objs{ind_objs};
                                                                dataTable.Signal{ind, 1} = sigs{ind_sig};
                                                                dataTable.Dimension(ind, 1) = ind_dim;
                                                                dataTable.m(ind, 1) = mEntropy(jj);
                                                                dataTable.r(ind, 1) = rEntropy(kk);
                                                                dataTable.N(ind, 1) = size(dat, 1);
                                                                dataTable.SignalMean(ind, 1) = mean(dat, 1);
                                                                dataTable.SignalSD(ind, 1) = std(dat, 1);

                                                                % All of the results will be saved to the analysis type.
                                                                app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).m(ind_SE, ind_dim) = mEntropy(jj);
                                                                app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).r(ind_SE, ind_dim) = rEntropy(kk);
                                                                app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).N(ind_SE, ind_dim) = size(dat, 1);
                                                                app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalMean(ind_SE, ind_dim) = mean(dat, 1);
                                                                app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalSD(ind_SE, ind_dim) = std(dat, 1);

                                                                % Check the data meets the length requirement.
                                                                if size(dat, 1) > app.MinimumDataLengthSpinner.Value

                                                                    try
                                                                        SE = Ent_Samp(dat, mEntropy(jj), rEntropy(kk));
                                                                        app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SE(ind_SE, ind_dim) = SE;
                                                                        dataTable.SE(ind, 1) = SE;
                                                                    catch ME
                                                                        % If there was a failure create NaNs.
                                                                        app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SE(ind_SE, ind_dim) = NaN;
                                                                        dataTable.SE(ind, 1) = NaN;

                                                                        % Print a message saying which data failed.
                                                                        printLog(app, '003', [files{ind_files} ' ' objs{ind_objs} ' ' sigs{ind_sig}])

                                                                        % Get MATLAB's last error message and display it.
                                                                        printLog(app, '004', ME.message)
                                                                        % Add to the fail counter.
                                                                        fails = fails + 1;

                                                                    end

                                                                else
                                                                    % If the data didn't meet the NaN requirement create NaNs.
                                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SE(ind_SE, ind_dim) = NaN;
                                                                    dataTable.SE(ind, 1) = NaN;

                                                                    % Print a message saying which data failed.
                                                                    printLog(app, '003', [files{ind_files} ' ' objs{ind_objs} ' ' sigs{ind_sig}])

                                                                    % Get MATLAB's last error message and display it.
                                                                    printLog(app, '004', 'data is less than the minimum length')
                                                                end
                                                                % Add one to the counter used to index multiple r and m values.
                                                                ind_SE = ind_SE + 1;
                                                                % Increase the index for the table structure.
                                                                ind = ind + 1;

                                                                % Every 10 files print an update message.
                                                                if rem(ind, 10) == 0
                                                                    printLog(app, '001', ['finished ' num2str(ind) ' results from ' num2str(height(files)) ' files'])
                                                                    % Sent the table structure to the module's table and set the column names to the fieldnames.
                                                                    app.UITable.Data = struct2table(dataTable);
                                                                    app.UITable.ColumnName = fieldnames(dataTable);
                                                                    if strcmp(app.SavePeriodicallySwitch.Value, 'Yes') && flagSave
                                                                        analysisComplete(app.DAMVi_App, app.Data, 'Entropy', 1)
                                                                    end
                                                                end

                                                            end
                                                        end

                                                    case 'EntropyMultiscale'
                                                        % Iterate through the m values.
                                                        for jj = 1:length(mEntropy)
                                                            % Iterate through the r values.
                                                            for kk = 1:length(rEntropy)
                                                                % Iterate through the scale values.
                                                                for ind_scale = 1:length(scale)

                                                                    % Save results to the table structure.
                                                                    dataTable.File{ind, 1} = files{ind_files};
                                                                    dataTable.Object{ind, 1} = objs{ind_objs};
                                                                    dataTable.Signal{ind, 1} = sigs{ind_sig};
                                                                    dataTable.Dimension(ind, 1) = ind_dim;
                                                                    dataTable.m(ind, 1) = mEntropy(jj);
                                                                    dataTable.r(ind, 1) = rEntropy(kk);
                                                                    dataTable.scale(ind, 1) = scale(ind_scale);
                                                                    dataTable.N(ind, 1) = size(dat, 1);
                                                                    dataTable.SignalMean(ind, 1) = mean(dat, 1);
                                                                    dataTable.SignalSD(ind, 1) = std(dat, 1);

                                                                    % All of the results will be saved to the analysis type.
                                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).m(ind_SE, ind_dim) = mEntropy(jj);
                                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).r(ind_SE, ind_dim) = rEntropy(kk);
                                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).scale(ind_SE, ind_dim) = scale(ind_scale);
                                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).N(ind_SE, ind_dim) = size(dat, 1);
                                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalMean(ind_SE, ind_dim) = mean(dat, 1);
                                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalSD(ind_SE, ind_dim) = std(dat, 1);

                                                                    % Check if the data meets the length requirement.
                                                                    if size(dat, 1) > app.MinimumDataLengthSpinner.Value

                                                                        try
                                                                            [RCMSE, CMSE, MSE, MSFE, GMSE] = Ent_MS_Plus(dat, scale(ind_scale), mEntropy(jj), rEntropy(kk));
                                                                            % Add the inidividual values to ana.
                                                                            app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).RCMSE{ind_SE, ind_dim} = RCMSE;
                                                                            app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).CMSE{ind_SE, ind_dim} = CMSE;
                                                                            app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSE{ind_SE, ind_dim} = MSE;
                                                                            app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSFE{ind_SE, ind_dim} = MSFE;
                                                                            app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).GMSE{ind_SE, ind_dim} = GMSE;
                                                                            % Add the complexity index to the results.
                                                                            app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).RCMSE(ind_SE, ind_dim) = sum(RCMSE);
                                                                            app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).CMSE(ind_SE, ind_dim) = sum(CMSE);
                                                                            app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSE(ind_SE, ind_dim) = sum(MSE);
                                                                            app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSFE(ind_SE, ind_dim) = sum(MSFE);
                                                                            app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).GMSE(ind_SE, ind_dim) = sum(GMSE);
                                                                            % Add the data to the data table.
                                                                            dataTable.RCMSE(ind, 1) = sum(RCMSE);
                                                                            dataTable.CMSE(ind, 1) = sum(CMSE);
                                                                            dataTable.MSE(ind, 1) = sum(MSE);
                                                                            dataTable.MSFE(ind, 1) = sum(MSFE);
                                                                            dataTable.GMSE(ind, 1) = sum(GMSE);

                                                                        catch
                                                                            % If there was a failure create NaNs.
                                                                            app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).RCMSE(ind_SE, ind_dim) = NaN;
                                                                            app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).CMSE(ind_SE, ind_dim) = NaN;
                                                                            app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSE(ind_SE, ind_dim) = NaN;
                                                                            app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSFE(ind_SE, ind_dim) = NaN;
                                                                            app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).GMSE(ind_SE, ind_dim) = NaN;
                                                                            app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).RCMSE(ind_SE, ind_dim) = NaN;
                                                                            app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).CMSE(ind_SE, ind_dim) = NaN;
                                                                            app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSE(ind_SE, ind_dim) = NaN;
                                                                            app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSFE(ind_SE, ind_dim) = NaN;
                                                                            app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).GMSE(ind_SE, ind_dim) = NaN;

                                                                            dataTable.RCMSE(ind, 1) = NaN;
                                                                            dataTable.CMSE(ind, 1) = NaN;
                                                                            dataTable.MSE(ind, 1) = NaN;
                                                                            dataTable.MSFE(ind, 1) = NaN;
                                                                            dataTable.GMSE(ind, 1) = NaN;

                                                                            % Print a message saying which data failed.
                                                                            printLog(app, '003', [files{ind_files} ' ' objs{ind_objs} ' ' sigs{ind_sig}])

                                                                            % Get MATLAB's last error message and display it.
                                                                            printLog(app, '004', ME.message)
                                                                            % Add to the fail counter.
                                                                            fails = fails + 1;
                                                                        end
                                                                    else
                                                                        % If the signal did not meet the length requirement.
                                                                        app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).RCMSE(ind_SE, ind_dim) = NaN;
                                                                        app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).CMSE(ind_SE, ind_dim) = NaN;
                                                                        app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSE(ind_SE, ind_dim) = NaN;
                                                                        app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSFE(ind_SE, ind_dim) = NaN;
                                                                        app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).GMSE(ind_SE, ind_dim) = NaN;
                                                                        app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).RCMSE(ind_SE, ind_dim) = NaN;
                                                                        app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).CMSE(ind_SE, ind_dim) = NaN;
                                                                        app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSE(ind_SE, ind_dim) = NaN;
                                                                        app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSFE(ind_SE, ind_dim) = NaN;
                                                                        app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).GMSE(ind_SE, ind_dim) = NaN;

                                                                        dataTable.RCMSE(ind, 1) = NaN;
                                                                        dataTable.CMSE(ind, 1) = NaN;
                                                                        dataTable.MSE(ind, 1) = NaN;
                                                                        dataTable.MSFE(ind, 1) = NaN;
                                                                        dataTable.GMSE(ind, 1) = NaN;
                                                                    end
                                                                    % Add one to the counter used to index multiple r and m values.
                                                                    ind_SE = ind_SE + 1;
                                                                    % Increase the index for the table structure.
                                                                    ind = ind + 1;

                                                                    % Every 10 files print an update message.
                                                                    if rem(ind, 1000) == 0
                                                                        printLog(app, '001', ['finished ' num2str(ind) ' results from ' num2str(height(files)) ' files'])
                                                                        % Sent the table structure to the module's table and set the column names to the fieldnames.
                                                                        app.UITable.Data = struct2table(dataTable);
                                                                        app.UITable.ColumnName = fieldnames(dataTable);
                                                                        if strcmp(app.SavePeriodicallySwitch.Value, 'Yes') && flagSave
                                                                            analysisComplete(app.DAMVi_App, app.Data, 'Entropy', 1)
                                                                        end
                                                                    end
                                                                end
                                                            end
                                                        end

                                                end

                                                % Change the save flag so this data saves.
                                                flagSave = 1;

                                            else % The data already exists so fill in the values.

                                                % Set the values in the table so they are visible.
                                                dataTable.File{ind, 1} = files{ind_files};
                                                dataTable.Object{ind, 1} = objs{ind_objs};
                                                dataTable.Signal{ind, 1} = sigs{ind_sig};
                                                dataTable.Dimension(ind, 1) = ind_dim;

                                                switch entropy
                                                    case 'EntropySample'

                                                        for jj = 1:length(mEntropy)

                                                            for kk = 1:length(rEntropy)

                                                                % Save results to the table structure.
                                                                dataTable.m(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).m(ind_SE, ind_dim);
                                                                dataTable.r(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).r(ind_SE, ind_dim);
                                                                dataTable.N(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).N(ind_SE, ind_dim);
                                                                dataTable.SignalMean(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalMean(ind_SE, ind_dim);
                                                                dataTable.SignalSD(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalSD(ind_SE, ind_dim);

                                                                dataTable.SE(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SE(ind_SE, ind_dim);
                                                                
                                                            end
                                                        end

                                                    case 'EntropyMultiscale'
                                                        % Iterate through the m values.
                                                        for jj = 1:length(mEntropy)
                                                            % Iterate through the r values.
                                                            for kk = 1:length(rEntropy)
                                                                % Iterate through the scale values.
                                                                for ind_scale = 1:length(scale)

                                                                    % Save results to the table structure.
                                                                    dataTable.m(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).m(ind_SE, ind_dim);
                                                                    dataTable.r(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).r(ind_SE, ind_dim);
                                                                    dataTable.scale(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).scale(ind_SE, ind_dim);
                                                                    dataTable.N(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).N(ind_SE, ind_dim);
                                                                    dataTable.SignalMean(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalMean(ind_SE, ind_dim);
                                                                    dataTable.SignalSD(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalSD(ind_SE, ind_dim);

                                                                    dataTable.RCMSE(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).RCMSE(ind_SE, ind_dim);
                                                                    dataTable.CMSE(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).CMSE(ind_SE, ind_dim);
                                                                    dataTable.MSE(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSE(ind_SE, ind_dim);
                                                                    dataTable.MSFE(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSFE(ind_SE, ind_dim);
                                                                    dataTable.GMSE(ind, 1) = app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).GMSE(ind_SE, ind_dim);

                                                                end
                                                            end
                                                        end

                                                end

                                                % Change the save flag so this existing data does not save.
                                                flagSave = 0;

                                            end

                                        else % The data is empty so fill in NaNs.

                                            dataTable.File{ind, 1} = files{ind_files};
                                            dataTable.Object{ind, 1} = objs{ind_objs};
                                            dataTable.Signal{ind, 1} = sigs{ind_sig};
                                            dataTable.Dimension(ind, 1) = ind_dim;

                                            % If an error occurs set all the same values to NaNs.
                                            switch entropy
                                                case 'EntropySample'
                                                    % Save results to the table structure.
                                                    dataTable.m(ind, 1) = NaN;
                                                    dataTable.r(ind, 1) = NaN;
                                                    dataTable.N(ind, 1) = size(dat, 1);
                                                    dataTable.SignalMean(ind, 1) = NaN;
                                                    dataTable.SignalSD(ind, 1) = NaN;

                                                    % All of the results will be saved to the results type.
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).m(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).r(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).N(ind_SE, ind_dim) = size(dat, 1);
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalMean(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalSD(ind_SE, ind_dim) = NaN;

                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SE(ind_SE, ind_dim) = NaN;
                                                    dataTable.SE(ind, 1) = NaN;

                                                case 'EntropyMultiscale'
                                                    % Save results to the table structure.
                                                    dataTable.m(ind, 1) = NaN;
                                                    dataTable.r(ind, 1) = NaN;
                                                    dataTable.scale(ind, 1) = NaN;
                                                    dataTable.N(ind, 1) = size(dat, 1);
                                                    dataTable.SignalMean(ind, 1) = NaN;
                                                    dataTable.SignalSD(ind, 1) = NaN;

                                                    % All of the results will be saved to the analysis type.
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).m(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).r(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).scale(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).N(ind_SE, ind_dim) = size(dat, 1);
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalMean(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).SignalSD(ind_SE, ind_dim) = NaN;

                                                    app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).RCMSE(ind_SE, ind_dim) = NaN;
                                                    app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).CMSE(ind_SE, ind_dim) = NaN;
                                                    app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSE(ind_SE, ind_dim) = NaN;
                                                    app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSFE(ind_SE, ind_dim) = NaN;
                                                    app.Data.ana.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).GMSE(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).RCMSE(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).CMSE(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSE(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).MSFE(ind_SE, ind_dim) = NaN;
                                                    app.Data.res.(entropy).(files{ind_files}).(objs{ind_objs}).data.(sigs{ind_sig}).GMSE(ind_SE, ind_dim) = NaN;

                                                    dataTable.RCMSE(ind, 1) = NaN;
                                                    dataTable.CMSE(ind, 1) = NaN;
                                                    dataTable.MSE(ind, 1) = NaN;
                                                    dataTable.MSFE(ind, 1) = NaN;
                                                    dataTable.GMSE(ind, 1) = NaN;

                                            end


                                        end

                                        % If more than 10% of the time series fail stop the analysis.
                                        if fails/length(files) > 0.1
                                            printLog(app, '005')
                                            return
                                        end

                                    end
                                end
                            end
                        end
                    end
            end
            % Print a message complete message to the log.
            printLog(app, '002')
            % Sent the table structure to the module's table and set the column names to the fieldnames.
            app.UITable.Data = struct2table(dataTable);
            app.UITable.ColumnName = fieldnames(dataTable);

        end

        function results = splitInput(app, input)
            % results = splitInput(app, input)
            % inputs  - app, required mlapp object.
            %         - input, a cell array of strings.
            % Remarks
            % - This method was made a subroutine because it is used to split the inputs for the various entropy methods.
            % Future Work
            % - A couple other methods could be added as options.
            % Mar 2023 - Created by Ben Senderling, bsender@bu.edu

            if isempty(input{1})
                printLog(app, '006')
                return
            end

            % Create an index for the results.
            ind2 = 1;
            % Iterate through the cell array.
            for i = 1:length(input)
                % Check if the string can be converted directly to a number and does not contain a dash.
                if ~isnan(str2double(input{i})) && ~contains(input{i}, '-')
                    % Convert the number directly.
                    mEntropy2(ind2, 1) = str2double(input{i});
                    ind2 = ind2 + 1;
                    % Check if the string includes a dash at the end.
                elseif strcmp(input{i}(end), '-')
                    % Create numbers from the string to the total number.
                    temp = str2double(input{i}(1:strfind(input{i}, '-') - 1)):length(cols);
                    mEntropy2(ind2:ind2 + length(temp) - 1, 1) = temp;
                    ind2 = ind2 + length(temp);
                    % Check iof the first number is a dash.
                elseif strcmp(input{i}(1), '-')
                    % Create numbers from 1 to the string as a number.
                    temp = 1:str2double(input{i}(strfind(input{i}, '-') + 1));
                    mEntropy2(ind2:ind2 + length(temp) - 1, 1) = temp;
                    ind2 = ind2 + length(temp);
                    % Check if it otherwise contains a dash.
                elseif contains(input{i}, '-')
                    % Convert the string before and after the dash and create an array of all the numbers inbetween.
                    temp = str2double(input{i}(1:strfind(input{i}, '-') - 1)):str2double(input{i}(strfind(input{i}, '-') + 1:end));
                    mEntropy2(ind2:ind2 + length(temp) - 1, 1) = temp;
                    ind2 = ind2 + length(temp);
                    % Check if it otherwise contains a colon.
                elseif length(strfind(input{i}, ':')) == 1
                    % Use the colon like you would in MATLAB to create an array.
                    temp = str2double(input{i}(1:strfind(input{i}, '-') - 1)):str2double(input{i}(strfind(input{i}, '-') + 1:end));
                end

            end
            % Create a unique array in case there were duplicates.
            results = sort(unique(mEntropy2));

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % StartupFcn(app, mainapp, data, analysis)
            % inputs  - app, required mlapp object.
            %         - mlapp, BAR App objects.
            %         - data, BAR App data structure.
            %         - analysis, string of the analysis name.
            % Remarks
            % - This is the startup method that initiallized the app.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.DAMVi_App = mainapp;
            % Save the data into the module.
            app.Data = data;
            % Initialize the signal list in the app.
            updatelist(app)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.DAMVi_App.Version.Text;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

            processData(app, 'sample')

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Callback function
        function SwitchValueChanged(app, event)
            % SwitchValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method controls the ability to use the time lag spinner. If the user selects 'AMI' the spinner is disabled.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current value of the spinner.
            value = app.Switch.Value;
            % If 'TIme Lag' is selected enable it and if 'AMI' is selected disable it.
            switch value
                case 'Time Lag'
                    app.MaximumLagSpinner.Enable = 'on';
                case 'AMI'
                    app.MaximumLagSpinner.Enable = 'off';
            end

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will send the data back to the BAR App and save it.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % The analysis name that will be used to save the data.
            analysis = 'Entropy';
            % Save the data using a BAR_App method.
            analysisComplete(app.DAMVi_App, app.Data, analysis, 0)
            % Close the app.
            delete(app)
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            updatelist(app)
        end

        % Button pushed function: ProcessButton_MSE
        function ProcessButton_MSEPushed(app, event)

            processData(app, 'multiscale')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.EntropyAnalysisLabel = uilabel(app.UIFigure);
            app.EntropyAnalysisLabel.Text = 'Entropy Analysis';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Raw', 'Processed'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Raw';
            app.SelectthesignalstoprocessListBoxLabel = uilabel(app.UIFigure);
            app.SelectthesignalstoprocessListBoxLabel.Text = 'Select the signals to process';
            app.SelectthesignalstoprocessListBox = uilistbox(app.UIFigure);
            app.SelectthesignalstoprocessListBox.Items = {};
            app.SelectthesignalstoprocessListBox.Multiselect = 'on';
            app.SelectthesignalstoprocessListBox.Value = {};
            app.Version = uilabel(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralParametersTab = uitab(app.TabGroup);
            app.GeneralParametersTab.Title = 'General Parameters';
            app.Label2 = uilabel(app.GeneralParametersTab);
            app.Label2.Text = 'Enter single numbers for m and r. Or enter multiple numbers separated by commas (Ex: 2,3,4). With multiple values either m or r can have one value or both have the same number of values.';
            app.mEditField_2Label_2 = uilabel(app.GeneralParametersTab);
            app.mEditField_2Label_2.Text = 'm';
            app.mEditField = uieditfield(app.GeneralParametersTab, 'text');
            app.rEditField_2Label_2 = uilabel(app.GeneralParametersTab);
            app.rEditField_2Label_2.Text = 'r';
            app.rEditField = uieditfield(app.GeneralParametersTab, 'text');
            app.MinimumDataLengthSpinner_3Label = uilabel(app.GeneralParametersTab);
            app.MinimumDataLengthSpinner_3Label.Text = 'Minimum Data Length';
            app.MinimumDataLengthSpinner = uispinner(app.GeneralParametersTab);
            app.SampleTab = uitab(app.TabGroup);
            app.SampleTab.Title = 'Sample';
            app.ProcessButton = uibutton(app.SampleTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.MultiscaleTab = uitab(app.TabGroup);
            app.MultiscaleTab.Title = 'Multiscale';
            app.ProcessButton_MSE = uibutton(app.MultiscaleTab, 'push');
            app.ProcessButton_MSE.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_MSEPushed, true);
            app.ProcessButton_MSE.Text = 'Process';
            app.Label2_2 = uilabel(app.MultiscaleTab);
            app.Label2_2.Text = 'Enter a single number for the scale factor. Or enter multiple numbers separated by commas (Ex: 2,3,4).';
            app.ScaleFactorsEditFieldLabel = uilabel(app.MultiscaleTab);
            app.ScaleFactorsEditFieldLabel.Text = 'Scale Factors';
            app.ScaleFactorsEditField = uieditfield(app.MultiscaleTab, 'text');
            app.SavePeriodicallySwitchLabel = uilabel(app.UIFigure);
            app.SavePeriodicallySwitchLabel.Text = 'Save Periodically?';
            app.SavePeriodicallySwitch = uiswitch(app.UIFigure, 'slider');
            app.SavePeriodicallySwitch.Items = {'No', 'Yes'};
            app.SavePeriodicallySwitch.Value = 'No';
            app.SkipExistingSwitchLabel = uilabel(app.UIFigure);
            app.SkipExistingSwitchLabel.Text = 'Skip Existing?';
            app.SkipExistingSwitch = uiswitch(app.UIFigure, 'slider');
            app.SkipExistingSwitch.Items = {'No', 'Yes'};
            app.SkipExistingSwitch.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_Entropy(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end