classdef analysis_ExampleApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Version                   matlab.ui.control.Label
        SelectthesignalstoprocessListBox  matlab.ui.control.ListBox
        SelectthesignalstoprocessListBoxLabel  matlab.ui.control.Label
        Switch_2                  matlab.ui.control.Switch
        SaveResultsandExitButton  matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        Image                     matlab.ui.control.Image
        CustomLabel               matlab.ui.control.Label
        ProcessButton             matlab.ui.control.Button
    end


    properties (Access = private)
        BAR_App   % Main app object
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of
            %           the error and organize the errors available.
            %         - newText, a string that may be inserted into the
            %                    message.
            % Remarks
            % - This method will print messages to the log of the BAR App.
            %   The error codes are unique to each message so they can be
            %   found and referenced easier.
            % - The method is public and is acessible outside of the BAR
            %   App. It is used in some of the example analysis and process
            %   scripts.
            % Future Work
            % - This log should be printed and saved to a file location
            %   each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text within the log.
            text = app.BAR_App.Log.Value;

            % Match the error code with the message and update the text for
            % the log.
            switch code
                case '001'
                    text{length(text) + 1, 1} = ['CSA-001 ' newText];
                case '002'
                    text{length(text) + 1, 1} = ['CSA-002 analysis complete'];
                case '003'
                    text{length(text) + 1, 1} = ['CSA-003 analysis failed on: ' newText];
                case '004'
                    text{length(text) + 1, 1} = ['CSA-004 ' newText];
            end

            % Update the log.
            app.BAR_App.Log.Value = text;

            % Scroll to the bottom so the most recent message is visible.
            scroll(app.BAR_App.Log,'bottom')
            drawnow
        end

        function updatelist(app)
            % updatelist(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the signal list within the app with the
            %   currently selected data type.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu


            % Get the current data from the app.
            data = getappdata(app.UITable, 'data');
            % Find which data type was selected.
            switch app.Switch_2.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end
            % Initialize the list with an 'all' option.
            sigsAll = {'all'};
            % Get the file names.
            files = fieldnames(data.(type));
            % Iterate through the file names.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects
                for j = 1:length(objs)
                    % Append the signals to the list.
                    sigsAll = [sigsAll; fieldnames(data.(type).(files{i}).(objs{j}).data)];
                end
            end
            % Set the unique signals to the list box.
            app.SelectthesignalstoprocessListBox.Items = unique(sigsAll);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % StartupFcn(app, mainapp, data, analysis)
            % inputs  - app, required mlapp object.
            %         - mlapp, BAR App objects.
            %         - data, BAR App data structure.
            %         - analysis, string of the analysis name.
            % Remarks
            % - This is the startup method that initiallized the app.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            % Save the data into the module.
            setappdata(app.UITable, 'data', data)
            % Initialize the signal list in the app.
            updatelist(app)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.BAR_App.Version.Text;

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % ProcessButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This is an example app for creating custom analysis
            %   methods. It is functional as an example but may not work
            %   with all data sets.
            % - Much of the user interfase has been removed. If switches
            %   or other components are added they would likely be used in
            %   this method.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the data from the app.
            data = getappdata(app.UITable, 'data');
            % Get the selected level of data to process.
            switch app.Switch_2.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end
            % Initialize an index for the array.
            ind = 1;

            % Get the field names of all the files that were loaded.
            files = fieldnames(data.(type));

            % Iterate through the files.
            for i = 1:length(files)

                % Get the fieldnames for all the signals that were
                % contained within that file. This could be different for
                % each file so it is within the loop.
                objs = fieldnames(data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];

                % Iterate through the objects.
                for j = 1:length(objs)

                    % Get the user selected signals.
                    sigs = app.SelectthesignalstoprocessListBox.Value;

                    % If 'all' or nothing was selected get the signal names
                    % from the current object.
                    if any(contains(sigs, 'all')) || isempty(sigs)
                        sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                    end

                    % Iterate through the signals.
                    for k = 1:length(sigs)

                        % Get the number of dimensions to use.
                        m = size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2);

                        % Iterate through the dimensions.
                        for ii = 1:m

                            % Come up with a tag to reference the
                            % dimensions.
                            if ii < 10
                                mString = ['00' num2str(ii)];
                            elseif m < 100
                                mString = ['0' num2str(ii)];
                            elseif m < 1000
                                mString = num2str(ii);
                            end

                            try
                                % Run the analysis method.
                                [resultMean] = mean(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:,ii));

                                % The result will be saved to the
                                % analysis type. Usually this would
                                % be an array or some other type
                                % that would not be exported but
                                % might be used in figures.
                                data.ana.CustomApp.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).mean = resultMean;

                                % The result will be saved to the
                                % results type.
                                data.res.CustomApp.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).mean = resultMean;

                                % Save results to the table
                                % structure.
                                dataTable.File{ind, 1} = files{i};
                                dataTable.Object{ind, 1} = objs{j};
                                dataTable.Signal{ind, 1} = sigs{k};
                                dataTable.Dimension{ind, 1} = ii;
                                dataTable.Mean(ind, 1) = resultMean;

                            catch ME
                                % If there is an error set all the same
                                % reults to NaNs.
                                data.ana.CustomApp.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).mean = NaN;

                                data.res.CustomApp.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).mean = NaN;

                                dataTable.File{ind, 1} = files{i};
                                dataTable.Object{ind, 1} = objs{j};
                                dataTable.Signal{ind, 1} = sigs{k};
                                dataTable.Dimension{ind, 1} = ii;
                                dataTable.Mean(ind, 1) = resultMean;

                                % Print a message saying which data
                                % failed.
                                printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])

                                % Get MATLAB's last error message and display it.
                                printLog(app, '004', ME.message)

                            end
                            % Increase the index for the table structure.
                            ind = ind + 1;
                        end
                    end
                end
                % Every 10 files print an update message.
                if rem(i, 10) == 0
                    printLog(app, '001', ['finished ' num2str(i) ' of ' num2str(height(files)) ' files'])
                end
            end

            % Print a message complete message to the log.
            printLog(app, '002')
            % Save the data to the module.
            setappdata(app.UITable, 'data', data)
            % Sent the table structure to the module's table and set the
            % column names to the fieldnames.
            app.UITable.Data = struct2table(dataTable);
            app.UITable.ColumnName = fieldnames(dataTable);

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Callback function
        function SwitchValueChanged(app, event)
            % SwitchValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This method controls the ability to use the time lag
            %   spinner. If the user selects 'AMI' the spinner is disabled.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current value of the spinner.
            value = app.Switch.Value;
            % If 'TIme Lag' is selected enable it and if 'AMI' is selected
            % disable it.
            switch value
                case 'Time Lag'
                    app.MaximumLagSpinner.Enable = 'on';
                case 'AMI'
                    app.MaximumLagSpinner.Enable = 'off';
            end

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This method will send the data back to the BAR App and save
            %   it.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the module.
            data = getappdata(app.UITable, 'data');
            % The analysis name that will be used to save the data.
            analysis = 'TimeLag';
            % Save the data using a BAR_App method.
            analysisComplete(app.BAR_App, data, analysis, 0)
            % Close the app.
            delete(app)
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            updatelist(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.CustomLabel = uilabel(app.UIFigure);
            app.CustomLabel.Text = 'Custom';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Raw', 'Processed'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Raw';
            app.SelectthesignalstoprocessListBoxLabel = uilabel(app.UIFigure);
            app.SelectthesignalstoprocessListBoxLabel.Text = 'Select the signals to process';
            app.SelectthesignalstoprocessListBox = uilistbox(app.UIFigure);
            app.SelectthesignalstoprocessListBox.Items = {};
            app.SelectthesignalstoprocessListBox.Multiselect = 'on';
            app.SelectthesignalstoprocessListBox.Value = {};
            app.Version = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_ExampleApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end