classdef analysis_FNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SkipexistingSwitch            matlab.ui.control.Switch
        SkipexistingSwitchLabel       matlab.ui.control.Label
        SaveperiodicallySwitch        matlab.ui.control.Switch
        SaveperiodicallySwitchLabel   matlab.ui.control.Label
        Version                       matlab.ui.control.Label
        SelectthesignalstoprocessListBox  matlab.ui.control.ListBox
        SelectthesignalstoprocessListBoxLabel  matlab.ui.control.Label
        TimelagselectionSwitch        matlab.ui.control.Switch
        TimelagselectionSwitchLabel   matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        EvaluatealldimensionsSwitch   matlab.ui.control.Switch
        EvaluatealldimensionsSwitchLabel  matlab.ui.control.Label
        AToleranceSpinner             matlab.ui.control.Spinner
        AToleranceSpinnerLabel        matlab.ui.control.Label
        RToleranceSpinner             matlab.ui.control.Spinner
        RToleranceSpinnerLabel        matlab.ui.control.Label
        SaveResultsandExitButton      matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        MaximumDimensionSpinner       matlab.ui.control.Spinner
        MaximumDimensionSpinnerLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        FalseNearestNeighborsAnalysisLabel  matlab.ui.control.Label
        ProcessButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        BAR_App   % Main app object
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of the error and organize the errors available.
            %         - newText, a string that may be inserted into the message.
            % Remarks
            % - This method will print messages to the log of the BAR App. The error codes are unique to each message so they can be found and 
            %   referenced easier.
            % - The method is public and is acessible outside of the BAR App. It is used in some of the example analysis and process scripts.
            % Future Work
            % - This log should be printed and saved to a file location each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text of the log.
            text = app.BAR_App.Log.Value;
            % Match the code with the message.
            switch code
                case '001'
                    text{length(text) + 1,1} = ['FNN-001 ' newText];
                case '002'
                    text{length(text) + 1,1} = ['FNN-002 analysis complete'];
                case '003'
                    text{length(text) + 1,1} = ['FNN-003 analysis failed on: ' newText];
                case '004'
                    text{length(text) + 1,1} = ['FNN-004 ' newText];
            end
            % Create a date-time for the message.
            text{end, 1} = [char(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z')) ' | ' text{end, 1}];
            % Add the message to the log.
            app.BAR_App.Log.Value = text;
            % Scroll down so the message is visible.
            scroll(app.BAR_App.Log,'bottom')
            % Make sure the app updates.
            drawnow
            % Write to the log file.
            writeLog(app.BAR_App, text{end, 1})
            
        end

        function updatelist(app)
            % updatelist(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the signal list within the app with the currently selected data type.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the app.
            data = getappdata(app.UITable, 'data');
            % Find which data type was selected.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Initialize the list with an 'all' option.
            sigsAll = {'all'};

            % Get the file names.
            files = fieldnames(data.(type));
            % Iterate through the file names.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects
                for j = 1:length(objs)
                    % Append the signals to the list.
                    sigsAll = [sigsAll; fieldnames(data.(type).(files{i}).(objs{j}).data)];
                end
            end

            % Set the unique signals to the list box.
            app.SelectthesignalstoprocessListBox.Items = unique(sigsAll);
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % StartupFcn(app, mainapp, data, analysis)
            % inputs  - app, required mlapp object.
            %         - mlapp, BAR App objects.
            %         - data, BAR App data structure.
            %         - analysis, string of the analysis name.
            % Remarks
            % - This is the startup method that initiallized the app.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            % Save the data into the module.
            setappdata(app.UITable, 'data', data)
            % Initialize the signal list in the app.
            updatelist(app)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.BAR_App.Version.Text;

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % ProcessButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method starts the analysis method. It pulls much of the primary and secondary inputs from the app interface. These have default 
            %   values but can be changed by the user.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Empty the current table.
            app.UITable.Data = [];

            % Get the data from the app.
            data = getappdata(app.UITable, 'data');

            % Get the selected level of data to process.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Get the secondary inputs from the app interface.
            % Maximum dimension to run the calculations to.
            maxDim = app.MaximumDimensionSpinner.Value;
            % R Tolerance.
            rTol = app.RToleranceSpinner.Value;
            % A Tolarance.
            aTol = app.AToleranceSpinner.Value;
            % The processing speed. A 0 will tell the method to end once an embedding dimension is found and it will not calculate to the maximum 
            % dimension.
            switch app.EvaluatealldimensionsSwitch.Value
                case 'Yes'
                    speed = 0;
                case 'No'
                    speed = 1;
            end

            % Initialize the table to view the results.
            dataTable = struct;
            % Starting index for the table.
            ind = 1;
            % A flag that will prevent skipped data from being saved.
            flagSave = 0;

            % Get the user selected signals.
            sigsSelected = app.SelectthesignalstoprocessListBox.Value;

            % Get the field names of all the files that were loaded.
            files = fieldnames(data.(type));
            % Iterate through the files.
            for i = 1:length(files)
                % Get the fieldnames for all the signals that were contained within that file. This could be different for each file so it is within 
                % the loop.
                objs = fieldnames(data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects.
                for j = 1:length(objs)
                    % Get the signals from the object.
                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                    % Iterate through the signals.
                    for k = 1:length(sigs)
                        % Check if 'all' or nothing was selected or the current signal matches the selection.
                        if any(contains(sigsSelected, 'all')) || isempty(sigsSelected) || any(strcmp(app.SelectthesignalstoprocessListBox.Value, sigs{ind_sig}))
                            % Get the number of dimensions.
                            m = size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2);
                            % Iterate through the dimensions.
                            for ii = 1:m

                                % if strcmp(app.SkipexistingSwitch.Value, 'Yes') && (isfield(data.res, 'FNN') && isfield(data.res.FNN, files{i}) && isfield(data.res.FNN.(files{i}), objs{j}) && isfield(data.res.FNN.(files{i}).(objs{j}).data, sigs{k}) && isfield(data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}), 'dim') && length(data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}).dim) >= ii)
                                %     % Set the values in the table so they are visible.
                                %     dataTable.File{ind, 1} = files{i};
                                %     dataTable.Object{ind, 1} = objs{j};
                                %     dataTable.Signal{ind, 1} = sigs{k};
                                %     dataTable.Dimension(ind, 1) = ii;
                                %     dataTable.EmbeddingDim(ind, 1) = data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}).dim(1, ii);
                                %     continue
                                % end
                                
                                % This try-catch helps check if a time series wasn't processed in a previous step and did not have a tau value 
                                % created.
                                try
                                    % Find which type of time lag to use and pull it from previous results.
                                    switch app.TimelagselectionSwitch.Value
                                        case 'First Minimum'
                                            tau = data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1st(1, ii);
                                        case '1 Fifth Value'
                                            tau = data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1_5th(1, ii);
                                    end
                                catch
                                    tau = NaN;
                                end
                                % If the time lag was undefined also set the embedding dimension to NaN.
                                if isnan(tau)
                                    data.ana.FNN.(files{i}).(objs{j}).data.(sigs{k}).dE{1, ii} = NaN;
                                    data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}).dim(1, ii) = NaN;

                                    dataTable.File{ind, 1} = files{i};
                                    dataTable.Object{ind, 1} = objs{j};
                                    dataTable.Signal{ind, 1} = sigs{k};
                                    dataTable.Dimension(ind, 1) = ii;
                                    dataTable.EmbeddingDim(ind, 1) = NaN;

                                    % Print a message with the file and object and signal information for the one that failed.
%                                     printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])

                                    % Get MATLAB's last error message and display it.
%                                     printLog(app, '004', 'time lag is nan')
                                else
                                    try
                                        if (~(strcmp(app.SkipexistingSwitch.Value, 'Yes') && (isfield(data.res, 'FNN') && isfield(data.res.FNN, files{i}) && isfield(data.res.FNN.(files{i}), objs{j}) && isfield(data.res.FNN.(files{i}).(objs{j}).data, sigs{k}) && isfield(data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}), 'dim') && length(data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}).dim) >= ii))) || isnan(data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}).dim(ii))

                                            % Run the FNN method on the data.
                                            [dim, dE] = FNN(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:,ii), tau, maxDim, rTol, aTol, speed);
                                            % The array of % FNN values is saved to analysis results.
                                            data.ana.FNN.(files{i}).(objs{j}).data.(sigs{k}).dE{1, ii} = dE;
                                            % The found embedding dimension is saved to the results.
                                            data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}).dim(1, ii) = dim;

                                            % Set the values in the table so they are visible.
                                            dataTable.File{ind, 1} = files{i};
                                            dataTable.Object{ind, 1} = objs{j};
                                            dataTable.Signal{ind, 1} = sigs{k};
                                            dataTable.Dimension(ind, 1) = ii;
                                            dataTable.EmbeddingDim(ind, 1) = dim;

                                            % Change the save flag so this data saves.
                                            flagSave = 1;

                                        else
                                            % Set the values in the table so they are visible.
                                            dataTable.File{ind, 1} = files{i};
                                            dataTable.Object{ind, 1} = objs{j};
                                            dataTable.Signal{ind, 1} = sigs{k};
                                            dataTable.Dimension(ind, 1) = ii;
                                            dataTable.EmbeddingDim(ind, 1) = data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}).dim(1, ii);

                                            % Change the save flag so this existing data does not save.
                                            flagSave = 0;

                                        end

                                    catch ME

                                        % If an error occurs set all the same values to NaNs.

                                        data.ana.FNN.(files{i}).(objs{j}).data.(sigs{k}).dE{1, ii} = NaN;
                                        data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}).dim(1, ii) = NaN;

                                        dataTable.File{ind, 1} = files{i};
                                        dataTable.Object{ind, 1} = objs{j};
                                        dataTable.Signal{ind, 1} = sigs{k};
                                        dataTable.Dimension(ind, 1) = ii;
                                        dataTable.EmbeddingDim(ind, 1) = NaN;

                                        % Print a message with the file and object and signal information for the one that failed.
                                        printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])

                                        % Get MATLAB's last error message and display it.
                                        printLog(app, '004', ME.message)
                                    end
                                end
                                % Increase the index for the table.
                                ind = ind + 1;
                            end
                        end
                    end
                end

                % Every 10 results print an update to the BAR App log.
                if rem(i, 10) == 0
                    printLog(app, '001', ['finished ' num2str(i) ' of ' num2str(height(files)) ' files'])
                    % Sent the table structure to the module's table and set the column names to the fieldnames.
                    app.UITable.Data = struct2table(dataTable);
                    app.UITable.ColumnName = fieldnames(dataTable);
                    if strcmp(app.SaveperiodicallySwitch.Value, 'Yes') && flagSave
                        analysisComplete(app.BAR_App, data, 'FNN', 1)
                    end
                end

            end

            % Print a message that the analysis is complete.
            printLog(app, '002')
            % Save the app data into the module.
            setappdata(app.UITable, 'data', data)
            % Put the table data into the module and update the column headers with the field names.
            app.UITable.Data = struct2table(dataTable);
            app.UITable.ColumnName = fieldnames(dataTable);

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will send the data back to the BAR App and save it.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the module.
            data = getappdata(app.UITable, 'data');
            % The analysis name that will be used to save the data.
            analysis = 'FNN';
            % Save the data using a BAR_App method.
            analysisComplete(app.BAR_App, data, analysis, 0)
            % Close the app.
            delete(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            updatelist(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.FalseNearestNeighborsAnalysisLabel = uilabel(app.UIFigure);
            app.FalseNearestNeighborsAnalysisLabel.Text = 'False Nearest Neighbors Analysis';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.MaximumDimensionSpinnerLabel = uilabel(app.UIFigure);
            app.MaximumDimensionSpinnerLabel.Text = 'Maximum Dimension';
            app.MaximumDimensionSpinner = uispinner(app.UIFigure);
            app.MaximumDimensionSpinner.Limits = [1 Inf];
            app.MaximumDimensionSpinner.RoundFractionalValues = 'on';
            app.MaximumDimensionSpinner.Value = 12;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.RToleranceSpinnerLabel = uilabel(app.UIFigure);
            app.RToleranceSpinnerLabel.Text = 'R Tolerance';
            app.RToleranceSpinner = uispinner(app.UIFigure);
            app.RToleranceSpinner.Limits = [1 Inf];
            app.RToleranceSpinner.Value = 15;
            app.AToleranceSpinnerLabel = uilabel(app.UIFigure);
            app.AToleranceSpinnerLabel.Text = 'A Tolerance';
            app.AToleranceSpinner = uispinner(app.UIFigure);
            app.AToleranceSpinner.Limits = [1 Inf];
            app.AToleranceSpinner.Value = 2;
            app.EvaluatealldimensionsSwitchLabel = uilabel(app.UIFigure);
            app.EvaluatealldimensionsSwitchLabel.Text = 'Evaluate all dimensions?';
            app.EvaluatealldimensionsSwitch = uiswitch(app.UIFigure, 'slider');
            app.EvaluatealldimensionsSwitch.Items = {'Yes', 'No'};
            app.EvaluatealldimensionsSwitch.Value = 'Yes';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Raw';
            app.TimelagselectionSwitchLabel = uilabel(app.UIFigure);
            app.TimelagselectionSwitchLabel.Text = 'Time lag selection?';
            app.TimelagselectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.TimelagselectionSwitch.Items = {'First Minimum', '1 Fifth Value'};
            app.TimelagselectionSwitch.Value = 'First Minimum';
            app.SelectthesignalstoprocessListBoxLabel = uilabel(app.UIFigure);
            app.SelectthesignalstoprocessListBoxLabel.Text = 'Select the signals to process';
            app.SelectthesignalstoprocessListBox = uilistbox(app.UIFigure);
            app.SelectthesignalstoprocessListBox.Items = {};
            app.SelectthesignalstoprocessListBox.Multiselect = 'on';
            app.SelectthesignalstoprocessListBox.Value = {};
            app.Version = uilabel(app.UIFigure);
            app.SaveperiodicallySwitchLabel = uilabel(app.UIFigure);
            app.SaveperiodicallySwitchLabel.Text = 'Save periodically?';
            app.SaveperiodicallySwitch = uiswitch(app.UIFigure, 'slider');
            app.SaveperiodicallySwitch.Items = {'Yes', 'No'};
            app.SaveperiodicallySwitch.Value = 'Yes';
            app.SkipexistingSwitchLabel = uilabel(app.UIFigure);
            app.SkipexistingSwitchLabel.Text = 'Skip existing?';
            app.SkipexistingSwitch = uiswitch(app.UIFigure, 'slider');
            app.SkipexistingSwitch.Items = {'Yes', 'No'};
            app.SkipexistingSwitch.Value = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_FNN(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end