classdef analysis_LyE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MaximumdatalengthtoprocessSpinner  matlab.ui.control.Spinner
        MaximumdatalengthtoprocessSpinnerLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        WolfTab                       matlab.ui.container.Tab
        SecondaryInputsPanel          matlab.ui.container.Panel
        UsesecondaryparametersSwitch  matlab.ui.control.Switch
        UsesecondaryparametersSwitchLabel  matlab.ui.control.Label
        ZMultiplierEditField          matlab.ui.control.NumericEditField
        ZMultiplierEditFieldLabel     matlab.ui.control.Label
        AngleMaximumasaportionofpiEditField  matlab.ui.control.NumericEditField
        AngleMaximumasaportionofpiEditFieldLabel  matlab.ui.control.Label
        SetValueEditField             matlab.ui.control.NumericEditField
        SetValueEditFieldLabel        matlab.ui.control.Label
        ScaleMaximumMethodDropDown    matlab.ui.control.DropDown
        ScaleMaximumMethodDropDownLabel  matlab.ui.control.Label
        EvolveinframesEditField       matlab.ui.control.NumericEditField
        EvolveinframesLabel           matlab.ui.control.Label
        ProcessButton                 matlab.ui.control.Button
        SetValueSpinner               matlab.ui.control.Spinner
        SetValueSpinnerLabel          matlab.ui.control.Label
        TimelagselectionSwitch        matlab.ui.control.Switch
        TimelagselectionSwitchLabel   matlab.ui.control.Label
        EmbeddingDimensionDropDown    matlab.ui.control.DropDown
        EmbeddingDimensionDropDownLabel  matlab.ui.control.Label
        RosensteinTab                 matlab.ui.container.Tab
        SkipexistingSwitch            matlab.ui.control.Switch
        SkipexistingSwitchLabel       matlab.ui.control.Label
        Version                       matlab.ui.control.Label
        SelectthesignalstoprocessListBox  matlab.ui.control.ListBox
        SelectthesignalstoprocessListBoxLabel  matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        SaveperiodicallySwitch        matlab.ui.control.Switch
        SaveperiodicallySwitchLabel   matlab.ui.control.Label
        SaveResultsandExitButton      matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        Image                         matlab.ui.control.Image
        LyapunovExponentsLabel        matlab.ui.control.Label
    end


    properties (Access = private)
        BAR_App   % Main app object
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of the error and organize the errors available.
            %         - newText, a string that may be inserted into the message.
            % Remarks
            % - This method will print messages to the log of the BAR App. The error codes are unique to each message so they can be found and
            %   referenced easier.
            % - The method is public and is acessible outside of the BAR App. It is used in some of the example analysis and process scripts.
            % Future Work
            % - This log should be printed and saved to a file location each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text of the log.
            text = app.BAR_App.Log.Value;
            % Match the code with the message.
            switch code
                case '001'
                    text{length(text) + 1,1} = ['LYE-001 ' newText];
                case '002'
                    text{length(text) + 1,1} = 'LYE-002 analysis complete';
                case '003'
                    text{length(text) + 1,1} = ['LYE-003 analysis failed on: ' newText];
                case '004'
                    text{length(text) + 1,1} = ['LYE-004 ' newText];
            end
            % Create a date-time for the message.
            text{end, 1} = [char(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z')) ' | ' text{end, 1}];
            % Add the message to the log.
            app.BAR_App.Log.Value = text;
            % Scroll down so the message is visible.
            scroll(app.BAR_App.Log,'bottom')
            % Make sure the app updates.
            drawnow
            % Write to the log file.
            writeLog(app.BAR_App, text{end, 1})
        end

        function updatelist(app)
            % updatelist(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the signal list within the app with the currently selected data type.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the app.
            data = getappdata(app.UITable, 'data');
            % Find which data type was selected.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end
            % Initialize the list with an 'all' option.
            sigsAll = {'all'};
            % Get the file names.
            files = fieldnames(data.(type));
            % Iterate through the file names.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects
                for j = 1:length(objs)
                    % Append the signals to the list.
                    sigsAll = [sigsAll; fieldnames(data.(type).(files{i}).(objs{j}).data)];
                end
            end
            % Set the unique signals to the list box.
            app.SelectthesignalstoprocessListBox.Items = unique(sigsAll);

        end

        function dataTable = adjustExport(app, dataTable)
            % dataTable = adjustExport(app, dataTable)
            % inputs  - app, required mlapp object.
            %         - dataTable, the structure that will be adjusted for variable length before being viewable in the app.
            % output  - dataTable, the same structure adjusted for variable length.
            % Remarks
            % - This method adjusts the structure that will be set as the table data in the app for variable lengths.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % It's possible not all rows will have the same data, all the fields of the structure need to be the same size before it can be made into
            % a table. First find the largest field.
            exportfields = fieldnames(dataTable);
            N = zeros(length(exportfields), 1);
            for j = 1:length(exportfields)
                N(j) = length(dataTable.(exportfields{j}));
            end
            % Padd all the fields with empty arrays if they do not match the maximum.
            for j = 1:length(exportfields)
                if length(dataTable.(exportfields{j})) < max(N)
                    dataTable.(exportfields{j})(length(dataTable.(exportfields{j})):max(N)) = {[]};
                end
            end

        end

        function processData(app, analysis)
            % ProcessButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method starts the analysis method. It pulls much of the primary and secondary inputs from the app interface. These have default
            %   values but can be changed by the user.
            % Future Work
            % - The options for the embedding dimensions could include the ability to set them by groups instead of by dimension.
            % - The Rosenstein method needs to be added to this.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the data from the app.
            data = getappdata(app.UITable, 'data');

            % Empty the current table.
            app.UITable.Data = [];

            % Get the selected level of data to process.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Get the user selected signals.
            sigsSelected = app.SelectthesignalstoprocessListBox.Value;

            % All the dimensions will need to be retrieved beforehand. Each cell will house a different dimension. Initialize an empty array.
            dims = {};
            % Initialize an index for the array.
            ind = 1;
            % A flag that will prevent skipped data from being saved.
            flagSave = 0;

            % Get the file names.
            files = fieldnames(data.(type));
            % Iterate through the files.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects.
                for j = 1:length(objs)
                    % Get the signals from the object.
                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                    % Iterate through the signals.
                    for k = 1:length(sigs)
                        % Check if 'all' or nothing was selected or the current signal matches the selection.
                        if any(contains(sigsSelected, 'all')) || isempty(sigsSelected) || any(strcmp(app.SelectthesignalstoprocessListBox.Value, sigs{ind_sig}))
                            % Get the number of dimensions to use.
                            m = size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2);
                            % Iterate through the dimensions.
                            for ii = 1:m
                                % Store the dimension from previous results.
                                dims{ii}(ind, 1) = data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}).dim(ii);
                            end
                            % Increase the index of the array.
                            ind = ind + 1;
                        end
                    end
                end
            end

            % Set the embedding dimension to use for all the data based on the user selection.
            for i = 1:length(dims)
                switch app.EmbeddingDimensionDropDown.Value
                    case 'Mode'
                        dims{i} = mode(dims{i});
                    case 'Median'
                        dims{i} = median(dims{i});
                    case 'Mean'
                        dims{i} = mean(dims{i}, 'omitnan');
                    case 'Set Value'
                        dims{i} = app.SetValueSpinner.Value;
                end
            end

            % Get the field names of all the files that were loaded.
            files = fieldnames(data.(type));
            ind = 1;
            for i = 1:length(files)

                % Get the fieldnames for all the signals that were contained within that file. This could be different for each file so it is within
                % the loop.
                objs = fieldnames(data.(type).(files{i}));
                % Iterate through the objects.
                for j = 1:length(objs)
                    % Get the signals from the object.
                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                    % Iterate through the signal names.
                    for k = 1:length(sigs)
                        % Check if 'all' or nothing was selected or the current signal matches the selection.
                        if any(contains(sigsSelected, 'all')) || isempty(sigsSelected) || any(strcmp(app.SelectthesignalstoprocessListBox.Value, sigs{ind_sig}))

                            % Get the number of dimensions.
                            m = size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2);

                            % Skip time series that exceed the length requirement.
                            if length(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:, ii)) > app.MaximumdatalengthtoprocessSpinner.Value
                                for ii = 1:m

                                    data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE(1, ii) = -1;

                                    dataTable.File{ind, 1} = files{i};
                                    dataTable.Object{ind, 1} = objs{j};
                                    dataTable.Signal{ind, 1} = sigs{k};
                                    dataTable.Dimension(ind, 1) = ii;
                                    dataTable.TimeLag(ind, 1) = tau;
                                    dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                    dataTable.LyE(ind, 1) = data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE(1, ii);

                                    ind = ind + 1;
                                end

                                continue
                            end

                            % Iterate through the dimensions.
                            for ii = 1:m

                                % The time lag selection and pull them from a previous analysis.
                                if isfield(data.res.TimeLag.(files{i}).(objs{j}).data, sigs{k}) && isfield(data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}), 'tau1st')
                                    switch app.TimelagselectionSwitch.Value
                                        case 'First Minimum'
                                            tau = data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1st(ii);
                                        case '1 Fifth Value'
                                            tau = data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1_5th(ii);
                                    end
                                else
                                    data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE(1, ii) = -2;

                                    dataTable.File{ind, 1} = files{i};
                                    dataTable.Object{ind, 1} = objs{j};
                                    dataTable.Signal{ind, 1} = sigs{k};
                                    dataTable.Dimension(ind, 1) = ii;
                                    dataTable.TimeLag(ind, 1) = tau;
                                    dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                    dataTable.LyE(ind, 1) = data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE(1, ii);

                                    ind = ind + 1;

                                    continue
                                end
                                % If a unique value for the embedding dimension was selected pull it from the prior values.
                                if strcmp(app.EmbeddingDimensionDropDown.Value, 'Unique')
                                    dims{ii} = data.res.FNN.(files{i}).(objs{j}).data.(sigs{k}).dim(ii);
                                end
                                % If either the time lag or embedding dimension are NaNs set the new results and table values to NaNs.
                                if isnan(tau) || isnan(dims{ii})
                                    data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE(1, ii) = -3;

                                    dataTable.File{ind, 1} = files{i};
                                    dataTable.Object{ind, 1} = objs{j};
                                    dataTable.Signal{ind, 1} = sigs{k};
                                    dataTable.Dimension(ind, 1) = ii;
                                    dataTable.TimeLag(ind, 1) = tau;
                                    dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                    dataTable.LyE(ind, 1) = data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE(1, ii);

                                    ind = ind + 1;

                                    continue
                                else

                                    try

                                        switch analysis

                                            case 'Wolf'

                                                if ~(strcmp(app.SkipexistingSwitch.Value, 'Yes') && (isfield(data.res, 'LyE') && isfield(data.res.LyE, files{i}) && isfield(data.res.LyE.(files{i}), objs{j}) && isfield(data.res.LyE.(files{i}).(objs{j}).data, sigs{k}) && isfield(data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}), 'LyE') && length(data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE) >= ii))

                                                    if app.EvolveinframesEditField.Value == 0
                                                        error('Evolve cannot be 0.')
                                                    else
                                                        evolve = app.EvolveinframesEditField.Value;
                                                    end

                                                    % Get the secondary parameters.
                                                    if strcmp(app.UsesecondaryparametersSwitch.Value, 'Yes')
                                                        if app.SetValueEditField.Value ~= 0
                                                            switch app.ScaleMaximumMethodDropDown.Value
                                                                case 'Set Percent of Range'
                                                                    scaleMax = (max(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:, ii)) - min(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:, ii)))*app.SetValueEditField.Value/100;
                                                                case 'Set Value'
                                                                    scaleMax = app.SetValueEditField.Value;
                                                            end
                                                        else
                                                            scaleMax = (max(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:, ii)) - min(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:, ii)))/10;
                                                        end
                                                        if app.AngleMaximumasaportionofpiEditField.Value ~= 0
                                                            angleMax = app.AngleMaximumasaportionofpiEditField.Value*pi;
                                                        else
                                                            angleMax = 30/180*pi;
                                                        end
                                                        zMult = 1;

                                                        if isfield(data.(type).(files{i}).(objs{j}), 'freq')
                                                            freq = data.(type).(files{i}).(objs{j}).freq;
                                                        elseif isfield(data.raw.(files{i}).(objs{j}), 'freq')
                                                            freq = data.raw.(files{i}).(objs{j}).freq;
                                                        else
                                                            error('No sampling frequency found.')
                                                        end

                                                        % tic
                                                        [out, LyE] = LyE_W(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:, ii), freq, tau, dims{ii}, evolve, scaleMax, angleMax, zMult);
                                                        % toc
                                                    else
                                                        % tic
                                                        [out, LyE] = LyE_W(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:, ii), freq, tau, dims{ii}, evolve);
                                                        % toc
                                                    end

                                                    % Save the LyE results to the results.
                                                    data.ana.LyE.(files{i}).(objs{j}).data.(sigs{k}).out{1, ii} = out;
                                                    data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE(1, ii) = LyE;
                                                    % Save values to the table structure so the user can see them.
                                                    dataTable.File{ind, 1} = files{i};
                                                    dataTable.Object{ind, 1} = objs{j};
                                                    dataTable.Signal{ind, 1} = sigs{k};
                                                    dataTable.Dimension(ind, 1) = ii;
                                                    dataTable.TimeLag(ind, 1) = tau;
                                                    dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                                    dataTable.LyE(ind, 1) = data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE(1, ii);

                                                    % Change the save flag so this data saves.
                                                    flagSave = 1;

                                                else
                                                    % Save values to the table structure so the user can see them.
                                                    dataTable.File{ind, 1} = files{i};
                                                    dataTable.Object{ind, 1} = objs{j};
                                                    dataTable.Signal{ind, 1} = sigs{k};
                                                    dataTable.Dimension(ind, 1) = ii;
                                                    dataTable.TimeLag(ind, 1) = tau;
                                                    dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                                    dataTable.LyE(ind, 1) = data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE(1, ii);

                                                    % Change the save flag so this existing data does not save.
                                                    flagSave = 0;
                                                end

                                        end


                                    catch ME
                                        % If there is an error set all the same reults to NaNs.

                                        data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE(1, ii) = -4;

                                        dataTable.File{ind, 1} = files{i};
                                        dataTable.Object{ind, 1} = objs{j};
                                        dataTable.Signal{ind, 1} = sigs{k};
                                        dataTable.Dimension(ind, 1) = ii;
                                        dataTable.TimeLag(ind, 1) = tau;
                                        dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                        dataTable.LyE(ind, 1) = data.res.LyE.(files{i}).(objs{j}).data.(sigs{k}).LyE(1, ii);

                                        % Print a message saying which data failed.
                                        printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])
                                        % Get MATLAB's last error message and display it.
                                        printLog(app, '004', ME.message)
                                    end

                                end
                                % Increase the index for the table structure.
                                ind = ind + 1;

                            end
                        end
                    end
                end
                % Every 50 files print an update message.
                if rem(i, 10) == 0
                    printLog(app, '001', ['finished ' num2str(i) ' of ' num2str(height(files)) ' files'])
                    % Every 10 files and if the user selected to periodically save the data, the table structure will be displayed in the table and
                    % saved to the computer automatically.
                    dataTable = adjustExport(app, dataTable);
                    app.UITable.Data = struct2table(dataTable);
                    app.UITable.ColumnName = fieldnames(dataTable);
                    setappdata(app.UITable, 'data', data)
                    if strcmp(app.SaveperiodicallySwitch.Value, 'Yes') && flagSave
                        analysisComplete(app.BAR_App, data, 'LyE', 1)
                    end
                end
            end
            % Print a message complete message to the log.
            printLog(app, '002')
            % Save the data to the module.
            setappdata(app.UITable, 'data', data)
            % Adjust the length of the table structure.
            dataTable = adjustExport(app, dataTable);
            % Sent the table structure to the module's table and set the column names to the fieldnames.
            app.UITable.Data = struct2table(dataTable);
            app.UITable.ColumnName = fieldnames(dataTable);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % StartupFcn(app, mainapp, data, analysis)
            % inputs  - app, required mlapp object.
            %         - mlapp, BAR App objects.
            %         - data, BAR App data structure.
            %         - analysis, string of the analysis name.
            % Remarks
            % - This is the startup method that initiallized the app.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            % Save the data into the module.
            setappdata(app.UITable, 'data', data)
            % Initialize the signal list in the app.
            updatelist(app)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.BAR_App.Version.Text;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

            processData(app, 'Wolf')

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will send the data back to the BAR App and save it.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the module.
            data = getappdata(app.UITable, 'data');
            % The analysis name that will be used to save the data.
            analysis = 'LyE';
            % Save the data using a BAR_App method.
            analysisComplete(app.BAR_App, data, analysis, 0)
            % Close the app.
            delete(app)
        end

        % Value changed function: EmbeddingDimensionDropDown
        function EmbeddingDimensionDropDownValueChanged(app, event)
            % EmbeddingDimensionDropDownValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - If the user selects that a specific value should be used for the embedding dimension this will allow the spinner to be changed.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current value for which embedding dimension to use.
            value = app.EmbeddingDimensionDropDown.Value;
            switch value
                case 'Set Value'
                    % If it is set to 'Set Value' turn the spinner on.
                    app.SetValueSpinner.Enable = 1;
                    app.SetValueSpinnerLabel.Enable = 1;
                otherwise
                    % For an other option turn the spinner off.
                    app.SetValueSpinner.Enable = 0;
                    app.SetValueSpinnerLabel.Enable = 0;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged2(app, event)
            updatelist(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.LyapunovExponentsLabel = uilabel(app.UIFigure);
            app.LyapunovExponentsLabel.Text = 'Lyapunov Exponents';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.SaveperiodicallySwitchLabel = uilabel(app.UIFigure);
            app.SaveperiodicallySwitchLabel.Text = 'Save periodically?';
            app.SaveperiodicallySwitch = uiswitch(app.UIFigure, 'slider');
            app.SaveperiodicallySwitch.Items = {'Yes', 'No'};
            app.SaveperiodicallySwitch.Value = 'Yes';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged2, true);
            app.Switch.Value = 'Raw';
            app.SelectthesignalstoprocessListBoxLabel = uilabel(app.UIFigure);
            app.SelectthesignalstoprocessListBoxLabel.Text = 'Select the signals to process';
            app.SelectthesignalstoprocessListBox = uilistbox(app.UIFigure);
            app.SelectthesignalstoprocessListBox.Items = {};
            app.SelectthesignalstoprocessListBox.Multiselect = 'on';
            app.SelectthesignalstoprocessListBox.Value = {};
            app.Version = uilabel(app.UIFigure);
            app.SkipexistingSwitchLabel = uilabel(app.UIFigure);
            app.SkipexistingSwitchLabel.Text = 'Skip existing?';
            app.SkipexistingSwitch = uiswitch(app.UIFigure, 'slider');
            app.SkipexistingSwitch.Items = {'Yes', 'No'};
            app.SkipexistingSwitch.Value = 'Yes';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WolfTab = uitab(app.TabGroup);
            app.WolfTab.Title = 'Wolf';
            app.EmbeddingDimensionDropDownLabel = uilabel(app.WolfTab);
            app.EmbeddingDimensionDropDownLabel.Text = 'Embedding Dimension';
            app.EmbeddingDimensionDropDown = uidropdown(app.WolfTab);
            app.EmbeddingDimensionDropDown.Items = {'Unique', 'Mode', 'Mean', 'Median', 'Set Value'};
            app.EmbeddingDimensionDropDown.ValueChangedFcn = createCallbackFcn(app, @EmbeddingDimensionDropDownValueChanged, true);
            app.EmbeddingDimensionDropDown.Value = 'Unique';
            app.TimelagselectionSwitchLabel = uilabel(app.WolfTab);
            app.TimelagselectionSwitchLabel.Text = 'Time lag selection?';
            app.TimelagselectionSwitch = uiswitch(app.WolfTab, 'slider');
            app.TimelagselectionSwitch.Items = {'First Minimum', '1 Fifth Value'};
            app.TimelagselectionSwitch.Value = 'First Minimum';
            app.SetValueSpinnerLabel = uilabel(app.WolfTab);
            app.SetValueSpinnerLabel.Enable = 'off';
            app.SetValueSpinnerLabel.Text = 'Set Value';
            app.SetValueSpinner = uispinner(app.WolfTab);
            app.SetValueSpinner.Limits = [1 Inf];
            app.SetValueSpinner.RoundFractionalValues = 'on';
            app.SetValueSpinner.Enable = 'off';
            app.SetValueSpinner.Value = 1;
            app.ProcessButton = uibutton(app.WolfTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.EvolveinframesLabel = uilabel(app.WolfTab);
            app.EvolveinframesLabel.Text = 'Evolve (in frames)';
            app.EvolveinframesEditField = uieditfield(app.WolfTab, 'numeric');
            app.EvolveinframesEditField.Limits = [1 Inf];
            app.EvolveinframesEditField.RoundFractionalValues = 'on';
            app.EvolveinframesEditField.Value = 1;
            app.SecondaryInputsPanel = uipanel(app.WolfTab);
            app.SecondaryInputsPanel.Title = 'Secondary Inputs';
            app.ScaleMaximumMethodDropDownLabel = uilabel(app.SecondaryInputsPanel);
            app.ScaleMaximumMethodDropDownLabel.Text = 'Scale Maximum Method';
            app.ScaleMaximumMethodDropDown = uidropdown(app.SecondaryInputsPanel);
            app.ScaleMaximumMethodDropDown.Items = {'Set Percent of Range', 'Set Value'};
            app.ScaleMaximumMethodDropDown.Value = 'Set Percent of Range';
            app.SetValueEditFieldLabel = uilabel(app.SecondaryInputsPanel);
            app.SetValueEditFieldLabel.Text = 'Set Value';
            app.SetValueEditField = uieditfield(app.SecondaryInputsPanel, 'numeric');
            app.SetValueEditField.Limits = [0 Inf];
            app.AngleMaximumasaportionofpiEditFieldLabel = uilabel(app.SecondaryInputsPanel);
            app.AngleMaximumasaportionofpiEditFieldLabel.Text = {'Angle Maximum'; '(as a portion of pi)'};
            app.AngleMaximumasaportionofpiEditField = uieditfield(app.SecondaryInputsPanel, 'numeric');
            app.AngleMaximumasaportionofpiEditField.Limits = [0 Inf];
            app.ZMultiplierEditFieldLabel = uilabel(app.SecondaryInputsPanel);
            app.ZMultiplierEditFieldLabel.Enable = 'off';
            app.ZMultiplierEditFieldLabel.Text = 'Z Multiplier';
            app.ZMultiplierEditField = uieditfield(app.SecondaryInputsPanel, 'numeric');
            app.ZMultiplierEditField.Limits = [0 Inf];
            app.ZMultiplierEditField.Enable = 'off';
            app.UsesecondaryparametersSwitchLabel = uilabel(app.SecondaryInputsPanel);
            app.UsesecondaryparametersSwitchLabel.Text = 'Use secondary parameters?';
            app.UsesecondaryparametersSwitch = uiswitch(app.SecondaryInputsPanel, 'slider');
            app.UsesecondaryparametersSwitch.Items = {'No', 'Yes'};
            app.UsesecondaryparametersSwitch.Value = 'No';
            app.RosensteinTab = uitab(app.TabGroup);
            app.RosensteinTab.Title = 'Rosenstein';
            app.MaximumdatalengthtoprocessSpinnerLabel = uilabel(app.UIFigure);
            app.MaximumdatalengthtoprocessSpinnerLabel.Text = 'Maximum data length to process';
            app.MaximumdatalengthtoprocessSpinner = uispinner(app.UIFigure);
            app.MaximumdatalengthtoprocessSpinner.Step = 100;
            app.MaximumdatalengthtoprocessSpinner.Limits = [100 Inf];
            app.MaximumdatalengthtoprocessSpinner.RoundFractionalValues = 'on';
            app.MaximumdatalengthtoprocessSpinner.ValueDisplayFormat = '%.0f';
            app.MaximumdatalengthtoprocessSpinner.Value = 40000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_LyE(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end