classdef analysis_TimeLag < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Version                     matlab.ui.control.Label
        SelectthesignalstoprocessListBox  matlab.ui.control.ListBox
        SelectthesignalstoprocessListBoxLabel  matlab.ui.control.Label
        MethodDropDown              matlab.ui.control.DropDown
        MethodDropDownLabel         matlab.ui.control.Label
        ofmultiplesofthemaximumlagtoextendSpinner  matlab.ui.control.Spinner
        ofmultiplesofthemaximumlagtoextendSpinnerLabel  matlab.ui.control.Label
        ExtendtimelagifafirstminimumisnotfoundCheckBox  matlab.ui.control.CheckBox
        Switch_2                    matlab.ui.control.Switch
        SaveResultsandExitButton    matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        Switch                      matlab.ui.control.Switch
        MaximumLagSpinner           matlab.ui.control.Spinner
        MaximumLagSpinnerLabel      matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        TimeLagandAMIAnalysisLabel  matlab.ui.control.Label
        ProcessButton               matlab.ui.control.Button
    end


    properties (Access = private)
        BAR_App   % Main app object
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of
            %           the error and organize the errors available.
            %         - newText, a string that may be inserted into the
            %                    message.
            % Remarks
            % - This method will print messages to the log of the BAR App.
            %   The error codes are unique to each message so they can be
            %   found and referenced easier.
            % - The method is public and is acessible outside of the BAR
            %   App. It is used in some of the example analysis and process
            %   scripts.
            % Future Work
            % - This log should be printed and saved to a file location
            %   each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text within the log.
            text = app.BAR_App.Log.Value;
            % Match the error code with the message and update the text for
            % the log.
            switch code
                case '001'
                    text{length(text) + 1, 1} = ['AMI-001 ' newText];
                case '002'
                    text{length(text) + 1, 1} = ['AMI-002 analysis complete'];
                case '003'
                    text{length(text) + 1, 1} = ['AMI-003 analysis failed on: ' newText];
                case '004'
                    text{length(text) + 1, 1} = ['AMI-004 ' newText];
                case '005'
                    text{length(text) + 1, 1} = ['AMI-005 existing analysis, more than 10% have failed'];
            end
            % Create a date-time for the message.
            text{end, 1} = [char(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z')) ' | ' text{end, 1}];
            % Update the log.
            app.BAR_App.Log.Value = text;
            % Scroll to the bottom so the most recent message is visible.
            scroll(app.BAR_App.Log,'bottom')
            drawnow
            % Write to the log file.
            writeLog(app.BAR_App, text{end, 1})

        end

        function updatelist(app)
            % updatelist(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the signal list within the app with the
            %   currently selected data type.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu


            % Get the current data from the app.
            data = getappdata(app.UITable, 'data');
            % Find which data type was selected.
            switch app.Switch_2.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end
            % Initialize the list with an 'all' option.
            sigsAll = {'all'};
            % Get the file names.
            files = fieldnames(data.(type));
            % Iterate through the file names.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects
                for j = 1:length(objs)
                    % Append the signals to the list.
                    sigsAll = [sigsAll; fieldnames(data.(type).(files{i}).(objs{j}).data)];
                end
            end
            % Set the unique signals to the list box.
            app.SelectthesignalstoprocessListBox.Items = unique(sigsAll);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % StartupFcn(app, mainapp, data, analysis)
            % inputs  - app, required mlapp object.
            %         - mlapp, BAR App objects.
            %         - data, BAR App data structure.
            %         - analysis, string of the analysis name.
            % Remarks
            % - This is the startup method that initiallized the app.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            % Save the data into the module.
            setappdata(app.UITable, 'data', data)
            % Initialize the signal list in the app.
            updatelist(app)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.BAR_App.Version.Text;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % ProcessButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This method starts the analysis method. It pulls much of
            %   the primary and secondary inputs from the app interface.
            %   These have default values but can be changed by the user.
            % - The module has two processing functions. It can calculate a
            %   time lag or it can calculate average mutual information.
            % Future Work
            % - A couple other methods could be added as options.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the data from the app.
            data = getappdata(app.UITable, 'data');

            % Empty the current table.
            app.UITable.Data = [];

            % Get the selected level of data to process.
            switch app.Switch_2.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end
            % Initialize an index for the array.
            ind = 1;

            % Initialize a variable to count the fails.
            fails = 0;

            % The module
            switch app.Switch.Value
                % If the user selects time lags to be calculated.
                case 'Time Lag'
                    % Get the maximum lag set by the user.
                    lag = app.MaximumLagSpinner.Value;

                    % Get the field names of all the files that were loaded.
                    files = fieldnames(data.(type));

                    for i = 1:length(files)
                        % Get the fieldnames for all the signals that were
                        % contained within that file. This could be different for
                        % each file so it is within the loop.
                        objs = fieldnames(data.(type).(files{i}));
                        objs(strcmp(objs, 'groups')) = [];
                        objs(strcmp(objs, 'meta')) = [];
                        % Iterate through the objects.
                        for j = 1:length(objs)
                            % Get the user selected signals.
                            sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                            % Iterate through the signals.
                            for k = 1:length(sigs)
                                % Check if 'all' or nothing was selected or the current signal matches the selection.
                                if any(contains(sigs, 'all')) || isempty(sigs) || any(strcmp(app.SelectthesignalstoprocessListBox.Value, sigs{k}))
                                    % Get the number of dimensions to use.
                                    m = size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2);
                                    if size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 1) >= 2*app.MaximumLagSpinner.Value
                                        % Iterate through the dimensions.
                                        for ii = 1:m

                                            try
                                                % Run the analysis method.
                                                [tau1st, tau1_5th, ami] = AMI_Stergiou(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:, ii), lag);
                                                % All of the results will be saved
                                                % to the analysis type.
                                                data.ana.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1st{1, ii} = tau1st;
                                                data.ana.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1_5th{1, ii} = tau1_5th;
                                                data.ana.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).ami{1, ii} = ami;
                                                % The singular results will be
                                                % saved to the results type.
                                                data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1st(1, ii) = tau1st(1, 1);
                                                data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1_5th(1, ii) = tau1_5th(end, 1);
                                                data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).length(1, ii) = length(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:,ii));
                                                % Save results to the table
                                                % structure.
                                                dataTable.File{ind, 1} = files{i};
                                                dataTable.Object{ind, 1} = objs{j};
                                                dataTable.Signal{ind, 1} = sigs{k};
                                                dataTable.Dimension{ind, 1} = ii;
                                                dataTable.Minimum1st(ind, 1) = tau1st(1,1);
                                                dataTable.tau1_5th(ind, 1) = tau1_5th(end,1);

                                            catch ME
                                                % If there is an error set all the same
                                                % reults to NaNs.
                                                data.ana.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1st{1, ii} = NaN;
                                                data.ana.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1_5th{1, ii} = NaN;
                                                data.ana.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).ami{1, ii} = NaN;

                                                data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1st(1, ii) = NaN;
                                                data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1_5th(1, ii) = NaN;
                                                data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).length(1, ii) = length(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:,ii));

                                                dataTable.File{ind, 1} = files{i};
                                                dataTable.Object{ind, 1} = objs{j};
                                                dataTable.Signal{ind, 1} = sigs{k};
                                                dataTable.Dimension{ind, 1} = ii;
                                                dataTable.Minimum1st(ind, 1) = NaN;
                                                dataTable.tau1_5th(ind, 1) = NaN;

                                                % Print a message saying which data
                                                % failed.
                                                printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])

                                                % Get MATLAB's last error message and display it.
                                                printLog(app, '004', ME.message)

                                                fails = fails + 1;
                                                if fails/length(files) > 0.1
                                                    printLog(app, '005')
                                                    return
                                                end

                                            end
                                            % Increase the index for the table structure.
                                            ind = ind + 1;
                                        end
                                    else

                                        for ii = 1:m
                                            data.ana.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1st{1, ii} = NaN;
                                            data.ana.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1_5th{1, ii} = NaN;
                                            data.ana.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).ami{1, ii} = NaN;

                                            data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1st(1, ii) = NaN;
                                            data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).tau1_5th(1, ii) = NaN;
                                            data.res.TimeLag.(files{i}).(objs{j}).data.(sigs{k}).length(1, ii) = length(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:,ii));

                                            dataTable.File{ind, 1} = files{i};
                                            dataTable.Object{ind, 1} = objs{j};
                                            dataTable.Signal{ind, 1} = sigs{k};
                                            dataTable.Dimension{ind, 1} = ii;
                                            dataTable.Minimum1st(ind, 1) = NaN;
                                            dataTable.tau1_5th(ind, 1) = NaN;

                                            % Increase the index for the table structure.
                                            ind = ind + 1;

                                        end

                                        % Print a message saying which data
                                        % failed.
%                                         printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])

                                        % Get MATLAB's last error message and display it.
%                                         printLog(app, '004', 'data is less than twice the maximum lag')

                                    end
                                end
                            end
                        end
                        % Every 10 files print an update message.
                        if rem(i, 10) == 0
                            printLog(app, '001', ['finished ' num2str(i) ' of ' num2str(height(files)) ' files'])
                            % Sent the table structure to the module's table and set the
                            % column names to the fieldnames.
                            app.UITable.Data = struct2table(dataTable);
                            app.UITable.ColumnName = fieldnames(dataTable);
                        end
                    end

                case 'AMI'

            end
            % Print a message complete message to the log.
            printLog(app, '002')
            % Save the data to the module.
            setappdata(app.UITable, 'data', data)
            % Sent the table structure to the module's table and set the
            % column names to the fieldnames.
            app.UITable.Data = struct2table(dataTable);
            app.UITable.ColumnName = fieldnames(dataTable);

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            % SwitchValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This method controls the ability to use the time lag
            %   spinner. If the user selects 'AMI' the spinner is disabled.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current value of the spinner.
            value = app.Switch.Value;
            % If 'TIme Lag' is selected enable it and if 'AMI' is selected
            % disable it.
            switch value
                case 'Time Lag'
                    app.MaximumLagSpinner.Enable = 'on';
                case 'AMI'
                    app.MaximumLagSpinner.Enable = 'off';
            end

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This method will send the data back to the BAR App and save
            %   it.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the module.
            data = getappdata(app.UITable, 'data');
            % The analysis name that will be used to save the data.
            analysis = 'TimeLag';
            % Save the data using a BAR_App method.
            analysisComplete(app.BAR_App, data, analysis, 0)
            % Close the app.
            delete(app)
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            updatelist(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.TimeLagandAMIAnalysisLabel = uilabel(app.UIFigure);
            app.TimeLagandAMIAnalysisLabel.Text = 'Time Lag and AMI Analysis';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.MaximumLagSpinnerLabel = uilabel(app.UIFigure);
            app.MaximumLagSpinnerLabel.Text = 'Maximum Lag';
            app.MaximumLagSpinner = uispinner(app.UIFigure);
            app.MaximumLagSpinner.Limits = [1 Inf];
            app.MaximumLagSpinner.RoundFractionalValues = 'on';
            app.MaximumLagSpinner.Value = 1;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Time Lag', 'AMI'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Time Lag';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Raw', 'Processed'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Raw';
            app.ExtendtimelagifafirstminimumisnotfoundCheckBox = uicheckbox(app.UIFigure);
            app.ExtendtimelagifafirstminimumisnotfoundCheckBox.Text = 'Extend time lag if a first minimum is not found.';
            app.ofmultiplesofthemaximumlagtoextendSpinnerLabel = uilabel(app.UIFigure);
            app.ofmultiplesofthemaximumlagtoextendSpinnerLabel.Text = '# of multiples of the maximum lag to extend';
            app.ofmultiplesofthemaximumlagtoextendSpinner = uispinner(app.UIFigure);
            app.ofmultiplesofthemaximumlagtoextendSpinner.Limits = [0 100];
            app.ofmultiplesofthemaximumlagtoextendSpinner.RoundFractionalValues = 'on';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Stergiou', 'Thomas'};
            app.MethodDropDown.Value = 'Stergiou';
            app.SelectthesignalstoprocessListBoxLabel = uilabel(app.UIFigure);
            app.SelectthesignalstoprocessListBoxLabel.Text = 'Select the signals to process';
            app.SelectthesignalstoprocessListBox = uilistbox(app.UIFigure);
            app.SelectthesignalstoprocessListBox.Items = {};
            app.SelectthesignalstoprocessListBox.Multiselect = 'on';
            app.SelectthesignalstoprocessListBox.Value = {};
            app.Version = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_TimeLag(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end