classdef BAR_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        v10Label                        matlab.ui.control.Label
        BiomechanicsAnalysisandReportingApplicationLabel  matlab.ui.control.Label
        Log                             matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        DatabaseSearchTab               matlab.ui.container.Tab
        Directory                       matlab.ui.control.TextArea
        MergeButton                     matlab.ui.control.Button
        CopyButton                      matlab.ui.control.Button
        MoveButton                      matlab.ui.control.Button
        ExcludeLabel                    matlab.ui.control.Label
        IncludeLabel                    matlab.ui.control.Label
        SearchTagsExclude               matlab.ui.control.EditField
        IdentifyGroupsButton            matlab.ui.control.Button
        Database                        matlab.ui.control.EditField
        SelectWorkingDatabaseButton     matlab.ui.control.Button
        SaveCurrentDataButton           matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        FindButton                      matlab.ui.control.Button
        TableFiles                      matlab.ui.control.Table
        SearchTagsInclude               matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        EquipmentDropDown               matlab.ui.control.DropDown
        SelectequipmentthedatawasrecordedfromLabel  matlab.ui.control.Label
        FileExtensionDropDown           matlab.ui.control.DropDown
        SelectfileextensionofthedatafilesLabel  matlab.ui.control.Label
        AddDirectorytoSearchButton      matlab.ui.control.Button
        ProcessingTab                   matlab.ui.container.Tab
        ProcessButton                   matlab.ui.control.Button
        SelectProcessingModuleDropDown  matlab.ui.control.DropDown
        SelectProcessingModuleDropDownLabel  matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        RunAnalysisButton               matlab.ui.control.Button
        AnalysisDropDown                matlab.ui.control.DropDown
        AnalysisDropDownLabel           matlab.ui.control.Label
        ReviewTab                       matlab.ui.container.Tab
        ReviewDropDown                  matlab.ui.control.DropDown
        AnalysisDropDown_2Label         matlab.ui.control.Label
        ReviewAnalysisButton            matlab.ui.control.Button
        StatisticsTab                   matlab.ui.container.Tab
        ExportTab                       matlab.ui.container.Tab
        ExportMeansSwitch               matlab.ui.control.Switch
        ExportMeansSwitchLabel          matlab.ui.control.Label
        SelectitemstoexportListBox      matlab.ui.control.ListBox
        SelectitemstoexportListBoxLabel  matlab.ui.control.Label
        FindeligibleexportsButton       matlab.ui.control.Button
        ExportResultsButton             matlab.ui.control.Button
        FormatsDropDown                 matlab.ui.control.DropDown
        FormatsDropDownLabel            matlab.ui.control.Label
        UITableExport                   matlab.ui.control.Table
        ButtonFormatExport              matlab.ui.control.Button
        ConfigurationTab                matlab.ui.container.Tab
        FilesReview                     matlab.ui.control.TextArea
        ReviewTextAreaLabel             matlab.ui.control.Label
        FilesAnalysis                   matlab.ui.control.TextArea
        AnalysisTextAreaLabel           matlab.ui.control.Label
        FilesLoad                       matlab.ui.control.TextArea
        LoadTextAreaLabel               matlab.ui.control.Label
        FilesProcess                    matlab.ui.control.TextArea
        ProcessTextAreaLabel            matlab.ui.control.Label
        MethodDirectoriesLabel          matlab.ui.control.Label
        UpdateConfigurationButton       matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        AppAnalysis % Description
    end
    
    methods (Access = private)
        
        function files = filesearch(app, files, m_directory)
            % files = filesearch(app, files, m_directory)
            % inputs  - app, required mlapp object.
            %         - files, structure with details for the files found.
            %         - m_directory, the new directory to search and add to
            %           the files structure.
            % outputs - files, structure with details for the files found.
            % Remarks
            % - This is a recursive function used to search within
            %   directories for files of a certain format and organization.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the files within the directory.
            m_files = dir(m_directory);

            % Pull the inclusion and exclusion tags entered into the app.
            m_tags_include = app.SearchTagsInclude.Value;
            m_tags_exclude = app.SearchTagsExclude.Value;
            % Read the strings with the tags and separate them into cell
            % arrays.
            if ~isempty(m_tags_include)
                m_tags_include = textscan(m_tags_include,'%s','Delimiter',',');
            end
            if ~isempty(m_tags_exclude)
                m_tags_exclude = textscan(m_tags_exclude,'%s','Delimiter',',');
            end

            % Iterate through the files found in the directory.
            for i = 3:length(m_files)
                % Identify if the item is a file or a directory.
                if isfolder([m_files(i).folder '\' m_files(i).name]) && ~strcmp(m_files(i).name(1), '.')
                    % If the item is a directory run the search algorithm
                    % on it recursively.
                    files = filesearch(app, files, [m_files(i).folder '\' m_files(i).name]);
                elseif isfile([m_files(i).folder '\' m_files(i).name])
                    % If the item is a file but none have been found yet
                    % initialize the file structure.
                    if isempty(fieldnames(files))
                        n = 0;
                        files.name = {};
                        files.folder = {};
                        files.bytes = [];
                    else
                        % If files have already been found get the length
                        % of the structure so it can be used to add more
                        % files to it later.
                        n = length(files.name);
                    end
                    % The file extention will be needed to check if it is a
                    % valid target file.
                    [~, ~, ext] = fileparts(m_files(i).name);
                    % Check if there are tags available and if they are
                    % determine which files have them.
                    if ~isempty(m_tags_include)
                        bol_include = cellfun(@(s)find(~cellfun('isempty', strfind([m_files(i).name;split({m_files(i).folder},'\')],s))),m_tags_include{1},'uni',0);
                    else
                        bol_include = '';
                    end
                    % Determine if there are tags available and if they are
                    % determine which files do not have them.
                    if ~isempty(m_tags_exclude)
                        bol_exclude = cellfun(@(s)find(~cellfun('isempty', strfind([m_files(i).name;split({m_files(i).folder},'\')],s))),m_tags_exclude{1},'uni',0);
                    else
                        bol_exclude = '';
                    end
                    % If the file matches the selected file extension and
                    % the tag requirements it will be added to the table.
                    if (strcmp(ext, ['.' app.FileExtensionDropDown.Value]) && ((strcmp(m_tags_include, '') || ~any(cellfun(@isempty,bol_include))) && (strcmp(m_tags_exclude, '') || ~any(~cellfun(@isempty,bol_exclude)))))
                        files.name{n+1, 1} = m_files(i).name;
                        files.folder{n+1, 1} = m_files(i).folder;
                        files.bytes(n+1, 1) = m_files(i).bytes/1000;
                    end
                end
                % Every ten files update the table.
                if rem(length(m_files),10) == 0
                    app.TableFiles.Data = struct2table(files);
                    % Drawnow and pause are used to make sure the table
                    % updates.
                    drawnow
                    pause(0.01)
                end
            end
            
        end
        
        function dataExport = formatBUCaltest(app, data)
            % dataExport = formatBUCaltest(app, data)
            % inputs  - app, required mlapp object.
            %         - data, structure with the data to export.
            % outputs - dataExport, structure formated for conversion to a 
            %                       table and exported.
            % Remarks
            % - This method formats data from a C-Motion Caltest procedure
            %   into a specific table format for the BU MoveLab.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the files from the raw data structure.
            files = fieldnames(data.raw);
            for i = 1:length(files)
                % The measures store the force plate names so we need to
                % find certain delimiters and pull out those strings.
                measures = fieldnames(data.raw.(files{i}));
                measures(strcmp(measures, 'groups')) = [];
                for j = 1:length(measures)
                    ind3 = strfind(measures{j}, '_');
                    if ind3(1) > 4
                        ind3 = [4, ind3];
                    end
                    if strcmp(measures{j}(ind3(1) + 1), 'M')
                        forcePlates{j,1} = measures{j}(1:3);
                    else
                        forcePlates{j,1} = measures{j}(1:ind3(1)-1);
                    end
                end
                % The for loop will generate a long list but we only need
                % the unique ones.
                forcePlates = unique(forcePlates);
                for j = 1:length(forcePlates)
                    % Some spurious data may be present and needs to be
                    % skipped.
                    if ~contains(forcePlates{j},'FP')
                        continue
                    end
                    % We need the trial information from the data so their
                    % results can be arrayed in columns in the export.
                    ind = 1;
                    trials = {};
                    for k = 1:length(measures)
                        if contains(measures{k},forcePlates{j})
                            ind3 = strfind(measures{k}, '_');
                            if ind3(1) > 4
                                ind3 = [4, ind3];
                            end
                            if strcmp(measures{k}(ind3(1) + 1), 'M')
                                trials{ind, 1} = measures{k}(4:ind3(1)-1);
                            else
                                trials{ind,1} = measures{k}(ind3(1)+1:ind3(2)-1);
                            end
                            ind = ind + 1;
                        end
                    end
                    % We'll get a long array of trial numbers but only need
                    % the unique ones.
                    trials = unique(trials);
                    % There are some quirks with the trial numbers and
                    % their equencing. This helps handle that.
                    trialsN = [1:length(trials)]';
                    for k = 1:length(trials)
                        ind4 = [];
                        % The trial number is mapped to the column of data
                        % it will be in the results spreadsheet.
                        switch trialsN(k)
                            case 1
                                ind4 = 0;
                            case 2
                                ind4 = 8;
                            case 3
                                ind4 = 16;
                            case 4
                                ind4 = 24;
                            case 5
                                ind4 = 32;
                        end

                        % For the first row we need to set the column
                        % headers.
                        if i == 1
                            dataExport.(forcePlates{j}){1, ind4 + 1} = trialsN(k);
                            dataExport.(forcePlates{j}){1, 41} = 'Force Orientation Error';
                            dataExport.(forcePlates{j}){2, ind4 + 1} = 'Date';
                            dataExport.(forcePlates{j}){2, ind4 + 2} = 'xmean';
                            dataExport.(forcePlates{j}){2, ind4 + 3} = 'xsd';
                            dataExport.(forcePlates{j}){2, ind4 + 4} = 'ymean';
                            dataExport.(forcePlates{j}){2, ind4 + 5} = 'ysd';
                            dataExport.(forcePlates{j}){2, ind4 + 6} = 'zmean';
                            dataExport.(forcePlates{j}){2, ind4 + 7} = 'zsd';
                            dataExport.(forcePlates{j}){2, 41} = 'Date';
                            dataExport.(forcePlates{j}){2, 42 + 2*(k - 1)} = [num2str(trialsN(k)) 'mean'];
                            dataExport.(forcePlates{j}){2, 42 + 2*(k - 1) + 1} = [num2str(trialsN(k)) 'sd'];
                        end
                        dataExport.(forcePlates{j}){i + 2, ind4 + 1} = datestr(datetime(data.raw.(files{i}).groups{end},'InputFormat','yyyyMMdd'));

                        if k == 1
                            dataExport.(forcePlates{j}){i + 2, 41} = datestr(datetime(data.raw.(files{i}).groups{end},'InputFormat','yyyyMMdd'));
                        end

                        % This will handle all the various names that were
                        % used in the files over several years. The data
                        % from specific forceplates will be assigned to a
                        % location in the structure so it ends up in a
                        % certain location in the results spreadsheet.
                        try
                            dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN(1)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD(1)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN(2)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD(2)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN(3)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD(3)*1000;
                            dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_MEAN(1);
                            dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_SD(1);
                        catch
                            try
                                dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_MEAN(1)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_SD(1)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_MEAN(2)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_SD(2)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_MEAN(3)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_SD(3)*1000;
                                dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_OrientationError_MEAN(1);
                                dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_OrientationError_SD(1);
                            catch
                                try
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_MEAN(1);
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_SD(1);
                                catch
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_MEAN(1);
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_SD(1);
                                end
                            end
                        end
                    end
                end
            end

        end
        
        function createDatabase(app)
            % createDatabase(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method creates the folders within the working
            %   directory that are assumed to be present for various file
            %   saving and retreival methods.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            m_path = app.Database.Value;

            % Get the current files and folders within the working
            % directory.
            folders = dir(m_path);

            % Create any folders that are not already present and print a
            % notification to the log.
            if ~any(contains({folders.name}, 'Results'))
                mkdir([m_path '\Results'])
                printLog(app, '010', [m_path '\Results'])
            end
            if ~any(contains({folders.name}, 'Figures'))
                mkdir([m_path '\Figures'])
                printLog(app, '011', [m_path '\Figures'])
            end
            if ~any(contains({folders.name}, 'Export'))
                mkdir([m_path '\Export'])
                printLog(app, '011', [m_path '\Export'])
            end
            
            % Update the configuration file with the current working
            % directory.
            config = loadConfig(app);
            config.database = m_path;
            saveConfig(app, config);
            
        end
        
        function identifyFileTypes(app)
            % identifyFileTypes(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Load folder for valid files that
            %   would be used to load data into the app. These files need
            %   to have a name load_*.m. The results will be saved to the
            %   configuration file and made selectable in the app.
            % - The goal of this function was to make the addition of new
            %   functions easier.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Load the existing configuration so it can be updated.
            config = loadConfig(app);

            % Update the configuration with the directory entered into the 
            % app.
            config.options.filesload = app.FilesLoad.Value;

            % Empty the available file types from the saved configuration.
            config.options.filetypes = [];

            for j = 1:length(app.FilesLoad.Value)

                % Use the directory entered into the app to find the load
                % scripts.
                m_files = dir(app.FilesLoad.Value{j});

                % Look for files within the directory. If the file names
                % contain the word 'load' identify the file extension and the
                % format of the data it intends to load.
                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are
                    % currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'load') && ~contains(m_files(i).name, 'asv')
                        % Identify the different parts of the load file name.
                        parts = textscan(m_files(i).name, '%s%s%s', 'Delimiter', '_');
                        % Match the equipment type with the current options in
                        % the configuration file.
                        if isfield(config.options.filetypes, parts{2}{1})
                            % If a current match is found the equipment type
                            % will be added to the options for that file
                            % extension.
                            n = length(config.options.filetypes.(parts{2}{1})) + 1;
                        else
                            n = 1;
                        end
                        config.options.filetypes.(parts{2}{1}){n} = parts{3}{1}(1:end-2);
                    end
                end

            end
            
            % Sort the options so they appear alphnumerically sorted in the app.
            config.options.filetypes = orderfields(config.options.filetypes);

            % If there are no load files or if the directory is misleading
            % because files have been moved or the app was moved nothing
            % will be found. The object in the app can't be set to empty.
            if ~isempty(config.options.filetypes)
                app.FileExtensionDropDown.Items = fieldnames(config.options.filetypes);
            end

            % Save the configuration with the updated directory and or file
            % extensions and formats.
            saveConfig(app, config)
            
            % Run the callback for the file extension selection object in
            % the app so the format selection object displays the correct
            % options.
            app.FileExtensionDropDownValueChanged;

        end

        function identifyProcessOptions(app)
            % identifyProcessOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Process folder for valid files 
            %   that would be used to process data within the app. These 
            %   files need to have a name process_*.m. The results will be 
            %   saved to the configuration file and made selectable in the 
            %   app.
            % - The goal of this function was to make the addition of new
            %   functions easier.
            % - The idea of processing is to take an input and create a new
            %   ouput of similar size and scale.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Load the existing configuration so it can be updated.
            config = loadConfig(app);

            % Update the configuration with the directory entered into the 
            % app.
            config.options.filesprocess = app.FilesProcess.Value;
            
            % Empty the available treatment files from the saved 
            % configuration.
            config.options.process = [];
            ind = 1;

            for j = 1:length(app.FilesProcess.Value)
                % Use the directory entered into the app to find the processing
                % scripts.
                m_files = dir(app.FilesProcess.Value{j});

                % Look for files within the directory. If the file names
                % contain the word 'process' save them within the app and
                % configuration.
                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are
                    % currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'process') && ~contains(m_files(i).name, 'asv')

                        [~, name] = fileparts(m_files(i).name);
                        parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                        config.options.process{ind,1} = parts{2}{1};
                        ind = ind + 1;
                    end
                end
            end

            % It is assumed the files will have the format
            % "process_<Processing Name>....." So there will be duplicates
            % for options within the processing apps. Such as
            % process_Treatment_mlapp and process_Treatment_LowPass.mlapp.
            config.options.process = unique(config.options.process);
            
            % If there are no load files or if the directory is misleading
            % because files have been moved or the app was moved nothing
            % will be found. The object in the app can't be set to empty.
            if ~isempty(config.options.process)
                app.SelectProcessingModuleDropDown.Items = config.options.process;
            end

            % Save the configuration with the updated directory and or file
            % extensions and formats.
            saveConfig(app, config)
            
        end
        
        
        function identifyAnalysisOptions(app)
            % identifyAnalysisOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Analysis folder for valid files 
            %   that would be used to analyze data within the app. These 
            %   files need to have a name analysis_*.m. The results will be 
            %   saved to the configuration file and made selectable in the 
            %   app.
            % - The goal of this function was to make the addition of new
            %   functions easier.
            % - The idea of analyzing is to take an input and create a
            %   summary or result of lesser size.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Load the existing configuration so it can be updated.
            config = loadConfig(app);

            % Update the configuration with the directory entered into the 
            % app.
            config.options.filesanalysis = app.FilesAnalysis.Value;

            % Empty the available treatment files from the saved
            % configuration.
            config.options.analysis = [];

            % Look for files within the directory. If the file names
            % contain the word 'analysis' save them within the app and
            % configuration.
            ind = 1;

            % Multiple files can be used.
            for j = 1:length(app.FilesAnalysis.Value)

                % Use the directory entered into the app to find the analysis
                % scripts.
                m_files = dir(app.FilesAnalysis.Value{j});

                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are
                    % currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'analysis') && ~contains(m_files(i).name, 'asv')
                        [~, name] = fileparts(m_files(i).name);
                        % The filenames are expected to have a specific format
                        % so the app can determine if they are analysis files
                        % and make them identifible in the app.
                        parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                        config.options.analysis{ind} = parts{2}{1};
                        ind = ind + 1;
                    end
                end

            end

            % If there are no load files or if the directory is misleading
            % because files have been moved or the app was moved nothing
            % will be found. The object in the app can't be set to empty.
            if ~isempty(config.options.analysis)
                app.AnalysisDropDown.Items = config.options.analysis;
            end

            % Save the configuration with the updated directory and or file
            % extensions and formats.
            saveConfig(app, config)
            
        end

        function identifyReviewOptions(app)
            % identifyReviewOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Review folder for valid files 
            %   that would be used to view data within the app. These 
            %   files need to have a name review_*.m. The results will be 
            %   saved to the configuration file and made selectable in the 
            %   app.
            % - The goal of this function was to make the addition of new
            %   functions easier.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Load the existing configuration so it can be updated.
            config = loadConfig(app);

            % Update the configuration with the directory entered into the 
            % app.
            config.options.filesreview = app.FilesReview.Value;

            % Empty the available treatment files from the saved
            % configuration.
            config.options.review = [];

            % Look for files within the directory. If the file names
            % contain the word 'review' save them within the app and
            % configuration.
            ind = 1;
            % Multiple directories can be used.
            for j = 1:length(app.FilesReview.Value)

                % Use the directory entered into the app to find the review
                % methods.
                m_files = dir(app.FilesReview.Value{j});
                
                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are
                    % currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'review') && ~contains(m_files(i).name, 'asv')
                        [~, name] = fileparts(m_files(i).name);
                        % The filenames are expected to have a specific format
                        % so the app can determine if they are review files
                        % and make them identifible in the app.
                        parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                        config.options.review{ind} = parts{2}{1};
                        ind = ind + 1;
                    end
                end

            end

            % If there are no load files or if the directory is misleading
            % because files have been moved or the app was moved nothing
            % will be found. The object in the app can't be set to empty.
            if ~isempty(config.options.review)
                app.ReviewDropDown.Items = config.options.review;
            end

            % Save the configuration with the updated directory and or file
            % extensions and formats.
            saveConfig(app, config)
            
        end
        
        function dat = groupAuto(app, level, dat)
            % dat = groupAuto(app, level, dat)
            % inputs  - app, required mlapp object.
            %         - level, a string specifying the level of the data
            %                  structure to identify group information in.
            %         - dat, a string from which to identify group 
            %                information or the BAR App data structure.
            % outputs - dat, a cell array of strings representing groups
            %                the parent data belongs to or the BAR App data
            %                structure.
            % Remarks
            % - This method pulls information from a string to identify
            %   groups within data sets. The intent is these groups would
            %   be used for figures or statistics later on.
            % - This method was also a solution to a variable name problem.
            %   Individuals may create directory structures, file names or
            %   variable names of long length and duplicate information.
            %   This method is a place that information can be stored while
            %   a simplier name can be used for the item.
            % - Group information is expected to be found at the file and
            %   object levels, not at the signal level. Signals should all
            %   be named the same so they can be exported.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            switch level
                case 'filename'
                    % The various folders in the files directory path will
                    % be turned into group information. This includes the
                    % drive letter.
                    dat = split(dat, '\');
                    i = 1;
                    % For each folder check if underscores were used as a
                    % delimter and use that to create groups.
                    while i <= length(dat)
                        temp = split(dat{i}, '_');
                        if length(temp) > 1
                            % Extend the group array so the order is
                            % preserved.
                            dat = [dat(1:i-1); temp; dat(i+1:end)];
                        end
                        i = i + 1;
                    end
                    % Remove the file extension so it is not used as a
                    % group.
                    ind = strfind(dat{end}, '.');
                    dat{end}(ind(end):end) = [];
                case 'object'
                    files = fieldnames(dat.raw);
                    for i = 1:length(files)
                        % The objects will be iterated through and their
                        % text turned into group information.
                        obj = fieldnames(dat.raw.(files{i}));
                        obj(strcmp(obj, 'meta')) = [];
                        obj(strcmp(obj, 'groups')) = [];
                        for j = 1:length(obj)
                            if isfield(dat.raw.(files{i}).(obj{j}), 'groups')
                                % Add the deliminated object name to the
                                % existing group information.
                                dat.raw.(files{i}).(obj{j}).groups = [dat.raw.(files{i}).(obj{j}).groups; split(obj{j}, '_')];
                                % The stable option will retain the order
                                % which helps interpret the groups.
                                dat.raw.(files{i}).(obj{j}).groups = unique(dat.raw.(files{i}).(obj{j}).groups, 'stable');
                            else
                                % Add the delimited object name to the
                                % group information.
                                dat.raw.(files{i}).(obj{j}).groups = split(obj{j}, '_');
                            end
                        end
                    end
            end
            
        end
        
        function dataExport = formatTables(app, data)
            % dataExport = formatTables(app, data)
            % inputs  - app, required mlapp object.
            %         - data, BAR App data structure.
            % outputs - dataExport, the BAR App data formated as a table.
            % Remarks
            % - This method pulls tabular information from the results and formats it for export. It is assumed that all tables in the data will be
            %   formated the same. Anything that is not a table will be skipped.
            % Future Work
            % - None
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % A flag is used to initialize the table within the for loops.
            flag = 0;

            % Get the filenames.
            files = fieldnames(data.res);
            % Start iterating thought he filenames.
            for i = 1:length(files)
                % Get the object names.
                obj = fieldnames(data.res.(files{i}));
                % Start iterating through the objects.
                for j = 1:length(obj)
                    % Get the signal names.
                    sig = fieldnames(data.res.(files{i}).(obj{j}).data);
                    % Start iterating through the signal names.
                    for k = 1:length(sig)
                        % Check if the signal is a table.
                        if istable(data.res.(files{i}).(obj{j}).data.(sig{k}))
                            if flag == 0
                                % For the initial data the table is used as is.
                                dataExport = data.res.(files{i}).(obj{j}).data.(sig{k});
                            else
                                % Additional data are concatenated together. This requires them to have the same columns.
                                dataExport = [dataExport; data.res.(files{i}).(obj{j}).data.(sig{k})];
                                flag = 1;
                            end
                        end
                    end
                end
            end
            
        end
    end
    
    methods (Access = public)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of
            %           the error and organize the errors available.
            %         - newText, a string that may be inserted into the
            %                    message.
            % Remarks
            % - This method will print messages to the log of the BAR App.
            %   The error codes are unique to each message so they can be
            %   found and referenced easier.
            % - The method is public and is acessible outside of the BAR
            %   App. It is used in some of the example analysis and process
            %   scripts.
            % Future Work
            % - This log should be printed and saved to a file location
            %   each time the BAR App is run.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text within the log.
            text = app.Log.Value;

            % Match the error code with the message and update the text for
            % the log.
            switch code
                case '001'
                    text{length(text) + 1,1} = ['BAR-001 file search started'];
                case '002'
                    text{length(text) + 1,1} = ['BAR-002 file search complete, ' num2str(newText{1}) ' files found, ' num2str(ceil(newText{2}*1e-6)) ' GB total'];
                case '003'
                    text{length(text) + 1,1} = ['BAR-003 error reading file: ' newText];
                case '004'
                    text{length(text) + 1,1} = ['BAR-004 loading files started for: ' newText];
                case '005'
                    text{length(text) + 1,1} = ['BAR-005 loading files complete for: ' newText];
                case '006'
                    text{length(text) + 1,1} = ['BAR-006 results not found'];
                case '007'
                    text{length(text) + 1,1} = ['BAR-007 results exported'];
                case '008'
                    text{length(text) + 1,1} = ['BAR-008 results formated for export and displayed in table'];
                case '009'
                    text{length(text) + 1,1} = ['BAR-009 results formated for exported but are not compatible with the table'];
                case '010'
                    text{length(text) + 1,1} = ['BAR-010 created Results folder at ' newText];
                case '011'
                    text{length(text) + 1,1} = ['BAR-011 created Figure folder at ' newText];
                case '012'
                    text{length(text) + 1,1} = ['BAR-012 files saving....'];
                case '013'
                    text{length(text) + 1,1} = ['BAR-013 files saved'];
                case '014'
                    text{length(text) + 1,1} = ['BAR-014 moving files...'];
                case '015'
                    text{length(text) + 1,1} = ['BAR-015 moving files complete'];
                case '016'
                    text{length(text) + 1,1} = ['BAR-016 no files found in the selected directory'];
                case '017'
                    text{length(text) + 1,1} = ['BAR-017 error moving file: ' newText];
                case '018'
                    text{length(text) + 1,1} = ['BAR-018 copying files...'];
                case '019'
                    text{length(text) + 1,1} = ['BAR-019 copying files complete'];
                case '020'
                    text{length(text) + 1,1} = ['BAR-020 error copying file: ' newText];
                case '021'
                    text{length(text) + 1,1} = ['BAR-021 ' newText];
                case '022'
                    text{length(text) + 1,1} = ['BAR-022 starting analysis:' newText];
                case '023'
                    text{length(text) + 1,1} = ['BAR-022 analysis complete:' newText];
                case '024'
                    text{length(text) + 1,1} = ['BAR-024 ' newText];
                case '025'
                    text{length(text) + 1,1} = ['BAR-025 saving data from ' newText];
                case '026'
                    text{length(text) + 1,1} = ['BAR-026 data saved from ' newText];
                case '027'
                    text{length(text) + 1,1} = ['BAR-027 ' newText];
                case '028'
                    text{length(text) + 1,1} = ['BAR-028 error creating figure: ' newText];
                case '029'
                    text{length(text) + 1,1} = ['BAR-029 unhandled exception in analysis: ' newText];
                case '030'
                    text{length(text) + 1,1} = ['BAR-030 no data loaded'];
                case '031'
                    text{length(text) + 1,1} = ['BAR-031 ' newText];
                case '032'
                    text{length(text) + 1,1} = ['BAR-032 no load method found'];
            end
            % Update the log.
            app.Log.Value = text;

            % Scroll to the bottom so the most recent message is visible.
            scroll(app.Log,'bottom')
            drawnow
            
        end
        
        function analysisComplete(app, data, analysis)
            % analysisComplete(app, data, analysis)
            % inputs  - app, required mlapp object.
            %         - data, data structure from the BAR App.
            %         - analysis, a string with the name of the analysis 
            %                     that was completed.
            % Remarks
            % - This method helps return data from an analysis or process
            %   method. It will save the data within the BAR App and save
            %   it to file.
            % Future Work
            % - none
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            printLog(app, '025', analysis)

            % Save the data returned to the BAR App within the app.
            setappdata(app.TableFiles, 'data', data);
            
            % Get the database location so the filename can be constructed.
            m_resultsPath = app.Database.Value;

            % Construct a timestamp to use in the file name.
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            % Get a user entered file name and suggest the coded version.
            [file, path] = uiputfile('.mat', 'Save As', [m_resultsPath '\Results\' app.FileExtensionDropDown.Value app.EquipmentDropDown.Value timeStamp '.mat']);
            % Exit if no filename was entered.
            if ~(isnumeric(file) && file == 0)
                % Some data can take a while to save so an update to the
                % log is printed before and after.
                save([path file], 'data', '-v7.3')
            end

            % Update the log to notify the user the data has saved.
            printLog(app, '026', analysis)

        end
        
        function dataExport = formatResults(app, data)
            % dataExport = formatResults(app, data)
            % inputs  - app, required mlapp object.
            %         - data, data structure from the BAR App.
            % outputs - dataExport, a structure with data reformated for
            %                       export and ready to be made into a
            %                       table.
            % Remarks
            % - This method reformats the data structure into a form that
            %   can be exported as a table for further analysis outside of
            %   the BAR App.
            % - The method was intended to be generalized for any export.
            %   If told to it would try to export raw data of great
            %   length.
            % - There is an option to average within signals or to export
            %   each item within a signal separately.
            % Future Work
            % - This code is likely to encounter errors and need further
            %   development, as it is very near the end of the processing
            %   steps and likely to encounter varied data.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the user selected data types to export.
            types = app.SelectitemstoexportListBox.Value;

            % If only one was selected it needs to be converted to a cell.
            if ~iscell(types)
                types = {types};
            end

            dataExport = struct;

            for i = 1:length(types)

                switch types{i}
                    
                    % This code is intended to be used for both raw and
                    % results data.
                    case {'raw', 'res'}
                        ind = 1;
                        files = fieldnames(data.(types{i}));
                        for j = 1:length(files)
                            
                            % Get the different measures and remove the
                            % meta and group information from the export.
                            measures = fieldnames(data.(types{i}).(files{j}));
                            measures(strcmp(measures, 'meta')) = [];
                            measures(strcmp(measures, 'groups')) = [];
                            for k = 1:length(measures)
                                sigs = fieldnames(data.(types{i}).(files{j}).(measures{k}).data);
                                ind2_max = 0;
                                
                                % Put the signals into the structure for
                                % export.
                                for ii = 1:length(sigs)
                                    
                                    % This index is used if each element of
                                    % the signals will be exported.
                                    ind2 = 0;
                                    
                                    % If the signals were selected to be
                                    % averaged within each signal.
                                    if strcmp(app.ExportMeansSwitch.Value, 'Yes')

                                        dataExport.file{ind, 1} = files{j};
                                        
                                        % The group information will be
                                        % exported for each row of data.
                                        if isfield(data.(types{i}).(files{j}), 'groups')
                                            for jj = 1:length(data.(types{i}).(files{j}).groups)
                                                dataExport.(['group' num2str(jj)]){ind, 1} = data.(types{i}).(files{j}).groups{jj};
                                            end
                                        elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{j}), 'groups')
                                            % If groups are not present in
                                            % the results data the same
                                            % fields will be looked for in
                                            % the raw data and put in the
                                            % export.
                                            for jj = 1:length(data.raw.(files{j}).groups)
                                                dataExport.(['group' num2str(jj)]){ind, 1} = data.raw.(files{j}).groups{jj};
                                            end
                                        end

                                        % Put the name of the measure in
                                        % the export.
                                        dataExport.measures{ind, 1} = measures{k};
                                        % Calculate the mean of the signal.
                                        dataExport.(sigs{ii}){ind, 1} = mean(data.(types{i}).(files{j}).(measures{k}).data.(sigs{ii}), 1, 'omitnan');

                                        % Up the final index used to change the row in the table.
                                        ind2_max = ind2 + 1;
                                                                            
                                    % If the individual elements of each
                                    % signal is to be exported.
                                    elseif strcmp(app.ExportMeansSwitch.Value, 'No')
                                        
                                        % Numel is used in case the signal
                                        % is a multi-dimentional array.
                                        for kk = 1:numel(data.(types{i}).(files{j}).(measures{k}).data.(sigs{ii}))
                                            
                                            % The group information will be
                                            % exported for each row of data.
                                            dataExport.file{ind + ind2, 1} = files{j};
                                            if isfield(data.(types{i}).(files{j}), 'groups')
                                                for jj = 1:length(data.(types{i}).(files{j}).groups)
                                                    dataExport.(['group' num2str(jj)]){ind + ind2, 1} = data.(types{i}).(files{j}).groups{jj};
                                                end
                                            % If groups are not present in
                                            % the results data the same
                                            % fields will be looked for in
                                            % the raw data and put in the
                                            % export.
                                            elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{j}), 'groups')
                                                for jj = 1:length(data.raw.(files{j}).groups)
                                                    dataExport.(['group' num2str(jj)]){ind + ind2, 1} = data.raw.(files{j}).groups{jj};
                                                end
                                            end
                                            
                                            % Export the measure and
                                            % individual signal value.
                                            dataExport.measures{ind + ind2, 1} = measures{k};
                                            dataExport.(sigs{ii}){ind + ind2, 1} = data.(types{i}).(files{j}).(measures{k}).data.(sigs{ii})(kk);
                                            
                                            % Add one to the index for each
                                            % signal. This will be used to
                                            % update the total row count at
                                            % the end of this measure.
                                            ind2 = ind2 + 1;

                                        end
                                        
                                        % Not all signals may have the same
                                        % elements so we need to keep track
                                        % of the maximum. The holes will
                                        % remain empty.
                                        if ind2 > ind2_max
                                            ind2_max = ind2;
                                        end
                                    end
                                end
                                
                                % Update the row index with the number of
                                % signal exports.
                                ind = ind + ind2_max;
                            end

                            % Print an update to the log every 10 files.
                            if rem(j, 10) == 0
                                printLog(app, '021', ['formated ' num2str(j) ' of ' num2str(length(files))])
                            end

                        end

                        % It's possible not all rows will have the same
                        % data, all the fields of the structure need to be
                        % the same size before it can be made into a table.
                        % Firstm find the largest field.
                        N = [];
                        exportfields = fieldnames(dataExport);
                        for j = 1:length(exportfields)
                            N(j) = length(dataExport.(exportfields{j}));
                        end
                        % Padd all the fields with empty arrays if they do
                        % not match the maximum.
                        for j = 1:length(exportfields)
                            if length(dataExport.(exportfields{j})) < max(N)
                                dataExport.(exportfields{j})(length(dataExport.(exportfields{j})):max(N)) = {[]};
                            end
                        end

                end
            end

            % Update the BAR App table with the data formated for export
            % with the column names.
            app.UITableExport.Data = struct2table(dataExport);
            app.UITableExport.ColumnName = fieldnames(dataExport);

        end

        function config = loadConfig(app)
            % config = loadConfig(app)
            % inputs  - app, required mlapp object.
            % outputs - config, structure with configuration information.
            % Remarks
            % - This method load information from the configuration file
            %   into the app. This is the primary way options and settings
            %   are retained each time the BAR App is run.
            % Future Work
            % - This code is likely to need upkeep as the capabilities of
            %   the app increase.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % This code was used when trying to develop the app into a standalone executable. However the dynamic nature of the app is not possible
            % when the code is compiled. So instead of looking for the configuration in an AppData folder for the executable it is looked for in the
            % same directory as the app.
%             pathConfig = ['C:\\Users\' getenv('username') '\AppData\Local\BAR'];
%             if ~isfolder(pathConfig)
%                 mkdir(pathConfig);
%             end

            pathConfig = mfilename('fullpath');
            ind = strfind(pathConfig, '\');
            pathConfig(ind(end):end) = [];
            
            % Create the config file if it doesn't exist.
            if ~isfile([pathConfig '\config.xml'])
                createConfig([pathConfig '\']);
            end

            % Load the config file.
            config = readstruct([pathConfig '\config.xml']);
            
        end
        
        
        function saveConfig(app, config)
            % saveConfig(app, config)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method saves the configuration information to an xml
            %   file. 
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % This method was removed after finding the dynamic nature of the app was not possible after it was compiled into a standalone executable.
%             pathConfig = ['C:\\Users\' getenv('username') '\AppData\Local\BAR'];

            pathConfig = mfilename('fullpath');
            ind = strfind(pathConfig, '\');
            pathConfig(ind(end):end) = [];
            
            % Load the config file.
            writestruct(config, [pathConfig '\config.xml']);
        end
        
        function data = loadData(app)
            % data = loadData(app)
            % inputs  - app, required mlapp object.
            % outputs - data, data structure specific to the BAR App.
            % Remarks
            % - This method dynamically loads data into the BAR App. It 
            %   uses the files found during the search process, the
            %   selected data types and formats, and the available load
            %   functions in the load folder.
            % - This method is intended to be highly flexible. So that if
            %   additional load functions are available it will use them to
            %   load the data.
            % - This method will find group information within the file
            %   names and from the objects loaded into the app.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            printLog(app, '004', [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value])

            files = app.TableFiles.Data;
            % Return if no files have been found by the app.
            if isempty(files)
                printLog(app, '032')
                return
            end

            % Find the load function that matches the selected file extension and equipment type.
            % Get the load functions for the first directory.
            filesLoad = dir(app.FilesLoad.Value{1});
            % Get the load functions for the additional directories if there are more.
            if length(app.FilesLoad.Value) > 1
                for i = 2:length(app.FilesLoad.Value)
                    filesLoad = [filesLoad; dir(app.FilesLoad.Value{i})];
                end
            end
            % Find the index where the filename contains the target file extension and the equipment type and is not an asv-file.
            ind3 = find(contains({filesLoad.name}', app.FileExtensionDropDown.Value) & contains({filesLoad.name}', [app.EquipmentDropDown.Value '.']) & ~contains({filesLoad.name}', 'asv'));

            % Exit if no file was found.
            if isempty(ind3)
                return
            end

            % If the selected format is a BAR App format it cannot go
            % through the same methods as new data.
            if strcmp(filesLoad(ind3).name(1:end - 2), 'load_mat_BAR')
                % Load data saved in a mat file.
                load([files.folder{1} '\' files.name{1}])
            elseif contains(filesLoad(ind3).name(1:end - 2), 'load') && contains(filesLoad(ind3).name(1:end - 2), 'BAR')
                % Load data not saved in a mat-file but also in a BAR App
                % format.
                data = feval(filesLoad(ind3).name(1:end - 2), [files.folder{1} '\' files.name{1}]);
            else
                data = struct;
                ind1 = 1;

                for i = 1:height(files)

                    % The name of the file will be used as the parent data
                    % field. It cannot have certain characters as a structure
                    % field. These are all replaced with underscores.
                    if i < 10
                        name = ['files000' num2str(i)];
                    elseif i < 100
                        name = ['files00' num2str(i)];
                    elseif i < 1000
                        name = ['files0' num2str(i)];
                    elseif i < 10000
                        name = ['files' num2str(i)];
                    else
                        name = ['files' num2str(i)];
                    end
                    
                    % Dynamically load the data into a structure.
                    try
                        data.raw.(name) = feval(filesLoad(ind3).name(1:end - 2), [files.folder{i} '\' files.name{i}]);
                        % Get the group information from the file's
                        % directory and name.
                        data.raw.(name).groups = groupAuto(app, 'filename', [files.folder{i}, '\', files.name{i}]);
                        % Get the group information for the objects of the
                        % data.
                        data = groupAuto(app, 'object', data);
                    catch ME
                        
                        % Print a message to the log if the load fails for any reason.
                        printLog(app, '003', [files.folder{i} '\' files.name{i}])

                        % Get MATLAB's last error message and display it.
                        printLog(app, '031', ME.message)
                    end

                    % Provide an update to the log every 10 files.
                    if rem(i, 10) == 0
                        printLog(app, '027', ['loaded ' num2str(i) ' of ' num2str(height(files)) ' files'])
                    end

                end
            end
            
            % Print a message notifying the user if the load was completed.
            printLog(app, '005', [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value])

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % startupFcn(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method is run automatically when the app starts. It's
            %   main function is to load the configuration file and set the
            %   various options within the BAR App.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % The goal of the current version of the configuration file is
            % to quicken the app startup processes. There are methods to
            % programatically configure these but they are only run as
            % needed.
            
            % Load the config file.
            config = loadConfig(app);

            % Set the workspace, directory and database text fields using the saved
            % configuration.
            app.Directory.Value = config.directory;
            app.Database.Value = config.database;

            % Set the file extension options using the saved configuration.
            if isfield(config.options, 'filetypes')
                app.FileExtensionDropDown.Items = fieldnames(config.options.filetypes);
                % Run the callback for the file extension selection object in
                % the app so the format selection object displays the correct
                % options.
                app.FileExtensionDropDownValueChanged;
            end
            
            % Set the directories in the configuration tab using what is 
            % saved in the app.
            app.FilesLoad.Value = config.options.filesload;
            app.FilesProcess.Value = config.options.filesprocess;
            app.FilesAnalysis.Value = config.options.filesanalysis;
            app.FilesReview.Value = config.options.filesreview;

            % Add the workspace directory and all subfolders to the search path.
            for i = config.options.filesload
                pLoad = genpath(i{1});
                addpath(pLoad)
            end
            for i = config.options.filesprocess
                pProcess = genpath(i{1});
                addpath(pProcess)
            end
            for i = config.options.filesanalysis
                pAnalysis = genpath(i{1});
                addpath(pAnalysis)
            end
            for i = config.options.filesreview
                pReview = genpath(i{1});
                addpath(pReview)
            end

            % Set the analysis method options.
            if isfield(config.options, 'analysis')
                app.AnalysisDropDown.Items = config.options.analysis;
            end

            % Set the processing method options.
            if isfield(config.options, 'process')
                app.SelectProcessingModuleDropDown.Items = config.options.process;
            end
            
            % Set the review method options.
            if isfield(config.options, 'review')
                app.ReviewDropDown.Items = config.options.review;
            end

            % Set the export format options.
            app.FormatsDropDown.Items = config.export;
            
        end

        % Button pushed function: AddDirectorytoSearchButton
        function AddDirectorytoSearchButtonPushed(app, event)
            % AddDirectorytoSearchButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback is used to add new directories to the search
            %   options. Those can also be entered in directly.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Allow the user to select the parent directory.
            m_path = uigetdir(pwd,'Please select the data directory');
            % The directory is saved to the text field where it will be
            % pulled from later.
            if m_path == 0
                return
            end
            % If the current text box is empty the directory path will be
            % set to it directly.
            if isempty(app.Directory.Value)
                app.Directory.Value = m_path;
            else
                % If path or paths are already existing in the text box the
                % new path will be appended to it.
                text = app.Directory.Value;
                app.Directory.Value{length(app.Directory.Value) + 1, 1} = m_path;
            end

            % If the user selected a directory it will be saved to the
            % configuration file so it can be loaded the next time the app
            % is run.
            config = loadConfig(app);
            config.directory = app.Directory.Value;
            saveConfig(app, config)

        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            % FindButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run the search algorithm to find the
            %   target file types and formats among the files in the
            %   directories listed in the search text field.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Pull the selected directory from the app text box.
            m_directory = app.Directory.Value;
            % Pull the selected file extension from the app dropdown.
            m_fileType = app.FileExtensionDropDown.Value;
            % Pull the equipment type from the app dropdown.
            m_equipmentType = app.EquipmentDropDown.Value;
            % Pull the search tags the user entered into the app text box.
            m_searchTags = app.SearchTagsInclude.Value;
            
            % Initialize an empty structure for the object to store the
            % file information.
            files = struct;
            % Perform recursive file search.
            printLog(app, '001', {''})
            for i = 1:length(m_directory)
                files = filesearch(app, files, m_directory{i});
            end
            if isempty(fieldnames(files))
                printLog(app, '016')
                return
            end

            printLog(app, '002', {length(files.name);sum(files.bytes)})
            % Put the files found into the app table.
            app.TableFiles.Data = struct2table(files);
%             app.TableFiles.ColumnName = fieldnames(files);

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % LoadButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run load method to bring data into the
            %   app. It was structured this way so the load function could
            %   be used alone, elsewhere.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            data = loadData(app);

            setappdata(app.TableFiles,'data',data)

            app.IdentifyGroupsButton.Enable = 1;
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            % LoadButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will dynamically run the selected analysis on
            %   the data currently loaded into the BAR App. It was intended
            %   to be highly flexible so new analysis methods could be
            %   added and used quickly without knowing how to program the
            %   app.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            data = getappdata(app.TableFiles, 'data');
            if isempty(data)
                printLog(app ,'030')
                return
            end
            
            % There dropdown values were updated earlier during startup
            % using the config file.
            analysis = app.AnalysisDropDown.Value;
            
            printLog(app ,'022', analysis)
            
            % This function call makes use of the analysis file name
            % structure.
            app.AppAnalysis = feval(['analysis_' analysis], app, data, analysis);
            
        end

        % Value changed function: FileExtensionDropDown
        function FileExtensionDropDownValueChanged(app, event)
            % FileExtensionDropDownValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run when ever the file extension option
            %   is changed and will change the equipment type to match the
            %   available options. This information is pulled from the
            %   configuration file, which is dynamically pulled from the
            %   load functions found in the load folder.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the currently selected file extension.
            value = app.FileExtensionDropDown.Value;
            % Load the configuration file with the matching file extensions
            % and equipment names.
            config = loadConfig(app);
            % Get the file extension options.
            options = fieldnames(config.options.filetypes);
            for i = 1:length(options)
                if strcmp(value, options{i})
                    app.EquipmentDropDown.Items = config.options.filetypes.(options{i});
                    break
                end
            end
            
        end

        % Button pushed function: ButtonFormatExport
        function ButtonFormatExportPushed(app, event)
            % ButtonFormatExportPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run different subroutines to format data
            %   for export. It is intended to be somewhat flexible so
            %   different methods could be used.
            % Future Work
            % - Similar to the load, process and analysis methods this one
            %   could be made more dynamic to allow for easier user
            %   modification.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            data = getappdata(app.TableFiles, 'data');

            switch app.FormatsDropDown.Value
                case 'General'
                    % This is a generic export that should be able to work
                    % for everything.
                    dataExport = formatResults(app, data);
                    app.UITableExport.Data = struct2table(dataExport);
                    app.UITableExport.ColumnName = fieldnames(dataExport);
                    setappdata(app.UITableExport,'exportType','General')
                    printLog(app, '008')
                case 'BU Caltesting'
                    % This export is specific to existing BU MoveLab
                    % practices.
                    dataExport = formatBUCaltest(app, data);
                    setappdata(app.UITableExport,'dataExport',dataExport)
                    setappdata(app.UITableExport,'exportType','BU Caltesting')
                    printLog(app, '009')
                case 'Tables'
                    % This export will print all the results as tables.
                    dataExport = formatTables(app, data);
                    app.UITableExport.Data = dataExport;
                    app.UITableExport.ColumnName = fieldnames(dataExport);
                    setappdata(app.UITableExport,'exportType','General')
                    
            end

        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            % ExportResultsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will export the data formated for export. The
            %   intent was a user could format the data, review it within
            %   the app and then export it through this callback.
            % Future Work
            % - Similar to the load, process and analysis methods this one
            %   could be made more dynamic to allow for easier user
            %   modification.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            exportType = app.FormatsDropDown.Value;
            
            m_resultsPath = app.Database.Value;
            
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            switch exportType
                case {'General', 'Tables'}
                    % The data was previously formated and displayed in the
                    % table. That data will be directly exported.
                    dataExport = app.UITableExport.Data;
                    % Allow the user to specify a file. A suggested file
                    % name is provided.
                    [file, path] = uiputfile('.xlsx', 'Save As', [m_resultsPath '\Export\General' timeStamp '.xlsx']);
                    % If nothing is selected the function returns.
                    if isnumeric(file) && file == 0
                        return
                    else
                        % Write the data.
                        writetable(dataExport,[path file])
                    end
                case 'BU Caltesting'
                    % This data cannot be displayed in the BAR App table so
                    % it needs to be pulled from the app data.
                    dataExport = getappdata(app.UITableExport,'dataExport');
                    fields = fieldnames(dataExport);
                    % The filename is coded and the user does not specify
                    % what they want.
                    fileName = [m_resultsPath '\Export\' exportType timeStamp '.xlsx'];
                    for i = 1:length(fields)
                        xlswrite(fileName,dataExport.(fields{i}),fields{i})
                    end
            end

            printLog(app, '007')
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % ProcessButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will dynamically run the available process methods based on what is stored in the configuration file and what was found
            %   in the process folder.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            % Dec 2022 - Modified by Ben Senderling, bsender@bu.edu
            %          - Updated to use the same dynamic referencing as the load and analysis methods.

            % Get the current data to process.
            data = getappdata(app.TableFiles, 'data');
            
            % Find the load function that matches the selected file extension and equipment type.
            % Get the load functions for the first directory.
            filesProcess = dir(app.FilesProcess.Value{1});
            % Get the load functions for the additional directories if there are more.
            if length(app.FilesProcess.Value) > 1
                for i = 2:length(app.FilesProcess.Value)
                    filesProcess = [filesProcess; dir(app.FilesProcess.Value{i})];
                end
            end

            % Find the index where the filename contains the target file extension and the equipment type and is not an asv-file.
            ind3 = find(contains({filesProcess.name}', app.SelectProcessingModuleDropDown.Value) & ~contains({filesProcess.name}', 'asv'), 1);

            % Exit if no file was found.
            if isempty(ind3)
                return
            end

            % These drop down values were found dynamically by the app using the file names of the processfile.
            process = app.SelectProcessingModuleDropDown.Value;

            % Find the index of '.m' which will be present in all m- and mlapp-files.
            ind4 = strfind(filesProcess(ind3).name, '.m');

            % Dynamically run the method.
            app.AppAnalysis = feval(filesProcess(ind3).name(1:ind4 - 1), app, data, process);
        end

        % Button pushed function: SaveCurrentDataButton
        function SaveCurrentDataButtonPushed(app, event)
            % SaveCurrentDataButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will save the current data to a user selected
            %   file. It will save the entire BAR App data structure and
            %   could be of great size if a lot of data was loaded into the
            %   app. It will also inlcude process and results data.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Pull the current data from the app data.
            data = getappdata(app.TableFiles, 'data');

            % Get the database location so the filename can be constructed.
            m_resultsPath = app.Database.Value;

            % Construct a timestamp to use in the file name.
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            % Get a user entered file name and suggest the coded version.
            [file, path] = uiputfile('.mat', 'Save As', [m_resultsPath '\Data\' app.FileExtensionDropDown.Value app.EquipmentDropDown.Value timeStamp '.mat']);
            % Exit if no filename was entered.
            if isnumeric(file) && file == 0
                return
            else
                % Some data can take a while to save so an update to the
                % log is printed before and after.
                printLog(app, '012')
                save([path file], 'data', '-v7.3')
                printLog(app, '013')
            end

        end

        % Button pushed function: SelectWorkingDatabaseButton
        function SelectWorkingDatabaseButtonPushed(app, event)
            % SelectWorkingDatabaseButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback allows the user to select the working
            %   database. Once selected the database will be configured for
            %   use with the BAR App with the required subfolders.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Allow the user to select the working directory to store data.
            m_path = uigetdir(pwd,'Please select the working directory');
            % The directory is saved to the text field where it will be
            % pulled from later.
            if m_path==0
                return
            end
            app.Database.Value = m_path;

            createDatabase(app);


        end

        % Button pushed function: ReviewAnalysisButton
        function ReviewAnalysisButtonPushed(app, event)
            % ReviewAnalysisButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run a BAR App Module to review results
            %   calculated from the data. Most of these modules are
            %   developed to work with specific data.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            data = getappdata(app.TableFiles, 'data');

            % Find the review function that matches the selected file extension and equipment type.
            % Get the load functions for the first directory.
            filesReview = dir(app.FilesReview.Value{1});
            % Get the load functions for the additional directories if there are more.
            if length(app.FilesReview.Value) > 1
                for i = 2:length(app.FilesReview.Value)
                    filesReview = [filesReview; dir(app.FilesReview.Value{i})];
                end
            end

            % Find the index where the filename contains the target file extension and the equipment type and is not an asv-file.
            ind3 = find(contains({filesReview.name}', app.ReviewDropDown.Value) & ~contains({filesReview.name}', 'asv'), 1);

            % Exit if no file was found.
            if isempty(ind3)
                return
            end

            % Get the selected review method.
            review = app.ReviewDropDown.Value;

            % Find the index of '.m' which will be present in all m- and mlapp-files.
            ind4 = strfind(filesReview(ind3).name, '.m');

            % Dynamically run the method.
            app.AppAnalysis = feval(filesReview(ind3).name(1:ind4 - 1), app);

        end

        % Button pushed function: IdentifyGroupsButton
        function IdentifyGroupsButtonPushed(app, event)
            % IdentifyGroupsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run a BAR App Module to process group
            %   information within the BAR App. Most of the groups will be
            %   identified when the data is loaded in but these are likely
            %   to contain extraneous information. That information can be
            %   removed through the module.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            data = getappdata(app.TableFiles, 'data');
            % Run the Groupsing module.
            app.AppAnalysis = process_Groupings(app, data);

        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            % MoveButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will move the files found by the app to a
            %   target directory. This can be useful if files in various
            %   subfolders need to be moved to a single folder, or to a
            %   duplicate location.
            % - This is a move operation and the files will no longer be
            %   present in the original directories.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Print to the log the move is starting.
            printLog(app, '014')

            % Return if no files have been found by the BAR App.
            files = app.TableFiles.Data;
            if isempty(files)
                return
            end

            % Get the target directory from the user.
            targetDirectory = uigetdir(app.Database.Value, 'Please select a target directory to move the files to');

            filesLoad = dir(app.FilesLoad.Value);

            % Iterate through each file and move them.
            for i = 1:height(files)
                [status, msg] = movefile([files.folder{i} '\' files.name{i}], targetDirectory);
                % Print an update to the log if a move failed.
                if status == 0
                    printLog(app, '017', msg)
                end

            end
            
            % Print to the log the move has completed.
            printLog(app, '015')
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            % CopyButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will copy the files found by the app to a
            %   target directory. This can be useful if files in various
            %   subfolders need to be moved to a single folder, or to a
            %   duplicate location.
            % - This is a copy operation and the original files will remain
            %   in their original location. This may significantly increase
            %   storage requirements if a large amount of data is being
            %   moved.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            
            printLog(app, '018')

            % Get the current list of files found by the BAR App.
            files = app.TableFiles.Data;
            % Return if no files have been found by the BAR App.
            if isempty(files)
                return
            end

            % Get the target directory from the user.
            targetDirectory = uigetdir(app.Database.Value, 'Please select a target directory to move the files to');

            % Iterate through the files and copy them.
            for i = 1:height(files)
                [status, msg] = copyfile([files.folder{i} '\' files.name{i}], targetDirectory);
                % Print an update to the log if a copy fails.
                if status == 0
                    printLog(app, '020', msg)
                end

            end
            
            printLog(app, '019')
        end

        % Button pushed function: UpdateConfigurationButton
        function UpdateConfigurationButtonPushed(app, event)
            % UpdateConfigurationButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run a number of subroutines that will
            %   update the configuration file will the current files. These
            %   files are pulled from the locations listed in the
            %   configuration tab.
            % Future Work
            % - This may need to update the search path if a new folder is added that is not in the current path.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Search the Load folder and update the file extensions and
            % equipment types.
            identifyFileTypes(app)

            % Search the Process folder and update the available methods.
            identifyProcessOptions(app)

            % Search the Analysis folder and update the available methods.
            identifyAnalysisOptions(app)

            % Search the Analysis folder and update the available methods.
            identifyReviewOptions(app)

        end

        % Button pushed function: FindeligibleexportsButton
        function FindeligibleexportsButtonPushed(app, event)
            % FindeligibleexportsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will determine if raw, processed or results
            %   data is present within the data structure. It will update a
            %   list in the app with those options.
            % Future Work
            % - This callback may not be necessary. It was created as part% Print to the log the move is starting.
            %   of developing the app.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Pull the current data from the app.
            data = getappdata(app.TableFiles, 'data');

            % The eligible exports are the very first level of the BAR App
            % data structure.
            app.SelectitemstoexportListBox.Items = fieldnames(data);

        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            % MergeButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run the merge module. This is used to
            %   merge currently loaded data with newly found files. For
            %   this method to work properly the existing and new data do
            %   need to share common group information.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % This is a BAR App Module.
            process_Merge(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseSearchTab = uitab(app.TabGroup);
            app.DatabaseSearchTab.Title = 'Database Search';
            app.AddDirectorytoSearchButton = uibutton(app.DatabaseSearchTab, 'push');
            app.AddDirectorytoSearchButton.ButtonPushedFcn = createCallbackFcn(app, @AddDirectorytoSearchButtonPushed, true);
            app.AddDirectorytoSearchButton.Text = 'Add Directory to Search';
            app.SelectfileextensionofthedatafilesLabel = uilabel(app.DatabaseSearchTab);
            app.SelectfileextensionofthedatafilesLabel.Text = {'Select file extension'; 'of the data files.'};
            app.FileExtensionDropDown = uidropdown(app.DatabaseSearchTab);
            app.FileExtensionDropDown.Items = {};
            app.FileExtensionDropDown.ValueChangedFcn = createCallbackFcn(app, @FileExtensionDropDownValueChanged, true);
            app.FileExtensionDropDown.Value = {};
            app.SelectequipmentthedatawasrecordedfromLabel = uilabel(app.DatabaseSearchTab);
            app.SelectequipmentthedatawasrecordedfromLabel.Text = {'Select equipment the'; 'data was recorded from.'};
            app.EquipmentDropDown = uidropdown(app.DatabaseSearchTab);
            app.EquipmentDropDown.Items = {};
            app.EquipmentDropDown.Value = {};
            app.EditFieldLabel = uilabel(app.DatabaseSearchTab);
            app.EditFieldLabel.Text = {'Enter specific strings separated by'; 'commas to filter the files selected.'};
            app.SearchTagsInclude = uieditfield(app.DatabaseSearchTab, 'text');
            app.TableFiles = uitable(app.DatabaseSearchTab);
            app.TableFiles.ColumnName = {'File Name'; 'Folder'; 'Size (MB)'};
            app.TableFiles.RowName = {};
            app.FindButton = uibutton(app.DatabaseSearchTab, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.LoadButton = uibutton(app.DatabaseSearchTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveCurrentDataButton = uibutton(app.DatabaseSearchTab, 'push');
            app.SaveCurrentDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentDataButtonPushed, true);
            app.SaveCurrentDataButton.Text = 'Save Current Data';
            app.SelectWorkingDatabaseButton = uibutton(app.DatabaseSearchTab, 'push');
            app.SelectWorkingDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWorkingDatabaseButtonPushed, true);
            app.SelectWorkingDatabaseButton.Text = 'Select Working Database';
            app.Database = uieditfield(app.DatabaseSearchTab, 'text');
            app.IdentifyGroupsButton = uibutton(app.DatabaseSearchTab, 'push');
            app.IdentifyGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyGroupsButtonPushed, true);
            app.IdentifyGroupsButton.Enable = 'off';
            app.IdentifyGroupsButton.Text = 'Identify Groups';
            app.SearchTagsExclude = uieditfield(app.DatabaseSearchTab, 'text');
            app.IncludeLabel = uilabel(app.DatabaseSearchTab);
            app.IncludeLabel.Text = 'Include';
            app.ExcludeLabel = uilabel(app.DatabaseSearchTab);
            app.ExcludeLabel.Text = 'Exclude';
            app.MoveButton = uibutton(app.DatabaseSearchTab, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.CopyButton = uibutton(app.DatabaseSearchTab, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.MergeButton = uibutton(app.DatabaseSearchTab, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge';
            app.Directory = uitextarea(app.DatabaseSearchTab);
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.SelectProcessingModuleDropDownLabel = uilabel(app.ProcessingTab);
            app.SelectProcessingModuleDropDownLabel.Text = 'Select Processing Module';
            app.SelectProcessingModuleDropDown = uidropdown(app.ProcessingTab);
            app.SelectProcessingModuleDropDown.Items = {};
            app.SelectProcessingModuleDropDown.Value = {};
            app.ProcessButton = uibutton(app.ProcessingTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisDropDownLabel = uilabel(app.AnalysisTab);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.AnalysisTab);
            app.AnalysisDropDown.Items = {};
            app.AnalysisDropDown.Value = {};
            app.RunAnalysisButton = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            app.ReviewAnalysisButton = uibutton(app.ReviewTab, 'push');
            app.ReviewAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewAnalysisButtonPushed, true);
            app.ReviewAnalysisButton.Text = 'Review Analysis';
            app.AnalysisDropDown_2Label = uilabel(app.ReviewTab);
            app.AnalysisDropDown_2Label.Text = 'Analysis';
            app.ReviewDropDown = uidropdown(app.ReviewTab);
            app.ReviewDropDown.Items = {};
            app.ReviewDropDown.Value = {};
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ButtonFormatExport = uibutton(app.ExportTab, 'push');
            app.ButtonFormatExport.ButtonPushedFcn = createCallbackFcn(app, @ButtonFormatExportPushed, true);
            app.ButtonFormatExport.Text = 'Format loaded results for export';
            app.UITableExport = uitable(app.ExportTab);
            app.UITableExport.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableExport.RowName = {};
            app.FormatsDropDownLabel = uilabel(app.ExportTab);
            app.FormatsDropDownLabel.Text = 'Formats';
            app.FormatsDropDown = uidropdown(app.ExportTab);
            app.FormatsDropDown.Items = {};
            app.FormatsDropDown.Value = {};
            app.ExportResultsButton = uibutton(app.ExportTab, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.FindeligibleexportsButton = uibutton(app.ExportTab, 'push');
            app.FindeligibleexportsButton.ButtonPushedFcn = createCallbackFcn(app, @FindeligibleexportsButtonPushed, true);
            app.FindeligibleexportsButton.Text = 'Find eligible exports';
            app.SelectitemstoexportListBoxLabel = uilabel(app.ExportTab);
            app.SelectitemstoexportListBoxLabel.Text = 'Select items to export';
            app.SelectitemstoexportListBox = uilistbox(app.ExportTab);
            app.SelectitemstoexportListBox.Items = {};
            app.SelectitemstoexportListBox.Value = {};
            app.ExportMeansSwitchLabel = uilabel(app.ExportTab);
            app.ExportMeansSwitchLabel.Text = 'Export Means';
            app.ExportMeansSwitch = uiswitch(app.ExportTab, 'slider');
            app.ExportMeansSwitch.Items = {'No', 'Yes'};
            app.ExportMeansSwitch.Value = 'No';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.UpdateConfigurationButton = uibutton(app.ConfigurationTab, 'push');
            app.UpdateConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateConfigurationButtonPushed, true);
            app.UpdateConfigurationButton.Text = 'Update Configuration';
            app.MethodDirectoriesLabel = uilabel(app.ConfigurationTab);
            app.MethodDirectoriesLabel.Text = 'Method Directories';
            app.ProcessTextAreaLabel = uilabel(app.ConfigurationTab);
            app.ProcessTextAreaLabel.Text = 'Process';
            app.FilesProcess = uitextarea(app.ConfigurationTab);
            app.LoadTextAreaLabel = uilabel(app.ConfigurationTab);
            app.LoadTextAreaLabel.Text = 'Load';
            app.FilesLoad = uitextarea(app.ConfigurationTab);
            app.AnalysisTextAreaLabel = uilabel(app.ConfigurationTab);
            app.AnalysisTextAreaLabel.Text = 'Analysis';
            app.FilesAnalysis = uitextarea(app.ConfigurationTab);
            app.ReviewTextAreaLabel = uilabel(app.ConfigurationTab);
            app.ReviewTextAreaLabel.Text = 'Review';
            app.FilesReview = uitextarea(app.ConfigurationTab);
            app.Log = uitextarea(app.UIFigure);
            app.BiomechanicsAnalysisandReportingApplicationLabel = uilabel(app.UIFigure);
            app.BiomechanicsAnalysisandReportingApplicationLabel.Text = 'Biomechanics Analysis and Reporting Application';
            app.v10Label = uilabel(app.UIFigure);
            app.v10Label.Text = 'v1.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BAR_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end