classdef DAMVi_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Version                         matlab.ui.control.Label
        DAMViAppLabel                   matlab.ui.control.Label
        Log                             matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        DatabaseSearchTab               matlab.ui.container.Tab
        ExportButton                    matlab.ui.control.Button
        Directory                       matlab.ui.control.TextArea
        MergeButton                     matlab.ui.control.Button
        CopyButton                      matlab.ui.control.Button
        MoveButton                      matlab.ui.control.Button
        ExcludeLabel                    matlab.ui.control.Label
        IncludeLabel                    matlab.ui.control.Label
        SearchTagsExclude               matlab.ui.control.EditField
        IdentifyGroupsButton            matlab.ui.control.Button
        Database                        matlab.ui.control.EditField
        SelectWorkingDatabaseButton     matlab.ui.control.Button
        SaveCurrentDataButton           matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        FindButton                      matlab.ui.control.Button
        TableFiles                      matlab.ui.control.Table
        SearchTagsInclude               matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        EquipmentDropDown               matlab.ui.control.DropDown
        SelectequipmentthedatawasrecordedfromLabel  matlab.ui.control.Label
        FileExtensionDropDown           matlab.ui.control.DropDown
        SelectfileextensionofthedatafilesLabel  matlab.ui.control.Label
        AddDirectorytoSearchButton      matlab.ui.control.Button
        ProcessingTab                   matlab.ui.container.Tab
        ProcessButton                   matlab.ui.control.Button
        SelectProcessingModuleDropDown  matlab.ui.control.DropDown
        SelectProcessingModuleDropDownLabel  matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        RunAnalysisButton               matlab.ui.control.Button
        AnalysisDropDown                matlab.ui.control.DropDown
        AnalysisDropDownLabel           matlab.ui.control.Label
        ReviewTab                       matlab.ui.container.Tab
        ReviewDropDown                  matlab.ui.control.DropDown
        AnalysisDropDown_2Label         matlab.ui.control.Label
        ReviewAnalysisButton            matlab.ui.control.Button
        ExportTab                       matlab.ui.container.Tab
        ExportMetaSwitch                matlab.ui.control.Switch
        ExportMetaSwitchLabel           matlab.ui.control.Label
        ExportMeansSwitch               matlab.ui.control.Switch
        ExportMeansSwitchLabel          matlab.ui.control.Label
        SelectitemstoexportListBox      matlab.ui.control.ListBox
        SelectitemstoexportListBoxLabel  matlab.ui.control.Label
        FindeligibleexportsButton       matlab.ui.control.Button
        ExportResultsButton             matlab.ui.control.Button
        FormatsDropDown                 matlab.ui.control.DropDown
        FormatsDropDownLabel            matlab.ui.control.Label
        UITableExport                   matlab.ui.control.Table
        ButtonFormatExport              matlab.ui.control.Button
        ConfigurationTab                matlab.ui.container.Tab
        ClearLogWindowButton            matlab.ui.control.Button
        LogfilelocationEditField        matlab.ui.control.EditField
        LogfilelocationEditFieldLabel   matlab.ui.control.Label
        ConfigurationfilelocationEditField  matlab.ui.control.EditField
        ConfigurationfilelocationEditFieldLabel  matlab.ui.control.Label
        FilesReview                     matlab.ui.control.TextArea
        ReviewTextAreaLabel             matlab.ui.control.Label
        FilesAnalysis                   matlab.ui.control.TextArea
        AnalysisTextAreaLabel           matlab.ui.control.Label
        FilesLoad                       matlab.ui.control.TextArea
        LoadTextAreaLabel               matlab.ui.control.Label
        FilesProcess                    matlab.ui.control.TextArea
        ProcessTextAreaLabel            matlab.ui.control.Label
        MethodDirectoriesLabel          matlab.ui.control.Label
        UpdateConfigurationButton       matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end


    properties (Access = private)
        AppAnalysis % Description
    end

    methods (Access = private)

        function files = filesearch(app, files, m_directory)
            % files = filesearch(app, files, m_directory)
            % inputs  - app, required mlapp object.
            %         - files, structure with details for the files found.
            %         - m_directory, the new directory to search and add to the files structure.
            % outputs - files, structure with details for the files found.
            % Remarks
            % - This is a recursive function used to search within directories for files of a certain format and organization.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Get the files within the directory.
            m_files = dir(m_directory);

            % Pull the inclusion and exclusion tags entered into the app.
            m_tags_include = app.SearchTagsInclude.Value;
            m_tags_exclude = app.SearchTagsExclude.Value;
            % Read the strings with the tags and separate them into cell
            % arrays.
            if ~isempty(m_tags_include)
                m_tags_include = textscan(m_tags_include,'%s','Delimiter',',');
            end
            if ~isempty(m_tags_exclude)
                m_tags_exclude = textscan(m_tags_exclude,'%s','Delimiter',',');
            end

            % Iterate through the files found in the directory.
            for i = 3:length(m_files)
                % Identify if the item is a file or a directory.
                if isfolder([m_files(i).folder '\' m_files(i).name]) && ~strcmp(m_files(i).name(1), '.')
                    % Determine if there are tags available and if they are determine which files do not have them.
                    if ~isempty(m_tags_exclude)
                        bol_exclude = cellfun(@(s)find(~cellfun('isempty', strfind([m_files(i).name;split({m_files(i).folder},'\')],s))),m_tags_exclude{1},'uni',0);
                    else
                        bol_exclude = '';
                    end
                    if  strcmp(m_tags_exclude, '') || ~any(~cellfun(@isempty,bol_exclude))
                        % If the item is a directory run the search algorithm on it recursively.
                        files = filesearch(app, files, [m_files(i).folder '\' m_files(i).name]);
                    end
                elseif isfile([m_files(i).folder '\' m_files(i).name])
                    % If the item is a file but none have been found yet initialize the file structure.
                    if isempty(fieldnames(files))
                        n = 0;
                        files.name = {};
                        files.folder = {};
                        files.bytes = {};
                    else
                        % If files have already been found get the length of the structure so it can be used to add more files to it later.
                        n = length(files.name);
                    end
                    % The file extention will be needed to check if it is a valid target file.
                    [~, ~, ext] = fileparts(m_files(i).name);
                    % Check if there are tags available and if they are determine which files have them.
                    if ~isempty(m_tags_include)
                        bol_include = cellfun(@(s)find(~cellfun('isempty', strfind([m_files(i).name;split({m_files(i).folder},'\')],s))),m_tags_include{1},'uni',0);
                    else
                        bol_include = '';
                    end
                    % Determine if there are tags available and if they are determine which files do not have them.
                    if ~isempty(m_tags_exclude)
                        bol_exclude = cellfun(@(s)find(~cellfun('isempty', strfind([m_files(i).name;split({m_files(i).folder},'\')],s))),m_tags_exclude{1},'uni',0);
                    else
                        bol_exclude = '';
                    end
                    % If the file matches the selected file extension or is AllFiles, and the tag requirements it will be added to the table.
                    if ((strcmp(ext, ['.' app.FileExtensionDropDown.Value]) || strcmp(app.FileExtensionDropDown.Value, 'AllFiles')) && ((strcmp(m_tags_include, '') || ~any(cellfun(@isempty,bol_include))) && (strcmp(m_tags_exclude, '') || ~any(~cellfun(@isempty,bol_exclude)))))
                        files.name{n+1, 1} = m_files(i).name;
                        files.folder{n+1, 1} = m_files(i).folder;
                        % File size is converted from bytes to kila-, mega- or giga-bytes depending on the file size.
                        if m_files(i).bytes < 1e3
                            files.bytes{n+1, 1} = [num2str(m_files(i).bytes) ' B'];
                        elseif m_files(i).bytes < 1e6
                            files.bytes{n+1, 1} = [num2str(round(m_files(i).bytes/1e3, 1)) ' KB'];
                        elseif m_files(i).bytes < 1e9
                            files.bytes{n+1, 1} = [num2str(round(m_files(i).bytes/1e6, 1)) ' MB'];
                        elseif m_files(i).bytes >= 1e9
                            files.bytes{n+1, 1} = [num2str(round(m_files(i).bytes/1e9, 1)) ' GB'];
                        end
                    end
                end
                % Every ten files update the table.
                if ~isempty(fieldnames(files)) && length(files.name) > 0 && rem(length(files.name),10) == 0
                    app.TableFiles.Data = struct2table(files);
                    % Drawnow and pause are used to make sure the table updates.
                    drawnow
                    pause(0.01)
                end
            end

        end

        function dataExport = formatBUCaltest(app, data)
            % dataExport = formatBUCaltest(app, data)
            % inputs  - app, required mlapp object.
            %         - data, structure with the data to export.
            % outputs - dataExport, structure formated for conversion to a table and exported.
            % Remarks
            % - This method formats data from a C-Motion Caltest procedure into a specific table format for the BU MoveLab.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Get the files from the raw data structure.
            files = fieldnames(data.raw);
            for i = 1:length(files)
                % The measures store the force plate names so we need to find certain delimiters and pull out those strings.
                measures = fieldnames(data.raw.(files{i}));
                measures(strcmp(measures, 'groups')) = [];
                for j = 1:length(measures)
                    ind3 = strfind(measures{j}, '_');
                    if ind3(1) > 4
                        ind3 = [4, ind3];
                    end
                    if strcmp(measures{j}(ind3(1) + 1), 'M')
                        forcePlates{j,1} = measures{j}(1:3);
                    else
                        forcePlates{j,1} = measures{j}(1:ind3(1)-1);
                    end
                end
                % The for loop will generate a long list but we only need the unique ones.
                forcePlates = unique(forcePlates);
                for j = 1:length(forcePlates)
                    % Some spurious data may be present and needs to be skipped.
                    if ~contains(forcePlates{j},'FP')
                        continue
                    end
                    % We need the trial information from the data so their results can be arrayed in columns in the export.
                    ind = 1;
                    trials = {};
                    for k = 1:length(measures)
                        if contains(measures{k},forcePlates{j})
                            ind3 = strfind(measures{k}, '_');
                            if ind3(1) > 4
                                ind3 = [4, ind3];
                            end
                            if strcmp(measures{k}(ind3(1) + 1), 'M')
                                trials{ind, 1} = measures{k}(4:ind3(1)-1);
                            else
                                trials{ind,1} = measures{k}(ind3(1)+1:ind3(2)-1);
                            end
                            ind = ind + 1;
                        end
                    end
                    % We'll get a long array of trial numbers but only need the unique ones.
                    trials = unique(trials);
                    % There are some quirks with the trial numbers and their equencing. This helps handle that.
                    trialsN = [1:length(trials)]';
                    for k = 1:length(trials)
                        ind4 = [];
                        % The trial number is mapped to the column of data it will be in the results spreadsheet.
                        switch trialsN(k)
                            case 1
                                ind4 = 0;
                            case 2
                                ind4 = 8;
                            case 3
                                ind4 = 16;
                            case 4
                                ind4 = 24;
                            case 5
                                ind4 = 32;
                        end

                        % For the first row we need to set the column headers.
                        if i == 1
                            dataExport.(forcePlates{j}){1, ind4 + 1} = trialsN(k);
                            dataExport.(forcePlates{j}){1, 41} = 'Force Orientation Error';
                            dataExport.(forcePlates{j}){2, ind4 + 1} = 'Date';
                            dataExport.(forcePlates{j}){2, ind4 + 2} = 'xmean';
                            dataExport.(forcePlates{j}){2, ind4 + 3} = 'xsd';
                            dataExport.(forcePlates{j}){2, ind4 + 4} = 'ymean';
                            dataExport.(forcePlates{j}){2, ind4 + 5} = 'ysd';
                            dataExport.(forcePlates{j}){2, ind4 + 6} = 'zmean';
                            dataExport.(forcePlates{j}){2, ind4 + 7} = 'zsd';
                            dataExport.(forcePlates{j}){2, 41} = 'Date';
                            dataExport.(forcePlates{j}){2, 42 + 2*(k - 1)} = [num2str(trialsN(k)) 'mean'];
                            dataExport.(forcePlates{j}){2, 42 + 2*(k - 1) + 1} = [num2str(trialsN(k)) 'sd'];
                        end
                        dataExport.(forcePlates{j}){i + 2, ind4 + 1} = datestr(datetime(data.raw.(files{i}).groups{end},'InputFormat','yyyyMMdd'));

                        if k == 1
                            dataExport.(forcePlates{j}){i + 2, 41} = datestr(datetime(data.raw.(files{i}).groups{end},'InputFormat','yyyyMMdd'));
                        end

                        % This will handle all the various names that were used in the files over several years. The data from specific forceplates 
                        % will be assigned to a location in the structure so it ends up in a certain location in the results spreadsheet.
                        try
                            dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN_0*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD_0*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN_1*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD_1*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN_2*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD_2*1000;
                            dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_MEAN_0;
                            dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_SD_0;
                        catch
                            try
                                dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_MEAN_0*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_SD_0*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_MEAN_1*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_SD_1*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_MEAN_2*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_TipDiff_SD_2*1000;
                                dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_OrientationError_MEAN_0;
                                dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__METRIC_CALTESTER']).data.CT_OrientationError_SD_0;
                            catch
                                try
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN_0*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD_0*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN_1*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD_1*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN_2*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD_2*1000;
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_MEAN_0;
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_SD_0;
                                catch
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN_0*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD_0*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN_1*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD_1*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_MEAN_2*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_TipDiff_SD_2*1000;
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_MEAN_0;
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_METRIC_CALTESTER']).data.CT_OrientationError_SD_0;
                                end
                            end
                        end
                    end
                end
            end

        end

        function createDatabase(app)
            % createDatabase(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method creates the folders within the working directory that are assumed to be present for various file saving and retreival 
            %   methods.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com
            m_path = app.Database.Value;

            % Get the current files and folders within the working directory.
            folders = dir(m_path);

            % Create any folders that are not already present and print a notification to the log.
            if ~any(contains({folders.name}, 'Results'))
                mkdir([m_path '\Results'])
                printLog(app, '010', [m_path '\Results'])
            end
            if ~any(contains({folders.name}, 'Figures'))
                mkdir([m_path '\Figures'])
                printLog(app, '011', [m_path '\Figures'])
            end
            if ~any(contains({folders.name}, 'Export'))
                mkdir([m_path '\Export'])
                printLog(app, '011', [m_path '\Export'])
            end

            % Update the configuration file with the current working directory.
            config = loadConfig(app);
            config.database = m_path;
            saveConfig(app, config);

        end

        function identifyFileTypes(app)
            % identifyFileTypes(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Load folder for valid files that would be used to load data into the app. These files need to have a name 
            %   load_*.m. The results will be saved to the configuration file and made selectable in the app.
            % - The goal of this function was to make the addition of new functions easier.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Load the existing configuration so it can be updated.
            config = loadConfig(app);

            % Update the configuration with the directory entered into the app.
            config.options.filesload = app.FilesLoad.Value;

            % Empty the available file types from the saved configuration.
            config.options.filetypes = [];

            for j = 1:length(app.FilesLoad.Value)

                % Use the directory entered into the app to find the load scripts.
                if isfolder(app.FilesLoad.Value{j})
                    m_files = dir(app.FilesLoad.Value{j});
                else
                    printLog(app, '033', ['load folder not found at ' app.FilesLoad.Value{j}])
                    continue
                end

                % Look for files within the directory. If the file names contain the word 'load' identify the file extension and the format of the 
                % data it intends to load.
                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'load') && ~contains(m_files(i).name, 'asv')
                        % Identify the different parts of the load file name.
                        parts = textscan(m_files(i).name, '%s%s%s', 'Delimiter', '_');
                        % Match the equipment type with the current options in the configuration file.
                        if isfield(config.options.filetypes, parts{2}{1})
                            % If a current match is found the equipment type will be added to the options for that file extension.
                            n = length(config.options.filetypes.(parts{2}{1})) + 1;
                        else
                            n = 1;
                        end
                        config.options.filetypes.(parts{2}{1}){n} = parts{3}{1}(1:end-2);
                    end
                end

            end

            % If there are no load files or if the directory is misleading because files have been moved or the app was moved nothing will be found. 
            % The object in the app can't be set to empty.
            if ~isempty(config.options.filetypes)
                % Sort the options so they appear alphnumerically sorted in the app.
                config.options.filetypes = orderfields(config.options.filetypes);
                app.FileExtensionDropDown.Items = fieldnames(config.options.filetypes);

                printLog(app, '037', strjoin(app.FileExtensionDropDown.Items,', '))

                % Save the configuration with the updated directory and or file extensions and formats.
                saveConfig(app, config)

                % Run the callback for the file extension selection object in the app so the format selection object displays the correct options.
                app.FileExtensionDropDownValueChanged;
            end


        end

        function identifyProcessOptions(app)
            % identifyProcessOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Process folder for valid files that would be used to process data within the app. These files need to have a
            %   name process_*.m. The results will be saved to the configuration file and made selectable in the app.
            % - The goal of this function was to make the addition of new functions easier.
            % - The idea of processing is to take an input and create a new ouput of similar size and scale.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Load the existing configuration so it can be updated.
            config = loadConfig(app);

            % Update the configuration with the directory entered into the app.
            config.options.filesprocess = app.FilesProcess.Value;

            % Empty the available treatment files from the saved configuration.
            config.options.process = [];
            ind = 1;

            for j = 1:length(app.FilesProcess.Value)
                % Use the directory entered into the app to find the processing scripts.
                if isfolder(app.FilesProcess.Value{j})
                    m_files = dir(app.FilesProcess.Value{j});
                else
                    printLog(app, '033', ['process folder not found at ' app.FilesProcess.Value{j}])
                    continue
                end

                % Look for files within the directory. If the file names contain the word 'process' save them within the app and configuration.
                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'process') && ~contains(m_files(i).name, 'asv')

                        [~, name] = fileparts(m_files(i).name);
                        parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                        config.options.process{ind,1} = parts{2}{1};
                        ind = ind + 1;
                    end
                end
            end

            % It is assumed the files will have the format "process_<Processing Name>....." So there will be duplicates for options within the 
            % processing apps. Such as process_Treatment_mlapp and process_Treatment_LowPass.mlapp.
            config.options.process = unique(config.options.process);

            % If there are no load files or if the directory is misleading because files have been moved or the app was moved nothing will be found. 
            % The object in the app can't be set to empty.
            if ~isempty(config.options.process)
                % Sort the options so they appear alphnumerically sorted in the app.
                config.options.process = sort(config.options.process);
                app.SelectProcessingModuleDropDown.Items = config.options.process;

                printLog(app, '038', strjoin(app.SelectProcessingModuleDropDown.Items,', '))

                % Save the configuration with the updated directory and or file extensions and formats.
                saveConfig(app, config)
            end

        end


        function identifyAnalysisOptions(app)
            % identifyAnalysisOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Analysis folder for valid files that would be used to analyze data within the app. These files need to have 
            %   a name analysis_*.m. The results will be saved to the configuration file and made selectable in the app.
            % - The goal of this function was to make the addition of new functions easier.
            % - The idea of analyzing is to take an input and create a summary or result of lesser size.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Load the existing configuration so it can be updated.
            config = loadConfig(app);

            % Update the configuration with the directory entered into the app.
            config.options.filesanalysis = app.FilesAnalysis.Value;

            % Empty the available treatment files from the saved configuration.
            config.options.analysis = [];

            % Look for files within the directory. If the file names contain the word 'analysis' save them within the app and configuration.
            ind = 1;

            % Multiple files can be used.
            for j = 1:length(app.FilesAnalysis.Value)

                % Use the directory entered into the app to find the analysis scripts.
                if isfolder(app.FilesAnalysis.Value{j})
                    m_files = dir(app.FilesAnalysis.Value{j});
                else
                    printLog(app, '033', ['analysis folder not found at ' app.FilesAnalysis.Value{j}])
                    continue
                end


                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'analysis') && ~contains(m_files(i).name, 'asv')
                        [~, name] = fileparts(m_files(i).name);
                        % The filenames are expected to have a specific format so the app can determine if they are analysis files and make them 
                        % identifible in the app.
                        parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                        config.options.analysis{ind} = parts{2}{1};
                        ind = ind + 1;
                    end
                end
            end

            % If there are no load files or if the directory is misleading because files have been moved or the app was moved nothing will be found. 
            % The object in the app can't be set to empty.
            if ~isempty(config.options.analysis)
                % Sort the options so they appear alphnumerically sorted in the app.
                config.options.analysis = sort(config.options.analysis);
                app.AnalysisDropDown.Items = config.options.analysis;

                printLog(app, '039', strjoin(app.AnalysisDropDown.Items,', '))

                % Save the configuration with the updated directory and or file extensions and formats.
                saveConfig(app, config)
            end
            
        end

        function identifyReviewOptions(app)
            % identifyReviewOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Review folder for valid files that would be used to view data within the app. These files need to have a 
            %   name review_*.m. The results will be saved to the configuration file and made selectable in the app.
            % - The goal of this function was to make the addition of new functions easier.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Load the existing configuration so it can be updated.
            config = loadConfig(app);

            % Update the configuration with the directory entered into the app.
            config.options.filesreview = app.FilesReview.Value;

            % Empty the available treatment files from the saved configuration.
            config.options.review = [];

            % Look for files within the directory. If the file names contain the word 'review' save them within the app and configuration.
            ind = 1;
            % Multiple directories can be used.
            for j = 1:length(app.FilesReview.Value)

                % Use the directory entered into the app to find the review methods.
                if isfolder(app.FilesReview.Value{j})
                    m_files = dir(app.FilesReview.Value{j});
                else
                    printLog(app, '033', ['review folder not found at ' app.FilesReview.Value{j}])
                    continue
                end

                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'review') && ~contains(m_files(i).name, 'asv')
                        [~, name] = fileparts(m_files(i).name);
                        % The filenames are expected to have a specific format so the app can determine if they are review files and make them 
                        % identifible in the app.
                        parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                        config.options.review{ind} = parts{2}{1};
                        ind = ind + 1;
                    end
                end
            end

            % If there are no load files or if the directory is misleading because files have been moved or the app was moved nothing will be found. 
            % The object in the app can't be set to empty.
            if ~isempty(config.options.review)
                % Sort the options so they appear alphnumerically sorted in the app.
                config.options.review = sort(config.options.review);
                app.ReviewDropDown.Items = config.options.review;

                printLog(app, '040', strjoin(app.ReviewDropDown.Items,', '))

                % Save the configuration with the updated directory and or file extensions and formats.
                saveConfig(app, config)
            end

        end

        function dat = groupAuto(app, level, dat)
            % dat = groupAuto(app, level, dat)
            % inputs  - app, required mlapp object.
            %         - level, a string specifying the level of the data structure to identify group information in.
            %         - dat, a string from which to identify group information or the DAMVi App data structure.
            % outputs - dat, a cell array of strings representing groups the parent data belongs to or the DAMVi App data structure.
            % Remarks
            % - This method pulls information from a string to identify groups within data sets. The intent is these groups would be used for figures
            %   or statistics later on.
            % - This method was also a solution to a variable name problem. Individuals may create directory structures, file names or variable names
            %   of long length and duplicate information. This method is a place that information can be stored while a simplier name can be used for
            %   the item.
            % - Group information is expected to be found at the file and object levels, not at the signal level. Signals should all be named the 
            %   same so they can be exported.
            % Future Work
            % - None
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            switch level
                case 'filename'
                    % The various folders in the files directory path will be turned into group information. This includes the drive letter.
                    dat = split(dat, '\');
                    i = 1;
                    % For each folder check if underscores were used as a delimter and use that to create groups.
                    while i <= length(dat)
                        temp = split(dat{i}, '_');
                        if length(temp) > 1
                            % Extend the group array so the order is preserved.
                            dat = [dat(1:i-1); temp; dat(i+1:end)];
                        end
                        i = i + 1;
                    end
                    % Remove the file extension so it is not used as a group.
                    ind = strfind(dat{end}, '.');
                    dat{end}(ind(end):end) = [];
                case 'object'
                    % The objects will be iterated through and their text turned into group information.
                    obj = fieldnames(dat);
                    obj(strcmp(obj, 'meta')) = [];
                    obj(strcmp(obj, 'groups')) = [];
                    for j = 1:length(obj)
                        if isfield(dat.(obj{j}), 'groups')
                            % Add the deliminated object name to the existing group information.
                            dat.(obj{j}).groups = [dat.(obj{j}).groups; split(obj{j}, '_')];
                            % The stable option will retain the order which helps interpret the groups.
                            dat.(obj{j}).groups = unique(dat.(obj{j}).groups, 'stable');
                        else
                            % Add the delimited object name to the group information.
                            dat.(obj{j}).groups = split(obj{j}, '_');
                        end
                    end
            end
        end

        function dataExport = formatTables(app, data)
            % dataExport = formatTables(app, data)
            % inputs  - app, required mlapp object.
            %         - data, DAMVi App data structure.
            % outputs - dataExport, the DAMVi App data formated as a table.
            % Remarks
            % - This method pulls tabular information from the results and formats it for export. It is assumed that all tables in the data will be
            %   formated the same. Anything that is not a table will be skipped.
            % Future Work
            % - None
            % Dec 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % A flag is used to initialize the table within the for loops.
            flag = 0;

            % Get the analyeses.
            analyses = fieldnames(data.res);
            for ii = 1:length(analyses)
                % Get the filenames.
                files = fieldnames(data.res.(analyses{ii}));
                % Start iterating thought he filenames.
                for i = 1:length(files)
                    % Get the object names.
                    obj = fieldnames(data.res.(analyses{ii}).(files{i}));
                    % Start iterating through the objects.
                    for j = 1:length(obj)
                        % Get the signal names.
                        sig = fieldnames(data.res.(analyses{ii}).(files{i}).(obj{j}).data);
                        % Start iterating through the signal names.
                        for k = 1:length(sig)
                            % Get the names of the measures and iterate through them.
                            meas = fieldnames(data.res.(analyses{ii}).(files{i}).(obj{j}).data.(sig{k}));
                            for jj = 1:length(meas)
                                % Check if the measure is a table.
                                if istable(data.res.(analyses{ii}).(files{i}).(obj{j}).data.(sig{k}).(meas{jj}))
                                    if flag == 0
                                        % For the initial data the table is used as is.
                                        dataExport = data.res.(analyses{ii}).(files{i}).(obj{j}).data.(sig{k}).(meas{jj});
                                    else
                                        % Additional data are concatenated together. This requires them to have the same columns.
                                        dataExport = [dataExport; data.res.(analyses{ii}).(files{i}).(obj{j}).data.(sig{k}).(meas{jj})];
                                        flag = 1;
                                    end
                                end
                            end
                        end
                    end
                end
            end
        end
        
        function ind = preallocateExport(app, data)

            % Get the user selected data types to export.
            types = app.SelectitemstoexportListBox.Value;

            % If only one was selected it needs to be converted to a cell.
            if ~iscell(types)
                types = {types};
            end

            dataBool = [];

            ind = 0;

            % For each type that is selected.
            for i = 1:length(types)

                switch types{i}

                    % This code is intended to be used for both raw and results data.
                    case {'raw', 'pro'}
                        % Get the file names.
                        files = fieldnames(data.(types{i}));
                        % iterate through the files.
                        for ind_files = 1:length(files)

                            % Get the different measures and remove the meta and group information from the export.
                            obj = fieldnames(data.(types{i}).(files{ind_files}));
                            obj(strcmp(obj, 'meta')) = [];
                            obj(strcmp(obj, 'groups')) = [];

                            % Iterate through the objects.
                            for ind_obj = 1:length(obj)

                                % Get the signal names for the signals.
                                sigs = fieldnames(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data);

                                ind2Max = 0;

                                % Put the signals into the structure for export.
                                for ind_sigs = 1:length(sigs)

                                    ind2 = 0;

                                    % if the current signal is a character strict only one dimension will be set in the dataExport.
                                    if ischar(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}))
                                        m = 1;
                                    else
                                        % Get the number of dimensions to put in the dataExport.
                                        m = size(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}), 2);
                                    end

                                    % Iterate through the dimensions.
                                    for ind_dim = 1:m

                                        % If the signals were selected to be averaged within each signal.
                                        if strcmp(app.ExportMeansSwitch.Value, 'Yes')

                                            ind2 = ind2 + 1;

                                        % If the individual elements of each signal is to be exported.
                                        elseif strcmp(app.ExportMeansSwitch.Value, 'No')

                                            if ischar(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(:, ind_dim))
                                                N = 1;
                                            else
                                                N = length(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(:, ind_dim));
                                            end
                                            for ind_N = 1:N

                                                ind2 = ind2 + 1;

                                            end

                                        end
                                    end

                                    if ind2 > ind2Max
                                        ind2Max = ind2;
                                    end

                                end
                                ind = ind + ind2Max;
                            end
                        end
                    case {'ana', 'res'}
                        
                        % Get the analysis methods from the data type.
                        analyses = fieldnames(data.(types{i}));

                        % Iterate through the analysis types.
                        for ind_analyses = 1:length(analyses)

                            % Get the file names used in the analysis.
                            files = fieldnames(data.(types{i}).(analyses{ind_analyses}));

                            % Iterate through the files.
                            for ind_files = 1:length(files)

                                % Get the different measures and remove the meta and group information from the export.
                                obj = fieldnames(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}));
                                obj(strcmp(obj, 'meta')) = [];
                                obj(strcmp(obj, 'groups')) = [];

                                % Iterate through the objects.
                                for ind_obj = 1:length(obj)
                                    % Get the signal names.
                                    sigs = fieldnames(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data);

                                    % Put the signals into the structure for export.
                                    for ind_sigs = 1:length(sigs)

                                        ind2Max = 0;

                                        % Get the measures used for the current signal.
                                        meas = fieldnames(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}));
                                        % Iterate through the measures.
                                        for ind_meas = 1:length(meas)

                                            ind2 = 0;

                                            % if the current signal is a character strict only one dimension will be set in the dataExport.
                                            if ischar(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}))
                                                m = 1;
                                            else
                                                % Get the number of dimensions to put in the dataExport.
                                                m = size(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}), 2);
                                            end

                                            % Iterate through the dimensions.
                                            for ind_dim = 1:m

                                                % If the signals were selected to be averaged within each signal.
                                                if strcmp(app.ExportMeansSwitch.Value, 'Yes')

                                                    ind2 = ind2 + 1;

                                                    % If the individual elements of each signal is to be exported.
                                                elseif strcmp(app.ExportMeansSwitch.Value, 'No')

                                                    if ischar(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(:, ind_dim))
                                                        N = 1;
                                                    else
                                                        N = length(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(:, ind_dim));
                                                    end
                                                    for ind_N = 1:N

                                                        if isempty(dataBool) || ~any(strcmp(dataBool, [types{i} files{ind_files} obj{ind_obj} sigs{ind_sigs} num2str(ind_dim) num2str(ind_N)]))
                                                            dataBool{end + 1, 1} = [types{i} files{ind_files} obj{ind_obj} sigs{ind_sigs} num2str(ind_dim) num2str(ind_N)];

                                                            ind2 = ind2 + 1;
                                                        end
                                                    end
                                                end
                                            end

                                            if ind2 > ind2Max
                                                ind2Max = ind2;
                                            end

                                        end
                                        ind = ind + ind2Max;
                                    end
                                end
                            end
                        end
                end
            end
        end
        
        function toggleApp(app, bool)
            % toggleApp(app, bool)
            % inputs  - app, required mlapp object.
            %         - bool, a boolean used to toggle the app buttons.
            % Remarks
            % - This method enables and disables certain elements of the app. It finds the objects in the app with these types
            %   and turns them all off or on. So if additional ones are added they will also be affected here without needing to 
            %   modify the code.
            % - The following elements are affected: buttons, dropdowns, edit fields, list boxes, text areas.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Mar 2023 - Modified by Ben Senderling, bensenderling@gmail.com
            %          - Added code to write messages to a log file. Added dates and times to the messages.

            h = findobj({'Type', 'uibutton', '-or', 'Type', 'uidropdown', '-or', 'Type', 'uieditfield', '-or', 'Type', 'uilistbox', '-or', 'Type', 'uitextarea'});
            switch bool
                case 0
                    set(h,'Enable','off')
                case 1
                    set(h,'Enable','on')
            end
            
        end
    end

    methods (Access = public)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of the error and organize the errors available.
            %         - newText, a string that may be inserted into the message.
            % Remarks
            % - This method will print messages to the log of the DAMVi App. The error codes are unique to each message so they can be found and
            %   referenced easier.
            % - The method is public and is acessible outside of the DAMVi App. It is used in some of the example analysis and process scripts.
            % Future Work
            % - This log should be printed and saved to a file location each time the DAMVi App is run.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Mar 2023 - Modified by Ben Senderling, bensenderling@gmail.com
            %          - Added code to write messages to a log file. Added dates and times to the messages.

            % Get the current text within the log.
            text = app.Log.Value;

            % Match the error code with the message and update the text for the log.
            switch code
                case '000'
                    text{length(text) + 1, 1} = ['DMV-000 DAMVi App ' app.Version.Text];
                case '001'
                    text{length(text) + 1, 1} = 'DMV-001 file search started';
                case '002'
                    text{length(text) + 1, 1} = ['DMV-002 file search complete, ' num2str(newText{1}) ' files found, ' newText{2} ' total'];
                case '003'
                    text{length(text) + 1, 1} = ['DMV-003 error reading file: ' newText];
                case '004'
                    text{length(text) + 1, 1} = ['DMV-004 loading files started for: ' newText];
                case '005'
                    text{length(text) + 1, 1} = ['DMV-005 loading files complete for: ' newText];
                case '006'
                    text{length(text) + 1, 1} = 'DMV-006 results not found';
                case '007'
                    text{length(text) + 1, 1} = 'DMV-007 results exported';
                case '008'
                    text{length(text) + 1, 1} = 'DMV-008 results formated for export and displayed in table';
                case '009'
                    text{length(text) + 1, 1} = 'DMV-009 results formated for exported but are not compatible with the table';
                case '010'
                    text{length(text) + 1, 1} = ['DMV-010 created Results folder at ' newText];
                case '011'
                    text{length(text) + 1, 1} = ['DMV-011 created Figure folder at ' newText];
                case '012'
                    text{length(text) + 1, 1} = 'DMV-012 files saving....';
                case '013'
                    text{length(text) + 1, 1} = 'DMV-013 files saved';
                case '014'
                    text{length(text) + 1, 1} = 'DMV-014 moving files...';
                case '015'
                    text{length(text) + 1, 1} = 'DMV-015 moving files complete';
                case '016'
                    text{length(text) + 1, 1} = 'DMV-016 no files found in the selected directory';
                case '017'
                    text{length(text) + 1, 1} = ['DMV-017 error moving file: ' newText];
                case '018'
                    text{length(text) + 1, 1} = 'DMV-018 copying files...';
                case '019'
                    text{length(text) + 1, 1} = 'DMV-019 copying files complete';
                case '020'
                    text{length(text) + 1, 1} = ['DMV-020 error copying file: ' newText];
                case '021'
                    text{length(text) + 1, 1} = ['DMV-021 ' newText];
                case '022'
                    text{length(text) + 1, 1} = ['DMV-022 starting analysis:' newText];
                case '023'
                    text{length(text) + 1, 1} = ['DMV-022 analysis complete:' newText];
                case '024'
                    text{length(text) + 1, 1} = ['DMV-024 ' newText];
                case '025'
                    text{length(text) + 1, 1} = ['DMV-025 saving data from ' newText];
                case '026'
                    text{length(text) + 1, 1} = ['DMV-026 data saved from ' newText];
                case '027'
                    text{length(text) + 1, 1} = ['DMV-027 ' newText];
                case '028'
                    text{length(text) + 1, 1} = ['DMV-028 error creating figure: ' newText];
                case '029'
                    text{length(text) + 1, 1} = ['DMV-029 unhandled exception in analysis: ' newText];
                case '030'
                    text{length(text) + 1, 1} = 'DMV-030 no data loaded';
                case '031'
                    text{length(text) + 1, 1} = ['DMV-031 ' newText];
                case '032'
                    text{length(text) + 1, 1} = 'DMV-032 no load method found';
                case '033'
                    text{length(text) + 1, 1} = ['DMV-033 ' newText];
                case '034'
                    text{length(text) + 1, 1} = 'DMV-034 stopping data load, over 10% of files have encountered an unhandled exception';
                case '035'
                    text{length(text) + 1, 1} = 'DMV-035 data format is not accounted for in the formated results';
                case '036'
                    text{length(text) + 1, 1} = 'DMV-036 log window cleared';
                case '037'
                    text{length(text) + 1, 1} = ['DMV-037 file types added to configuration: ' newText];
                case '038'
                    text{length(text) + 1, 1} = ['DMV-038 process modules added to configuration: ' newText];
                case '039'
                    text{length(text) + 1, 1} = ['DMV-039 analysis modules added to configuration: ' newText];
                case '040'
                    text{length(text) + 1, 1} = ['DMV-040 review modules added to configuration: ' newText];
                case '041'
                    text{length(text) + 1, 1} = 'DMV-041 search results exported';
                case '042'
                    text{length(text) + 1, 1} = 'DMV-042 meta format is not accounted for in the formated results';
            end

            % Create a date-time for the message.
            text{end, 1} = [char(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z')) ' | ' text{end, 1}];

            % Update the log.
            app.Log.Value = text;

            % Scroll to the bottom so the most recent message is visible.
            scroll(app.Log,'bottom')
            drawnow

            % Write to the log.
            writeLog(app, text{end, 1})

        end

        function analysisComplete(app, data, analysis, auto)
            % analysisComplete(app, data, analysis)
            % inputs  - app, required mlapp object.
            %         - data, data structure from the DAMVi App.
            %         - analysis, a string with the name of the analysis that was completed.
            %         - auto, a boolean to specify if the data should be saved without a prompt.
            % Remarks
            % - This method helps return data from an analysis or process method. It will save the data within the DAMVi App and save it to file. If 
            %   auto is a 1 it will save the data without prompting the user for a filename.
            % Future Work
            % - none
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Print a message that the saving as started.
            printLog(app, '025', analysis)

            % Save the data returned to the DAMVi App within the app.
            setappdata(app.TableFiles, 'data', data);

            % Get the database location so the filename can be constructed.
            m_resultsPath = app.Database.Value;

            % Construct a timestamp to use in the file name.
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            % Set the automatic file path and the suggested path.
            file = [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value timeStamp '.mat'];
            path = [m_resultsPath '\Results\'];

            % If auto is 0 prompt the user for a filename, if it is 1 automatically save the data.
            if auto
                save([path file], 'data', '-v7.3')
                
                % Update the log to notify the user the data has saved.
                printLog(app, '026', analysis)
            else
                % Get a user entered file name and suggest the coded version.
                [file, path] = uiputfile('.mat', 'Save As', [path file]);
                % Exit if no filename was entered.
                if ~(isnumeric(file) && file == 0)
                    % Some data can take a while to save so an update to the log is printed before and after.
                    save([path file], 'data', '-v7.3')

                    % Update the log to notify the user the data has saved.
                    printLog(app, '026', analysis)
                end
            end
        end

        function dataExport = formatResults(app, data)
            % dataExport = formatResults(app, data)
            % inputs  - app, required mlapp object.
            %         - data, data structure from the DAMVi App.
            % outputs - dataExport, a structure with data reformated for export and ready to be made into a table.
            % Remarks
            % - This method reformats the data structure into a form that can be exported as a table for further analysis outside of the DAMVi App.
            % - The method was intended to be generalized for any export. If told to it would try to export raw data of great length.
            % - There is an option to average within signals or to export each item within a signal separately.
            % Future Work
            % - This code is likely to encounter errors and need further development, as it is very near the end of the processing steps and likely to
            % - encounter varied data.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Empty the current table and column labels.
            app.UITableExport.Data = [];
            app.UITableExport.ColumnName = [];

            % Get the user selected data types to export.
            types = app.SelectitemstoexportListBox.Value;

            % If only one was selected it needs to be converted to a cell.
            if ~iscell(types)
                types = {types};
            end

            % Initialize the structure. This is usefull for troubleshooting.
            indFinal = preallocateExport(app, data);
            dataExport = struct;
            dataExport.type = cell(indFinal, 1);
            dataExport.file = cell(indFinal, 1);
            dataExport.object = cell(indFinal, 1);
            dataExport.fault = ones(indFinal, 1)*NaN;
            dataExport.notes = cell(indFinal, 1);

            % For each type that is selected.
            for i = 1:length(types)

                switch types{i}

                    % This code is intended to be used for both raw and results data.
                    case {'raw', 'pro'}

                        dataExport.dimension = ones(indFinal, 1)*NaN;
                        if strcmp(app.ExportMeansSwitch.Value, 'No')
                            dataExport.n = ones(indFinal, 1)*NaN;
                        end

                        % Get the file names.
                        files = fieldnames(data.(types{i}));

                        % iterate through the files.
                        for ind_files = 1:length(files)

                            % Get the different measures and remove the meta and group information from the export.
                            obj = fieldnames(data.(types{i}).(files{ind_files}));
                            obj(strcmp(obj, 'meta')) = [];
                            obj(strcmp(obj, 'groups')) = [];

                            % Iterate through the objects.
                            for ind_obj = 1:length(obj)
                                
                                % Get the signal names for the signals.
                                sigs = fieldnames(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data);

                                % Put the signals into the structure for export.
                                for ind_sigs = 1:length(sigs)

                                    % if the current signal is a character strict only one dimension will be set in the dataExport.
                                    if ischar(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}))
                                        m = 1;
                                    else
                                        % Get the number of dimensions to put in the dataExport.
                                        m = size(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}), 2);
                                    end

                                    % Iterate through the dimensions.
                                    for ind_dim = 1:m

                                        % If results from multiple items are present this will put them in the same row.
                                        bool = [];
                                        if isfield(dataExport, 'file') && ~isempty(dataExport.file)
                                            % The file, object, signal and dimension have to match. The dimensions are changed to characters and then
                                            % compared as strings so empties in the cell array are retained. Otherwise the empties would be removed 
                                            % and the different logical arrays would have different lengths.
                                            bool = strcmp(dataExport.file, files{ind_files}) &...
                                                strcmp(dataExport.object, obj{ind_obj}) &...
                                                dataExport.dimension == ind_dim;
                                        end

                                        % If the signals were selected to be averaged within each signal.
                                        if strcmp(app.ExportMeansSwitch.Value, 'Yes')

                                            % The boolean array from earlier is reused with a new logical array for the number.
                                                bool2 = bool;
                                                indTemp = find(bool2);
                                                if ~isempty(indTemp)
                                                    ind = indTemp;
                                                    flag = 0;
                                                else
                                                    ind = find(cellfun(@isempty, dataExport.file), 1);
                                                    flag = 1;
                                                end

                                                if flag
                                                    % Add the data type to the export.
                                                    dataExport.type{ind, 1} = types{i};
                                                    % Add the file name to the export.
                                                    dataExport.file{ind, 1} = files{ind_files};

                                                    % Add the file group information to the export.
                                                    if isfield(data.(types{i}).(files{ind_files}), 'groups')
                                                        for jj = 1:length(data.(types{i}).(files{ind_files}).groups)
                                                            if ~isfield(dataExport, ['group' num2str(jj)])
                                                                dataExport.(['group' num2str(jj)]) = cell(indFinal, 1);
                                                            end
                                                            dataExport.(['group' num2str(jj)]){ind, 1} = data.(types{i}).(files{ind_files}).groups{jj};
                                                        end
                                                        fileGroupCount = length(data.(types{i}).(files{ind_files}).groups);
                                                        % If groups are not present in the results data the same fields will be looked for in the raw
                                                        % data and put in the export.
                                                    elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{ind_files}), 'groups')
                                                        for jj = 1:length(data.raw.(files{ind_files}).groups)
                                                            if ~isfield(dataExport, ['group' num2str(jj)])
                                                                dataExport.(['group' num2str(jj)]) = cell(indFinal, 1);
                                                            end
                                                            dataExport.(['group' num2str(jj)]){ind, 1} = data.raw.(files{ind_files}).groups{jj};
                                                        end
                                                        fileGroupCount = length(data.raw.(files{ind_files}).groups);
                                                    end

                                                    % Put the name of the measure in the export.
                                                    dataExport.object{ind, 1} = obj{ind_obj};

                                                    % Add the object group information to the export.
                                                    if isfield(data.(types{i}).(files{ind_files}).(obj{ind_obj}), 'groups')
                                                        for jj = 1:length(data.(types{i}).(files{ind_files}).(obj{ind_obj}).groups)
                                                            if ~isfield(dataExport, ['group' num2str(fileGroupCount + jj)])
                                                                dataExport.(['group' num2str(fileGroupCount + jj)]) = cell(indFinal, 1);
                                                            end
                                                            dataExport.(['group' num2str(fileGroupCount + jj)]){ind, 1} = data.(types{i}).(files{ind_files}).(obj{ind_obj}).groups{jj};
                                                        end
                                                        % If groups are not present in the results data the same fields will be looked for in the raw
                                                        % data and put in the export.
                                                    elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{ind_files}), obj{ind_obj}) && isfield(data.raw.(files{ind_files}).(obj{ind_obj}), 'groups')
                                                        for jj = 1:length(data.raw.(files{ind_files}).(obj{ind_obj}).groups)
                                                            if ~isfield(dataExport, ['group' num2str(fileGroupCount + jj)])
                                                                dataExport.(['group' num2str(fileGroupCount + jj)]) = cell(indFinal, 1);
                                                            end
                                                            dataExport.(['group' num2str(fileGroupCount + jj)]){ind, 1} = data.raw.(files{ind_files}).(obj{ind_obj}).groups{jj};
                                                        end
                                                    end

                                                    % Add the dimension to the export.
                                                    dataExport.dimension(ind, 1) = ind_dim;
                                                    
                                                end

                                                % Export the measure and individual signal value.
                                                if ischar(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(1, ind_dim))
                                                    if ~isfield(dataExport, sigs{ind_sigs})
                                                        dataExport.(sigs{ind_sigs}) = cell(indFinal, 1);
                                                    end
                                                    % If the data is a character it will be placed in the export.
                                                    dataExport.(sigs{ind_sigs}){ind, 1} = data.(types{i})(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(1, ind_dim);
                                                elseif isnumeric(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(:, ind_dim))
                                                    if ~isfield(dataExport, sigs{ind_sigs})
                                                        dataExport.(sigs{ind_sigs}) = ones(indFinal, 1)*NaN;
                                                    end
                                                    % If the data is numeric put it in the export.
                                                    dataExport.(sigs{ind_sigs})(ind, 1) = mean(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(:, ind_dim));
                                                    % If the data is in a cell it needs to be handled as a cell.
                                                elseif iscell(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(1, ind_dim))
                                                    if ischar(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}){1, ind_dim})
                                                        if ~isfield(dataExport, sigs{ind_sigs})
                                                            dataExport.(sigs{ind_sigs}) = cell(indFinal, 1);
                                                        end
                                                        % If the data is a character it will be placed in the export.
                                                        dataExport.(sigs{ind_sigs}){ind, 1} = data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}){1, ind_dim};
                                                    elseif isnumeric(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}){1, ind_dim})
                                                        if ~isfield(dataExport, sigs{ind_sigs})
                                                            dataExport.(sigs{ind_sigs}) = ones(indFinal, 1)*NaN;
                                                        end
                                                        if ~isempty(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}){1, ind_dim})
                                                            % If the data is a cell but is numeric, put it in the export.
                                                            dataExport.(sigs{ind_sigs})(ind, 1) = mean(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}){:, ind_dim});
                                                        end
                                                    end
                                                else
                                                    % Print to the log that a data format is not handelled by the code.
                                                    printLog(app, '035')
                                                    % There are other data types that are not handled and skipped.
                                                    continue
                                                end

                                            % If the individual elements of each signal is to be exported.
                                        elseif strcmp(app.ExportMeansSwitch.Value, 'No')

                                            % Numel is used in case the signal is a multi-dimentional array.
                                            if ischar(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}))
                                                N = 1;
                                            else
                                                N = length(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(:, ind_dim));
                                            end
                                            for ind_N = 1:N

                                                % The boolean array from earlier is reused with a new logical array for the number.
                                                bool2 = bool & dataExport.n == ind_N;
                                                indTemp = find(bool2);
                                                if ~isempty(indTemp)
                                                    ind = indTemp;
                                                    flag = 0;
                                                else
                                                    ind = find(cellfun(@isempty, dataExport.file), 1);
                                                    flag = 1;
                                                end

                                                if flag
                                                    % Add the data type to the export.
                                                    dataExport.type{ind, 1} = types{i};
                                                    % Add the file name to the export.
                                                    dataExport.file{ind, 1} = files{ind_files};

                                                    % Add the file group information to the export.
                                                    if isfield(data.(types{i}).(files{ind_files}), 'groups')
                                                        for jj = 1:length(data.(types{i}).(files{ind_files}).groups)
                                                            if ~isfield(dataExport, ['group' num2str(jj)])
                                                                dataExport.(['group' num2str(jj)]) = cell(indFinal, 1);
                                                            end
                                                            dataExport.(['group' num2str(jj)]){ind, 1} = data.(types{i}).(files{ind_files}).groups{jj};
                                                        end
                                                        fileGroupCount = length(data.(types{i}).(files{ind_files}).groups);
                                                        % If groups are not present in the results data the same fields will be looked for in the raw
                                                        % data and put in the export.
                                                    elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{ind_files}), 'groups')
                                                        for jj = 1:length(data.raw.(files{ind_files}).groups)
                                                            if ~isfield(dataExport, ['group' num2str(jj)])
                                                                dataExport.(['group' num2str(jj)]) = cell(indFinal, 1);
                                                            end
                                                            dataExport.(['group' num2str(jj)]){ind, 1} = data.raw.(files{ind_files}).groups{jj};
                                                        end
                                                        fileGroupCount = length(data.raw.(files{ind_files}).groups);
                                                    end

                                                    % Put the name of the measure in the export.
                                                    dataExport.object{ind, 1} = obj{ind_obj};

                                                    % Add the object group information to the export.
                                                    if isfield(data.(types{i}).(files{ind_files}).(obj{ind_obj}), 'groups')
                                                        for jj = 1:length(data.(types{i}).(files{ind_files}).(obj{ind_obj}).groups)
                                                            if ~isfield(dataExport, ['group' num2str(fileGroupCount + jj)])
                                                                dataExport.(['group' num2str(fileGroupCount + jj)]) = cell(indFinal, 1);
                                                            end
                                                            dataExport.(['group' num2str(fileGroupCount + jj)]){ind, 1} = data.(types{i}).(files{ind_files}).(obj{ind_obj}).groups{jj};
                                                        end
                                                        % If groups are not present in the results data the same fields will be looked for in the raw
                                                        % data and put in the export.
                                                    elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{ind_files}), obj{ind_obj}) && isfield(data.raw.(files{ind_files}).(obj{ind_obj}), 'groups')
                                                        for jj = 1:length(data.raw.(files{ind_files}).(obj{ind_obj}).groups)
                                                            if ~isfield(dataExport, ['group' num2str(fileGroupCount + jj)])
                                                                dataExport.(['group' num2str(fileGroupCount + jj)]) = cell(indFinal, 1);
                                                            end
                                                            dataExport.(['group' num2str(fileGroupCount + jj)]){ind, 1} = data.raw.(files{ind_files}).(obj{ind_obj}).groups{jj};
                                                        end
                                                    end

                                                    % Add the dimension to the export.
                                                    dataExport.dimension(ind, 1) = ind_dim;
                                                    % Add the number to the export.
                                                    dataExport.n(ind, 1) = ind_N;

                                                end

                                                % Export the measure and individual signal value.
                                                if ischar(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}))
                                                    if ~isfield(dataExport, sigs{ind_sigs})
                                                        dataExport.(sigs{ind_sigs}) = cell(indFinal, 1);
                                                    end
                                                    % If the data is a character it will be placed in the export.
                                                    dataExport.(sigs{ind_sigs}){ind, 1} = data.(types{i})(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs});
                                                elseif isnumeric(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(ind_N, ind_dim))
                                                    if ~isfield(dataExport, sigs{ind_sigs})
                                                        dataExport.(sigs{ind_sigs}) = ones(indFinal, 1)*NaN;
                                                    end
                                                    % If the data is numeric put it in the export.
                                                    try
                                                        dataExport.(sigs{ind_sigs})(ind, 1) = data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(ind_N, ind_dim);
                                                    catch
                                                        printLog(app, '021', ['Export data of ' class(dataExport.(sigs{ind_sigs})(ind, 1)) ' does not match data of ' class(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(ind_N, ind_dim))])
                                                    end
                                                    % If the data is in a cell it needs to be handled as a cell.
                                                elseif iscell(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(ind_N, ind_dim))
                                                    if ischar(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}){ind_N, ind_dim})
                                                        if ~isfield(dataExport, sigs{ind_sigs})
                                                            dataExport.(sigs{ind_sigs}) = cell(indFinal, 1);
                                                        end
                                                        % If the data is a character it will be placed in the export.
                                                        dataExport.(sigs{ind_sigs}){ind, 1} = data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}){ind_N, ind_dim};
                                                    elseif isnumeric(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}){ind_N, ind_dim})
                                                        if ~isfield(dataExport, sigs{ind_sigs})
                                                            dataExport.(sigs{ind_sigs}) = ones(indFinal, 1)*NaN;
                                                        end
                                                        if ~isempty(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}){ind_N, ind_dim})
                                                            % If the data is a cell but is numeric, put it in the export.
                                                            dataExport.(sigs{ind_sigs})(ind, 1) = data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}){ind_N, ind_dim};
                                                        end
                                                    end
                                                    % Check if the data is missing.
                                                elseif ismissing(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}))
                                                    if ~isfield(dataExport, sigs{ind_sigs})
                                                        dataExport.(sigs{ind_sigs}) = ones(indFinal, 1)*NaN;
                                                    end
                                                    dataExport.(sigs{ind_sigs}){ind, 1} = data.(types{i})(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs});
                                                elseif isdatetime(data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(ind_N, ind_dim))
                                                    if ~isfield(dataExport, sigs{ind_sigs})
                                                        dataExport.(sigs{ind_sigs}) = NaT(indFinal, 1);
                                                    end
                                                    dataExport.(sigs{ind_sigs})(ind, 1) = data.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs})(ind_N, ind_dim);
                                                else
                                                    % Print to the log that a data format is not handelled by the code.
                                                    printLog(app, '035')
                                                    % There are other data types that are not handled and skipped.
                                                    continue
                                                end

                                                % Check if faults exists and add it to the export array.
                                                if isfield(data, 'qua') && isfield(data.qua, types{i}) &&...
                                                        isfield(data.qua.(types{i}), files{ind_files}) &&...
                                                        isfield(data.qua.(types{i}).(files{ind_files}), obj{ind_obj}) &&...
                                                        isfield(data.qua.(types{i}).(files{ind_files}).(obj{ind_obj}).data, sigs{ind_sigs}) &&...
                                                        isfield(data.qua.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}), 'fault')
                                                    if isfield(dataExport, 'fault') && length(dataExport.fault) >= ind
                                                        dataExport.fault(ind, 1) = dataExport.fault(ind, 1) + data.qua.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).fault;
                                                    else
                                                        dataExport.fault(ind, 1) = data.qua.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).fault;
                                                    end
                                                end
                                                % Check if notes exist in the quality type and add it to the array.
                                                if isfield(data, 'qua') && isfield(data.qua, types{i}) &&...
                                                        isfield(data.qua.(types{i}), files{ind_files}) &&...
                                                        isfield(data.qua.(types{i}).(files{ind_files}), obj{ind_obj}) &&...
                                                        isfield(data.qua.(types{i}).(files{ind_files}).(obj{ind_obj}).data, sigs{ind_sigs}) &&...
                                                        isfield(data.qua.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}), meas{ind_meas}) &&...
                                                        isfield(data.qua.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}), 'notes')
                                                    if isfield(dataExport, 'notes') && length(dataExport.notes) >= ind
                                                        dataExport.notes{ind, 1} = [dataExport.notes{ind, 1} ',' meas{ind_meas} '-' data.qua.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).notes];
                                                    else
                                                        dataExport.notes{ind, 1} = data.qua.(types{i}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).notes;
                                                    end
                                                end

                                            end
                                        end
                                    end
                                end
                            end
                            % Print an update to the log every 10 files.
                            if rem(ind_files, 10) == 0
                                printLog(app, '021', ['formated ' num2str(ind_files) ' of ' num2str(length(files))])
                            end
                        end
                    case {'ana', 'res'}

                        dataExport.signal = cell(indFinal, 1);
                        dataExport.dimension = ones(indFinal, 1)*NaN;
                        if strcmp(app.ExportMeansSwitch.Value, 'No')
                            dataExport.n = ones(indFinal, 1)*NaN;
                        end
                        
                        % Get the analysis methods from the data type.
                        analyses = fieldnames(data.(types{i}));
                        % Iterate through the analysis types.
                        for ind_analyses = 1:length(analyses)
                            % Get the file names used in the analysis.
                            files = fieldnames(data.(types{i}).(analyses{ind_analyses}));
                            % Iterate through the files.
                            for ind_files = 1:length(files)

                                % Get the different measures and remove the meta and group information from the export.
                                obj = fieldnames(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}));
                                obj(strcmp(obj, 'meta')) = [];
                                obj(strcmp(obj, 'groups')) = [];
                                % Iterate through the objects.
                                for ind_obj = 1:length(obj)
                                    % Get the signal names.
                                    sigs = fieldnames(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data);

                                    % Put the signals into the structure for export.
                                    for ind_sigs = 1:length(sigs)

                                        % Get the measures used for the current signal.
                                        meas = fieldnames(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}));
                                        % Iterate through the measures.
                                        for ind_meas = 1:length(meas)

                                            % if the current signal is a character strict only one dimension will be set in the dataExport.
                                            if ischar(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}))
                                                m = 1;
                                            else
                                                % Get the number of dimensions to put in the dataExport.
                                                m = size(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}), 2);
                                            end

                                            % Iterate through the dimensions.
                                            for ind_dim = 1:m

                                                % If results from multiple analyses are present this will put them in the same row.
                                                bool = [];
                                                if isfield(dataExport, 'file') && ~isempty(dataExport.file)
                                                    % The file, object, signal and dimension have to match. The dimensions are changed to characters
                                                    % and then compared as strings so empties in the cell array are retained. Otherwise the empties
                                                    % would be removed and the different logical arrays would have different lengths.
                                                    bool = strcmp(dataExport.file, files{ind_files}) &...
                                                        strcmp(dataExport.object, obj{ind_obj}) &...
                                                        strcmp(dataExport.signal, sigs{ind_sigs}) &...
                                                        dataExport.dimension == ind_dim;
                                                end

                                                % If the signals were selected to be averaged within each signal.
                                                if strcmp(app.ExportMeansSwitch.Value, 'Yes')

                                                    % Find the index if the data is existing.
                                                    indTemp = find(bool);
                                                    if ~isempty(indTemp)
                                                        % If a number is found use the index.
                                                        ind = indTemp;
                                                        % If the number is found don't write the file, object, signal, group or meta data.
                                                        flag = 0;
                                                    else
                                                        % If the data was not entered before find the first empty row and use that.
                                                        ind = find(cellfun(@isempty, dataExport.file), 1);
                                                        % If the data was not entered before do write all the file, object, signal, group and meta
                                                        % data.
                                                        flag = 1;
                                                    end

                                                    if flag
                                                        % Add the data type to the export.
                                                        dataExport.type{ind, 1} = types{i};
                                                        % Add the file name to the export.
                                                        dataExport.file{ind, 1} = files{ind_files};

                                                        % The file-level group information will be exported for each row of data.
                                                        if isfield(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}), 'groups')
                                                            for ind_groupFile = 1:length(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).groups)
                                                                if ~isfield(dataExport, ['group' num2str(ind_groupFile)])
                                                                    dataExport.(['group' num2str(ind_groupFile)]) = cell(indFinal, 1);
                                                                end
                                                                dataExport.(['group' num2str(ind_groupFile)]){ind, 1} = data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).groups{ind_groupFile};
                                                            end
                                                            fileGroupCount = length(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).groups);
                                                        elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{ind_files}), 'groups')
                                                            % If groups are not present in the results data the same fields will be looked for in the raw
                                                            % data and put in the export.
                                                            for ind_groupFile = 1:length(data.raw.(files{ind_files}).groups)
                                                                if ~isfield(dataExport, ['group' num2str(ind_groupFile)])
                                                                    dataExport.(['group' num2str(ind_groupFile)]) = cell(indFinal, 1);
                                                                end
                                                                dataExport.(['group' num2str(ind_groupFile)]){ind, 1} = data.raw.(files{ind_files}).groups{ind_groupFile};
                                                            end
                                                            fileGroupCount = length(data.raw.(files{ind_files}).groups);
                                                        end

                                                        % Add the object name to the export.
                                                        dataExport.object{ind, 1} = obj{ind_obj};

                                                        % The object-level group information will be exported for each row of data.
                                                        if isfield(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}), 'groups')
                                                            for ind_groupObject = 1:length(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).groups)
                                                                if ~isfield(dataExport, ['group' num2str(fileGroupCount + ind_groupObject)])
                                                                    dataExport.(['group' num2str(fileGroupCount + ind_groupObject)]) = cell(indFinal, 1);
                                                                end
                                                                dataExport.(['group' num2str(fileGroupCount + ind_groupObject)]){ind, 1} = data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).groups{ind_groupObject};
                                                            end
                                                        elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{ind_files}).(obj{ind_obj}), 'groups')
                                                            % If groups are not present in the results data the same fields will be looked for in the raw
                                                            % data and put in the export.
                                                            for ind_groupObject = 1:length(data.raw.(files{ind_files}).(obj{ind_obj}).groups)
                                                                if ~isfield(dataExport, ['group' num2str(fileGroupCount + ind_groupObject)])
                                                                    dataExport.(['group' num2str(fileGroupCount + ind_groupObject)]) = cell(indFinal, 1);
                                                                end
                                                                dataExport.(['group' num2str(fileGroupCount + ind_groupObject)]){ind, 1} = data.raw.(files{ind_files}).(obj{ind_obj}).groups{ind_groupObject};
                                                            end
                                                        end

                                                        % This code is duplicated between the yes and no options to export the means.
                                                        if strcmp(app.ExportMetaSwitch.Value, 'Yes')
                                                            % The meta data will be exported for each row of data.
                                                            if isfield(data.raw.(files{ind_files}), 'meta')
                                                                meta = fieldnames(data.raw.(files{ind_files}).meta);
                                                                for ind_meta = 1:length(meta)
                                                                    % Export the measure and individual signal value.
                                                                    if ischar(data.raw.(files{ind_files}).meta.(meta{ind_meta}))
                                                                        if ~isfield(dataExport, meta{ind_meta})
                                                                            dataExport.(meta{ind_meta}) = cell(indFinal, 1);
                                                                        end
                                                                        % If the data is a character it will be placed in the export.
                                                                        dataExport.(meta{ind_meta}){ind, 1} = data.raw.(files{ind_files}).meta.(meta{ind_meta});
                                                                    elseif isnumeric(data.raw.(files{ind_files}).(meta{ind_meta}))
                                                                        if ~isfield(dataExport, meta{ind_meta})
                                                                            dataExport.(meta{ind_meta}) = ones(indFinal, 1)*NaN;
                                                                        end
                                                                        % If the data is numeric put it in the export.
                                                                        dataExport.(meta{ind_meta})(ind, 1) = data.raw.(files{ind_files}).meta.(meta{ind_meta})(1);
                                                                        % If the data is in a cell it needs to be handled as a cell.
                                                                    elseif iscell(data.raw.(files{ind_files}).(meta{ind_meta}))
                                                                        if ischar(data.raw.(files{ind_files}).(meta{ind_meta}){1})
                                                                            if ~isfield(dataExport, meta{ind_meta})
                                                                                dataExport.(meta{ind_meta}) = cell(indFinal, 1);
                                                                            end
                                                                            % If the data is a character it will be placed in the export.
                                                                            dataExport.(meta{ind_meta}){ind, 1} = data.raw.(files{ind_files}).meta.(meta{ind_meta}){1};
                                                                        elseif isnumeric(data.raw.(files{ind_files}).(meta{ind_meta}){1})
                                                                            if ~isfield(dataExport, meta{ind_meta})
                                                                                dataExport.(meta{ind_meta}) = ones(indFinal, 1)*NaN;
                                                                            end
                                                                            if ~isempty(data.raw.(files{ind_files}).meta.(meta{ind_meta}){1})
                                                                                % If the data is a cell but is numeric, put it in the export.
                                                                                dataExport.(meta{ind_meta})(ind, 1) = data.raw.(files{ind_files}).meta.(meta{ind_meta}){1};
                                                                            end
                                                                        end
                                                                    elseif ismissing(data.raw.(files{ind_files}).meta.(meta{ind_meta}))
                                                                        if ~isfield(dataExport, meta{ind_meta})
                                                                            dataExport.(meta{ind_meta}) = cell(indFinal, 1);
                                                                        end
                                                                        % If the data is a character it will be placed in the export.
                                                                        if iscell(dataExport.([analyses{ind_analyses} '_' meas{ind_meas}])(ind, 1))
                                                                            dataExport.(meta{ind_meta}){ind, 1} = data.raw.(files{ind_files}).meta.(meta{ind_meta})(1);
                                                                        else
                                                                            % Print to the log that a data format is not handelled by the code.
                                                                            printLog(app, '042')
                                                                        end
                                                                    else
                                                                        % Print to the log that a data format is not handelled by the code.
                                                                        printLog(app, '042')
                                                                        % There are other data types that are not handled and skipped.
                                                                        continue
                                                                    end
                                                                end
                                                            end
                                                        end
                                                    end

                                                    % Add the signal name to the export.
                                                    dataExport.signal{ind, 1} = sigs{ind_sigs};
                                                    % Add the dimension to the export.
                                                    dataExport.dimension(ind, 1) = ind_dim;

                                                    % Export the measure and individual signal value.
                                                    if ischar(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}))
                                                        if ~isfield(dataExport, [analyses{ind_analyses} '_' meas{ind_meas}])
                                                            dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]) = cell(indFinal, 1);
                                                        end
                                                        % If the data is a character it will be placed in the export.
                                                        dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]){ind, 1} = data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas});
                                                    elseif isnumeric(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(1, ind_dim))
                                                        if ~isfield(dataExport, [analyses{ind_analyses} '_' meas{ind_meas}])
                                                            dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]) = ones(indFinal, 1)*NaN;
                                                        end
                                                        % If the data is numeric put it in the export.
                                                        dataExport.([analyses{ind_analyses} '_' meas{ind_meas}])(ind, 1) = mean(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(:, ind_dim));
                                                        % If the data is in a cell it needs to be handled as a cell.
                                                    elseif iscell(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(1, ind_dim))
                                                        if ischar(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}){1, ind_dim})
                                                            if ~isfield(dataExport, [analyses{ind_analyses} '_' meas{ind_meas}])
                                                                dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]) = cell(indFinal, 1);
                                                            end
                                                            % If the data is a character it will be placed in the export.
                                                            dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]){ind, 1} = data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}){1, ind_dim};
                                                        elseif isnumeric(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}){1, ind_dim})
                                                            if ~isfield(dataExport, [analyses{ind_analyses} '_' meas{ind_meas}])
                                                                dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]) = ones(indFinal, 1)*NaN;
                                                            end
                                                            if ~isempty(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}){1, ind_dim})
                                                                % If the data is a cell but is numeric, put it in the export.
                                                                dataExport.([analyses{ind_analyses} '_' meas{ind_meas}])(ind, 1) = mean(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}){:, ind_dim});
                                                            end
                                                        end
                                                    elseif ismissing(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(1, ind_dim))
                                                        if ~isfield(dataExport, [analyses{ind_analyses} '_' meas{ind_meas}])
                                                            dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]) = cell(indFinal, 1);
                                                        end
                                                        % If the data is a character it will be placed in the export.
                                                        if iscell(dataExport.([analyses{ind_analyses} '_' meas{ind_meas}])(ind, 1))
                                                            dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]){ind, 1} = mean(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(:, ind_dim));
                                                        else
                                                            % Print to the log that a data format is not handelled by the code.
                                                            printLog(app, '035')
                                                        end
                                                    else
                                                        % Print to the log that a data format is not handelled by the code.
                                                        printLog(app, '035')
                                                        % There are other data types that are not handled and skipped.
                                                        continue
                                                    end
                                                    
                                                    % If the individual elements of each signal is to be exported.
                                                elseif strcmp(app.ExportMeansSwitch.Value, 'No')

                                                    % Numel is used in case the signal is a multi-dimentional array.
                                                    if ischar(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}))
                                                        N = 1;
                                                    else
                                                        N = length(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(:, ind_dim));
                                                    end
                                                    for ind_N = 1:N

                                                        % The boolean array from earlier is reused with a new logical array for the number.
                                                        bool2 = bool & dataExport.n == ind_N;
                                                        indTemp = find(bool2);
                                                        if ~isempty(indTemp)
                                                            ind = indTemp;
                                                            flag = 0;
                                                        else
                                                            ind = find(cellfun(@isempty, dataExport.file), 1);
                                                            flag = 1;
                                                        end

                                                        if flag
                                                            % Add the data type to the export.
                                                            dataExport.type{ind, 1} = types{i};
                                                            % Add the file name to the export.
                                                            dataExport.file{ind, 1} = files{ind_files};

                                                            % Add the file group information to the export.
                                                            if isfield(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}), 'groups')
                                                                for jj = 1:length(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).groups)
                                                                    if ~isfield(dataExport, ['group' num2str(jj)])
                                                                        dataExport.(['group' num2str(jj)]) = cell(indFinal, 1);
                                                                    end
                                                                    dataExport.(['group' num2str(jj)]){ind, 1} = data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).groups{jj};
                                                                end
                                                                fileGroupCount = length(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).groups);
                                                                % If groups are not present in the results data the same fields will be looked for in the raw
                                                                % data and put in the export.
                                                            elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{ind_files}), 'groups')
                                                                for jj = 1:length(data.raw.(files{ind_files}).groups)
                                                                    if ~isfield(dataExport, ['group' num2str(jj)])
                                                                        dataExport.(['group' num2str(jj)]) = cell(indFinal, 1);
                                                                    end
                                                                    dataExport.(['group' num2str(jj)]){ind, 1} = data.raw.(files{ind_files}).groups{jj};
                                                                end
                                                                fileGroupCount = length(data.raw.(files{ind_files}).groups);
                                                            end

                                                            % Put the name of the measure in the export.
                                                            dataExport.object{ind, 1} = obj{ind_obj};

                                                            % Add the object group information to the export.
                                                            if isfield(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}), 'groups')
                                                                for jj = 1:length(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).groups)
                                                                    if ~isfield(dataExport, ['group' num2str(fileGroupCount + jj)])
                                                                        dataExport.(['group' num2str(fileGroupCount + jj)]) = cell(indFinal, 1);
                                                                    end
                                                                    dataExport.(['group' num2str(fileGroupCount + jj)]){ind, 1} = data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).groups{jj};
                                                                end
                                                                % If groups are not present in the results data the same fields will be looked for in the raw
                                                                % data and put in the export.
                                                            elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{ind_files}), obj{ind_obj}) && isfield(data.raw.(files{ind_files}).(obj{ind_obj}), 'groups')
                                                                for jj = 1:length(data.raw.(files{ind_files}).(obj{ind_obj}).groups)
                                                                    if ~isfield(dataExport, ['group' num2str(fileGroupCount + jj)])
                                                                        dataExport.(['group' num2str(fileGroupCount + jj)]) = cell(indFinal, 1);
                                                                    end
                                                                    dataExport.(['group' num2str(fileGroupCount + jj)]){ind, 1} = data.raw.(files{ind_files}).(obj{ind_obj}).groups{jj};
                                                                end
                                                            end

                                                            % This code is duplicated between the yes and no options to export the means.
                                                            if strcmp(app.ExportMetaSwitch.Value, 'Yes')
                                                                % The meta data will be exported for each row of data.
                                                                if isfield(data.raw.(files{ind_files}), 'meta')
                                                                    meta = fieldnames(data.raw.(files{ind_files}).meta);
                                                                    for ind_meta = 1:length(meta)
                                                                        % Export the measure and individual signal value.
                                                                        if ischar(data.raw.(files{ind_files}).meta.(meta{ind_meta}))
                                                                            if ~isfield(dataExport, meta{ind_meta})
                                                                                dataExport.(meta{ind_meta}) = cell(indFinal, 1);
                                                                            end
                                                                            % If the data is a character it will be placed in the export.
                                                                            dataExport.(meta{ind_meta}){ind, 1} = data.raw.(files{ind_files}).meta.(meta{ind_meta});
                                                                        elseif isnumeric(data.raw.(files{ind_files}).(meta{ind_meta}))
                                                                            if ~isfield(dataExport, meta{ind_meta})
                                                                                dataExport.(meta{ind_meta}) = ones(indFinal, 1)*NaN;
                                                                            end
                                                                            % If the data is numeric put it in the export.
                                                                            dataExport.(meta{ind_meta})(ind, 1) = data.raw.(files{ind_files}).meta.(meta{ind_meta})(1);
                                                                            % If the data is in a cell it needs to be handled as a cell.
                                                                        elseif iscell(data.raw.(files{ind_files}).(meta{ind_meta}))
                                                                            if ischar(data.raw.(files{ind_files}).(meta{ind_meta}){1})
                                                                                if ~isfield(dataExport, meta{ind_meta})
                                                                                    dataExport.(meta{ind_meta}) = cell(indFinal, 1);
                                                                                end
                                                                                % If the data is a character it will be placed in the export.
                                                                                dataExport.(meta{ind_meta}){ind, 1} = data.raw.(files{ind_files}).meta.(meta{ind_meta}){1};
                                                                            elseif isnumeric(data.raw.(files{ind_files}).(meta{ind_meta}){1})
                                                                                if ~isfield(dataExport, meta{ind_meta})
                                                                                    dataExport.(meta{ind_meta}) = ones(indFinal, 1)*NaN;
                                                                                end
                                                                                if ~isempty(data.raw.(files{ind_files}).meta.(meta{ind_meta}){1})
                                                                                    % If the data is a cell but is numeric, put it in the export.
                                                                                    dataExport.(meta{ind_meta})(ind, 1) = data.raw.(files{ind_files}).meta.(meta{ind_meta}){1};
                                                                                end
                                                                            end
                                                                        elseif ismissing(data.raw.(files{ind_files}).meta.(meta{ind_meta}))
                                                                            if ~isfield(dataExport, meta{ind_meta})
                                                                                dataExport.(meta{ind_meta}) = cell(indFinal, 1);
                                                                            end
                                                                            % If the data is a character it will be placed in the export.
                                                                            if iscell(dataExport.([analyses{ind_analyses} '_' meas{ind_meas}])(ind, 1))
                                                                                dataExport.(meta{ind_meta}){ind, 1} = data.raw.(files{ind_files}).meta.(meta{ind_meta})(1);
                                                                            else
                                                                                % Print to the log that a data format is not handelled by the code.
                                                                                printLog(app, '042')
                                                                            end
                                                                        else
                                                                            % Print to the log that a data format is not handelled by the code.
                                                                            printLog(app, '042')
                                                                            % There are other data types that are not handled and skipped.
                                                                            continue
                                                                        end
                                                                    end
                                                                end
                                                            end

                                                            % Add the signal name to the export.
                                                            dataExport.signal{ind, 1} = sigs{ind_sigs};
                                                            % Add the dimension to the export.
                                                            dataExport.dimension(ind, 1) = ind_dim;
                                                            % Add the number to the export.
                                                            dataExport.n(ind, 1) = ind_N;

                                                        end

                                                        % Export the measure and individual signal value.
                                                        if ischar(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}))
                                                            if ~isfield(dataExport, [analyses{ind_analyses} '_' meas{ind_meas}])
                                                                dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]) = cell(indFinal, 1);
                                                            end
                                                            % If the data is a character it will be placed in the export.
                                                            dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]){ind, 1} = data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas});
                                                        elseif isnumeric(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(ind_N, ind_dim))
                                                            if ~isfield(dataExport, [analyses{ind_analyses} '_' meas{ind_meas}])
                                                                dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]) = ones(indFinal, 1)*NaN;
                                                            end
                                                            % If the data is numeric put it in the export.
                                                            dataExport.([analyses{ind_analyses} '_' meas{ind_meas}])(ind, 1) = data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(ind_N, ind_dim);
                                                        % If the data is in a cell it needs to be handled as a cell.
                                                        elseif iscell(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(ind_N, ind_dim))
                                                            if ischar(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}){ind_N, ind_dim})
                                                                if ~isfield(dataExport, [analyses{ind_analyses} '_' meas{ind_meas}])
                                                                    dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]) = cell(indFinal, 1);
                                                                end
                                                                % If the data is a character it will be placed in the export.
                                                                dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]){ind, 1} = data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}){ind_N, ind_dim};
                                                            elseif isnumeric(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}){ind_N, ind_dim})
                                                                if ~isfield(dataExport, [analyses{ind_analyses} '_' meas{ind_meas}])
                                                                    dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]) = ones(indFinal, 1)*NaN;
                                                                end
                                                                if ~isempty(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}){ind_N, ind_dim})
                                                                    % If the data is a cell but is numeric, put it in the export.
                                                                    dataExport.([analyses{ind_analyses} '_' meas{ind_meas}])(ind, 1) = data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}){ind_N, ind_dim};
                                                                end
                                                            end
                                                        elseif ismissing(data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(ind_N, ind_dim))
                                                            if ~isfield(dataExport, [analyses{ind_analyses} '_' meas{ind_meas}])
                                                                dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]) = cell(indFinal, 1);
                                                            end
                                                            % If the data is a character it will be placed in the export.
                                                            if iscell(dataExport.([analyses{ind_analyses} '_' meas{ind_meas}])(ind, 1))
                                                                dataExport.([analyses{ind_analyses} '_' meas{ind_meas}]){ind, 1} = data.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas})(ind_N, ind_dim);
                                                            else
                                                                % Print to the log that a data format is not handelled by the code.
                                                                printLog(app, '035')
                                                            end
                                                        else
                                                            % Print to the log that a data format is not handelled by the code.
                                                            printLog(app, '035')
                                                            % There are other data types that are not handled and skipped.
                                                            continue
                                                        end

                                                        % Check if notes exist in the quality type and add it to the array.
                                                        if isfield(data, 'qua') && isfield(data.qua, types{i}) && isfield(data.qua.(types{i}), analyses{ind_analyses}) &&...
                                                                isfield(data.qua.(types{i}).(analyses{ind_analyses}), files{ind_files}) &&...
                                                                isfield(data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}), obj{ind_obj}) &&...
                                                                isfield(data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data, sigs{ind_sigs}) &&...
                                                                isfield(data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}), meas{ind_meas}) &&...
                                                                isfield(data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}), 'notes') &&...
                                                                data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}).fault > 0
                                                            if ~isnan(dataExport.fault(ind, 1))
                                                                dataExport.notes{ind, 1} = [dataExport.notes{ind, 1} ',' meas{ind_meas} '-' data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}).notes];
                                                            else
                                                                dataExport.notes{ind, 1} = [meas{ind_meas} '-' data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}).notes];
                                                            end
                                                        end
                                                        % Check if faults exists and add it to the export array.
                                                        if isfield(data, 'qua') && isfield(data.qua, types{i}) && isfield(data.qua.(types{i}), analyses{ind_analyses}) &&...
                                                                isfield(data.qua.(types{i}).(analyses{ind_analyses}), files{ind_files}) &&...
                                                                isfield(data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}), obj{ind_obj}) &&...
                                                                isfield(data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data, sigs{ind_sigs}) &&...
                                                                isfield(data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}), meas{ind_meas}) &&...
                                                                isfield(data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}), 'fault') &&...
                                                                data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}).fault > 0
                                                            if ~isnan(dataExport.fault(ind, 1))
                                                                dataExport.fault(ind, 1) = dataExport.fault(ind, 1) + data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}).fault;
                                                            else
                                                                dataExport.fault(ind, 1) = data.qua.(types{i}).(analyses{ind_analyses}).(files{ind_files}).(obj{ind_obj}).data.(sigs{ind_sigs}).(meas{ind_meas}).fault;
                                                            end
                                                        end
                                                    end
                                                end
                                            end
                                        end
                                    end
                                end
                                % Print an update to the log every 10 files.
                                if rem(ind_files, 10) == 0
                                    printLog(app, '021', ['analysis ' num2str(ind_analyses) ' of ' num2str(length(analyses)) ', file ' num2str(ind_files) ' of ' num2str(length(files))])
                                end
                            end
                        end
                end
            end


            % Because the export structure is pre-allocated the length of the fields no longer needs to be checked. This code is retained as comments
            % in case it is needed again.

            % It's possible not all rows will have the same data, all the fields of the structure need to be the same size before it can be made into
            % a table.
            % First find the largest field.
            % N = [];
            % exportfields = fieldnames(dataExport);
            % for ind_files = 1:length(exportfields)
            %     N(ind_files) = length(dataExport.(exportfields{ind_files}));
            % end
            % % Padd all the fields with empty arrays if they do not match the maximum.
            % for ind_files = 1:length(exportfields)
            %     if length(dataExport.(exportfields{ind_files})) < max(N)
            %         dataExport.(exportfields{ind_files})(length(dataExport.(exportfields{ind_files})):max(N)) = {[]};
            %     end
            % end
            
            % The DAMVi export table is updated by the parent method to this one. The table does not need to be updated here.

        end

        function config = loadConfig(app)
            % config = loadConfig(app)
            % inputs  - app, required mlapp object.
            % outputs - config, structure with configuration information.
            % Remarks
            % - This method load information from the configuration file into the app. This is the primary way options and settings are retained each
            %   time the DAMVi App is run.
            % Future Work
            % - This code is likely to need upkeep as the capabilities of the app increase.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % The config file is assummed to be in the same directory as the DAMVi App.
            pathConfig = mfilename('fullpath');
            % Find the last slash to remove the filename from the path.
            ind = strfind(pathConfig, '\');
            pathConfig(ind(end):end) = [];

            % Create the config file if it doesn't exist.
            if ~isfile([pathConfig '\config.xml'])
                createConfig([pathConfig '\']);
                toggleApp(app, 0)
                uiwait(msgbox('A configuration file has been created. Please check the configuration window and set the necessary folder paths.', 'DAMVi'))
                toggleApp(app, 1)
            end

            % Load the config file.
            config = readstruct([pathConfig '\config.xml']);

            % For any users except the current user and Public delete and recreate the config file.
            %ind2 = strfind(config.configuration, '\');
            %ind3 = strfind(config.configuration, 'Users');
            %user = config.configuration{1}(ind3 + 6:ind2(find(ind2 > ind3 + 6, 1)) - 1);
            %if ~strcmp(user, getenv('username')) && ~strcmp(user, 'Public')
            %    createConfig([pathConfig '\']);
                % Reload the config file.
            %    config = readstruct([pathConfig '\config.xml']);
            %    toggleApp(app, 0)
            %    uiwait(msgbox('A different user has been detected so the configuration file was recreated. Please check the configuration window and set the necessary folder paths.', 'DAMVi'))
            %    toggleApp(app, 1)
            %end

        end


        function saveConfig(app, config)
            % saveConfig(app, config)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method saves the configuration information to an xml file.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % The config file is assummed to be in the same directory as the DAMVi App.
            pathConfig = mfilename('fullpath');
            % Find the last slash to remove the filename from the path.
            ind = strfind(pathConfig, '\');
            pathConfig(ind(end):end) = [];

            % Load the config file.
            writestruct(config, [pathConfig '\config.xml']);
        end

        function data = loadData(app)
            % data = loadData(app)
            % inputs  - app, required mlapp object.
            % outputs - data, data structure specific to the DAMVi App.
            % Remarks
            % - This method dynamically loads data into the DAMVi App. It uses the files found during the search process, the selected data types and
            %   formats, and the available load functions in the load folder.
            % - This method is intended to be highly flexible. So that if additional load functions are available it will use them to load the data.
            % - This method will find group information within the file names and from the objects loaded into the app.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Dec 2025 - Modified by Ben Senderling, bensenderling@gmail.com
            %          - Removed feval functions that prevent the code from compiling into a standalone function.
            %          - Removed code used to dynamically find load methods in the app directories.

            printLog(app, '004', [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value])

            files = app.TableFiles.Data;
            % Return if no files have been found by the app.
            if isempty(files)
                printLog(app, '032')
                return
            end

            data = struct;
            ind1 = 1;

            % Create a flag to count errors so the loading can be exited if there are more than a threshold.
            flag = 0;

            for i = 1:height(files)

                % The name of the file will be used as the parent data field. It cannot have certain characters as a structure field. These are
                % all replaced with underscores.
                if i < 10
                    name = ['files000' num2str(i)];
                elseif i < 100
                    name = ['files00' num2str(i)];
                elseif i < 1000
                    name = ['files0' num2str(i)];
                elseif i < 10000
                    name = ['files' num2str(i)];
                else
                    name = ['files' num2str(i)];
                end

                % Dynamically load the data into a structure.
                try
                    %data.raw.(name) = load_data(filesLoad(ind3).name(1:end - 2), [files.folder{i} '\' files.name{i}]);
                    data.raw.(name) = load_data(app.FileExtensionDropDown.Value, app.EquipmentDropDown.Value, [files.folder{i} '\' files.name{i}]);
                    % Get the group information from the file's directory and name.
                    data.raw.(name).groups = groupAuto(app, 'filename', [files.folder{i}, '\', files.name{i}]);
                    % Get the group information for the objects of the data.
                    data.raw.(name) = groupAuto(app, 'object', data.raw.(name));
                catch ME

                    % Add one to the flag counter.
                    flag = flag + 1;

                    % Print a message to the log if the load fails for any reason.
                    printLog(app, '003', [files.folder{i} '\' files.name{i}])

                    % Get MATLAB's last error message and display it.
                    printLog(app, '031', ME.message)
                end

                % Print a message to the log if more than 10% of files have errored and exit the load method.
                if flag/height(files) > 0.1
                    printLog(app, '034')
                    return
                end

                % Provide an update to the log every 10 files.
                if rem(i, 10) == 0
                    printLog(app, '027', ['loaded ' num2str(i) ' of ' num2str(height(files)) ' files'])
                end

            end

            % Print a message notifying the user if the load was completed.
            printLog(app, '005', [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value])

        end

        function writeLog(app, text)

            % Write the message to the log file.
            pathLog = mfilename('fullpath');
            % Find the last slash to remove the filename from the path.
            ind = strfind(pathLog, '\');
            pathLog(ind(end):end) = [];
            % Find all the current log files.
            files = dir([pathLog '\Log']);
            % Sort the log files by name.
            fileLog = sort({files.name});
            % Open the log file for appending.
            fid = fopen([pathLog '\Log\' fileLog{end}], 'a');
            % Write the last message to the log file.
            fprintf(fid, ['%s' '\n'], text);
            % Close the log file.
            fclose(fid);

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % startupFcn(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method is run automatically when the app starts. It's main function is to load the configuration file and set the various 
            %   options within the DAMVi App.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % The goal of the current version of the configuration file is to quicken the app startup processes. There are methods to programatically
            % configure these but they are only run as needed.

            % Load the config file.
            config = loadConfig(app);

            % Set the workspace, directory and database text fields using the saved configuration.
            app.Directory.Value = config.directory;
            % There is a bug where the first line is empty, if it is remove it.
            if isempty(app.Directory.Value{1}) && numel(app.Directory.Value) > 1
                app.Directory.Value(1) = [];
            end
            app.Database.Value = config.database;

            % Set the file extension options using the saved configuration.
            if isfield(config.options, 'filetypes')
                app.FileExtensionDropDown.Items = fieldnames(config.options.filetypes);
                % Run the callback for the file extension selection object in the app so the format selection object displays the correct options.
                app.FileExtensionDropDownValueChanged;
            end

            % Set the directories in the configuration tab using what is saved in the app.
            %app.FilesLoad.Value = config.options.filesload;
            %app.FilesProcess.Value = config.options.filesprocess;
            %app.FilesAnalysis.Value = config.options.filesanalysis;
            %app.FilesReview.Value = config.options.filesreview;

            % Add the workspace directory and all subfolders to the search path.
            %for i = config.options.filesload
            %    pLoad = genpath(i{1});
            %    addpath(pLoad)
            %end
            %for i = config.options.filesprocess
            %    pProcess = genpath(i{1});
            %    addpath(pProcess)
            %end
            %for i = config.options.filesanalysis
            %    pAnalysis = genpath(i{1});
            %    addpath(pAnalysis)
            %end
            %for i = config.options.filesreview
            %    pReview = genpath(i{1});
            %    addpath(pReview)
            %end

            % Set the analysis method options.
            if isfield(config.options, 'analysis')
                app.AnalysisDropDown.Items = config.options.analysis;
            end

            % Set the processing method options.
            if isfield(config.options, 'process')
                app.SelectProcessingModuleDropDown.Items = config.options.process;
            end

            % Set the review method options.
            if isfield(config.options, 'review')
                app.ReviewDropDown.Items = config.options.review;
            end

            % Set the export format options.
            app.FormatsDropDown.Items = config.export;

            % Make the path to the configuration file visible in the app.
            app.ConfigurationfilelocationEditField.Value = config.configuration;

            % Check if a log is created and if not create it.
            % Get the directory of the main app file.
            pathLog = mfilename('fullpath');
            % Find the last slash to remove the filename from the path.
            ind = strfind(pathLog, '\');
            pathLog(ind(end):end) = [];
            % Construct a timestamp to use in the file name.
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');
            % Check if the log folder exists and if not create it.
            if ~isfolder([pathLog '\Log'])
                mkdir([pathLog '\Log'])
            end
            % Check if the log file exists and if not create it.
            if ~isfile([pathLog '\Log\log' timeStamp '.txt'])
                fid = fopen([pathLog '\Log\log' timeStamp '.txt'], 'w');
                fclose(fid);
            end

            % Display the log file location in the app.
            app.LogfilelocationEditField.Value = [pathLog '\Log\log' timeStamp '.txt'];

            % Print the version number of the app to the log.
            printLog(app, '000')

        end

        % Button pushed function: AddDirectorytoSearchButton
        function AddDirectorytoSearchButtonPushed(app, event)
            % AddDirectorytoSearchButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback is used to add new directories to the search options. Those can also be entered in directly.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Aug 2023 - Modified by Ben Senderling, bensenderling@gmail.com
            %          - The dialog box will now start with the database directory if it is present.

            % Allow the user to select the parent directory.
            if isempty(app.Database.Value)
                m_path = uigetdir(pwd, 'Please select the data directory');
            else
                m_path = uigetdir(app.Database.Value, 'Please select the data directory');
            end
            % The directory is saved to the text field where it will be pulled from later.
            if m_path == 0
                return
            end
            % If the current text box is empty the directory path will be set to it directly.
            if isempty(app.Directory.Value)
                app.Directory.Value = m_path;
            else
                % If path or paths are already existing in the text box the new path will be appended to it.
                text = app.Directory.Value;
                app.Directory.Value{length(app.Directory.Value) + 1, 1} = m_path;
                % Check if the first value is empty and if it is remove it.
                if isempty(app.Directory.Value{1})
                    app.Directory.Value(1) = [];
                end
            end

            % If the user selected a directory it will be saved to the configuration file so it can be loaded the next time the app is run.
            config = loadConfig(app);
            config.directory = app.Directory.Value;
            saveConfig(app, config)

        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            % FindButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run the search algorithm to find the target file types and formats among the files in the directories listed in 
            %   the search text field.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Pull the selected directory from the app text box.
            m_directory = app.Directory.Value;
            % Pull the selected file extension from the app dropdown.
            m_fileType = app.FileExtensionDropDown.Value;
            % Pull the equipment type from the app dropdown.
            m_equipmentType = app.EquipmentDropDown.Value;
            % Pull the search tags the user entered into the app text box.
            m_searchTags = app.SearchTagsInclude.Value;

            % Empty the current table.
            app.TableFiles.Data = [];

            % Initialize an empty structure for the object to store the file information.
            files = struct;
            % Perform recursive file search.
            printLog(app, '001', {''})
            for i = 1:length(m_directory)
                files = filesearch(app, files, m_directory{i});
            end
            if isempty(fieldnames(files))
                printLog(app, '016')
                return
            end

            % The files sizes will be converted back to bytes so the sum can be calculated. This allows different rows to have different KB/MB/GB.
            bytes = zeros(length(files.bytes), 1);
            for i = 1:length(files.bytes)
                if contains(files.bytes{i}, 'KB')
                    bytes(i) = str2double(files.bytes{i}(1:strfind(files.bytes{i}, ' ')))*1e3;
                elseif contains(files.bytes{i}, 'MB')
                    bytes(i) = str2double(files.bytes{i}(1:strfind(files.bytes{i}, ' ')))*1e6;
                elseif contains(files.bytes{i}, 'GB')
                    bytes(i) = str2double(files.bytes{i}(1:strfind(files.bytes{i}, ' ')))*1e9;
                end
            end
            % Find the sum and convert it back to an easily understood number.
            if sum(bytes) < 1e3
                bytesSum = [num2str(sum(bytes)) ' B'];
            elseif sum(bytes) < 1e6
                bytesSum = [num2str(round(sum(bytes)/1e3, 1)) ' KB'];
            elseif sum(bytes) < 1e9
                bytesSum = [num2str(round(sum(bytes)/1e6, 1)) ' MB'];
            elseif sum(bytes) >= 1e9
                bytesSum = [num2str(round(sum(bytes)/1e9, 1)) ' GB'];
            end
            printLog(app, '002', {length(files.name);bytesSum})
            % Put the files found into the app table.
            app.TableFiles.Data = struct2table(files);
            %             app.TableFiles.ColumnName = fieldnames(files);

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % LoadButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run load method to bring data into the app. It was structured this way so the load function could be used alone, 
            %   elsewhere.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            data = loadData(app);

            if isempty(data)
                printLog(app, '030')
                return
            end

            setappdata(app.TableFiles,'data',data)

            app.IdentifyGroupsButton.Enable = 1;
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            % LoadButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will dynamically run the selected analysis on the data currently loaded into the DAMVi App. It was intended to be 
            %   highly flexible so new analysis methods could be added and used quickly without knowing how to program the app.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Dec 2025 - Modified by Ben Senderling, bensenderling@gmail.com
            %          - Removed feval function and replaced it with a subroutine. MATLAB no longer allows code with feval to be
            %            compiled into a standalone app.

            data = getappdata(app.TableFiles, 'data');
            if isempty(data)
                printLog(app ,'030')
                return
            end

            % There dropdown values were updated earlier during startup using the config file.
            analysis = app.AnalysisDropDown.Value;

            printLog(app ,'022', analysis)

            % This function call makes use of the analysis file name structure.
            try
                app.AppAnalysis = run_analysis(app, data, analysis);
            catch ME
                % Print a message to the log if the load fails for any reason.
                printLog(app, '029', analysis)

                % Get MATLAB's last error message and display it.
                printLog(app, '031', ME.message)
            end

        end

        % Value changed function: FileExtensionDropDown
        function FileExtensionDropDownValueChanged(app, event)
            % FileExtensionDropDownValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run when ever the file extension option is changed and will change the equipment type to match the available 
            %   options. This information is pulled from the configuration file, which is dynamically pulled from the load functions found in the 
            %   load folder.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Get the currently selected file extension.
            value = app.FileExtensionDropDown.Value;
            % Load the configuration file with the matching file extensions and equipment names.
            config = loadConfig(app);
            % Get the file extension options.
            options = fieldnames(config.options.filetypes);
            for i = 1:length(options)
                if strcmp(value, options{i})
                    app.EquipmentDropDown.Items = config.options.filetypes.(options{i});
                    break
                end
            end

        end

        % Button pushed function: ButtonFormatExport
        function ButtonFormatExportPushed(app, event)
            % ButtonFormatExportPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run different subroutines to format data for export. It is intended to be somewhat flexible so different methods 
            %   could be used.
            % Future Work
            % - Similar to the load, process and analysis methods this one could be made more dynamic to allow for easier user modification.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Empty the current data from the table and make sure the app updates.
            app.UITableExport.Data = [];
            drawnow

            % Get the current data from the app.
            data = getappdata(app.TableFiles, 'data');

            switch app.FormatsDropDown.Value
                case 'General'
                    % This is a generic export that should be able to work for everything.
                    dataExport = formatResults(app, data);
                    app.UITableExport.Data = struct2table(dataExport);
                    app.UITableExport.ColumnName = fieldnames(dataExport);
                    setappdata(app.UITableExport,'exportType','General')
                    printLog(app, '008')
                case 'BU Caltesting'
                    % This export is specific to existing BU MoveLab practices.
                    dataExport = formatBUCaltest(app, data);
                    setappdata(app.UITableExport,'dataExport',dataExport)
                    setappdata(app.UITableExport,'exportType','BU Caltesting')
                    printLog(app, '009')
                case 'Tables'
                    % This export will print all the results as tables.
                    dataExport = formatTables(app, data);
                    app.UITableExport.Data = dataExport;
                    app.UITableExport.ColumnName = fieldnames(dataExport);
                    setappdata(app.UITableExport,'exportType','General')

            end

        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            % ExportResultsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will export the data formated for export. The intent was a user could format the data, review it within the app and 
            %   then export it through this callback.
            % Future Work
            % - Similar to the load, process and analysis methods this one could be made more dynamic to allow for easier user modification.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            exportType = app.FormatsDropDown.Value;

            m_resultsPath = app.Database.Value;

            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            switch exportType
                case {'General', 'Tables'}
                    % The data was previously formated and displayed in the table. That data will be directly exported.
                    dataExport = app.UITableExport.Data;
                    % If no data is present stop the export.
                    if isempty(dataExport)
                        return
                    end
                    % Allow the user to specify a file. A suggested file name is provided.
                    [file, path] = uiputfile('.xlsx', 'Save As', [m_resultsPath '\Export\General_' timeStamp '.xlsx']);
                    % If nothing is selected the function returns.
                    if isnumeric(file) && file == 0
                        return
                    else
                        % Write the data.
                        writetable(dataExport,[path file])
                    end
                case 'BU Caltesting'
                    % This data cannot be displayed in the DAMVi App table so it needs to be pulled from the app data.
                    dataExport = getappdata(app.UITableExport,'dataExport');
                    fields = fieldnames(dataExport);
                    % The filename is coded and the user does not specify what they want.
                    fileName = [m_resultsPath '\Export\' exportType timeStamp '.xlsx'];
                    for i = 1:length(fields)
                        writecell(dataExport.(fields{i}), fileName, 'Sheet', fields{i})
                    end
            end

            printLog(app, '007')
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % ProcessButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will dynamically run the available process methods based on what is stored in the configuration file and what was found
            %   in the process folder.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Dec 2022 - Modified by Ben Senderling, bensenderling@gmail.com
            %          - Updated to use the same dynamic referencing as the load and analysis methods.

            % Get the current data to process.
            data = getappdata(app.TableFiles, 'data');

            % Find the load function that matches the selected file extension and equipment type.
            % Get the load functions for the first directory.
            %filesProcess = dir(app.FilesProcess.Value{1});
            % Get the load functions for the additional directories if there are more.
            %if length(app.FilesProcess.Value) > 1
            %    for i = 2:length(app.FilesProcess.Value)
            %        filesProcess = [filesProcess; dir(app.FilesProcess.Value{i})];
            %    end
            %end

            % Find the index where the filename contains the target file extension and the equipment type and is not an asv-file.
            %ind3 = find(contains({filesProcess.name}', ['_' app.SelectProcessingModuleDropDown.Value]) & ~contains({filesProcess.name}', 'asv'), 1);

            % Exit if no file was found.
            %if isempty(ind3)
            %    return
            %end

            % These drop down values were found dynamically by the app using the file names of the processfile.
            process = app.SelectProcessingModuleDropDown.Value;

            % Find the index of '.m' which will be present in all m- and mlapp-files.
            %ind4 = strfind(filesProcess(ind3).name, '.m');

            % Dynamically run the method.
            app.AppAnalysis = run_process(app, data, process);
        end

        % Button pushed function: SaveCurrentDataButton
        function SaveCurrentDataButtonPushed(app, event)
            % SaveCurrentDataButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will save the current data to a user selected file. It will save the entire DAMVi App data structure and could be of 
            %   great size if a lot of data was loaded into the app. It will also inlcude process and results data.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Pull the current data from the app data.
            data = getappdata(app.TableFiles, 'data');

            % Get the database location so the filename can be constructed.
            m_resultsPath = app.Database.Value;

            % Construct a timestamp to use in the file name.
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            % Get a user entered file name and suggest the coded version.
            [file, path] = uiputfile('.mat', 'Save As', [m_resultsPath '\Data\' app.FileExtensionDropDown.Value app.EquipmentDropDown.Value timeStamp '.mat']);
            % Exit if no filename was entered.
            if isnumeric(file) && file == 0
                return
            else
                % Some data can take a while to save so an update to the log is printed before and after.
                printLog(app, '012')
                save([path file], 'data', '-v7.3')
                printLog(app, '013')
            end

        end

        % Button pushed function: SelectWorkingDatabaseButton
        function SelectWorkingDatabaseButtonPushed(app, event)
            % SelectWorkingDatabaseButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback allows the user to select the working database. Once selected the database will be configured for use with the DAMVi 
            %   App with the required subfolders.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Aug 2023 - Modified by Ben Senderling, bensenderling@gmail.com
            %          - The dialog box will now start with the current working directory if there is one.

            % Allow the user to select the working directory to store data.
            if isempty(app.Database.Value)
                m_path = uigetdir(pwd, 'Please select the working directory');
            else
                m_path = uigetdir(app.Database.Value, 'Please select the working directory');
            end
            % The directory is saved to the text field where it will be pulled from later.
            if m_path==0
                return
            end
            app.Database.Value = m_path;

            % Create the folders that are assumed to be present by the DAMVi App.
            createDatabase(app);

        end

        % Button pushed function: ReviewAnalysisButton
        function ReviewAnalysisButtonPushed(app, event)
            % ReviewAnalysisButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run a DAMVi App Module to review results calculated from the data. Most of these modules are developed to work 
            %   with specific data.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            data = getappdata(app.TableFiles, 'data');

            % Find the review function that matches the selected file extension and equipment type.
            % Get the load functions for the first directory.
            %filesReview = dir(app.FilesReview.Value{1});
            % Get the load functions for the additional directories if there are more.
            %if length(app.FilesReview.Value) > 1
            %    for i = 2:length(app.FilesReview.Value)
            %        filesReview = [filesReview; dir(app.FilesReview.Value{i})];
            %    end
            %end

            % Find the index where the filename contains the target file extension and the equipment type and is not an asv-file.
            %ind3 = find(contains({filesReview.name}', app.ReviewDropDown.Value) & ~contains({filesReview.name}', 'asv'), 1);

            % Exit if no file was found.
            %if isempty(ind3)
            %    return
            %end

            % Get the selected review method.
            review = app.ReviewDropDown.Value;

            % Find the index of '.m' which will be present in all m- and mlapp-files.
            %ind4 = strfind(filesReview(ind3).name, '.m');

            % Dynamically run the method.
            app.AppAnalysis = run_review(app, data, review);

        end

        % Button pushed function: IdentifyGroupsButton
        function IdentifyGroupsButtonPushed(app, event)
            % IdentifyGroupsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run a DAMVi App Module to process group information within the DAMVi App. Most of the groups will be identified 
            %   when the data is loaded in but these are likely to contain extraneous information. That information can be removed through the module.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com
            data = getappdata(app.TableFiles, 'data');
            % Run the Groupsing module.
            app.AppAnalysis = process_Groupings(app, data);

        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            % MoveButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will move the files found by the app to a
            %   target directory. This can be useful if files in various
            %   subfolders need to be moved to a single folder, or to a
            %   duplicate location.
            % - This is a move operation and the files will no longer be
            %   present in the original directories.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Print to the log the move is starting.
            printLog(app, '014')

            % Return if no files have been found by the DAMVi App.
            files = app.TableFiles.Data;
            if isempty(files)
                return
            end

            % Get the target directory from the user.
            targetDirectory = uigetdir(app.Database.Value, 'Please select a target directory to move the files to');

            filesLoad = dir(app.FilesLoad.Value);

            % Iterate through each file and move them.
            for i = 1:height(files)
                [status, msg] = movefile([files.folder{i} '\' files.name{i}], targetDirectory);
                % Print an update to the log if a move failed.
                if status == 0
                    printLog(app, '017', msg)
                end

            end

            % Print to the log the move has completed.
            printLog(app, '015')
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            % CopyButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will copy the files found by the app to a target directory. This can be useful if files in various subfolders need to 
            %   be moved to a single folder, or to a duplicate location.
            % - This is a copy operation and the original files will remain in their original location. This may significantly increase storage 
            %   requirements if a large amount of data is being moved.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            printLog(app, '018')

            % Get the current list of files found by the DAMVi App.
            files = app.TableFiles.Data;
            % Return if no files have been found by the DAMVi App.
            if isempty(files)
                return
            end

            % Get the target directory from the user.
            targetDirectory = uigetdir(app.Database.Value, 'Please select a target directory to copy the files to');

            if targetDirectory == 0
                return
            end

            % Iterate through the files and copy them.
            for i = 1:height(files)
                [status, msg] = copyfile([files.folder{i} '\' files.name{i}], targetDirectory);
                % Print an update to the log if a copy fails.
                if status == 0
                    printLog(app, '020', msg)
                end

            end

            printLog(app, '019')
        end

        % Button pushed function: UpdateConfigurationButton
        function UpdateConfigurationButtonPushed(app, event)
            % UpdateConfigurationButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run a number of subroutines that will update the configuration file will the current files. These files are pulled
            %   from the locations listed in the configuration tab.
            % Future Work
            % - This may need to update the search path if a new folder is added that is not in the current path.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Search the Load folder and update the file extensions and equipment types.
            %identifyFileTypes(app)

            % Search the Process folder and update the available methods.
            identifyProcessOptions(app)

            % Search the Analysis folder and update the available methods.
            %identifyAnalysisOptions(app)

            % Search the Analysis folder and update the available methods.
            identifyReviewOptions(app)

        end

        % Button pushed function: FindeligibleexportsButton
        function FindeligibleexportsButtonPushed(app, event)
            % FindeligibleexportsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will determine if raw, processed or results data is present within the data structure. It will update a list in the app
            %   with those options.
            % Future Work
            % - This callback may not be necessary. It was created as part% Print to the log the move is starting. of developing the app.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Pull the current data from the app.
            data = getappdata(app.TableFiles, 'data');

            % The eligible exports are the very first level of the DAMVi App data structure.
            if ~isempty(data)
                app.SelectitemstoexportListBox.Items = fieldnames(data);
            end

        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            % MergeButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will run the merge module. This is used to merge currently loaded data with newly found files. For this method to work 
            %   properly the existing and new data do need to share common group information.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % This is a DAMVi App Module.
            process_Merge(app);

        end

        % Button pushed function: ClearLogWindowButton
        function ClearLogWindowButtonPushed(app, event)
            printLog(app, '036')
            app.Log.Value = '';
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % ExportButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information related to the callback.
            % Remarks
            % - This callback will export the results of the file search.
            %   This was created as a way to check the file content of new
            %   projects with subfolders.
            % Future Work
            % - None.
            % Oct 2023 - Created by Ben Senderling, bms322@drexel.edu
            m_resultsPath = app.Database.Value;

            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            dataExport = app.TableFiles.Data;
            % If no data is present stop the export.
            if isempty(dataExport)
                return
            end
            % Allow the user to specify a file. A suggested file name is provided.
            [file, path] = uiputfile('.xlsx', 'Save As', [m_resultsPath '\Export\FileSearch_' app.FileExtensionDropDown.Value '_' app.EquipmentDropDown.Value '_' timeStamp '.xlsx']);
            % If nothing is selected the function returns.
            if isnumeric(file) && file == 0
                return
            else
                % Write the data.
                writetable(dataExport,[path file])
            end

            printLog(app, '041')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'E:\bense\OneDrive\Documents\Academic\Projects\016 DAMVi App\Code\DAMVi_App\Logo.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseSearchTab = uitab(app.TabGroup);
            app.DatabaseSearchTab.Title = 'Database Search';
            app.AddDirectorytoSearchButton = uibutton(app.DatabaseSearchTab, 'push');
            app.AddDirectorytoSearchButton.ButtonPushedFcn = createCallbackFcn(app, @AddDirectorytoSearchButtonPushed, true);
            app.AddDirectorytoSearchButton.Text = 'Add Directory to Search';
            app.SelectfileextensionofthedatafilesLabel = uilabel(app.DatabaseSearchTab);
            app.SelectfileextensionofthedatafilesLabel.Text = {'Select file extension'; 'of the data files.'};
            app.FileExtensionDropDown = uidropdown(app.DatabaseSearchTab);
            app.FileExtensionDropDown.Items = {};
            app.FileExtensionDropDown.ValueChangedFcn = createCallbackFcn(app, @FileExtensionDropDownValueChanged, true);
            app.FileExtensionDropDown.Value = {};
            app.SelectequipmentthedatawasrecordedfromLabel = uilabel(app.DatabaseSearchTab);
            app.SelectequipmentthedatawasrecordedfromLabel.Text = {'Select equipment the'; 'data was recorded from.'};
            app.EquipmentDropDown = uidropdown(app.DatabaseSearchTab);
            app.EquipmentDropDown.Items = {};
            app.EquipmentDropDown.Value = {};
            app.EditFieldLabel = uilabel(app.DatabaseSearchTab);
            app.EditFieldLabel.Text = {'Enter specific strings separated by'; 'commas to filter the files selected.'};
            app.SearchTagsInclude = uieditfield(app.DatabaseSearchTab, 'text');
            app.TableFiles = uitable(app.DatabaseSearchTab);
            app.TableFiles.ColumnName = {'File Name'; 'Folder'; 'Size'};
            app.TableFiles.RowName = {};
            app.FindButton = uibutton(app.DatabaseSearchTab, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.LoadButton = uibutton(app.DatabaseSearchTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveCurrentDataButton = uibutton(app.DatabaseSearchTab, 'push');
            app.SaveCurrentDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentDataButtonPushed, true);
            app.SaveCurrentDataButton.Text = 'Save Current Data';
            app.SelectWorkingDatabaseButton = uibutton(app.DatabaseSearchTab, 'push');
            app.SelectWorkingDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWorkingDatabaseButtonPushed, true);
            app.SelectWorkingDatabaseButton.Text = 'Select Working Database';
            app.Database = uieditfield(app.DatabaseSearchTab, 'text');
            app.IdentifyGroupsButton = uibutton(app.DatabaseSearchTab, 'push');
            app.IdentifyGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyGroupsButtonPushed, true);
            app.IdentifyGroupsButton.Enable = 'off';
            app.IdentifyGroupsButton.Text = 'Identify Groups';
            app.SearchTagsExclude = uieditfield(app.DatabaseSearchTab, 'text');
            app.IncludeLabel = uilabel(app.DatabaseSearchTab);
            app.IncludeLabel.Text = 'Include';
            app.ExcludeLabel = uilabel(app.DatabaseSearchTab);
            app.ExcludeLabel.Text = 'Exclude';
            app.MoveButton = uibutton(app.DatabaseSearchTab, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.CopyButton = uibutton(app.DatabaseSearchTab, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.MergeButton = uibutton(app.DatabaseSearchTab, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge';
            app.Directory = uitextarea(app.DatabaseSearchTab);
            app.ExportButton = uibutton(app.DatabaseSearchTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.SelectProcessingModuleDropDownLabel = uilabel(app.ProcessingTab);
            app.SelectProcessingModuleDropDownLabel.Text = 'Select Processing Module';
            app.SelectProcessingModuleDropDown = uidropdown(app.ProcessingTab);
            app.SelectProcessingModuleDropDown.Items = {};
            app.SelectProcessingModuleDropDown.Value = {};
            app.ProcessButton = uibutton(app.ProcessingTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisDropDownLabel = uilabel(app.AnalysisTab);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.AnalysisTab);
            app.AnalysisDropDown.Items = {};
            app.AnalysisDropDown.Value = {};
            app.RunAnalysisButton = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            app.ReviewAnalysisButton = uibutton(app.ReviewTab, 'push');
            app.ReviewAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewAnalysisButtonPushed, true);
            app.ReviewAnalysisButton.Text = 'Review Analysis';
            app.AnalysisDropDown_2Label = uilabel(app.ReviewTab);
            app.AnalysisDropDown_2Label.Text = 'Analysis';
            app.ReviewDropDown = uidropdown(app.ReviewTab);
            app.ReviewDropDown.Items = {};
            app.ReviewDropDown.Value = {};
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ButtonFormatExport = uibutton(app.ExportTab, 'push');
            app.ButtonFormatExport.ButtonPushedFcn = createCallbackFcn(app, @ButtonFormatExportPushed, true);
            app.ButtonFormatExport.Text = 'Format loaded results for export';
            app.UITableExport = uitable(app.ExportTab);
            app.UITableExport.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableExport.RowName = {};
            app.FormatsDropDownLabel = uilabel(app.ExportTab);
            app.FormatsDropDownLabel.Text = 'Formats';
            app.FormatsDropDown = uidropdown(app.ExportTab);
            app.FormatsDropDown.Items = {};
            app.FormatsDropDown.Value = {};
            app.ExportResultsButton = uibutton(app.ExportTab, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.FindeligibleexportsButton = uibutton(app.ExportTab, 'push');
            app.FindeligibleexportsButton.ButtonPushedFcn = createCallbackFcn(app, @FindeligibleexportsButtonPushed, true);
            app.FindeligibleexportsButton.Text = 'Find eligible exports';
            app.SelectitemstoexportListBoxLabel = uilabel(app.ExportTab);
            app.SelectitemstoexportListBoxLabel.Text = 'Select items to export';
            app.SelectitemstoexportListBox = uilistbox(app.ExportTab);
            app.SelectitemstoexportListBox.Items = {};
            app.SelectitemstoexportListBox.Value = {};
            app.ExportMeansSwitchLabel = uilabel(app.ExportTab);
            app.ExportMeansSwitchLabel.Text = 'Export Means';
            app.ExportMeansSwitch = uiswitch(app.ExportTab, 'slider');
            app.ExportMeansSwitch.Items = {'No', 'Yes'};
            app.ExportMeansSwitch.Value = 'No';
            app.ExportMetaSwitchLabel = uilabel(app.ExportTab);
            app.ExportMetaSwitchLabel.Text = 'Export Meta';
            app.ExportMetaSwitch = uiswitch(app.ExportTab, 'slider');
            app.ExportMetaSwitch.Items = {'No', 'Yes'};
            app.ExportMetaSwitch.Value = 'No';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.UpdateConfigurationButton = uibutton(app.ConfigurationTab, 'push');
            app.UpdateConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateConfigurationButtonPushed, true);
            app.UpdateConfigurationButton.Text = 'Update Configuration';
            app.MethodDirectoriesLabel = uilabel(app.ConfigurationTab);
            app.MethodDirectoriesLabel.Text = 'Method Directories';
            app.ProcessTextAreaLabel = uilabel(app.ConfigurationTab);
            app.ProcessTextAreaLabel.Text = 'Process';
            app.FilesProcess = uitextarea(app.ConfigurationTab);
            app.LoadTextAreaLabel = uilabel(app.ConfigurationTab);
            app.LoadTextAreaLabel.Text = 'Load';
            app.FilesLoad = uitextarea(app.ConfigurationTab);
            app.AnalysisTextAreaLabel = uilabel(app.ConfigurationTab);
            app.AnalysisTextAreaLabel.Text = 'Analysis';
            app.FilesAnalysis = uitextarea(app.ConfigurationTab);
            app.ReviewTextAreaLabel = uilabel(app.ConfigurationTab);
            app.ReviewTextAreaLabel.Text = 'Review';
            app.FilesReview = uitextarea(app.ConfigurationTab);
            app.ConfigurationfilelocationEditFieldLabel = uilabel(app.ConfigurationTab);
            app.ConfigurationfilelocationEditFieldLabel.Text = 'Configuration file location';
            app.ConfigurationfilelocationEditField = uieditfield(app.ConfigurationTab, 'text');
            app.ConfigurationfilelocationEditField.Editable = 'off';
            app.LogfilelocationEditFieldLabel = uilabel(app.ConfigurationTab);
            app.LogfilelocationEditFieldLabel.Text = 'Log file location';
            app.LogfilelocationEditField = uieditfield(app.ConfigurationTab, 'text');
            app.LogfilelocationEditField.Editable = 'off';
            app.ClearLogWindowButton = uibutton(app.ConfigurationTab, 'push');
            app.ClearLogWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogWindowButtonPushed, true);
            app.ClearLogWindowButton.Text = 'Clear Log Window';
            app.Log = uitextarea(app.UIFigure);
            app.DAMViAppLabel = uilabel(app.UIFigure);
            app.DAMViAppLabel.Text = 'DAMVi App';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'v2.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAMVi_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end