classdef process_Eventing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CutoffEditField                matlab.ui.control.NumericEditField
        CutoffEditFieldLabel           matlab.ui.control.Label
        SamplingEditField              matlab.ui.control.NumericEditField
        SamplingEditFieldLabel         matlab.ui.control.Label
        NormalizemagnitudeCheckBox     matlab.ui.control.CheckBox
        UsederivativeCheckBox          matlab.ui.control.CheckBox
        ApplygentlefilterCheckBox      matlab.ui.control.CheckBox
        UsethesignalmagnitudeCheckBox  matlab.ui.control.CheckBox
        StartEventingButton            matlab.ui.control.Button
        PriorButton                    matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        SelectMethodDropDown           matlab.ui.control.DropDown
        SelectMethodDropDownLabel      matlab.ui.control.Label
        FindfirstfilewithoutresultsButton  matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        DimensionSpinner               matlab.ui.control.Spinner
        DimensionSpinnerLabel          matlab.ui.control.Label
        SignalDropDown                 matlab.ui.control.DropDown
        SignalDropDownLabel            matlab.ui.control.Label
        ObjectDropDown                 matlab.ui.control.DropDown
        ObjectDropDownLabel            matlab.ui.control.Label
        FileDropDown                   matlab.ui.control.DropDown
        FileDropDownLabel              matlab.ui.control.Label
        Version                        matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        SaveResultsandExitButton       matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        EventingLabel                  matlab.ui.control.Label
    end


    properties (Access = private)
        BAR_App   % Main app object
    end
    
    properties (Access = public)
        Data % Description
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of the error and organize the errors available.
            %         - newText, a string that may be inserted into the message.
            % Remarks
            % - This method will print messages to the log of the BAR App. The error codes are unique to each message so they can be
            %   found and referenced easier.
            % - The method is public and is acessible outside of the BAR App. It is used in some of the example analysis and process
            %   scripts.
            % Future Work
            % - This log should be printed and saved to a file location each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text within the log.
            text = app.BAR_App.Log.Value;

            % Match the error code with the message and update the text for the log.
            switch code
                case '001'
                    text{length(text) + 1, 1} = 'EVT-001 eventing started';
                case '002'
                    text{length(text) + 1, 1} = 'EVT-002 eventing complete';
                case '003'
                    text{length(text) + 1, 1} = ['EVT-003 eventing failed on: ' newText];
                case '004'
                    text{length(text) + 1, 1} = ['EVT-004 ' newText];
                case '005'
                    text{length(text) + 1, 1} = ['EVT-005 normalized sampling rate of ' newText ' is invalid'];
            end

            % Update the log.
            app.BAR_App.Log.Value = text;

            % Scroll to the bottom so the most recent message is visible.
            scroll(app.BAR_App.Log,'bottom')
            drawnow
        end

        function identifyProcessOptions(app)
            % identifySegmentOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Process folder for valid files that would be used to segment data within the app. These files 
            %   need to have a name process_Segment*.m. The results will be saved to the configuration file and made selectable in the 
            %   app.
            % - The goal of this function was to make the addition of new functions easier.
            % - The idea of processing is to take an input and create a new ouput of similar size and scale.
            % Future Work
            % - None
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Load the existing configuration so it can be updated.
            config = loadConfig(app.BAR_App);

            % Empty the available segment files from the saved configuration.
            config.options.eventing = [];
            ind = 1;

            for j = 1:length(app.BAR_App.FilesProcess.Value)
                % Use the directory entered into the app to find the processing scripts.
                m_files = dir(app.BAR_App.FilesProcess.Value{j});

                % Look for files within the directory. If the file names contain the word 'process' save them within the app and 
                % configuration.
                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'process') && contains(m_files(i).name, 'Eventing_') && ~contains(m_files(i).name, 'asv')

                        [~, name] = fileparts(m_files(i).name);
                        parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                        % The third part of the file name is used for the
                        % options.
                        config.options.eventing{ind,1} = parts{3}{1};
                        ind = ind + 1;
                    end
                end
            end

            % If there are no segment files or if the directory is misleading because files have been moved or the app was moved 
            % nothing will be found. The object in the app can't be set to empty.
            if ~isempty(config.options.eventing)
                app.SelectMethodDropDown.Items = config.options.eventing;
            end

            % Save the configuration with the updated directory and or file extensions and formats.
            saveConfig(app.BAR_App, config)
        end
        
        function x = modifySignal(app, x)
            
            % If the user didn't indicate the magnitude should be used.
            if app.UsethesignalmagnitudeCheckBox.Value == 0
                % Then use the regular time series.
                x = x(:, app.DimensionSpinner.Value);
            else
                % Else calculate the magnitude from the different dimensions.
                x = sqrt(sum(x.^2, 2));
            end

            % If the user indicated the signal should be filtered then apply a gentle low-pass filter.
            if app.ApplygentlefilterCheckBox.Value == 1
                freq = app.SamplingEditField.Value;
                cf = app.CutoffEditField.Value;
                if cf/(freq/2) <= 0 || cf/(freq/2) >= 1
                    printLog(app, '005', num2str(cf/(freq/2)))
                    return
                end
                [b, a] = butter(4, cf/(freq/2), 'low');
                x = filtfilt(b, a, x);
            end

            % If the user indicated to use the derivative.
            if app.UsederivativeCheckBox.Value == 1
                freq = app.SamplingEditField.Value;
                x = diff(x)/freq;
            end

            % If the user said to normalize the magnitude.
            if app.NormalizemagnitudeCheckBox.Value == 1
                x = (x - min(x))/(max(x) - min(x))*2 - 1;
            end

        end
    end
    
    methods (Access = public)
        
        function updatePlotBasic(app)
            % updatePlotBasic(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the plot with the current file, object and signal selection.
            % Future Work
            % - This method was named 'Basic' as it does not plot any extrema for the signal.
            % Aug 2023 - Created by Ben Senderling, bsender@bu.edu

            % Delete the current figure.
            app.Panel.Children.delete

            % Get if the Raw or Processed data should be used.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Get the file selection.
            file = app.FileDropDown.Value;
            % Remove group information from the file name.
            file = file(1:strfind(file, ':') - 1);
            % Get the object selection.
            obj = app.ObjectDropDown.Value;
            % Remove group information from the object name if it is present.
            if contains(obj, ':')
                obj = obj(1:strfind(obj, ':') - 1);
            end
            % Get the signal selection.
            sig = app.SignalDropDown.Value;
            % Get the dimension selection.
            d = app.DimensionSpinner.Value;
            
            % Plot the selected data in a hidden figure.
            H = figure('Visible', 'off');

            x = modifySignal(app, app.Data.(type).(file).(obj).data.(sig));

            frame = (1:length(x))';
            plot(frame, x, 'k')
            % Check if there are already results present and plot them.
            if isfield(app.Data, 'res') &&...
                    isfield(app.Data.res, 'Eventing') &&...
                    isfield(app.Data.res.Eventing, file) &&...
                    isfield(app.Data.res.Eventing.(file), obj) &&...
                    isfield(app.Data.res.Eventing.(file).(obj).data, sig) &&...
                    size(app.Data.res.Eventing.(file).(obj).data.(sig), 2) >= d
                % Create arrays so the extrema can be plotted sequentially. This is helpfull when reviewing many files. The pattern of 
                % the line helps observe the extrema and determine if they are correct.
                allLocs = [app.Data.res.Eventing.(file).(obj).data.(sig).locsmin{d}; app.Data.res.Eventing.(file).(obj).data.(sig).locsmax{d}];
                % The round is used to round numbers in scientific notation to integers.
                allPeaks = x(round(allLocs, 0));
                [allLocs, I] = sort(allLocs);
                allPeaks = allPeaks(I);
                hold on
                plot(app.Data.res.Eventing.(file).(obj).data.(sig).locsmax{d}, x(round(app.Data.res.Eventing.(file).(obj).data.(sig).locsmax{d}, 0)), 'bo',...
                    app.Data.res.Eventing.(file).(obj).data.(sig).locsmin{d}, x(round(app.Data.res.Eventing.(file).(obj).data.(sig).locsmin{d}, 0)), 'go',...
                    allLocs, allPeaks, '--k')
                hold off
            end
            axis tight
            
            % Copy the figure to the app.
            copyobj(H.Children, app.Panel)

            % Close the hidden figure.
            close(H)
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % StartupFcn(app, mainapp, data, analysis)
            % inputs  - app, required mlapp object.
            %         - mlapp, BAR App objects.
            %         - data, BAR App data structure.
            %         - analysis, string of the analysis name.
            % Remarks
            % - This is the startup method that initiallized the app.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;

            % Save the data to the app.
            app.Data = data;
            
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.BAR_App.Version.Text;

            % Identify the current eventing options.
            identifyProcessOptions(app)

            % Trigger the switch callback to populate some fields.
            app.Switch_ValueChanged

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will send the data back to the BAR App and save it.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the module.
            data = getappdata(app.UITable, 'data');
            % The analysis name that will be used to save the data.
            analysis = 'TimeLag';
            % Save the data using a BAR_App method.
            analysisComplete(app.BAR_App, data, analysis, 0)
            % Close the app.
            delete(app)
        end

        % Value changed function: Switch
        function Switch_ValueChanged(app, event)
            % Switch_ValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will update the file dropdown selection with the raw or processed data selection.
            % Future Work
            % - None.
            % Dec 2023 - Created by Ben Senderling, bsender@bu.edu
            
            % Empty the current lists.
            app.FileDropDown.Items = {''};
            app.ObjectDropDown.Items = {''};
            app.SignalDropDown.Items = {''};

            % The data is pulled from the BAR app.
            data = getappdata(app.BAR_App.TableFiles, 'data');

            % Get the data type selection from the app.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Check that the data type is present.
            if isfield(data, type)
                % Get the file names.
                files = fieldnames(data.(type));
                % Create an empty cell array.
                fileList = cell(length(files), 1);
                % For each of the files.
                for ind_file = 1:length(files)
                    % Concatenate the file name and the group information. Add it to the list.
                    fileList(ind_file) = join([files{ind_file} ': ' join(data.(type).(files{ind_file}).groups, ' ')],'');
                end
                % Set the list to the dropdown items.
                app.FileDropDown.Items = fileList;
            else
                % If the selected data type doesn't exist create an empty list for the dropdown.
                app.FileDropDown.Items = {''};
            end

            % Trigger the callback for the file dropdown.
            app.FileDropDownValueChanged

        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            % FileDropDownValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will update the object dropdown selection with the file selection.
            % Future Work
            % - None.
            % Dec 2023 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the current file selection.
            file = app.FileDropDown.Value;
            % Remove the group information from the file name.
            file = file(1:strfind(file, ':') - 1);
            
            % Empty the current lists.
            app.ObjectDropDown.Items = {''};
            app.SignalDropDown.Items = {''};

            % The data is pulled from the BAR app.
            data = getappdata(app.BAR_App.TableFiles, 'data');

            % Get the data type selection from the app.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            if ~isfield(data, type)
                return
            end

            % Get the object names and remove the informational ones.
            objs = fieldnames(data.(type).(file));
            objs(strcmp(objs, 'groups')) = [];
            objs(strcmp(objs, 'meta')) = [];
            % Create an empty cell array for the object list.
            objList = cell(length(objs), 1);
            % For each object.
            for ind_obj = 1:length(objs)
                % Check if it has group information.
                if isfield(data.(type).(file).(objs{ind_obj}), 'groups')
                    % If it does concatenate the object name with the group information.
                    objList(ind_obj) = join([objs{ind_obj} ': ' join(data.(type).(file).(objs{ind_obj}).groups, ' ')], '');
                else
                    % Else use just the group name.
                    objList{ind_obj} = objs{ind_obj};
                end
            end

            % Set the object dropdown to the object list.
            app.ObjectDropDown.Items = objList;

            % Trigger the call back to update the signal list.
            app.ObjectDropDownValueChanged
        end

        % Value changed function: ObjectDropDown
        function ObjectDropDownValueChanged(app, event)
            % ObjectDropDownValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will update the signal dropdown selection with the object selection.
            % Future Work
            % - None.
            % Dec 2023 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the current object selection.
            obj = app.ObjectDropDown.Value;
            % Remove the group information if it is present.
            if contains(obj, ':')
                obj = obj(1:strfind(obj, ':') - 1);
            end
            % Get the current file selection.
            file = app.FileDropDown.Value;
            % Remove the group information.
            file = file(1:strfind(file, ':') - 1);

            % Empty the current lists.
            app.SignalDropDown.Items = {''};

            % The data is pulled from the BAR app.
            data = getappdata(app.BAR_App.TableFiles, 'data');

            % Get the data type selection from the app.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end
            
            % Get the signal names.
            sigs = fieldnames(data.(type).(file).(obj).data);
            % Create an empty list.
            sigList = cell(length(sigs), 1);
            % Iterate through the signals.
            for ind_sig = 1:length(sigs)
                % Add the signal name to the list.
                sigList{ind_sig} = sigs{ind_sig};
            end

            % Update the dropdown list with the list.
            app.SignalDropDown.Items = sigList;

            % Trigger the callback for the dimension options.
            app.SignalDropDownValueChanged

            % Check if there is a sampling frequency in the selected data type or in the raw data.
            if isfield(app.Data.(type).(file).(obj), 'freq')
                freq = app.Data.(type).(file).(obj).freq;
            elseif isfield(app.Data.raw.(file).(obj), 'freq')
                freq = app.Data.(type).(file).(obj).freq;
            else
                % If no sampling is found it is set to one.
                freq = 1;
            end
            app.SamplingEditField.Value = freq;

        end

        % Value changed function: SignalDropDown
        function SignalDropDownValueChanged(app, event)
            % SignalDropDownValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will update the dimension spinned with limits for the current signal.
            % Future Work
            % - None.
            % Dec 2023 - Created by Ben Senderling, bsender@bu.edu

            % Get the current signal selection.
            sig = app.SignalDropDown.Value;
            % Get the current object selection.
            obj = app.ObjectDropDown.Value;
            % Remove the group information if it is present.
            if contains(obj, ':')
                obj = obj(1:strfind(obj, ':') - 1);
            end
            % Get the current file selection.
            file = app.FileDropDown.Value;
            % Remove the group information.
            file = file(1:strfind(file, ':') - 1);
            
            % Empty the current lists.
            app.DimensionSpinner.Value = 1;

            % The data is pulled from the BAR app.
            data = getappdata(app.BAR_App.TableFiles, 'data');

            % Get the data type selection from the app.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Get the number of columns for the signal.
            d = size(data.(type).(file).(obj).data.(sig), 2);

            % Set the limits for the spinner.
            app.DimensionSpinner.Limits = [1, d];

            % Update the plot with all the file, object, signal and dimension changes.
            updatePlotBasic(app)

        end

        % Button pushed function: FindfirstfilewithoutresultsButton
        function FindfirstfilewithoutresultsButtonPushed(app, event)
            % FindfirstfilewithoutresultsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will find the first file, using the current signal, without eventing results.
            % Future Work
            % - None.
            % Dec 2023 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the data type selection from the app.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Get the current object selection.
            obj = app.ObjectDropDown.Value;
            % Get the current signal selection.
            sig = app.SignalDropDown.Value;
            % Get the current dimension selection.
            d = app.DimensionSpinner.Value;

            % Get all the file names,
            files = fieldnames(data.(type));
            % Iterate through the file names.
            for ind_file = 1:length(files)
                % Check that there is a resutls type, that it is from Eventing, that the file, object and signal have been processed 
                % and that the signal results have as many dimensions as the selection.
                if ~(isfield(data, 'res') &&...
                        isfield(data.res, 'Eventing') &&...
                        isfield(data.res.Eventing, files{ind_file}) &&...
                        isfield(data.res.Eventing.(files{ind_file}), obj) &&...
                        isfield(data.res.Eventing.(files{ind_file}).(obj).data, sig) &&...
                        size(data.res.Eventing.(files{ind_file}).(obj).data.(sig), 2) > ind_d)
                    % If something was found update the selection and leave the loop.
                    app.FileDropDown.Value = files{ind_file};
                    break
                end
            end
        end

        % Button pushed function: StartEventingButton
        function StartEventingButtonPushed(app, event)
            % StartEventingButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will run the eventing method.
            % Future Work
            % - None.
            % Dec 2023 - Created by Ben Senderling, bsender@bu.edu

            % Print a message that eventing has started.
            printLog(app, '001')

            % Get if the raw or processed data should be used.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Get the file name that is currently selected.
            file = app.FileDropDown.Value;
            % Remove the group information from the file name.
            file = file(1:strfind(file, ':') - 1);
            % Get the object name that is currently selected.
            obj = app.ObjectDropDown.Value;
            % Remove the group information if it was added.
            if contains(obj, ':')
                obj = obj(1:strfind(obj, ':') - 1);
            end
            % Get the signal to use.
            sig = app.SignalDropDown.Value;
            % Get the dimension.
            d = app.DimensionSpinner.Value;

            % Find the eventing function that matches the drop down selection.
            filesProcess = dir(app.BAR_App.FilesProcess.Value{1});
            % Get the load functions for the additional directories if there are more.
            if length(app.BAR_App.FilesProcess.Value) > 1
                for i = 2:length(app.BAR_App.FilesProcess.Value)
                    filesProcess = [filesProcess; dir(app.BAR_App.FilesProcess.Value{i})];
                end
            end

            % Find the index where the filename contains the target file extension and the equipment type and is not an asv-file.
            ind3 = find(contains({filesProcess.name}', app.SelectMethodDropDown.Value) & ~contains({filesProcess.name}', 'asv'), 1);

            % Exit if no file was found.
            if isempty(ind3)
                return
            end

            % Find the index of '.m' which will be present in all m- and mlapp-files.
            ind4 = strfind(filesProcess(ind3).name, '.m');
            
            try
                % Modify the signal using the app selections.
                x = modifySignal(app, app.Data.(type).(file).(obj).data.(sig));

                freq = app.SamplingEditField.Value;

                % Create a name for the eventing module to display.
                name = [file ' ' join(app.Data.(type).(file).groups, ' ') ' ' obj ' ' join(app.Data.(type).(file).(obj).groups, ' ')];
                % Run the peak picking.
                feval(filesProcess(ind3).name(1:ind4 - 1), app, x, freq, name);
                
            catch ME
                % Print a message to the log if the eventing fails for any reason.
                printLog(app, '003', file)
                % Get MATLAB's last error message and display it.
                printLog(app, '004', ME.message)
            end
            % Print a message the eventing has finished.
            printLog(app, '002')
        end

        % Value changed function: UsethesignalmagnitudeCheckBox
        function UsethesignalmagnitudeCheckBoxValueChanged(app, event)
            value = app.UsethesignalmagnitudeCheckBox.Value;
            switch value
                case 0
                    app.DimensionSpinner.Visible = 'on';
                case 1
                    app.DimensionSpinner.Visible = 'off';
            end
            % Update the plot.
            updatePlotBasic(app)
        end

        % Value changed function: DimensionSpinner
        function DimensionSpinnerValueChanged(app, event)
            % Update the plot.
            updatePlotBasic(app)
        end

        % Value changed function: ApplygentlefilterCheckBox
        function ApplygentlefilterCheckBoxValueChanged(app, event)
            % Update the plot.
            updatePlotBasic(app)
        end

        % Value changed function: UsederivativeCheckBox
        function UsederivativeCheckBoxValueChanged(app, event)
            % Update the plot.
            updatePlotBasic(app)
        end

        % Value changed function: NormalizemagnitudeCheckBox
        function NormalizemagnitudeCheckBoxValueChanged(app, event)
            % Update the plot.
            updatePlotBasic(app)
        end

        % Value changed function: SamplingEditField
        function SamplingEditFieldValueChanged(app, event)
            % Update the plot.
            updatePlotBasic(app)
        end

        % Value changed function: CutoffEditField
        function CutoffEditFieldValueChanged(app, event)
            % Update the plot.
            updatePlotBasic(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.EventingLabel = uilabel(app.UIFigure);
            app.EventingLabel.Text = 'Eventing';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @Switch_ValueChanged, true);
            app.Switch.Value = 'Raw';
            app.Version = uilabel(app.UIFigure);
            app.FileDropDownLabel = uilabel(app.UIFigure);
            app.FileDropDownLabel.Text = 'File';
            app.FileDropDown = uidropdown(app.UIFigure);
            app.FileDropDown.Items = {};
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.FileDropDown.Value = {};
            app.ObjectDropDownLabel = uilabel(app.UIFigure);
            app.ObjectDropDownLabel.Text = 'Object';
            app.ObjectDropDown = uidropdown(app.UIFigure);
            app.ObjectDropDown.Items = {};
            app.ObjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectDropDownValueChanged, true);
            app.ObjectDropDown.Value = {};
            app.SignalDropDownLabel = uilabel(app.UIFigure);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.UIFigure);
            app.SignalDropDown.Items = {};
            app.SignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalDropDownValueChanged, true);
            app.SignalDropDown.Value = {};
            app.DimensionSpinnerLabel = uilabel(app.UIFigure);
            app.DimensionSpinnerLabel.Text = 'Dimension';
            app.DimensionSpinner = uispinner(app.UIFigure);
            app.DimensionSpinner.Limits = [1 Inf];
            app.DimensionSpinner.RoundFractionalValues = 'on';
            app.DimensionSpinner.ValueDisplayFormat = '%11.0g';
            app.DimensionSpinner.ValueChangedFcn = createCallbackFcn(app, @DimensionSpinnerValueChanged, true);
            app.DimensionSpinner.Value = 1;
            app.Panel = uipanel(app.UIFigure);
            app.FindfirstfilewithoutresultsButton = uibutton(app.UIFigure, 'push');
            app.FindfirstfilewithoutresultsButton.ButtonPushedFcn = createCallbackFcn(app, @FindfirstfilewithoutresultsButtonPushed, true);
            app.FindfirstfilewithoutresultsButton.Text = 'Find first file without results';
            app.SelectMethodDropDownLabel = uilabel(app.UIFigure);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.UIFigure);
            app.SelectMethodDropDown.Items = {};
            app.SelectMethodDropDown.Value = {};
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
            app.PriorButton = uibutton(app.UIFigure, 'push');
            app.PriorButton.Text = 'Prior';
            app.StartEventingButton = uibutton(app.UIFigure, 'push');
            app.StartEventingButton.ButtonPushedFcn = createCallbackFcn(app, @StartEventingButtonPushed, true);
            app.StartEventingButton.Text = 'Start Eventing';
            app.UsethesignalmagnitudeCheckBox = uicheckbox(app.UIFigure);
            app.UsethesignalmagnitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsethesignalmagnitudeCheckBoxValueChanged, true);
            app.UsethesignalmagnitudeCheckBox.Text = 'Use the signal magnitude';
            app.ApplygentlefilterCheckBox = uicheckbox(app.UIFigure);
            app.ApplygentlefilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplygentlefilterCheckBoxValueChanged, true);
            app.ApplygentlefilterCheckBox.Text = 'Apply gentle filter';
            app.UsederivativeCheckBox = uicheckbox(app.UIFigure);
            app.UsederivativeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsederivativeCheckBoxValueChanged, true);
            app.UsederivativeCheckBox.Text = 'Use derivative';
            app.NormalizemagnitudeCheckBox = uicheckbox(app.UIFigure);
            app.NormalizemagnitudeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizemagnitudeCheckBoxValueChanged, true);
            app.NormalizemagnitudeCheckBox.Text = 'Normalize magnitude';
            app.SamplingEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingEditFieldLabel.Text = 'Sampling';
            app.SamplingEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingEditFieldValueChanged, true);
            app.CutoffEditFieldLabel = uilabel(app.UIFigure);
            app.CutoffEditFieldLabel.Text = 'Cutoff';
            app.CutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.CutoffEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Eventing(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end