classdef process_Eventing_PeakPicker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TextArea                       matlab.ui.control.TextArea
        SequenceQualityLamp            matlab.ui.control.Lamp
        SequenceQualityLampLabel       matlab.ui.control.Label
        MethodSwitch                   matlab.ui.control.Switch
        MethodSwitchLabel              matlab.ui.control.Label
        PeakSeparationSlider           matlab.ui.control.Slider
        PeakSeparationSliderLabel      matlab.ui.control.Label
        CheckBox_8                     matlab.ui.control.CheckBox
        CheckBox_7                     matlab.ui.control.CheckBox
        CheckBox_6                     matlab.ui.control.CheckBox
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox                       matlab.ui.control.CheckBox
        MaxLabel                       matlab.ui.control.Label
        MinLabel                       matlab.ui.control.Label
        NearOFFLabel                   matlab.ui.control.Label
        NearONLabel                    matlab.ui.control.Label
        OFFONLabel                     matlab.ui.control.Label
        ONOFFLabel                     matlab.ui.control.Label
        FindeventswhenselectionchangesCheckBox  matlab.ui.control.CheckBox
        FindEventsButton               matlab.ui.control.Button
        MinimaProminenceMinimumWidthSlider  matlab.ui.control.Slider
        MinimaProminenceMinimumWidthSliderLabel  matlab.ui.control.Label
        MaximaProminenceMinimumWidthSlider  matlab.ui.control.Slider
        MaximaProminenceMinimumWidthSliderLabel  matlab.ui.control.Label
        MinimaProminenceMaximumWidthSlider  matlab.ui.control.Slider
        MinimaProminenceMaximumWidthSliderLabel  matlab.ui.control.Label
        Remove2ndminimapercycleCheckBox  matlab.ui.control.CheckBox
        Remove1stminimapercycleCheckBox  matlab.ui.control.CheckBox
        MaximaProminenceMaximumWidthSlider  matlab.ui.control.Slider
        MaximaProminenceMaximumWidthSliderLabel  matlab.ui.control.Label
        MaximaProminenceMinimumSlider  matlab.ui.control.Slider
        MaximaProminenceMinimumSlider_2Label  matlab.ui.control.Label
        MinimaProminenceMinimumSlider  matlab.ui.control.Slider
        MinimaProminenceMinimumSliderLabel  matlab.ui.control.Label
        MaximaMinimumSlider            matlab.ui.control.Slider
        MinimumMaximumLabel            matlab.ui.control.Label
        MinimaMinimumSlider            matlab.ui.control.Slider
        MinimaMinimumLabel             matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
    end


    properties (Access = private)
        Data % Description
        Freq % Description
        Name % Description
        Var % Description
        Extrema % Description
        EventingApp % Description
    end

    methods (Access = private)

        function setGUILimits(app)
            % setGUILimits(app)
            % inputs - app, required mlapp object.
            % Remarks
            % - This method will set the limits and starting values of the sliders. For most values the minimum, maximum and average of
            %   the time series is used.
            % Future Work
            % - Perhaps the parameters could be tuned to provide better initial results. Or maybe they could be stored in the config 
            %   file for repetitive use with different time series.
            % 2023 Aug - Created by Ben Senderling, bsender@bu.edu.

            % Set the magnitude limits for the minimum.
            app.MinimaMinimumSlider.Limits = [floor(min(app.Data)), ceil(max(app.Data))];
            app.MinimaMinimumSlider.Value = mean(app.Data);
            app.MaximaMinimumSlider.Limits = [floor(min(app.Data)), ceil(max(app.Data))];
            app.MaximaMinimumSlider.Value = mean(app.Data);

            % Set the magnitude limits for the maximum.
            app.MinimaProminenceMinimumSlider.Limits = [0, ceil(max(app.Data) - min(app.Data))];
            app.MinimaProminenceMinimumSlider.Value = ceil(max(app.Data) - min(app.Data))/2;
            app.MaximaProminenceMinimumSlider.Limits = [0, ceil(max(app.Data) - min(app.Data))];
            app.MaximaProminenceMinimumSlider.Value = ceil(max(app.Data) - min(app.Data))/2;

            % Set the peak separation limits.
            app.PeakSeparationSlider.Limits = [0, ceil(3*app.Freq)];
            app.PeakSeparationSlider.Value = 0;

            % Set the minima prominence width limits.
            app.MinimaProminenceMinimumWidthSlider.Limits = [0, ceil(2*app.Freq)];
            app.MinimaProminenceMinimumWidthSlider.Value = 0;
            app.MinimaProminenceMaximumWidthSlider.Limits = [0, ceil(2*app.Freq)];
            app.MinimaProminenceMaximumWidthSlider.Value = ceil(2*app.Freq);

            % Set the maxima prominence width limits.
            app.MaximaProminenceMinimumWidthSlider.Limits = [0, ceil(2*app.Freq)];
            app.MaximaProminenceMinimumWidthSlider.Value = 0;
            app.MaximaProminenceMaximumWidthSlider.Limits = [0, ceil(2*app.Freq)];
            app.MaximaProminenceMaximumWidthSlider.Value = ceil(2*app.Freq);
        end

        function toggleGUI(app, status)
            % toggleGUI(app, status)
            % inputs - app, required mlapp object.
            %        - status, a boolean of 0 or 1.
            % Remarks
            % - This method will is used to turn the different elements of the module off and on. This prevents the user from adjusting
            %   them while findpeaks is still running. For smaller time series it runs very quickly and the effect is not noticeable. 
            %   For longer time series is will slow down. Additional adjustments by the user made before it finishes could back up the 
            %   computer.
            % Future Work
            % - If additional GUI elements are added they will need to be added here. A good way to check this is to turn 'Manual' on 
            %   in the app so you can clearly see which are not covered by this method.
            % 2023 Aug - Created by Ben Senderling, bsender@bu.edu.

            % Checkboxes
            app.Remove1stminimapercycleCheckBox.Enable = status;
            app.Remove2ndminimapercycleCheckBox.Enable = status;
            app.CheckBox.Enable = status;
            app.CheckBox_2.Enable = status;
            app.CheckBox_3.Enable = status;
            app.CheckBox_4.Enable = status;
            app.CheckBox_5.Enable = status;
            app.CheckBox_6.Enable = status;
            app.CheckBox_7.Enable = status;
            app.CheckBox_8.Enable = status;
            app.FindeventswhenselectionchangesCheckBox.Enable = status;

            % Height sliders
            app.MaximaMinimumSlider.Enable = status;
            app.MinimaMinimumSlider.Enable = status;

            % Width sliders
            app.PeakSeparationSlider.Enable = status;
            app.MinimaProminenceMinimumWidthSlider.Enable = status;
            app.MaximaProminenceMinimumWidthSlider.Enable = status;
            app.MinimaProminenceMaximumWidthSlider.Enable = status;
            app.MaximaProminenceMaximumWidthSlider.Enable = status;

            % Prominence sliders
            app.MaximaProminenceMinimumSlider.Enable = status;
            app.MinimaProminenceMinimumSlider.Enable = status;

            % Buttons
            app.FindEventsButton.Enable = status;
        end

        function updatePlot(app, key)
            % updatePlot(app, key)
            % inputs - app, required mlapp object.
            %        - key, a string indicating the key that was pressed.
            % Remarks
            % - This method updates the plot in the GUI. It displays the raw data and the extrema. It is also where the key presses to 
            %   change the window are effected.
            % Future Work
            % - The method will not plot any NaN placeholders the user puts in.
            % 2023 Aug - Created by Ben Senderling, bsender@bu.edu.

            % Create arrays so the extrema can be plotted sequentially. This is helpfull when reviewing many files. The pattern of the
            % line helps observe the extrema and determine if they are correct.
            allLocs = [app.Extrema.locsmin; app.Extrema.locsmax];
            allPeaks = [app.Extrema.pksmin; app.Extrema.pksmax];
            [allLocs, I] = sort(allLocs);
            allPeaks = allPeaks(I);

            % Replot data with the same axes
            H = figure('Visible', 'Off');
            frame = (1:length(app.Data))';
            ax(1) = subplot(3, 1, 1:2);
            plot(frame, app.Data, 'k',...
                app.Extrema.locsmax, app.Extrema.pksmax, 'bo',...
                app.Extrema.locsmin, app.Extrema.pksmin, 'go',...
                app.Var.ON, app.Data(app.Var.ON), 'kx',...
                app.Var.OFF, app.Data(app.Var.OFF), 'ko',...
                allLocs, allPeaks, '--k')
            axis tight
            xticklabels([])
            yticklabels([])

            % Plot the difference between events with the same x axis as the data.
            ax(2) = subplot(3, 1, 3);
            diffmin = diff(app.Extrema.locsmax)/abs(max(diff(app.Extrema.locsmax)));
            diffmax = diff(app.Extrema.locsmin)/abs(max(diff(app.Extrema.locsmin)));
            marker = [min([diffmin; diffmax]); max([diffmin; diffmax])];
            plot(app.Extrema.locsmax(1:end-1), diffmin, '.-b',...
                app.Extrema.locsmin(1:end-1), diffmax,'.-g')
            axis tight
            xlim([frame(1), frame(end)])
            ylim tight
            xticklabels([]);
            yticklabels([]);

            % Retrieve axes from figure so the window doesn't continually zoom out when the user has zoomed in.
            if ~isempty(app.Panel.Children)
                a = app.Panel.Children(2).XLim;
                b = app.Panel.Children(2).YLim;

                subplot(3, 1, 1:2)
                xlim(a);
                ylim(b);

                subplot(3, 1, 3)
                xlim(a);

            end

            linkaxes(ax, 'x')

            % Save the current window size so it can be used to adjust the view.
            axisCurrent = xlim;

            switch key
                % Left arrow key. Move the window left.
                case 'leftarrow'
                    % Change the figure scales.
                    subplot(3, 1, 1:2)
                    xlim([axisCurrent(1) - diff(axisCurrent)/4, axisCurrent(2) - diff(axisCurrent)/4])
                    subplot(3, 1, 3)
                    xlim([axisCurrent(1) - diff(axisCurrent)/4, axisCurrent(2) - diff(axisCurrent)/4])
                % Right arrow key. Move the window right
                case 'rightarrow'
                    % Change the figure scales.
                    subplot(3, 1, 1:2)
                    xlim([axisCurrent(1) + diff(axisCurrent)/4, axisCurrent(2) + diff(axisCurrent)/4])
                    subplot(3, 1, 3)
                    xlim([axisCurrent(1) + diff(axisCurrent)/4, axisCurrent(2) + diff(axisCurrent)/4])
                % Down arrow. Zoom out.
                case 'downarrow'
                    % Change the figure scales.
                    subplot(3, 1, 1:2)
                    xlim([axisCurrent(1) - diff(axisCurrent)/4, axisCurrent(2) + diff(axisCurrent)/4])
                    subplot(3, 1, 3)
                    xlim([axisCurrent(1) - diff(axisCurrent)/4, axisCurrent(2) + diff(axisCurrent)/4])
                % Up arrow. Zoom in.
                case 'uparrow'
                    % Change the figure scales.
                    subplot(3, 1, 1:2)
                    xlim([axisCurrent(1) + diff(axisCurrent)/4, axisCurrent(2) - diff(axisCurrent)/4])
                    subplot(3, 1, 3)
                    xlim([axisCurrent(1) + diff(axisCurrent)/4, axisCurrent(2) - diff(axisCurrent)/4])
            end

            % Remove existing figures.
            app.Panel.Children.delete;

            % Copy both figures to the panel in the app.
            copyobj(ax(1), app.Panel)
            copyobj(ax(2), app.Panel)

            % Update the sequence quality indicator light.
            checkSequencing(app)

        end

        function checkSequencing(app)
            % checkSequencing(app)
            % inputs - app, required mlapp object.
            % Remarks
            % - This method checks the sequencing of the events. It checks that the minima and maxima are alternating. If they are 
            %   without any error the indicator will be green. If an error is found it will be red. If no extrema have been found it 
            %   will be yellow.
            % Future Work
            % - Currently the events have to be strictly alternating. There is no affordance for other patterns.
            % - There may be a more elegant way of checking the ordering than the iterative method here.
            % 2023 Aug - Created by Ben Senderling, bsender@bu.edu.

            % If no events have been found make the indicator yellow.
            if isempty(app.Extrema.pksmax) && isempty(app.Extrema.pksmin)
                app.SequenceQualityLamp.Color = 'y';
            elseif (isempty(app.Extrema.pksmax) && isempty(app.Extrema.pksmin)) && app.Extrema.pksmax(1, 1) < app.Extrema.pksmin(1, 1)
                % If a maxima occurs first.
                ii = 1;
                % Iterate through all the events to check they are sequential.
                while ii < length(app.Extrema.pksmax) && ii < length(app.Extrema.pksmin)
                    % If the current maxima is less than the current minima, and the current minima is less than the next maxima.
                    if (app.Extrema.pksmax(ii, 1) < app.Extrema.pksmin(ii, 1)) && (app.Extrema.pksmin(ii, 1) < app.Extrema.pksmax(ii + 1, 1)) && (app.Extrema.pksmax(ii + 1, 1) < app.Extrema.pksmin(ii + 1, 1))
                        ii = ii + 1;
                        app.SequenceQualityLamp.Color = 'g';
                    else
                        app.SequenceQualityLamp.Color = 'r';
                        % If an error is found break and do not proceed through the remaining.
                        break
                    end
                end
            else
                % If a minima occurs first.
                ii = 1;
                % Iterate through all the events to check they are sequential.
                while ii < length(app.Extrema.pksmax) && ii < length(app.Extrema.pksmin)
                    % If the current maxima is greater than the current minima, and the next minima is greater than the current maxima.
                    if (app.Extrema.pksmax(ii, 1) > app.Extrema.pksmin(ii, 1)) && (app.Extrema.pksmin(ii + 1, 1) > app.Extrema.pksmax(ii, 1)) && (app.Extrema.pksmax(ii + 1, 1) > app.Extrema.pksmin(ii + 1, 1))
                        ii = ii + 1;
                        app.SequenceQualityLamp.Color = 'g';
                    else
                        app.SequenceQualityLamp.Color = 'r';
                        % If an error is found break and do not proceed through the remaining.
                        break
                    end
                end
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, moduleapp, x, freq, name, varargin)

            % Save the main app.
            app.EventingApp = moduleapp;

            % Save the data to the app.
            app.Data = x;

            % Save the sampling frequency to the app.
            app.Freq = freq;

            % Save the figure name to the app.
            app.Name = name;

            % Set the panel name as the figure name.
            app.Panel.Title = name;

            % Get the variable input arguements and save them to the app.
            if length(varargin) == 1
                app.Var.ON = round(varargin{1});
                app.Var.OFF = [];
            else
                app.Var.ON = [];
                app.Var.OFF = [];
            end
            if length(varargin) == 2
                app.Var.ON = round(varargin{1});
                app.Var.OFF = round(varargin{2});
            else
                app.Var.ON = [];
                app.Var.OFF = [];
            end

            % Create empty extrema arrays
            app.Extrema.locsmin=[];
            app.Extrema.pksmin=[];
            app.Extrema.locsmax=[];
            app.Extrema.pksmax=[];

            % Set the limits for the sliders.
            setGUILimits(app);

            % Create the initial plot.
            updatePlot(app, 0);

            % Create the content for the text array.
            app.TextArea.Value = {'The manual selection can be made with the following keys and their actions.';...
                'F, adds a minima within a window sized at 1% of the sampling frequency.';...
                'J, adds a maxima within a window sized at 1% of the sampling frequency.';...
                'Delete, removes all extrema within a window sized at 4% of the sampling frequency.';...
                'N, adds a placeholder as a NaN value.';...
                'left arrow, moves the window to the left by 1/4th of the current window.';...
                'right arrow, moves the window to the right by 1/4th of the current window.';...
                'up arrow, zooms the window in by a factor of 2.';...
                'down arrow, zooms the window out by a factor of 2.'};

        end

        % Button pushed function: FindEventsButton
        function FindEventsButtonPushed(app, event)
            % FindEventsButtonPushed(app, event)
            % inputs - app, required mlapp object.
            %        - event, information about the app from the button press.
            % Remarks
            % - This method runs MATLAB's native findpeaks function using the current values of the various sliders and checkboxes. 
            %   The values of the sliders are used directly in findpeaks. The checkboxes are then used to modify its results.
            % Future Work
            % - If other GUI elements are added they will need to be added here.
            % 2023 Aug - Created by Ben Senderling, bsender@bu.edu.

            % Turn the GUI off so the user doesn't click exessively.
            toggleGUI(app, 0)

            % Run findpeaks to get all minima. For the minima the data is inverted and the peaks found.
            [app.Extrema.pksmin, app.Extrema.locsmin] = findpeaks(-app.Data,...
                'MinPeakHeight', -app.MinimaMinimumSlider.Value,...
                'MinPeakDistance', app.PeakSeparationSlider.Value,...
                'MinPeakProminence', app.MinimaProminenceMinimumSlider.Value,...
                'WidthReference', 'halfprom',...
                'MinPeakWidth', app.MinimaProminenceMinimumWidthSlider.Value, ...
                'MaxPeakWidth', app.MinimaProminenceMaximumWidthSlider.Value);
            % Because the data was inverted the peaks have to be re-inverted.
            app.Extrema.pksmin = -app.Extrema.pksmin;

            % Run findpeaks to get all maxima.
            [app.Extrema.pksmax, app.Extrema.locsmax] = findpeaks(app.Data,...
                'MinPeakHeight', app.MaximaMinimumSlider.Value,...
                'MinPeakDistance', app.PeakSeparationSlider.Value,...
                'MinPeakProminence', app.MaximaProminenceMinimumSlider.Value,...
                'WidthReference','halfprom',...
                'MinPeakWidth', app.MaximaProminenceMinimumWidthSlider.Value,...
                'MaxPeakWidth',app.MaximaProminenceMaximumWidthSlider.Value);

            % If the checkbox is checked remove all minima between on and off events.
            if app.CheckBox.Value == 1
                app.Extrema.pksmin(app.Extrema.locsmin < app.Var.ON(1)) = [];
                app.Extrema.locsmin(app.Extrema.locsmin < app.Var.ON(1)) = [];
                app.Extrema.pksmin(app.Extrema.locsmin > app.Var.OFF(end)) = [];
                app.Extrema.locsmin(app.Extrema.locsmin > app.Var.OFF(end)) = [];
                for i = 1:length(app.Var.ON) - 1
                    app.Extrema.pksmin(app.Extrema.locsmin > app.Var.OFF(i) & app.Extrema.locsmin < app.Var.ON(i + 1)) = [];
                    app.Extrema.locsmin(app.Extrema.locsmin > app.Var.OFF(i) & app.Extrema.locsmin < app.Var.ON(i + 1)) = [];
                end
            end

            % If the checkbox is checked remove all maxima between on and off events.
            if app.CheckBox_2.Value == 1
                app.Extrema.pksmax(app.Extrema.locsmax < app.Var.ON(1)) = [];
                app.Extrema.locsmax(app.Extrema.locsmax < app.Var.ON(1)) = [];
                app.Extrema.pksmax(app.Extrema.locsmax > app.Var.OFF(end)) = [];
                app.Extrema.locsmax(app.Extrema.locsmax > app.Var.OFF(end)) = [];
                for i = 1:length(app.Var.ON) - 1
                    app.Extrema.pksmax(app.Extrema.locsmax > app.Var.OFF(i) & app.Extrema.locsmax<app.Var.ON(i + 1)) = [];
                    app.Extrema.locsmax(app.Extrema.locsmax > app.Var.OFF(i) & app.Extrema.locsmax<app.Var.ON(i + 1)) = [];
                end
            end

            % If the checkbox is checked remove all minima between off and on events.
            if app.CheckBox_3.Value == 1
                app.Extrema.pksmin(app.Extrema.locsmin < app.Var.OFF(1)) = [];
                app.Extrema.locsmin(app.Extrema.locsmin < app.Var.OFF(1)) = [];
                app.Extrema.pksmin(app.Extrema.locsmin > app.Var.ON(end)) = [];
                app.Extrema.locsmin(app.Extrema.locsmin > app.Var.ON(end)) = [];
                for i = 1:length(app.Var.OFF)
                    app.Extrema.pksmin(app.Extrema.locsmin > app.Var.ON(i) & app.Extrema.locsmin < app.Var.OFF(i)) = [];
                    app.Extrema.locsmin(app.Extrema.locsmin > app.Var.ON(i) & app.Extrema.locsmin < app.Var.OFF(i)) = [];
                end
            end

            % If the checkbox is checked remove all maxima between off and on events.
            if app.CheckBox_4.Value == 1
                app.Extrema.pksmax(app.Extrema.locsmax < app.Var.OFF(1)) = [];
                app.Extrema.locsmax(app.Extrema.locsmax < app.Var.OFF(1)) = [];
                app.Extrema.pksmax(app.Extrema.locsmax > app.Var.ON(end)) = [];
                app.Extrema.locsmax(app.Extrema.locsmax > app.Var.ON(end)) = [];
                for i = 1:length(app.Var.OFF)
                    app.Extrema.pksmax(app.Extrema.locsmax > app.Var.ON(i) & app.Extrema.locsmax < app.Var.OFF(i)) = [];
                    app.Extrema.locsmax(app.Extrema.locsmax > app.Var.ON(i) & app.Extrema.locsmax < app.Var.OFF(i)) = [];
                end
            end

            % If the checkbox is checked remove minima within the peak separation distance to each on event.
            if app.CheckBox_5.Value == 1
                for i = 1:length(app.Var.ON)
                    j = 1;
                    while j <= length(app.Extrema.locsmin)
                        if abs(app.Extrema.locsmin(j) - app.Var.ON(i)) < app.PeakSeparationSlider.Value
                            app.Extrema.pksmin(j) = [];
                            app.Extrema.locsmin(j) = [];
                        end
                        j = j + 1;
                    end
                end
            end

            % If the checkbox is checked remove minima within the peak separation distance to each off event.
            if app.CheckBox_7.Value == 1
                for i = 1:length(app.Var.OFF)
                    j = 1;
                    while j <= length(app.Extrema.locsmin)
                        if abs(app.Extrema.locsmin(j) - app.Var.OFF(i)) < app.PeakSeparationSlider.Value
                            app.Extrema.pksmin(j) = [];
                            app.Extrema.locsmin(j) = [];
                        end
                        j = j + 1;
                    end
                end
            end

            % If the checkbox is checked remove maxima within the peak separation distance to each on event.
            if app.CheckBox_6.Value == 1
                for i = 1:length(app.Var.ON)
                    j = 1;
                    while j <= length(app.Extrema.locsmax)
                        if abs(app.Extrema.locsmax(j) - app.Var.ON(i)) < app.PeakSeparationSlider.Value
                            app.Extrema.pksmax(j) = [];
                            app.Extrema.locsmax(j) = [];
                        end
                        j = j + 1;
                    end
                end
            end

            % If the checkbox is checked remove maxima within the peak separation distance to each off event.
            if app.CheckBox_8.Value == 1
                for i = 1:length(app.Var.OFF)
                    j = 1;
                    while j <= length(app.Extrema.locsmax)
                        if abs(app.Extrema.locsmax(j) - app.Var.OFF(i)) < app.PeakSeparationSlider.Value
                            app.Extrema.pksmax(j) = [];
                            app.Extrema.locsmax(j) = [];
                        end
                        j = j + 1;
                    end
                end
            end

            % Remove the 1st minima when there are multiple occuring between maxima.
            if app.Remove1stminimapercycleCheckBox.Value == 1
                for i = 1:length(app.Extrema.locsmax) - 1
                    ind = find(app.Extrema.locsmin > app.Extrema.locsmax(i) & app.Extrema.locsmin < app.Extrema.locsmax(i + 1));
                    if length(ind) >= 2
                        app.Extrema.locsmin(ind(1:length(ind)-1)) = [];
                        app.Extrema.pksmin(ind(1:length(ind)-1)) = [];
                    end
                end
            end

            % Remove the 2nd minima when there are multiple occuring between maxima.
            if app.Remove2ndminimapercycleCheckBox.Value == 1
                for i = 1:length(app.Extrema.locsmax) - 1
                    ind = find(app.Extrema.locsmin > app.Extrema.locsmax(i) & app.Extrema.locsmin < app.Extrema.locsmax(i + 1));
                    if length(ind) >= 2
                        app.Extrema.locsmin(ind(2:length(ind))) = [];
                        app.Extrema.pksmin(ind(2:length(ind))) = [];
                    end
                end
            end

            % Update the figure.
            updatePlot(app, 0)

            % Turn the GUI on to indicate the method is done and the GUI can be used again.
            toggleGUI(app, 1)

        end

        % Value changed function: Remove1stminimapercycleCheckBox
        function Remove1stminimapercycleCheckBoxValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: Remove2ndminimapercycleCheckBox
        function Remove2ndminimapercycleCheckBoxValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: MinimaMinimumSlider
        function MinimaMinimumSliderValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: MaximaMinimumSlider
        function MaximaMinimumSliderValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: MinimaProminenceMinimumSlider
        function MinimaProminenceMinimumSliderValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: MaximaProminenceMinimumSlider
        function MaximaProminenceMinimumSliderValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: MaximaProminenceMaximumWidthSlider
        function MaximaProminenceMaximumWidthSliderValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: MinimaProminenceMaximumWidthSlider
        function MinimaProminenceMaximumWidthSliderValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: MaximaProminenceMinimumWidthSlider
        function MaximaProminenceMinimumWidthSliderValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: MinimaProminenceMinimumWidthSlider
        function MinimaProminenceMinimumWidthSliderValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: PeakSeparationSlider
        function PeakSeparationSliderValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            if app.FindeventswhenselectionchangesCheckBox.Value == 1
                app.FindEventsButtonPushed;
            end
        end

        % Value changed function: MethodSwitch
        function MethodSwitchValueChanged(app, event)
            value = app.MethodSwitch.Value;

            switch value
                case 'Auto'
                    toggleGUI(app, 1)
                    app.TextArea.Visible = 'off';
                case 'Manual'
                    toggleGUI(app, 0)
                    app.TextArea.Visible = 'on';
            end

        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            % UIFigureWindowKeyPress(app, event)
            % inputs - app, required mlapp object.
            %        - event, information about the app from the button press.
            % Remarks
            % - This method handles key presses from the user. They are only acted on if the module is in 'Manual' mode.
            % Future Work
            % - None.
            % 2023 Aug - Created by Ben Senderling, bsender@bu.edu.
            
            % If 'Manual' is not selected exit the method.
            if strcmp(app.MethodSwitch.Value, 'Auto')
                return
            end

            % Get the string of the key the user pressed.
            key = event.Key;

            % Window size for selecting and deleteing extrema.
            win = round(0.04*app.Freq);

            % Check button pressed.
            switch key
                % Arrow key presses will change the plot window.
                case {'leftarrow', 'rightarrow', 'downarrow', 'uparrow'}
                    updatePlot(app, key)
                % Delete. Remove extrema within a window.
                case 'delete'
                    % The handle visibility for the parent to the panel is the figure and needs to be on.
                    app.Panel.Parent.HandleVisibility = 'on';
                    % Set the panel to the current figure.
                    set(0, 'CurrentFigure', app.Panel.Parent)
                    % Allow the user to select a point.
                    [x, ~] = ginput(1);
                    % Turn the panel's parent handle visibility back to off.
                    app.Panel.Parent.HandleVisibility = 'off';
                    % Round the number so it can be used as an index.
                    x = round(x);
                    % Find an minima within a window and remove them.
                    app.Extrema.pksmin(app.Extrema.locsmin(:, 1) < x + win & app.Extrema.locsmin(:, 1) > x - win) = [];
                    app.Extrema.locsmin(app.Extrema.locsmin(:, 1) < x + win & app.Extrema.locsmin(:, 1) > x - win) = [];
                    % Find any maxima within a window and remove them.
                    app.Extrema.pksmax(app.Extrema.locsmax(:, 1) < x + win & app.Extrema.locsmax(:, 1) > x - win) = [];
                    app.Extrema.locsmax(app.Extrema.locsmax(:, 1) < x + win & app.Extrema.locsmax(:, 1) > x - win) = [];
                    % Update the plot.
                    updatePlot(app, 0)
                % f key. Add a minima.
                case 'f'
                    % The handle visibility for the parent to the panel is the figure and needs to be on.
                    app.Panel.Parent.HandleVisibility = 'on';
                    % Set the panel to the current figure.
                    set(0, 'CurrentFigure', app.Panel.Parent)
                    % Allow the user to select a point.
                    [x, ~] = ginput(1);
                    % Turn the panel's parent handle visibility back to off.
                    app.Panel.Parent.HandleVisibility = 'off';
                    % Round the number so it can be used as an index.
                    x = round(x);
                    % If the number was off the left side of the figure set it to the first visible index in the figure.
                    x(x < 1) = win/4 + 1;
                    % If the number was off the right side of the figure set it to the last visible index in the figure.
                    x(x > length(app.Data) - win/4) = length(app.Data) - win/4;
                    % Find the minimum within a window arround the point.
                    [M, I] = min(app.Data(floor(x - win/4):ceil(x + win/4)));
                    % Add the minima to the extrema list.
                    app.Extrema.locsmin(end + 1) = I + x - win/4 - 1;
                    app.Extrema.pksmin(end + 1) = M;
                    % Sort the extrema so the plot appears correct.
                    [app.Extrema.locsmin, I] = sort(app.Extrema.locsmin);
                    app.Extrema.pksmin = app.Extrema.pksmin(I);
                    % Update the plot.
                    updatePlot(app, 0)
                % j key. Add a maxima.
                case 'j'
                    % The handle visibility for the parent to the panel is the figure and needs to be on.
                    app.Panel.Parent.HandleVisibility = 'on';
                    % Set the panel to the current figure.
                    set(0, 'CurrentFigure', app.Panel.Parent)
                    % Allow the user to select a point.
                    [x, ~] = ginput(1);
                    % Turn the panel's parent handle visibility back to off.
                    app.Panel.Parent.HandleVisibility = 'off';
                    % Round the number so it can be used as an index.
                    x = round(x);
                    % If the number was off the left side of the figure set it to the first visible index in the figure.
                    x(x < 1) = win/4 + 1;
                    % If the number was off the right side of the figure set it to the last visible index in the figure.
                    x(x > length(app.Data) - win/4) = length(app.Data) - win/4;
                    % Find the maximum within a window arround the point.
                    [M, I] = max(app.Data(floor(x - win/4):ceil(x + win/4)));
                    % Add the maxima to the extrema list.
                    app.Extrema.locsmax(end + 1) = I + x - win/4 - 1;
                    app.Extrema.pksmax(end + 1) = M;
                    % Sort the extrema so the plot appears correct.
                    [app.Extrema.locsmax, I] = sort(app.Extrema.locsmax);
                    app.Extrema.pksmax = app.Extrema.pksmax(I);
                    % Update the plot.
                    updatePlot(app, 0)
                % n key. Add a NaN placeholder.
                case 'n'
                    % The handle visibility for the parent to the panel is the figure and needs to be on.
                    app.Panel.Parent.HandleVisibility = 'on';
                    % Set the panel to the current figure.
                    set(0, 'CurrentFigure', app.Panel.Parent)
                    % Allow the user to select a point.
                    [x, ~] = ginput(1);
                    % Turn the panel's parent handle visibility back to off.
                    app.Panel.Parent.HandleVisibility = 'off';
                    % Round the number so it can be used as an index.
                    x = round(x);
                    % Add the point to the NaN locations.
                    app.Extrema.NaNLoc(end + 1, 1) = x;
                    % Update the plot.
                    updatePlot(app, 0)
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % Get if the Raw or Processed data should be used.
            switch app.EventingApp.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end
            % Get the file name that is currently selected.
            file = app.EventingApp.FileDropDown.Value;
            % Remove the group information from the file name.
            file = file(1:strfind(file, ':') - 1);
            % Get the object name that is currently selected.
            obj = app.EventingApp.ObjectDropDown.Value;
            % Remove the group information if it was added.
            if contains(obj, ':')
                obj = obj(1:strfind(obj, ':') - 1);
            end
            % Get the signal to use.
            sig = app.EventingApp.SignalDropDown.Value;

            if app.EventingApp.UsethesignalmagnitudeCheckBox.Value == 0
                d = app.EventingApp.DimensionSpinner.Value;
                app.EventingApp.Data.res.Eventing.(file).(obj).data.(sig).pksmin{1, d} = app.Extrema.pksmin;
                app.EventingApp.Data.res.Eventing.(file).(obj).data.(sig).pksmax{1, d} = app.Extrema.pksmax;
                app.EventingApp.Data.res.Eventing.(file).(obj).data.(sig).locsmin{1, d} = app.Extrema.locsmin;
                app.EventingApp.Data.res.Eventing.(file).(obj).data.(sig).locsmax{1, d} = app.Extrema.locsmax;
            else
                d = size(app.EventingApp.Data.(type).(file).(obj).data.(sig), 2);
                for ind_d = 1:d
                    app.EventingApp.Data.res.Eventing.(file).(obj).data.(sig).pksmin{1, ind_d} = app.Extrema.pksmin;
                    app.EventingApp.Data.res.Eventing.(file).(obj).data.(sig).pksmax{1, ind_d} = app.Extrema.pksmax;
                    app.EventingApp.Data.res.Eventing.(file).(obj).data.(sig).locsmin{1, ind_d} = app.Extrema.locsmin;
                    app.EventingApp.Data.res.Eventing.(file).(obj).data.(sig).locsmax{1, ind_d} = app.Extrema.locsmax;
                end
            end

            updatePlotBasic(app.EventingApp)
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.MinimaMinimumLabel = uilabel(app.UIFigure);
            app.MinimaMinimumLabel.Text = {'Minima'; 'Minimum'};
            app.MinimaMinimumSlider = uislider(app.UIFigure);
            app.MinimaMinimumSlider.ValueChangedFcn = createCallbackFcn(app, @MinimaMinimumSliderValueChanged, true);
            app.MinimumMaximumLabel = uilabel(app.UIFigure);
            app.MinimumMaximumLabel.Text = {'Maxima'; 'Minimum'};
            app.MaximaMinimumSlider = uislider(app.UIFigure);
            app.MaximaMinimumSlider.ValueChangedFcn = createCallbackFcn(app, @MaximaMinimumSliderValueChanged, true);
            app.MinimaProminenceMinimumSliderLabel = uilabel(app.UIFigure);
            app.MinimaProminenceMinimumSliderLabel.Text = {'Minima'; 'Prominence'; 'Minimum'};
            app.MinimaProminenceMinimumSlider = uislider(app.UIFigure);
            app.MinimaProminenceMinimumSlider.ValueChangedFcn = createCallbackFcn(app, @MinimaProminenceMinimumSliderValueChanged, true);
            app.MaximaProminenceMinimumSlider_2Label = uilabel(app.UIFigure);
            app.MaximaProminenceMinimumSlider_2Label.Text = {'Maxima'; 'Prominence'; 'Minimum'};
            app.MaximaProminenceMinimumSlider = uislider(app.UIFigure);
            app.MaximaProminenceMinimumSlider.ValueChangedFcn = createCallbackFcn(app, @MaximaProminenceMinimumSliderValueChanged, true);
            app.MaximaProminenceMaximumWidthSliderLabel = uilabel(app.UIFigure);
            app.MaximaProminenceMaximumWidthSliderLabel.Text = 'Maxima Prominence Maximum Width';
            app.MaximaProminenceMaximumWidthSlider = uislider(app.UIFigure);
            app.MaximaProminenceMaximumWidthSlider.ValueChangedFcn = createCallbackFcn(app, @MaximaProminenceMaximumWidthSliderValueChanged, true);
            app.Remove1stminimapercycleCheckBox = uicheckbox(app.UIFigure);
            app.Remove1stminimapercycleCheckBox.ValueChangedFcn = createCallbackFcn(app, @Remove1stminimapercycleCheckBoxValueChanged, true);
            app.Remove1stminimapercycleCheckBox.Text = 'Remove 1st minima per cycle';
            app.Remove2ndminimapercycleCheckBox = uicheckbox(app.UIFigure);
            app.Remove2ndminimapercycleCheckBox.ValueChangedFcn = createCallbackFcn(app, @Remove2ndminimapercycleCheckBoxValueChanged, true);
            app.Remove2ndminimapercycleCheckBox.Text = 'Remove 2nd minima per cycle';
            app.MinimaProminenceMaximumWidthSliderLabel = uilabel(app.UIFigure);
            app.MinimaProminenceMaximumWidthSliderLabel.Text = 'Minima Prominence Maximum Width';
            app.MinimaProminenceMaximumWidthSlider = uislider(app.UIFigure);
            app.MinimaProminenceMaximumWidthSlider.ValueChangedFcn = createCallbackFcn(app, @MinimaProminenceMaximumWidthSliderValueChanged, true);
            app.MaximaProminenceMinimumWidthSliderLabel = uilabel(app.UIFigure);
            app.MaximaProminenceMinimumWidthSliderLabel.Text = 'Maxima Prominence Minimum Width';
            app.MaximaProminenceMinimumWidthSlider = uislider(app.UIFigure);
            app.MaximaProminenceMinimumWidthSlider.ValueChangedFcn = createCallbackFcn(app, @MaximaProminenceMinimumWidthSliderValueChanged, true);
            app.MinimaProminenceMinimumWidthSliderLabel = uilabel(app.UIFigure);
            app.MinimaProminenceMinimumWidthSliderLabel.Text = 'Minima Prominence Minimum Width';
            app.MinimaProminenceMinimumWidthSlider = uislider(app.UIFigure);
            app.MinimaProminenceMinimumWidthSlider.ValueChangedFcn = createCallbackFcn(app, @MinimaProminenceMinimumWidthSliderValueChanged, true);
            app.FindEventsButton = uibutton(app.UIFigure, 'push');
            app.FindEventsButton.ButtonPushedFcn = createCallbackFcn(app, @FindEventsButtonPushed, true);
            app.FindEventsButton.Text = 'Find Events';
            app.FindeventswhenselectionchangesCheckBox = uicheckbox(app.UIFigure);
            app.FindeventswhenselectionchangesCheckBox.Text = 'Find events when selection changes.';
            app.ONOFFLabel = uilabel(app.UIFigure);
            app.ONOFFLabel.Text = 'ON -> OFF';
            app.OFFONLabel = uilabel(app.UIFigure);
            app.OFFONLabel.Text = 'OFF -> ON';
            app.NearONLabel = uilabel(app.UIFigure);
            app.NearONLabel.Text = 'Near ON';
            app.NearOFFLabel = uilabel(app.UIFigure);
            app.NearOFFLabel.Text = 'Near OFF';
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Text = 'Max';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.UIFigure);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.UIFigure);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.UIFigure);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '';
            app.PeakSeparationSliderLabel = uilabel(app.UIFigure);
            app.PeakSeparationSliderLabel.Text = 'Peak Separation';
            app.PeakSeparationSlider = uislider(app.UIFigure);
            app.PeakSeparationSlider.ValueChangedFcn = createCallbackFcn(app, @PeakSeparationSliderValueChanged, true);
            app.MethodSwitchLabel = uilabel(app.UIFigure);
            app.MethodSwitchLabel.Text = 'Method';
            app.MethodSwitch = uiswitch(app.UIFigure, 'slider');
            app.MethodSwitch.Items = {'Auto', 'Manual'};
            app.MethodSwitch.ValueChangedFcn = createCallbackFcn(app, @MethodSwitchValueChanged, true);
            app.MethodSwitch.Value = 'Auto';
            app.SequenceQualityLampLabel = uilabel(app.UIFigure);
            app.SequenceQualityLampLabel.Text = {'Sequence'; 'Quality'};
            app.SequenceQualityLamp = uilamp(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Eventing_PeakPicker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end