classdef process_Groupings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AveragesignalsbeforemergeSwitch  matlab.ui.control.Switch
        AveragesignalsbeforemergeSwitchLabel  matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        CombineobjectswithsimilargroupsButton  matlab.ui.control.Button
        MovetagstogroupsButton       matlab.ui.control.Button
        EntertagstoremovefromsignalsEditField  matlab.ui.control.EditField
        EntertagstoremovefromsignalsEditFieldLabel  matlab.ui.control.Label
        RemovegroupsatanindexButton  matlab.ui.control.Button
        SelectthegroupwithdatestoconvertSpinner  matlab.ui.control.Spinner
        SelectthegroupwithdatestoconvertSpinnerLabel  matlab.ui.control.Label
        SelectthegroupdatesbelongtoSpinner  matlab.ui.control.Spinner
        SelectthegroupdatesbelongtoSpinnerLabel  matlab.ui.control.Label
        RemoveduplicateswithincolumnsButton  matlab.ui.control.Button
        RemoveduplicateswithinrowsButton  matlab.ui.control.Button
        Version                      matlab.ui.control.Label
        SaveResultsandExitButton     matlab.ui.control.Button
        ConvertDatesButton           matlab.ui.control.Button
        SelectdatetagformatDropDown  matlab.ui.control.DropDown
        SelectdatetagformatDropDownLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        Image                        matlab.ui.control.Image
        GroupingsLabel               matlab.ui.control.Label
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of
            %           the error and organize the errors available.
            %         - newText, a string that may be inserted into the
            %                    message.
            % Remarks
            % - This method will print messages to the log of the BAR App.
            %   The error codes are unique to each message so they can be
            %   found and referenced easier.
            % - The method is public and is acessible outside of the BAR
            %   App. It is used in some of the example analysis and process
            %   scripts.
            % Future Work
            % - This log should be printed and saved to a file location
            %   each time the BAR App is run.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the current text within the log.
            text = app.BAR_App.Log.Value;
            % Match the error code with the message and update the text for
            % the log.
            switch code
                case '001'
                    text{length(text) + 1,1} = ['GRP-001 removing duplicates within columns'];
                case '002'
                    text{length(text) + 1,1} = ['GRP-002 duplicates removed from within columns'];
                case '003'
                    text{length(text) + 1,1} = ['GRP-003 removing duplicates within rows'];
                case '004'
                    text{length(text) + 1,1} = ['GRP-004 duplicates removed from within rows'];
                case '005'
                    text{length(text) + 1,1} = ['GRP-005 identifying object groups'];
                case '006'
                    text{length(text) + 1,1} = ['GRP-006 identifying object groups complete'];
                case '007'
                    text{length(text) + 1,1} = ['GRP-007 updating table'];
                case '008'
                    text{length(text) + 1,1} = ['GRP-008 updating table complete'];
                case '009'
                    text{length(text) + 1,1} = ['GRP-009 starting: ' newText];
                case '010'
                    text{length(text) + 1,1} = ['GRP-010 update: ' newText];
                case '011'
                    text{length(text) + 1,1} = ['GRP-011 complete: ' newText];
                case '012'
                    text{length(text) + 1,1} = ['GRP-012 ' newText];
            end

            % Create a date-time for the message.
            text{end, 1} = [char(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z')) ' | ' text{end, 1}];
            % Update the log.
            app.BAR_App.Log.Value = text;
            % Scroll down so the message is visible.
            scroll(app.BAR_App.Log,'bottom')
            drawnow
        end
        
        
        function updateTable(app, data)
            % updateTable(app, data)
            % inputs  - app, required mlapp object.
            %         - data, BAR App data structure
            % Remarks
            % - This method will update the table in the app with the current group information.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Print a message to the log that it is starting.
            printLog(app, '009', 'Updating table')
            
            % Initialize the structure.
            groups = struct;

            % Get the file names so they can be iterated through.
            files = fieldnames(data.raw);
            % We'll use a separate index so the group and file information can be combined.
            ind = 1;
            for i = 1:length(files)

                % Get the object names and remove the informational ones.
                obj = fieldnames(data.raw.(files{i}));
                obj(strcmp(obj, 'meta')) = [];
                obj(strcmp(obj, 'groups')) = [];

                % If there are no objects only the file groups are needed.
                if isempty(obj)
                    % Initialize a count for the groups.
                    g = 1;
                    % The first group is the programmed file name.
                    groups.(['GroupFile' num2str(g)]){ind, 1} = files{i};
                    g = g + 1;
                    % Add all the group information for the file as additional groups.
                    for j = 1:length(data.raw.(files{i}).groups)
                        groups.(['GroupFile' num2str(g)]){ind, 1} = data.raw.(files{i}).groups{j};
                        g = g + 1;
                    end
                    ind = ind + 1;
                end
                % If there are objects add them.
                for k = 1:length(obj)
                    g = 1;
                    % The first group is the programmed file name.
                    groups.(['GroupFile' num2str(g)]){ind, 1} = files{i};
                    g = g + 1;
                    % Add all the group information for the file as additional groups.
                    for j = 1:length(data.raw.(files{i}).groups)
                        groups.(['GroupFile' num2str(g)]){ind, 1} = data.raw.(files{i}).groups{j};
                        g = g + 1;
                    end
                    % Add the object name.
                    groups.(['GroupObject' num2str(g)]){ind, 1} = obj{k};
                    g = g + 1;
                    % Check if there is group information for the object.
                    if isfield(data.raw.(files{i}).(obj{k}), 'groups')
                        % Iterate through the group informatioin and add them to the structure.
                        for ii = 1:length(data.raw.(files{i}).(obj{k}).groups)
                            groups.(['GroupObject' num2str(g)]){ind, 1} = data.raw.(files{i}).(obj{k}).groups{ii};
                            g = g + 1;
                        end
                    end
                    ind = ind + 1;
                end
            end

            % To display the structure in the table the different fields need the same number of elements.
            N = [];
            groupNames = fieldnames(groups);
            % Find the length of all the groups.
            for i = 1:length(groupNames)
                N(i) = length(groups.(groupNames{i}));
            end
            % Pad the shorter groups so they are the same length as the longest.
            for i = 1:length(groupNames)
                if length(groups.(groupNames{i})) < max(N)
                    groups.(groupNames{i})(N(i) + 1:max(N), 1) = {'NA'};
                end
            end
            
            % Set the group structure as the table.
            app.UITable.Data = struct2table(groups);
            % Use the fieldnames as the column headers.
            app.UITable.ColumnName = fieldnames(groups);

            % Print a message that the update as finished.
            printLog(app, '011', 'Updating table')

        end
    end
    
    methods (Access = public)
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % StartupFcn(app, mainapp, data)
            % inputs  - app, required mlapp object.
            %         - mainapp, BAR App that will be used to return data to it.
            %         - data, BAR App data structure
            % Remarks
            % - This is the required startup app. It is used to bring the data into the app and initialize the table with group information.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            % Store the data in the app.
            setappdata(app.UITable, 'data', data)
            % Update the table.
            updateTable(app, data)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.BAR_App.Version.Text;
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitSaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitSaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method will save the data into the BAR App.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the data.
            data = getappdata(app.UITable, 'data');
            % Get the name of the analysis.
            analysis = app.GroupingsLabel.Text;
            
            % Run the public BAR App analysisComplete method to get the data back into the app.
            analysisComplete(app.BAR_App, data, analysis, 0)

            % Delete this app.
            delete(app)
        end

        % Callback function
        function findDelimiter(app, event)
            % findDelimiter(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method was used previously but is not currently. It was previously part of the group processing to create the group information
            %   from the object names. Potetially it could be used later to do additional group processing with varied delimiters.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            data = getappdata(app.UITable, 'data');

            n = [];
            files = fieldnames(data.raw);
            for i = 1:length(files)
                objs = fieldnames(data.raw.(files{i}));
                groups = split(objs, '_');
                j = 1;
                while j <= size(groups, 2)
                    if numel(unique(groups(:,j))) == length(groups) || numel(unique(groups(:,j))) == 1
                        groups(:,j) = [];
                    else
                        n = [n;numel(unique(groups(:,j)))];
                        j = j + 1;
                    end
                end
                for j = 1:size(groups, 2)
                    groupsTable.(['g' num2str(j)]) = [unique(groups(:,j)); num2cell(nan(max(n) - n(j), 1))];
                end
                for j = 1:length(objs)
                    for k = 1:size(groups, 2)
                        data.raw.(files{i}).(objs{j}).groups.(['g' num2str(k)]) = groups{j,k};
                    end
                end
            end
            setappdata(app.UITable, 'data', data)
            app.UITable.Data = struct2table(groupsTable);
            app.UITable.ColumnName = fieldnames(groupsTable);

        end

        % Button pushed function: ConvertDatesButton
        function ConvertDatesButtonPushed(app, event)
            % ConvertDatesButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method is used to convert dates in the groups that are stored as strings into ordinal numbers. This does require input from the
            %   user to select the format of the date string, the groups with the date information and the group they belong to.
            % Future Work
            % - Potentialy the group with the dates could be automatically found. Input from the user does make it easier.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the groups from the table. This was used by the user to select the date groups.
            groups = app.UITable.Data;
            % Get the data from the app.
            data = getappdata(app.UITable, 'data');

            % Get the user selected format of the date string.
            dateFormat = app.SelectdatetagformatDropDown.Value;
            % Get the index of the group the dates belong to.
            ind_parent = app.SelectthegroupdatesbelongtoSpinner.Value;
            % Get the index of the date group.
            ind_date = app.SelectthegroupwithdatestoconvertSpinner.Value;

            % Get all the unqiue values of the parent group so the dates can be numbered sequentially.
            parent = table2cell(unique(groups(:, ind_parent)));
            % Get the file names so they can be iterated through.
            files = fieldnames(data.raw);
            % Start iterating through the unique parent names.
            for i = 1:length(parent)
                try
                    % Get the unique dates for
                    m_dates = unique(table2cell(groups(find(strcmp(groups.(ind_parent), parent{i})), ind_date)));
                    % If the format has the monthj
                    if strcmp(dateFormat, 'MMddyy')
                        for ind_date2 = 1:length(m_dates)
                            if length(m_dates{ind_date2}) == 5
                                m_dates{ind_date2} = ['0' m_dates{ind_date2}];
                            end
                        end
                    end
                    dates = datetime(regexprep(m_dates,'''',''), 'InputFormat', dateFormat);
                    % Sort the dates.
                    dates = sort(dates);
                    % Iterate through the files and add an ordinal number to the file group information.
                    for j = 1:length(files)
                        % Check that the current parent is a group in the current file.
                        if any(strcmp(data.raw.(files{j}).groups, parent{i}))
                            % Get the ordinal number of the date.
                            m_dates = regexprep(data.raw.(files{j}).groups{ind_date - 1}, '''', '');
                            if strcmp(dateFormat, 'MMddyy')
                                if length(m_dates) == 5
                                    m_dates = ['0' m_dates];
                                end
                            end
                            n = num2str(find(dates==datetime(m_dates, 'InputFormat', dateFormat)));
                            % Add the ordinal number to the group information.
                            data.raw.(files{j}).groups = [data.raw.(files{j}).groups(1:ind_date - 1); n; data.raw.(files{j}).groups(ind_date - 1 + 1:end)];
                        end
                    end
                catch
                    % Iterate through the files and add an ordinal number to the file group information.
                    for j = 1:length(files)
                        % Check that the current parent is a group in the current file.
                        if any(strcmp(data.raw.(files{j}).groups, parent{i}))
                            % Add the ordinal number to the group information.
                            data.raw.(files{j}).groups = [data.raw.(files{j}).groups(1:ind_date - 1); NaN; data.raw.(files{j}).groups(ind_date - 1 + 1:end)];
                        end
                    end
                end
            end

            % Set the data back into the app.
            setappdata(app.UITable, 'data', data)

            % Update the table with the new date information.
            updateTable(app, data)

        end

        % Callback function
        function ConvertObjectstoGroupsButtonPushed(app, event)
            % ConvertObjectstoGroupsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method was used previously to identify group information from objects. It is not currently used.
            % Future Work
            % - The method could be implemented again to identify groups from more variable deliminations or other user specified methods.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            printLog(app, '005')

            data = getappdata(app.UITable, 'data');

            files = fieldnames(data.raw);
            for i = 1:length(files)
                obj = fieldnames(data.raw.(files{i}));
                obj(strcmp(obj, 'meta')) = [];
                obj(strcmp(obj, 'groups')) = [];
                for j = 1:length(obj)
                    data.raw.(files{i}).(obj{j}).groups = split(obj{j}, '_');
                end
            end

            setappdata(app.UITable, 'data', data)

            updateTable(app, data)

            printLog(app, '006')

        end

        % Button pushed function: RemoveduplicateswithinrowsButton
        function RemoveduplicateswithinrowsButtonPushed(app, event)
            % RemoveduplicateswithinrowsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method will remove duplicate group information within each file. The duplication does need to be consistent across files or they
            %   will not be removed. However the values themselves do not need to be consistent across files. One instance of the duplicate
            %   information will not be removed.
            % Future Work
            % - The method may not handle object group informaitoin very well. Good test data will be needed for that.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Print a message to the log that the method is starting.
            printLog(app, '009', 'Removing duplicates within rows')

            % The data in the table will be used to find the duplicates.
            groups = app.UITable.Data;
            % Pull the current data from the app.
            data = getappdata(app.UITable, 'data');

            % Identify the duplicates within the rows.
            dups = [];
            for i = 1:height(groups)
                % Convert the current table row into cells.
                groupsUnique = table2cell(groups(i,:));
                % Remove empty cells. Empty cells will cause errors.
                groupsUnique(cellfun(@isempty, groupsUnique)) = [];
                % Find the unique groups.
                [~, idxu, idxc] = unique(groupsUnique);
                % Find the index of the unique values that have a count
                % greater than 1.
                ind_file = sort(idxu(find(histcounts(idxc, 0:max(idxc) + 1) > 1) - 1));
                % Save the indexes of the duplicates.
                dups(i,1:length(ind_file)) = ind_file;
            end
            
            % Check that the duplicates are common to every row.
            i = 1;
            while i <= size(dups, 2)
                % If there is more than one unique value in the column than
                % the rows have different values.
                if numel(unique(dups(:,i))) ~= 1
                    % Remove that column as a duplicate.
                    dups(:,i) = [];
                else
                    i = i + 1;
                end
            end

            % The fieldnames of the groups will be needed to remove them.
            fields = fieldnames(groups);
            % Iterate horizontally through each of the duplications.
            for i = 1:size(dups, 2)
                % Iterate vertically through the groups.
                for j = 1:height(groups)

                    % Get the string that is duplicated.
                    dup = groups.(fields{dups(j,i)})(j);
                    % Find the index of the exact string that is duplicated.
                    ind_file = find(strcmp(data.raw.(groups.GroupFile1{j}).groups, dup));
                    % Remove it if it was found.
                    if ~isempty(ind_file)
                        data.raw.(groups.GroupFile1{j}).groups(ind_file(2:end)) = [];
                    end

                end
            end
            
            % Save the data back into the app.
            setappdata(app.UITable, 'data', data)

            % Print a meassage that the method has completed.
            printLog(app, '011', 'Removing duplicates within rows')

            % Update the table with the new information.
            updateTable(app, data)
            
        end

        % Button pushed function: RemoveduplicateswithincolumnsButton
        function RemoveduplicateswithincolumnsButtonPushed(app, event)
            % RemoveduplicateswithincolumnsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method will remove duplicate group information across files. This information would be the same string in the same group
            %   locatiion across all files.
            % Future Work
            % - The method may not handle object group informaitoin very well. Good test data will be needed for that.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Print a message that the method is starting.
            printLog(app, '009', 'Removing duplicates within columns')

            % Get the group information from the table.
            groups = app.UITable.Data;
            % Get the current data.
            data = getappdata(app.UITable, 'data');
            % Get the column headers for the groups.
            cols = fieldnames(groups);
            
            % The file names will be needed for the removal.
            files = fieldnames(data.raw);
            % Start iterating through the columns backwards so the indexes are preserved.
            for i = length(cols) - 3 : -1 : 2
                % if none of the groups are empty and there is only 1 unique string,
                % or all the items are empty,
                % and the index is not for the object name.
                if ((sum(cellfun(@isempty, groups.(cols{i}))) == 0 && numel(unique(groups.(cols{i}))) == 1) || ...
                        sum(cellfun(@isempty, groups.(cols{i}))) == height(groups.(cols{i}))) && ...
                        i ~= sum(contains(cols, 'File')) + 1
                    % Check that the column is a file group.
                    if contains(cols{i}, 'File')
                        % Remove the index of the group for all data.
                        for j = 1:length(files)
                            if i - 1 <= length(data.raw.(files{j}).groups)
                                data.raw.(files{j}).groups(i - 1) = [];
                            end
                        end
                    % Check if the column is from the objects.
                    elseif contains(cols{i}, 'Object')
                        % Iterate through the files.
                        for j = 1:length(files)
                            % Get the object names.
                            objs = fieldnames(data.raw.(files{j}));
                            % Remove the informational ones.
                            objs(strcmp(objs, 'meta')) = [];
                            objs(strcmp(objs, 'groups')) = [];
                            % Iterate through the objects.
                            for k = 1:length(objs)
                                if i - length(data.raw.(files{j}).groups) - 2 <= length(data.raw.(files{j}).(objs{k}).groups)
                                    data.raw.(files{j}).(objs{k}).groups(i - length(data.raw.(files{j}).groups) - 2) = [];
                                end
                            end
                        end
                    end
                end
            end

            % Save the data back into the app.
            setappdata(app.UITable, 'data', data)

            % Print a meassage that the method has completed.
            printLog(app, '011', 'Removing duplicates within columns')
            
            % Update the table with the new information.
            updateTable(app, data)
            
        end

        % Button pushed function: RemovegroupsatanindexButton
        function RemovegroupsatanindexButtonPushed(app, event)
            % RemovegroupsatanindexButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method will remove groups from a selected index regardless of their content. This was originally made to remove processed date
            %   groups.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the group information from the table.
            groups = app.UITable.Data;
            % Get the current data.
            data = getappdata(app.UITable, 'data');
            % Get the column headers for the groups.
            cols = fieldnames(groups);

            indexes = split(app.EditField.Value, ',');
            ind = 1;
            for i = 1:length(indexes)
                if ~isnan(str2double(indexes{i})) && ~contains(indexes{i}, '-')
                    indexes2(ind, 1) = str2double(indexes{i});
                    ind = ind + 1;
                elseif strcmp(indexes{i}(end), '-')
                    temp = str2double(indexes{i}(1:strfind(indexes{i}, '-') - 1)):length(cols);
                    indexes2(ind:ind + length(temp) - 1, 1) = temp;
                    ind = ind + length(temp);
                elseif strcmp(indexes{i}(1), '-')
                    temp = 1:str2double(indexes{i}(strfind(indexes{i}, '-') + 1));
                    indexes2(ind:ind + length(temp) - 1, 1) = temp;
                    ind = ind + length(temp);
                elseif contains(indexes{i}, '-')
                    temp = str2double(indexes{i}(1:strfind(indexes{i}, '-') - 1)):str2double(indexes{i}(strfind(indexes{i}, '-') + 1:end));
                    indexes2(ind:ind + length(temp) - 1, 1) = temp;
                    ind = ind + length(temp);
                end
                
            end
            indexes2 = sort(indexes2);


            for i = length(indexes2):-1:1
                index = indexes2(i);

                % The file names will be needed for the removal.
                files = fieldnames(data.raw);
                % Remove the index of the group for all data.
                for j = 1:length(files)
                    % If the selected index minus the file name is less than or equal to the number of groups for the file and it is greater than 1.
                    if index - 1 <= length(data.raw.(files{j}).groups) && index > 1
                        % Remove the group.
                        data.raw.(files{j}).groups(index - 1) = [];
                    else
                        % Get the object names.
                        objs = fieldnames(data.raw.(files{j}));
                        % Remove the informational ones.
                        objs(strcmp(objs, 'meta')) = [];
                        objs(strcmp(objs, 'groups')) = [];
                        % Iterate throught he objects.
                        for k = 1:length(objs)
                            % If the selected value is greater than the number of file groups plus the filename plus the object name, and the selected
                            % value minus the file name minus the number of file groups minus the object name is less than or equal to the number of
                            % object groups.
                            if index > length(data.raw.(files{j}).groups) + 1 + 1 && index - 1 - length(data.raw.(files{j}).groups) - 1 <= length(data.raw.(files{j}).(objs{k}).groups)
                                % Remove it.
                                data.raw.(files{j}).(objs{k}).groups(index - 1 - length(data.raw.(files{j}).groups) - 1) = [];
                            end
                        end
                    end
                end
            end

            % Save the data back into the app.
            setappdata(app.UITable, 'data', data)

            % Update the table with the new information.
            updateTable(app, data)
        end

        % Button pushed function: MovetagstogroupsButton
        function MovetagstogroupsButtonPushed(app, event)
            % MovetagstogroupsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method will remove tags from signals and move them to the group information. In the process it creates a new object using the tag
            %   if it has not already been created.
            % - Any tags entered with square brackets will use the string between the square brackets to replace the target string. 'fw_[FastWalk'
            %   would search for 'fw_' in the signals but would replace it with 'FastWalk'.
            % Future Work
            % - None.
            % Jan 2022 - Created by Ben Senderling, bsender@bu.edu

            % Print a message the process is starting.
            printLog(app, '009', 'Move tags to groups')
            
            % Get the current data.
            data = getappdata(app.UITable, 'data');

            % Pull the inclusion and exclusion tags entered into the app.
            m_tags_include = app.EntertagstoremovefromsignalsEditField.Value;
            % Read the strings with the tags and separate them into cell arrays.
            if ~isempty(m_tags_include)
                m_tags_include = textscan(m_tags_include,'%s','Delimiter',',');
            else
                return
            end

            % Pull any replacement tags out of the input tags.
            for i = 1:length(m_tags_include{1})
                % Check if it contains both square brackets.
                if contains(m_tags_include{1}{i}, '[') && contains(m_tags_include{1}{i}, ']')
                    % The index of the character after the [.
                    ind1 = strfind(m_tags_include{1}{i}, '[') + 1;
                    % The index of the character before the ].
                    ind2 = strfind(m_tags_include{1}{i}, ']') - 1;
                    % The characters before the [ are the tag to look for in the signal name.
                    tags{i, 1} = m_tags_include{1}{i}(1:ind1(1) - 2);
                    % The characters inbetween the square brackets are what to use in naming the replacement signal.
                    tags{i, 2} = m_tags_include{1}{i}(ind1(1):ind2(1));
                else
                    % If there are not both square brackets use the entire string as the tag.
                    tags{i, 1} = m_tags_include{1}{i};
                    % Make the replacement the same.
                    tags{i, 2} = m_tags_include{1}{i};
                end
            end

            % The order the tags are removed in matters so they need to be iterated through outside the other for loops. Signal names may have
            % different tags in different orders. So otherwise many more objects might be created.
            for ii = 1:size(tags, 1)
                % Set a current tag.
                tagCurrent = tags{ii, 1};
                % Set the replacement tag.
                tagReplacement = tags{ii, 2};
                % Get the file names.
                files = fieldnames(data.raw);
                % Iterate through the file names.
                for i = 1:length(files)
                    % Get the object names.
                    objs = fieldnames(data.raw.(files{i}));
                    % Remove the informational ones.
                    objs(strcmp(objs, 'meta')) = [];
                    objs(strcmp(objs, 'groups')) = [];
                    % Iterate through the object names.
                    for j = 1:length(objs)
                        % Get the signal names.
                        sigs = fieldnames(data.raw.(files{i}).(objs{j}).data);
                        % Iterate through the signals.
                        for k = 1:length(sigs)
                            % If any tags were found in the signal name.
                            if contains(sigs{k}, tagCurrent)
                                ind = strfind(sigs{k}, tagCurrent);
                                % If both the first and last characters of the tag is an underscore.
                                % If the first character is an underscore and the last character is not then the tag needs to be at the beginning of 
                                % the signal or have an underscore before it in the signal name.
                                % If the first character is not an underscore and the last character is then the tag needs to be at the end of the 
                                % signal or have an underscore before it in the signal name.
                                % If neither the first or last character is an underscore.
                                if strcmp(tagCurrent(1), '_') && strcmp(tagCurrent(end), '_') || ...
                                        (~strcmp(tagCurrent(1), '_') && strcmp(tagCurrent(end), '_')) && (ind(1) == 1 || strcmp(sigs{k}(ind(1) - 1), '_')) || ...
                                        (strcmp(tagCurrent(1), '_') && ~strcmp(tagCurrent(end), '_')) && (ind(end) == length(sigs{k}) - length(tagCurrent) + 1 || strcmp(sigs{k}(ind(1) - 1), '_')) || ...
                                        ~strcmp(tagCurrent(1), '_') && ~strcmp(tagCurrent(end), '_')
                                    % If the object-tag has not previously been created and the object has group information.
                                    if ~isfield(data.raw.(files{i}), [objs{j} '_' tagReplacement])
                                        if isfield(data.raw.(files{i}).(objs{j}), 'groups')
                                            % Copy the group information to the new object.
                                            data.raw.(files{i}).([objs{j} '_' tagReplacement]).groups = [data.raw.(files{i}).(objs{j}).groups; {tagReplacement}];
                                        end
                                        if isfield(data.raw.(files{i}).(objs{j}), 'meta')
                                            % Copy the group information to the new object.
                                            data.raw.(files{i}).([objs{j} '_' tagReplacement]).groups = [data.raw.(files{i}).(objs{j}).groups; {tagReplacement}];
                                        end
                                        if isfield(data.raw.(files{i}).(objs{j}), 'freq')
                                            % Copy the group information to the new object.
                                            data.raw.(files{i}).([objs{j} '_' tagReplacement]).groups = [data.raw.(files{i}).(objs{j}).groups; {tagReplacement}];
                                        end
                                    end

                                    % The tag needs to be removed differently depending on what was entered and where it is found.
                                    sigNew = sigs{k};
                                    % Tags entered with an underscore at the start and end are clear. (_oa_)
                                    if strcmp(tagCurrent(1), '_') && strcmp(tagCurrent(end), '_')
                                        % Nothing needs to be done with these tags.
                                    % Tags that do not start with but end with an underscore. (oa_)
                                    elseif (~strcmp(tagCurrent(1), '_') && strcmp(tagCurrent(end), '_'))
                                        % Remove them once if they are found at the start or they have an underscore before them.
                                        for jj = 1:length(ind)
                                            if ~(ind(jj) == 1 || strcmp(sigs{k}(ind(jj) - 1), '_'))
                                                ind(jj) = [];
                                            end
                                        end
                                    % Tags that start with an underscore but end with a character. (_oa)
                                    elseif (strcmp(tagCurrent(1), '_') && ~strcmp(tagCurrent(end), '_'))
                                        % Remove them if they are at the end of the signal or they have an underscore after them.
                                        for jj = 1:length(ind)
                                            if ind(j) ~= length(sigs{k}) - length(tagCurrent) + 1 || ~strcmp(sigs{k}(ind(jj) + length(tagCurrent)), '_')
                                                ind(jj) = [];
                                            end
                                        end
                                        % Tags that do not start with and do not end with an underscore. (oa)
                                    elseif ~strcmp(tagCurrent(1), '_') && ~strcmp(tagCurrent(end), '_')
                                        % Remove them if not...the character after is an underscore and the tag occures at the beginning,
                                        % or if the character before is an underscore and the character after is an underscore,
                                        % or if the character before is an underscore and the tag is at the end.
                                        for jj = 1:length(ind)
                                            if ~(strcmp(sigs{k}(ind(jj) + length(tagCurrent)), '_') && ind(jj) == 1 || ...
                                                    strcmp(sigs{k}(ind(jj) - 1), '_') && strcmp(sigs{k}(ind(jj) + length(tagCurrent)), '_') || ...
                                                    strcmp(sigs{k}(ind(jj) - 1), '_') && ind(jj) == length(sigs{k}) - length(tagCurrent) + 1)
                                                ind(jj) = [];
                                            end
                                        end
                                    end
                                    % These are removed going backwards so the indexes stay correct.
                                    for jj = length(ind):-1:1
                                        % Remove the tags.
                                        sigNew(ind(jj):ind(jj) + length(tagCurrent) - 1) = [];
                                    end
                                    % Remove any repeated underscores if they were created.
                                    sigNew = regexprep(sigNew, '__', '_');
                                    % Remove an underscore in the first value if it was created.
                                    if strcmp(sigNew(1), '_')
                                        sigNew(1) = [];
                                    end
                                    % Copy the signal to the new object.
                                    data.raw.(files{i}).([objs{j} '_' tagReplacement]).data.(sigNew) = data.raw.(files{i}).(objs{j}).data.(sigs{k});
                                    % Remove the signal from the old object.
                                    data.raw.(files{i}).(objs{j}).data = rmfield(data.raw.(files{i}).(objs{j}).data, sigs{k});
                                end
                            end
                        end
                    end
                    if rem(i,10) == 0
                        % Print an update message to the log.
                        printLog(app, '012', ['completed files ' num2str(i) ' of ' num2str(length(files))])
                    end
                end
                % Print an update message to the log.
                printLog(app, '010', ['tag ' num2str(ii) ' of ' num2str(length(m_tags_include{1})) ' removed'])
            end

            % Remove any objects that are not empty and fill in empty arrays if it was not modified by a current tag.
            for i = 1:length(files)
                % Get the object names.
                objs = fieldnames(data.raw.(files{i}));
                % Remove the informational ones.
                objs(strcmp(objs, 'meta')) = [];
                objs(strcmp(objs, 'groups')) = [];
                % Iterate through the object names.
                for j = 1:length(objs)
                    % The the object had all the signals removed and is not empty, remove the object.
                    if isempty(fieldnames(data.raw.(files{i}).(objs{j}).data))
                        data.raw.(files{i}) = rmfield(data.raw.(files{i}), objs{j});
                    elseif isempty(data.raw.(files{i}).(objs{j}).groups) || ~any(strcmp(data.raw.(files{i}).(objs{j}).groups{end}, tags(:, 2)))
                        % Add an empty group to the original so they stay the same length. This keeps additional tags aligned in the same column.
                        data.raw.(files{i}).(objs{j}).groups = [data.raw.(files{i}).(objs{j}).groups; {[]}];
                    end
                end
            end

            % Save the data back into the app.
            setappdata(app.UITable, 'data', data)

            % Print a message the process is finished.
            printLog(app, '011', 'Move tags to groups')

            % Update the table with the new information.
            updateTable(app, data)

        end

        % Button pushed function: CombineobjectswithsimilargroupsButton
        function CombineobjectswithsimilargroupsButtonPushed(app, event)
            % CombineobjectswithsimilargroupsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method will combine objects that have the same group information. They may have different object names but through processing
            %   have the same group information.
            % - Empty groups are removed if the method results in moving all the signals from one group to another.
            % Future Work
            % - None.
            % Jan 2022 - Created by Ben Senderling, bsender@bu.edu

            % Print a message the process is starting.
            printLog(app, '009', 'Combining objects')

            % Get the current data.
            data = getappdata(app.UITable, 'data');

            % Get the file names.
            files = fieldnames(data.raw);

            % If the means should be averaged, do that first.
            if strcmp(app.AveragesignalsbeforemergeSwitch.Value, 'Yes')
                for i = 1:length(files)
                    objs = fieldnames(data.raw.(files{i}));
                    objs(strcmp(objs, 'meta')) = [];
                    objs(strcmp(objs, 'groups')) = [];
                    for j = 1:length(objs)
                        sigs = fieldnames(data.raw.(files{i}).(objs{j}).data);
                        for k = 1:length(sigs)
                            data.raw.(files{i}).(objs{j}).data.(sigs{k}) = mean(data.raw.(files{i}).(objs{j}).data.(sigs{k}), 1, 'omitnan');
                        end
                    end
                end
            end

            % Iterate through the file names and combine ones with similar groups.
            i = 1;
            while i < length(files)
                for j = i + 1:length(files)
                    % Remove empty groups since they will not match.
                    group1 = data.raw.(files{i}).groups;
                    group1(cellfun(@isempty, group1)) = [];
                    group2 = data.raw.(files{j}).groups;
                    group2(cellfun(@isempty, group2)) = [];
                    % Check that all the group information is the same between the two objects.
                    if length(group1) == length(group2) && ~any(~strcmp(group1, group2))
                        % Get the object names.
                        objs = fieldnames(data.raw.(files{j}));
                        objs(strcmp(objs, 'meta')) = [];
                        objs(strcmp(objs, 'groups')) = [];
                        for k = 1:length(objs)
                            if ~isfield(data.raw.(files{i}), objs{k})
                                % Move the object over to the index file.
                                data.raw.(files{i}).(objs{k}) = data.raw.(files{j}).(objs{k});
                                % Remove the object from the current index.
                                data.raw.(files{j}) = rmfield(data.raw.(files{j}), objs{k});
                            else
                                % Get the signal names.
                                sigs = fieldnames(data.raw.(files{j}).(objs{k}).data);
                                % Iterate throught the signal names.
                                for ii = 1:length(sigs)
                                    % If the signal does not already exist in the index object copied it over directly.
                                    if ~isfield(data.raw.(files{i}).(objs{k}).data, (sigs{ii}))
                                        % Move the signal over to the index object.
                                        data.raw.(files{i}).(objs{k}).data.(sigs{ii}) = data.raw.(files{j}).(objs{k}).data.(sigs{ii});
                                        % Remove the signal from the current index.
                                        data.raw.(files{j}).(objs{k}).data = rmfield(data.raw.(files{j}).(objs{k}).data, sigs{ii});
                                    else
                                        % If the signal already exists append it to the existing one.
                                        % Move the signal over to the index object.
                                        data.raw.(files{i}).(objs{k}).data.(sigs{ii}) = [data.raw.(files{i}).(objs{k}).data.(sigs{ii}); data.raw.(files{j}).(objs{k}).data.(sigs{ii})];
                                        % Remove the signal from the current index.
                                        data.raw.(files{j}).(objs{k}).data = rmfield(data.raw.(files{j}).(objs{k}).data, sigs{ii});
                                    end
                                end
                            end
                        end

                    end
                end
                % Make the next object the index.
                i = i + 1;
                % Refresh the file names.
                files = fieldnames(data.raw);
                if rem(i, 10) == 0
                    % Print an update message to the log.
                    printLog(app, '010', ['file ' num2str(i) ' of ' num2str(length(files)) ' complete'])
                end
            end

            % Get the file names.
            files = fieldnames(data.raw);
            % Remove any files that no longer have any objects.
            for i = 1:length(files)
                objs = fieldnames(data.raw.(files{i}));
                % Remove the informational ones.
                objs(strcmp(objs, 'meta')) = [];
                objs(strcmp(objs, 'groups')) = [];
                if isempty(objs)
                    data.raw = rmfield(data.raw, files{i});
                end
            end

            % Get the file names.
            files = fieldnames(data.raw);
            % Iterate through the file names.
            for i = 1:length(files)
                objs = fieldnames(data.raw.(files{i}));
                % Remove the informational ones.
                objs(strcmp(objs, 'meta')) = [];
                objs(strcmp(objs, 'groups')) = [];
                % Start with the first object as the index.
                j = 1;
                while j < length(objs)
                    % Start iterating through the rest of the objects after the index one.
                    for k = j + 1:length(objs)
                        % Remove empty groups since they will not match.
                        group1 = data.raw.(files{i}).(objs{j}).groups;
                        group1(cellfun(@isempty, group1)) = [];
                        group2 = data.raw.(files{i}).(objs{k}).groups;
                        group2(cellfun(@isempty, group2)) = [];
                        % Check that all the group information is the same between the two objects.
                        if length(group1) == length(group2) && ~any(~strcmp(group1, group2))
                            % Get the signal names.
                            sigs = fieldnames(data.raw.(files{i}).(objs{k}).data);
                            % Iterate throught the signal names.
                            for ii = 1:length(sigs)
                                % If the signal does not already exist in the index object copied it over directly.
                                if ~isfield(data.raw.(files{i}).(objs{j}).data, (sigs{ii}))
                                    % Move the signal over to the index object.
                                    data.raw.(files{i}).(objs{j}).data.(sigs{ii}) = data.raw.(files{i}).(objs{k}).data.(sigs{ii});
                                    % Remove the signal from the current index.
                                    data.raw.(files{i}).(objs{k}).data = rmfield(data.raw.(files{i}).(objs{k}).data, sigs{ii});
                                else
                                    % If the signal already exists append it to the existing one.
                                    % Move the signal over to the index object.
                                    data.raw.(files{i}).(objs{j}).data.(sigs{ii}) = [data.raw.(files{i}).(objs{j}).data.(sigs{ii}); data.raw.(files{i}).(objs{k}).data.(sigs{ii})];
                                    % Remove the signal from the current index.
                                    data.raw.(files{i}).(objs{k}).data = rmfield(data.raw.(files{i}).(objs{k}).data, sigs{ii});
                                end
                            end
                        end
                    end
                    % Make the next object the index.
                    j = j + 1;
                end

                if rem(i, 10) == 0
                    % Print an update message to the log.
                    printLog(app, '010', ['file ' num2str(i) ' of ' num2str(length(files)) ' complete'])
                end

            end

            % Remove any objects that are now empty.
            for i = 1:length(files)
                % Get the object names.
                objs = fieldnames(data.raw.(files{i}));
                % Remove the informational ones.
                objs(strcmp(objs, 'meta')) = [];
                objs(strcmp(objs, 'groups')) = [];
                % Iterate through the object names.
                for j = 1:length(objs)
                    % The the object had all the signals removed and is not empty, remove the object.
                    if isempty(fieldnames(data.raw.(files{i}).(objs{j}).data))
                        data.raw.(files{i}) = rmfield(data.raw.(files{i}), objs{j});
                    end
                end
            end

            % Save the data back into the app.
            setappdata(app.UITable, 'data', data)

            % Print a message the process is finished.
            printLog(app, '011', 'Combining objects')

            % Update the table with the new information.
            updateTable(app, data)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.GroupingsLabel = uilabel(app.UIFigure);
            app.GroupingsLabel.Text = 'Groupings';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SelectdatetagformatDropDownLabel = uilabel(app.UIFigure);
            app.SelectdatetagformatDropDownLabel.Text = 'Select date tag format';
            app.SelectdatetagformatDropDown = uidropdown(app.UIFigure);
            app.SelectdatetagformatDropDown.Items = {'yyyyMMdd', 'MMddyy'};
            app.SelectdatetagformatDropDown.Editable = 'on';
            app.SelectdatetagformatDropDown.Value = 'yyyyMMdd';
            app.ConvertDatesButton = uibutton(app.UIFigure, 'push');
            app.ConvertDatesButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertDatesButtonPushed, true);
            app.ConvertDatesButton.Text = 'Convert Dates';
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitSaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.Version = uilabel(app.UIFigure);
            app.RemoveduplicateswithinrowsButton = uibutton(app.UIFigure, 'push');
            app.RemoveduplicateswithinrowsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveduplicateswithinrowsButtonPushed, true);
            app.RemoveduplicateswithinrowsButton.Text = 'Remove duplicates within rows';
            app.RemoveduplicateswithincolumnsButton = uibutton(app.UIFigure, 'push');
            app.RemoveduplicateswithincolumnsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveduplicateswithincolumnsButtonPushed, true);
            app.RemoveduplicateswithincolumnsButton.Text = 'Remove duplicates within columns';
            app.SelectthegroupdatesbelongtoSpinnerLabel = uilabel(app.UIFigure);
            app.SelectthegroupdatesbelongtoSpinnerLabel.Text = 'Select the group dates belong to';
            app.SelectthegroupdatesbelongtoSpinner = uispinner(app.UIFigure);
            app.SelectthegroupdatesbelongtoSpinner.Value = 1;
            app.SelectthegroupwithdatestoconvertSpinnerLabel = uilabel(app.UIFigure);
            app.SelectthegroupwithdatestoconvertSpinnerLabel.Text = 'Select the group with dates to convert';
            app.SelectthegroupwithdatestoconvertSpinner = uispinner(app.UIFigure);
            app.SelectthegroupwithdatestoconvertSpinner.Value = 1;
            app.RemovegroupsatanindexButton = uibutton(app.UIFigure, 'push');
            app.RemovegroupsatanindexButton.ButtonPushedFcn = createCallbackFcn(app, @RemovegroupsatanindexButtonPushed, true);
            app.RemovegroupsatanindexButton.Text = 'Remove groups at an index';
            app.EntertagstoremovefromsignalsEditFieldLabel = uilabel(app.UIFigure);
            app.EntertagstoremovefromsignalsEditFieldLabel.Text = 'Enter tags to remove from signals';
            app.EntertagstoremovefromsignalsEditField = uieditfield(app.UIFigure, 'text');
            app.MovetagstogroupsButton = uibutton(app.UIFigure, 'push');
            app.MovetagstogroupsButton.ButtonPushedFcn = createCallbackFcn(app, @MovetagstogroupsButtonPushed, true);
            app.MovetagstogroupsButton.Text = 'Move tags to groups';
            app.CombineobjectswithsimilargroupsButton = uibutton(app.UIFigure, 'push');
            app.CombineobjectswithsimilargroupsButton.ButtonPushedFcn = createCallbackFcn(app, @CombineobjectswithsimilargroupsButtonPushed, true);
            app.CombineobjectswithsimilargroupsButton.Text = 'Combine objects with similar groups';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.AveragesignalsbeforemergeSwitchLabel = uilabel(app.UIFigure);
            app.AveragesignalsbeforemergeSwitchLabel.Text = 'Average signals before merge';
            app.AveragesignalsbeforemergeSwitch = uiswitch(app.UIFigure, 'slider');
            app.AveragesignalsbeforemergeSwitch.Items = {'No', 'Yes'};
            app.AveragesignalsbeforemergeSwitch.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Groupings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end