classdef process_Segment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SegmentonSelectionChangeSwitch  matlab.ui.control.Switch
        SegmentonSelectionChangeSwitchLabel  matlab.ui.control.Label
        SegmentSelectionButton          matlab.ui.control.Button
        SignalsListBox                  matlab.ui.control.ListBox
        SignalsListBoxLabel             matlab.ui.control.Label
        ObjectsListBox                  matlab.ui.control.ListBox
        ObjectsLabel                    matlab.ui.control.Label
        FilesListBox                    matlab.ui.control.ListBox
        FilesListBoxLabel               matlab.ui.control.Label
        Version                         matlab.ui.control.Label
        ExportFiguresSwitch             matlab.ui.control.Switch
        ExportFiguresSwitchLabel        matlab.ui.control.Label
        BeforePanel                     matlab.ui.container.Panel
        Switch                          matlab.ui.control.Switch
        SegmentallsaveandexitButton     matlab.ui.control.Button
        SelectMethodDropDown            matlab.ui.control.DropDown
        SelectMethodDropDownLabel       matlab.ui.control.Label
        AfterPanel                      matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        DataSegmentationLabel           matlab.ui.control.Label
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of
            %           the error and organize the errors available.
            %         - newText, a string that may be inserted into the
            %                    message.
            % Remarks
            % - This method will print messages to the log of the BAR App.
            %   The error codes are unique to each message so they can be
            %   found and referenced easier.
            % - The method is public and is acessible outside of the BAR
            %   App. It is used in some of the example analysis and process
            %   scripts.
            % Future Work
            % - This log should be printed and saved to a file location
            %   each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the current text from the BAR App.
            text = app.BAR_App.Log.Value;
            % Match the error code with the message.
            switch code
                case '001'
                    text{length(text) + 1,1} = 'SEG-001 selection not valid to plot';
                case '002'
                    text{length(text) + 1,1} = 'SEG-002 time series selection failed to segment';
                case '003'
                    text{length(text) + 1,1} = 'SEG-003 selection failed to segment';
                case '004'
                    text{length(text) + 1,1} = 'SEG-004 selection is not valid with the segmentation method';
                case '005'
                    text{length(text) + 1,1} = 'SEG-005 segmentation starting...';
                case '006'
                    text{length(text) + 1,1} = 'SEG-006 segmentation complete';
                case '007'
                    text{length(text) + 1,1} = ['SEG-007 ' newText];
            end
            % Set the text of the log to the new text.
            app.BAR_App.Log.Value = text;
            % Scroll to the bottom of the log.
            scroll(app.BAR_App.Log,'bottom')
            % Make sure the display updates.
            drawnow
        end
        
        function x_segmented = segment(app, sel)
            % x_segmented = segment(app, sel)
            % inputs  - app, required mlapp object.
            %         - sel, the level of the data to segment at.
            % Remarks
            % - This method runs the segmenting subroutine. It handles
            % which level of the data is being sent to the segmentation
            % code.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            data = getappdata(app.BAR_App.TableFiles, 'data');
            
            % Get if the raw or processed data should be used.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Find the segment function that matches the selected file extension and equipment type.
            % Get the load functions for the first directory.
            filesProcess = dir(app.BAR_App.FilesProcess.Value{1});
            % Get the load functions for the additional directories if there are more.
            if length(app.BAR_App.FilesProcess.Value) > 1
                for i = 2:length(app.BAR_App.FilesProcess.Value)
                    filesProcess = [filesProcess; dir(app.BAR_App.FilesProcess.Value{i})];
                end
            end

            % Find the index where the filename contains the target file extension and the equipment type and is not an asv-file.
            ind3 = find(contains({filesProcess.name}', app.SelectMethodDropDown.Value) & ~contains({filesProcess.name}', 'asv'), 1);

            % Exit if no file was found.
            if isempty(ind3)
                return
            end

            % Find the index of '.m' which will be present in all m- and mlapp-files.
            ind4 = strfind(filesProcess(ind3).name, '.m');
            % Print a message that the segmentation has started.
            printLog(app, '005')

            try
                switch sel
                    case 'timeseries'
                        % Send only the time series to the segmentation code.
                        x = data.(type).(app.FilesListBox.Value).(app.ObjectsListBox.Value).data.(app.SignalsListBox.Value);
                        x_segmented = feval(filesProcess(ind3).name(1:ind4 - 1), x, 'timeseries');
                    case 'object'
                        % Send the object with all its signals to the segmentation code.
                        obj = data.(type).(app.FilesListBox.Value).(app.ObjectsListBox.Value);
                        x_segmented = feval(filesProcess(ind3).name(1:ind4 - 1), obj, 'object');
                    case 'file'
                        % Send the file with all its objects and signals to the segmentation code.
                        file = data.(type).(app.FilesListBox.Value);
                        x_segmented = feval(filesProcess(ind3).name(1:ind4 - 1), file, 'file');
                    case 'all'
                        % Segment all of the data.
                        x_segmented = feval(filesProcess(ind3).name(1:ind4 - 1), data, type);
                end
                % Print a message the segmentation completed.
                printLog(app, '006')
            catch ME
                % Print a message to the log if the load fails for any reason.
                printLog(app, '003')
                % Get MATLAB's last error message and display it.
                printLog(app, '007', ME.message)
            end
        end

        function createNodeTree(app)
            % createNodeTree(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method create the node tree that allows the user to
            % preview segmentation results.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

%             % Get which level of the data whould be used.
%             switch app.Switch.Value
%                 case 'Raw'
%                     type = 'raw';
%                 case 'Processed'
%                     type = 'pro';
%             end
%             % Get the current data from the app.
%             data = getappdata(app.BAR_App.TableFiles, 'data');
%             % Delete the existing node tree.
%             app.Node.Children.delete;
%             % Create the first node.
%             app.Node.Text = type;
%             if ~isfield(data, type)
%                 % If the selected data type doesn't exist exit the method.
%                 return
%             end
%             % Get the file names.
%             files = fieldnames(data.(type));
%             % Start an index for the file nodes.
%             ind1 = 1;
%             % Iterate through the files.
%             for i = 1:length(files)
%                 % Create a node for the file.
%                 uitreenode(app.Node, 'Text', files{i});
%                 % Get the object names and remove the informational ones.
%                 objs = fieldnames(data.(type).(files{i}));
%                 objs(strcmp(objs, 'groups')) = [];
%                 objs(strcmp(objs, 'meta')) = [];
%                 % Create an index for the object nodes.
%                 ind2 = 1;
%                 % Iterate through the objects.
%                 for j = 1:length(objs)
%                     % Create a node for the object.
%                     uitreenode(app.Node.Children(ind1), 'Text', objs{j});
%                     % Get the signal names.
%                     sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
%                     % Iterate through the signal names.
%                     for k = 1:length(sigs)
%                         % Create a node for the signal.
%                         uitreenode(app.Node.Children(ind1).Children(ind2), 'Text', sigs{k});
%                     end
%                     % Increase the index for the object node.
%                     ind2 = ind2 + 1;
%                 end
%                 % Increase the index for the file node.
%                 ind1 = ind1 + 1;
%             end
        end

        function identifySegmentOptions(app)
            % identifySegmentOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Process folder for valid files that would be used to segment data within the app. These files need to have a
            %   name process_Segment*.m. The results will be saved to the configuration file and made selectable in the app.
            % - The goal of this function was to make the addition of new functions easier.
            % - The idea of processing is to take an input and create a new ouput of similar size and scale.
            % Future Work
            % - None
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Load the existing configuration so it can be updated.
            config = loadConfig(app.BAR_App);

            % Empty the available segment files from the saved configuration.
            config.options.segment = [];
            ind = 1;

            for j = 1:length(app.BAR_App.FilesProcess.Value)
                % Use the directory entered into the app to find the processing scripts.
                m_files = dir(app.BAR_App.FilesProcess.Value{j});

                % Look for files within the directory. If the file names contain the word 'process' save them within the app and configuration.
                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'process') && contains(m_files(i).name, 'Segment_') && ~contains(m_files(i).name, 'asv')

                        [~, name] = fileparts(m_files(i).name);
                        parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                        % The third part of the file name is used for the
                        % options.
                        config.options.segment{ind,1} = parts{3}{1};
                        ind = ind + 1;
                    end
                end
            end

            % If there are no segment files or if the directory is misleading because files have been moved or the app was moved nothing will be 
            % found. The object in the app can't be set to empty.
            if ~isempty(config.options.segment)
                app.SelectMethodDropDown.Items = config.options.segment;
            end

            % Save the configuration with the updated directory and or file
            % extensions and formats.
            saveConfig(app.BAR_App, config)
            
        end

        function createListFiles(app)
            % createLists(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method will populate the various dropdown lists in the app. In the method a very long list will be made, and then only the unique
            %   ones will be used. This allows items that are present in some levels but not others to still be used.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data.
            data = getappdata(app.BAR_App.TableFiles, 'data');

            if isempty(data)
                printLog(app.BAR_App ,'030')
                delete(app)
                return
            end

            % The lists will be recreated if the dropdown is changed.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Create list for Objects, Signals, Measures and Dimensions.
            % Create initial empty lists.
%             objList = [];
%             sigList = [];

                    % Create list for Files.
                    files = fieldnames(data.(type));
                    % Add all the files as an option at the top.
                    app.FilesListBox.Items = files;

                    % Iterate through the files.
%                     for i = 1:length(files)
%                         % Get the list of objects and remove the informational ones.
%                         obj = fieldnames(data.(type).(files{i}));
%                         obj(strcmp(obj, 'meta')) = [];
%                         obj(strcmp(obj, 'groups')) = [];
%                         % Add the objects for the current file to the list.
%                         objList = [objList; obj];
%                         % Iterate through the objects.
%                         for j = 1:length(obj)
%                             % Get the signal names.
%                             sig = fieldnames(data.(type).(files{i}).(obj{j}).data);
%                             % Add the signals for the current object to the list.
%                             sigList = [sigList; sig];
%                             % For each signal determine how many dimensions it has and add it to the list.
%                         end
%                     end

            % Set the list boxes to the unique values while adding an 'all' option to the top.
%             app.ObjectsListBox.Items = unique(objList);
app.ObjectsListBox.Items = {};
%             app.SignalsListBox.Items = unique(sigList);
app.SignalsListBox.Items = {};
            % The measures will be set only if they exist, only if the ana
            % or res was used.

        end
        
    end
    
    methods (Access = public)
        
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, process)
            % StartupFcn(app, mainapp, data, process)
            % inputs  - app, required mlapp object.
            %         - mainapp, BAR_App object.
            %         - data, BAR App data structure.
            %         - process, a string with the processing name.
            % Remarks
            % - This is the mandetory startup method for the module.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            % Find the process_segment methods.
            identifySegmentOptions(app)
            % Populate the list boxes.
            createListFiles(app)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.BAR_App.Version.Text;
                        
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Value changed function: SelectMethodDropDown
        function TreeSelectionChanged(app, event)
            % TreeSelectionChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information about the app from the change in
            %                  tree selection.
            % Remarks
            % - This method will run the segmentation subroutine based on
            %   what is selected in the node tree. Then it will plot the
            %   results.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get which nodes are selected.
%             selectedNodes = app.Tree.SelectedNodes;
            % Create a count that represents the level of the node tree
            % selection.
%             n = 0;
%             S = selectedNodes;
%             while isprop(S,'Parent')
%                 n = n + 1;
%                 S = S.Parent;
%             end
            % Get the current data from the app.
%             data = getappdata(app.BAR_App.TableFiles, 'data');

            % Find what type of data they want to use and set the right
            % case for the data structure.
%             switch app.Switch.Value
%                 case 'Raw'
%                     type = 'raw';
%                 case 'Processed'
%                     type = 'pro';
%             end

            % Remove existing plots.
%             app.BeforePanel.Children.delete;
%             app.AfterPanel.Children.delete;
            
            % This level covers signal segmentation.
%             if n == 7
%                 
%                 try
%                     % Segment the data.
%                     x = segment(app, 'timeseries');
%                     % If a character was returned there was an error.
%                     if ischar(x)
%                         printLog(app, x)
%                         return
%                     end
%                 catch
%                     % Print a message if there was an issue with the
%                     % segmentation.
%                     printLog(app, '002')
%                     return
%                 end
% 
%                 % Create a figure to hold the plot so it can be copied into
%                 % the module.
%                 H = figure('visible', 'off');
%                 ax1 = axes('Parent', H);
% 
%                 % Plot the raw data.
%                 plot(ax1, x.raw,'k')
%                 hold on
%                 % Get the transitions between segments.
%                 turns = fieldnames(x.res.Segment);
%                 % Find the max and min to create a vertical line.
%                 y = [min(x.raw); max(x.raw)];
%                 for i = 1:length(turns)
%                     % Create vertical lines for each segment transition.
%                     plot(ax1, [x.res.Segment.(turns{i}).data.startFrame; x.res.Segment.(turns{i}).data.startFrame], y, 'g')
%                     plot(ax1, [x.res.Segment.(turns{i}).data.endFrame; x.res.Segment.(turns{i}).data.endFrame], y, 'r')
%                 end
%                 hold off
%                 axis(ax1, 'tight')
% 
%                 % Copy the hidden figure into the app. This allows the same
%                 % figure to be copied elsewhere in the app or into a pdf or
%                 % image.
%                 copyobj(ax1, app.BeforePanel)
% 
%                 % Create a figure to hold the plot so it can be copied into
%                 % the module.
%                 % I = figure('visible', 'off');
% 
%                 % Plot the segmented data in subplots for each segment.
%                 N = length(x.pro);
%                 for i = 1:N
%                     ax2(i) = subplot(N,1,i);
%                     plot(x.pro{i},'k')
%                     set(ax2(i), 'xticklabels', []);
% %                     yticks(ax2(i), [ceil(min(x.pro{i})) - 1, floor(max(x.pro{i})) + 1]);
% %                     yticklabels(ax2(i), {'a', 'b'})
% %                     set(ax2(i), 'yticklabels', []);
%                     axis tight
% 
%                     % Copy the hidden figure into the app. This allows the same
%                     % figure to be copied elsewhere in the app or into a pdf or
%                     % image.
%                     copyobj(ax2(i), app.AfterPanel)
%                 end
% 
%             elseif n == 6
%                 % This level concers objects
% 
%                 try
%                     % Segment the data.
%                     x = segment(app, 'object');
%                     % If a character was returned there was an error.
%                     if ischar(x)
%                         printLog(app, x)
%                         return
%                     end
%                 catch
%                     % Print a message if there was an issue with the
%                     % segmentation.
%                     printLog(app, '003')
%                     return
%                 end
% 
%                 % Create a figure to hold the plot so it can be copied into
%                 % the module.
%                 H = figure('visible', 'off');
%                 ax1 = axes('Parent', H);
% 
%                 % Plot the raw data.
%                 sigs = fieldnames(x.raw);
%                 ymin = inf;
%                 ymax = -inf;
%                 ind = 1;
%                 for j = 1:length(sigs)
%                     plot(ax1, x.raw.(sigs{j}),'k')
%                     hold on
%                     % Get the transitions between segments.
%                     segs = fieldnames(x.res);
%                     % Find the segements with a name based on the signal.
%                     turns = segs(contains(segs, sigs{j}));
%                     % Find the max and min to create a vertical line.
%                     if min(x.raw.(sigs{j})) < ymin
%                         ymin = min(x.raw.(sigs{j}), [], 'all');
%                     end
%                     if max(x.raw.(sigs{j})) > ymax
%                         ymax = max(x.raw.(sigs{j}), [], 'all');
%                     end
%                     % Iterate through the segments.
%                     for i = 1:length(turns)
%                         % Create arrays to make the vertical lines for each segment transition.
%                         turnStart(ind,:) = [x.res.(turns{i}).data.startFrame; x.res.(turns{i}).data.startFrame];
%                         turnEnd(ind,:) = [x.res.(turns{i}).data.endFrame; x.res.(turns{i}).data.endFrame];
%                         ind = ind + 1;
%                     end
%                 end
%                 % Plot the vertical lines.
%                 plot(ax1, turnStart, [ymin; ymax], 'g')
%                 plot(ax1, turnEnd, [ymin; ymax], 'r')
%                 hold off
%                 axis(ax1, 'tight')
% 
%                 % Copy the hidden figure into the app. This allows the same
%                 % figure to be copied elsewhere in the app or into a pdf or
%                 % image.
%                 copyobj(ax1, app.BeforePanel)
% 
%                 % Create a figure to hold the plot so it can be copied into
%                 % the module.
%                 % I = figure('visible', 'off');
% 
%                 % Plot the segmented data in subplots for each segment.
%                 % Get the signal names and the segment names based on them.
%                 sigs = fieldnames(x.raw);
%                 segs = fieldnames(x.pro);
%                 % Create an empty array for the axes to copy to the app.
%                 ax2 = [];
%                 % Iterate through the signals.
%                 for j = 1:length(sigs)
%                     % Find the segements with a name based on the signal.
%                     turns = segs(contains(segs, sigs{j}));
%                     % Iterate through the segments.
%                     for k = 1:length(turns)
%                         % Create the subplot if it hasn't been created yet.
%                         if length(ax2) < k || isempty(ax2)
%                             ax2(k) = subplot(length(turns), 1, k);
%                             hold on
%                         else
%                             subplot(length(turns), 1, k)
%                         end
%                         % Plot the unsegmented data.
%                         plot(x.pro.(turns{k}),'k')
%                         % If this is the last plot remove the axes.
%                         if j == length(sigs)
%                             set(ax2(k), 'xticklabels', []);
% %                             yticks(ax2(k), [ceil(min(x.pro.(turns{k}))) - 1, floor(max(x.pro.(turns{k}))) + 1]);
%                             axis tight
% 
%                             % Copy the hidden figure into the app. This allows the same
%                             % figure to be copied elsewhere in the app or into a pdf or
%                             % image.
%                             copyobj(ax2(k), app.AfterPanel)
%                         end
%                     end
%                 end
%             elseif n == 5
%                 % This level concerns files.
% 
%                 try
%                     % Segment the data.
%                     x = segment(app, 'file');
%                     % If a character was returned there was an error.
%                     if ischar(x)
%                         printLog(app, x)
%                         return
%                     end
%                 catch
%                     % Print a message if there was an issue with the
%                     % segmentation.
%                     printLog(app, '003')
%                     return
%                 end
% 
%                 % Create a figure to hold the plot so it can be copied into
%                 % the module.
%                 H = figure('visible', 'off');
%                 ax1 = axes('Parent', H);
% 
%                 % Plot the raw data.
% 
%                 obj = fieldnames(x.raw);
%                 for k = 1: length(obj)
% 
%                     sigs = fieldnames(x.raw.(obj{k}).data);
% 
%                     ymin = inf;
%                     ymax = -inf;
%                     ind = 1;
%                     % Iterate through the signals.
%                     for j = 1:length(sigs)
%                         plot(ax1, x.raw.(obj{k}).data.(sigs{j}),'k')
%                         hold on
%                         % Get the transitions between segments.
%                         segs = fieldnames(x.res.(obj{k}).data);
%                         % Find the segements with a name based on the signal.
%                         turns = segs(contains(segs, sigs{j}));
%                         % Find the max and min to create a vertical line.
%                         if min(x.raw.(obj{k}).data.(sigs{j})) < ymin
%                             ymin = min(x.raw.(obj{k}).data.(sigs{j}), [], 'all');
%                         end
%                         if max(x.raw.(obj{k}).data.(sigs{j})) > ymax
%                             ymax = max(x.raw.(obj{k}).data.(sigs{j}), [], 'all');
%                         end
%                         % Iterate through the segments.
%                         for i = 1:length(turns)
%                             % Create vertical lines for each segment transition.
%                             turnStart(:, ind) = [x.res.(obj{k}).data.(turns{i}).startFrame; x.res.(obj{k}).data.(turns{i}).startFrame];
%                             turnEnd(:, ind) = [x.res.(obj{k}).data.(turns{i}).endFrame; x.res.(obj{k}).data.(turns{i}).endFrame];
%                             ind = ind + 1;
%                         end
%                     end
%                 end
%                 plot(ax1, turnStart, [ymin; ymax], 'g')
%                 plot(ax1, turnEnd, [ymin; ymax], 'r')
%                 hold off
%                 axis(ax1, 'tight')
% 
%                 % Copy the hidden figure into the app. This allows the same
%                 % figure to be copied elsewhere in the app or into a pdf or
%                 % image.
%                 copyobj(ax1, app.BeforePanel)
% 
%                 % Create a figure to hold the plot so it can be copied into
%                 % the module.
%                 I = figure('visible', 'off');
% 
%                 % Plot the segmented data in subplots for each segment.
%                 objs = fieldnames(x.raw);
%                 % Create an empty array for the axes to copy to the app.
%                 ax2 = [];
%                 for i = 1:length(objs)
%                     % Get the signal names and the segment names based on them.
%                     sigs = fieldnames(x.raw.(objs{i}).data);
%                     segs = fieldnames(x.pro.(objs{i}).data);
%                     % Iterate through the signals.
%                     for j = 1:length(sigs)
%                         % Find the segements with a name based on the signal.
%                         turns = segs(contains(segs, sigs{j}));
%                         % Iterate through the segments.
%                         for k = 1:length(turns)
%                             % Create the subplot if it hasn't been created yet.
%                             if length(ax2) < k || isempty(ax2)
%                                 ax2(k) = subplot(length(turns), 1, k);
%                                 hold on
%                             else
%                                 subplot(length(turns), 1, k)
%                             end
%                             % Plot the unsegmented data.
%                             plot(x.pro.(objs{i}).data.(turns{k}),'k')
%                             % If this is the last plot remove the axes.
%                             if j == length(sigs)
%                                 set(ax2(k), 'xticklabels', []);
% %                                 yticks(ax2(k), [ceil(min(x.pro.(objs{i}).data.(turns{k}))) - 1, floor(max(x.pro.(objs{i}).data.(turns{k}))) + 1]);
%                                 axis tight
% 
%                                 % Copy the hidden figure into the app. This allows the same
%                                 % figure to be copied elsewhere in the app or into a pdf or
%                                 % image.
%                                 copyobj(ax2(k), app.AfterPanel)
%                             end
%                         end
%                     end
%                 end
% 
%             else
%                 % If another level besides a signal or a file is selected nothing will
%                 % be plot.
%                 printLog(app, '001')
%                 return
%             end
% 
%             % If the option to export a figure was selected.
%             if strcmp(app.ExportFiguresSwitch.Value, 'Yes')
% 
%                 % Create a figure the full size of the screen that the
%                 % figure will be copied into.
%                 J = figure('visible', 'off', 'units', 'normalized', 'position', [0 0 1 1]);
%                 % Get how many figures need to be copied.
%                 N = length(app.AfterPanel.Children);
%                 % Create a placeholder axis for the unsegmented data.
%                 ax3(1) = subplot(N, 2, [1:2:N*2]);
%                 % Copy the figure of the unsegmented data.
%                 ax3(1,2) = copyobj(app.BeforePanel.Children, J);
%                 % Use the same position of the axes.
%                 ax3(1,2).Position = ax3(1,1).Position;
%                 % Delete the placeholder.
%                 delete(ax3(1,1))
%                 % Copy each of the segment plots into the figure.
%                 for i = 1:N
%                     % Create a placeholder plot.
%                     ax3(1 + i, 1) = subplot(N, 2, [2*i]);
%                     % Copy the figure.
%                     ax3(1 + i, 2) = copyobj(app.AfterPanel.Children(N - i + 1), J);
%                     % Update the position.
%                     ax3(1 + i, 2).Position = ax3(1 + i, 1).Position;
%                     % Delete the placeholder.
%                     delete(ax3(1 + i, 1))
%                 end
%             end
% 
%             m_FigurePath = app.BAR_App.Database.Value;
%             % The data is saved automatically using the name of the different data levels.
%             if strcmp(app.ExportFiguresSwitch.Value, 'Yes')
%                 if n == 5
%                     saveas(J, [m_FigurePath '\Figures\Segment_Figure01_'  selectedNodes.Text '.jpg'])
%                     savefig(J, [m_FigurePath '\Figures\Segment_Figure01_'  selectedNodes.Text '.fig'])
%                 elseif n == 6
%                     saveas(J, [m_FigurePath '\Figures\Segment_Figure01_'  selectedNodes.Parent.Text '_' selectedNodes.Text '.jpg'])
%                     savefig(J, [m_FigurePath '\Figures\Segment_Figure01_'  selectedNodes.Parent.Text '_' selectedNodes.Text '.fig'])
%                 elseif n == 7
%                     saveas(J, [m_FigurePath '\Figures\Segment_Figure01_'  selectedNodes.Parent.Text '_' selectedNodes.Parent.Text '_' selectedNodes.Text '.jpg'])
%                     savefig(J, [m_FigurePath '\Figures\Segment_Figure01_'  selectedNodes.Parent.Text '_' selectedNodes.Parent.Text '_' selectedNodes.Text '.fig'])
%                 end
%             end

        end

        % Button pushed function: SegmentallsaveandexitButton
        function SegmentallsaveandexitButtonPushed(app, event)
            % SegmentallsaveandexitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information about the app from the change in
            %                  tree selection.
            % Remarks
            % - This method saves the data and sends it back to the BAR
            %   App.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            try
                % Run the segment method on all the data.
                data = segment(app, 'all');
                % If a character is returned print a message and exit.
                if ischar(data)
                    printLog(app, data)
                    return
                end
            catch
                % Print a message and exit if there was an error.
                printLog(app, '002')
                return
            end
            % Send the data back to the BAR App and save it.
            analysisComplete(app.BAR_App, data, app.SelectMethodDropDown.Value, 0)
            % Delete the module.
            delete(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            createListFiles(app)
        end

        % Button pushed function: SegmentSelectionButton
        function SegmentSelectionButtonPushed(app, event)
            
            % Get which nodes are selected.
%             selectedNodes = app.Tree.SelectedNodes;
            % Create a count that represents the level of the node tree
            % selection.
%             n = 0;
%             S = selectedNodes;
%             while isprop(S,'Parent')
%                 n = n + 1;
%                 S = S.Parent;
%             end

            % Get the current data from the app.
            data = getappdata(app.BAR_App.TableFiles, 'data');

            % Find what type of data they want to use and set the right case for the data structure.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Remove existing plots.
            app.BeforePanel.Children.delete;
            app.AfterPanel.Children.delete;
            
            % This level covers signal segmentation.
            if ~isempty(app.FilesListBox.Value) && ~isempty(app.ObjectsListBox.Value) && ~isempty(app.SignalsListBox.Value) && ~strcmp(app.SignalsListBox.Value, 'none')
                
                try
                    % Segment the data.
                    x = segment(app, 'timeseries');
                    % If a character was returned there was an error.
                    if ischar(x)
                        printLog(app, x)
                        return
                    end
                catch
                    % Print a message if there was an issue with the
                    % segmentation.
                    printLog(app, '002')
                    return
                end

                % Create a figure to hold the plot so it can be copied into
                % the module.
                H = figure('visible', 'off');
                ax1 = axes('Parent', H);

                % Plot the raw data.
                plot(ax1, x.raw,'k')
                hold on
                % Get the transitions between segments.
                turns = fieldnames(x.res.Segment);
                % Find the max and min to create a vertical line.
                y = [min(x.raw); max(x.raw)];
                for i = 1:length(turns)
                    % Create vertical lines for each segment transition.
                    plot(ax1, [x.res.Segment.(turns{i}).data.startFrame; x.res.Segment.(turns{i}).data.startFrame], y, 'g')
                    plot(ax1, [x.res.Segment.(turns{i}).data.endFrame; x.res.Segment.(turns{i}).data.endFrame], y, 'r')
                end
                hold off
                axis(ax1, 'tight')


                % Copy the hidden figure into the app. This allows the same
                % figure to be copied elsewhere in the app or into a pdf or
                % image.
                copyobj(ax1, app.BeforePanel)

                % Create a figure to hold the plot so it can be copied into
                % the module.
                % I = figure('visible', 'off');

                % Plot the segmented data in subplots for each segment.
                N = length(x.pro);
                for i = 1:N
                    ax2(i) = subplot(N,1,i);
                    plot(x.pro{i},'k')
                    set(ax2(i), 'xticklabels', []);
%                     yticks(ax2(i), [ceil(min(x.pro{i})) - 1, floor(max(x.pro{i})) + 1]);
%                     yticklabels(ax2(i), {'a', 'b'})
%                     set(ax2(i), 'yticklabels', []);
                    axis tight

                    % Copy the hidden figure into the app. This allows the same
                    % figure to be copied elsewhere in the app or into a pdf or
                    % image.
                    copyobj(ax2(i), app.AfterPanel)
                end

            elseif ~isempty(app.FilesListBox.Value) && ~isempty(app.ObjectsListBox.Value) && ~strcmp(app.ObjectsListBox.Value, 'none') && (isempty(app.SignalsListBox.Value) || strcmp(app.SignalsListBox.Value, 'none'))
                % This level concers objects

                try
                    % Segment the data.
                    x = segment(app, 'object');
                    % If a character was returned there was an error.
                    if ischar(x)
                        printLog(app, x)
                        return
                    end
                catch
                    % Print a message if there was an issue with the
                    % segmentation.
                    printLog(app, '003')
                    return
                end

                % Create a figure to hold the plot so it can be copied into
                % the module.
                H = figure('visible', 'off');
                ax1 = axes('Parent', H);

                % Plot the raw data.
                sigs = fieldnames(x.raw);
                ymin = inf;
                ymax = -inf;
                ind = 1;
                for j = 1:length(sigs)
                    plot(ax1, x.raw.(sigs{j}),'k')
                    hold on
                    % Get the transitions between segments.
                    segs = fieldnames(x.res);
                    % Find the segements with a name based on the signal.
                    turns = segs(contains(segs, sigs{j}));
                    % Find the max and min to create a vertical line.
                    if min(x.raw.(sigs{j})) < ymin
                        ymin = min(x.raw.(sigs{j}), [], 'all');
                    end
                    if max(x.raw.(sigs{j})) > ymax
                        ymax = max(x.raw.(sigs{j}), [], 'all');
                    end
                    % Iterate through the segments.
                    for i = 1:length(turns)
                        % Create arrays to make the vertical lines for each segment transition.
                        turnStart(ind,:) = [x.res.(turns{i}).data.startFrame; x.res.(turns{i}).data.startFrame];
                        turnEnd(ind,:) = [x.res.(turns{i}).data.endFrame; x.res.(turns{i}).data.endFrame];
                        ind = ind + 1;
                    end
                end
                % Plot the vertical lines.
                plot(ax1, turnStart, [ymin; ymax], 'g')
                plot(ax1, turnEnd, [ymin; ymax], 'r')
                hold off
                axis(ax1, 'tight')

                % Copy the hidden figure into the app. This allows the same
                % figure to be copied elsewhere in the app or into a pdf or
                % image.
                copyobj(ax1, app.BeforePanel)

                % Create a figure to hold the plot so it can be copied into
                % the module.
                % I = figure('visible', 'off');

                % Plot the segmented data in subplots for each segment.
                % Get the signal names and the segment names based on them.
                sigs = fieldnames(x.raw);
                segs = fieldnames(x.pro);
                % Create an empty array for the axes to copy to the app.
                ax2 = [];
                % Iterate through the signals.
                for j = 1:length(sigs)
                    % Find the segements with a name based on the signal.
                    turns = segs(contains(segs, sigs{j}));
                    % Iterate through the segments.
                    for k = 1:length(turns)
                        % Create the subplot if it hasn't been created yet.
                        if length(ax2) < k || isempty(ax2)
                            ax2(k) = subplot(length(turns), 1, k);
                            hold on
                        else
                            subplot(length(turns), 1, k)
                        end
                        % Plot the unsegmented data.
                        plot(x.pro.(turns{k}),'k')
                        % If this is the last plot remove the axes.
                        if j == length(sigs)
                            set(ax2(k), 'xticklabels', []);
%                             yticks(ax2(k), [ceil(min(x.pro.(turns{k}))) - 1, floor(max(x.pro.(turns{k}))) + 1]);
                            axis tight

                            % Copy the hidden figure into the app. This allows the same
                            % figure to be copied elsewhere in the app or into a pdf or
                            % image.
                            copyobj(ax2(k), app.AfterPanel)
                        end
                    end
                end
            elseif ~isempty(app.FilesListBox.Value) && (isempty(app.ObjectsListBox.Value) || strcmp(app.ObjectsListBox.Value, 'none')) && (isempty(app.SignalsListBox.Value) || strcmp(app.SignalsListBox.Value, 'none'))
                % This level concerns files.

                try
                    % Segment the data.
                    x = segment(app, 'file');
                    % If a character was returned there was an error.
                    if ischar(x)
                        printLog(app, x)
                        return
                    end
                catch
                    % Print a message if there was an issue with the
                    % segmentation.
                    printLog(app, '003')
                    return
                end

                % Create a figure to hold the plot so it can be copied into
                % the module.
                H = figure('visible', 'off');
                ax1 = axes('Parent', H);

                % Plot the raw data.

                obj = fieldnames(x.raw);
                for k = 1: length(obj)

                    sigs = fieldnames(x.raw.(obj{k}).data);

                    ymin = inf;
                    ymax = -inf;
                    ind = 1;
                    % Iterate through the signals.
                    for j = 1:length(sigs)
                        plot(ax1, x.raw.(obj{k}).data.(sigs{j}),'k')
                        hold on
                        % Get the transitions between segments.
                        segs = fieldnames(x.res.(obj{k}).data);
                        % Find the segements with a name based on the signal.
                        turns = segs(contains(segs, sigs{j}));
                        % Find the max and min to create a vertical line.
                        if min(x.raw.(obj{k}).data.(sigs{j})) < ymin
                            ymin = min(x.raw.(obj{k}).data.(sigs{j}), [], 'all');
                        end
                        if max(x.raw.(obj{k}).data.(sigs{j})) > ymax
                            ymax = max(x.raw.(obj{k}).data.(sigs{j}), [], 'all');
                        end
                        % Iterate through the segments.
                        for i = 1:length(turns)
                            % Create vertical lines for each segment transition.
                            if length(x.res.(obj{k}).data.(turns{i}).startFrame) == 1
                                turnStart(:, ind) = [x.res.(obj{k}).data.(turns{i}).startFrame; x.res.(obj{k}).data.(turns{i}).startFrame];
                                turnEnd(:, ind) = [x.res.(obj{k}).data.(turns{i}).endFrame; x.res.(obj{k}).data.(turns{i}).endFrame];
                                ind = ind + 1;
                            elseif length(x.res.(obj{k}).data.(turns{i}).startFrame) > 1
                                for ind_segs = 1:length(x.res.(obj{k}).data.(turns{i}).startFrame)
                                    turnStart(:, ind) = [x.res.(obj{k}).data.(turns{i}).startFrame(ind_segs); x.res.(obj{k}).data.(turns{i}).startFrame(ind_segs)];
                                    turnEnd(:, ind) = [x.res.(obj{k}).data.(turns{i}).endFrame(ind_segs); x.res.(obj{k}).data.(turns{i}).endFrame(ind_segs)];
                                    ind = ind + 1;
                                end
                            end
                        end
                    end
                end
                plot(ax1, turnStart, [ymin; ymax], 'g')
                plot(ax1, turnEnd, [ymin; ymax], 'r')
                hold off
                axis(ax1, 'tight')
                title(join(data.raw.(app.FilesListBox.Value).groups))

                % Copy the hidden figure into the app. This allows the same
                % figure to be copied elsewhere in the app or into a pdf or
                % image.
                copyobj(ax1, app.BeforePanel)

                % Create a figure to hold the plot so it can be copied into
                % the module.
                I = figure('visible', 'off');

                % Plot the segmented data in subplots for each segment.
                objs = fieldnames(x.raw);
                % Create an empty array for the axes to copy to the app.
                ax2 = [];
                for i = 1:length(objs)
                    % Get the signal names and the segment names based on them.
                    sigs = fieldnames(x.raw.(objs{i}).data);
                    segs = fieldnames(x.pro.(objs{i}).data);
                    % Iterate through the signals.
                    for j = 1:length(sigs)
                        % Find the segements with a name based on the signal.
                        turns = segs(contains(segs, sigs{j}));
                        % Iterate through the segments.
                        for k = 1:length(turns)
                            % Create the subplot if it hasn't been created yet.
                            if length(ax2) < k || isempty(ax2)
                                ax2(k) = subplot(length(turns), 1, k);
                                hold on
                            else
                                subplot(length(turns), 1, k)
                            end
                            % Plot the unsegmented data.
                            plot(x.pro.(objs{i}).data.(turns{k}),'k')
                            % If this is the last plot remove the axes.
                            if j == length(sigs)
                                set(ax2(k), 'xticklabels', []);
%                                 yticks(ax2(k), [ceil(min(x.pro.(objs{i}).data.(turns{k}))) - 1, floor(max(x.pro.(objs{i}).data.(turns{k}))) + 1]);
                                axis tight

                                % Copy the hidden figure into the app. This allows the same
                                % figure to be copied elsewhere in the app or into a pdf or
                                % image.
                                copyobj(ax2(k), app.AfterPanel)
                            end
                        end
                    end
                end

            else
                % If another level besides a signal or a file is selected nothing will
                % be plot.
                printLog(app, '001')
                return
            end

            % If the option to export a figure was selected.
            if strcmp(app.ExportFiguresSwitch.Value, 'Yes')

                % Create a figure the full size of the screen that the
                % figure will be copied into.
                J = figure('visible', 'off', 'units', 'normalized', 'position', [0 0 1 1]);
                % Get how many figures need to be copied.
                N = length(app.AfterPanel.Children);
                % Create a placeholder axis for the unsegmented data.
                ax3(1) = subplot(N, 2, [1:2:N*2]);
                % Copy the figure of the unsegmented data.
                ax3(1,2) = copyobj(app.BeforePanel.Children, J);
                % Use the same position of the axes.
                ax3(1,2).Position = ax3(1,1).Position;
                % Delete the placeholder.
                delete(ax3(1,1))
                % Copy each of the segment plots into the figure.
                for i = 1:N
                    % Create a placeholder plot.
                    ax3(1 + i, 1) = subplot(N, 2, [2*i]);
                    % Copy the figure.
                    ax3(1 + i, 2) = copyobj(app.AfterPanel.Children(N - i + 1), J);
                    % Update the position.
                    ax3(1 + i, 2).Position = ax3(1 + i, 1).Position;
                    % Delete the placeholder.
                    delete(ax3(1 + i, 1))
                end
            end

            m_FigurePath = app.BAR_App.Database.Value;
            % The data is saved automatically using the name of the different data levels.
            if strcmp(app.ExportFiguresSwitch.Value, 'Yes')
                % Create the figure for files.
                if ~isempty(app.FilesListBox.Value) && (isempty(app.ObjectsListBox.Value) || strcmp(app.ObjectsListBox.Value, 'none')) && (isempty(app.SignalsListBox.Value) || strcmp(app.SignalsListBox.Value, 'none'))
                    saveas(J, [m_FigurePath '\Figures\Segment_Figure01_' app.FilesListBox.Value '.jpg'])
                    savefig(J, [m_FigurePath '\Figures\Segment_Figure01_' app.FilesListBox.Value '.fig'])
                elseif ~isempty(app.FilesListBox.Value) && ~isempty(app.ObjectsListBox.Value) && ~strcmp(app.ObjectsListBox.Value, 'none') && (isempty(app.SignalsListBox.Value) || strcmp(app.SignalsListBox.Value, 'none'))
                    saveas(J, [m_FigurePath '\Figures\Segment_Figure01_'  app.FilesListBox.Value '_' app.ObjectsListBox.Value '.jpg'])
                    savefig(J, [m_FigurePath '\Figures\Segment_Figure01_'  app.FilesListBox.Value '_' app.ObjectsListBox.Value '.fig'])
                elseif ~isempty(app.FilesListBox.Value) && ~isempty(app.ObjectsListBox.Value) && ~isempty(app.SignalsListBox.Value) && ~strcmp(app.SignalsListBox.Value, 'none')
                    saveas(J, [m_FigurePath '\Figures\Segment_Figure01_'  app.FilesListBox.Value '_' app.ObjectsListBox.Value '_' app.SignalsListBox.Value '.jpg'])
                    savefig(J, [m_FigurePath '\Figures\Segment_Figure01_'  app.FilesListBox.Value '_' app.ObjectsListBox.Value '_' app.SignalsListBox.Value '.fig'])
                end
            end
        end

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)
             % createLists(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method will populate the various dropdown lists in the app. In the method a very long list will be made, and then only the unique
            %   ones will be used. This allows items that are present in some levels but not others to still be used.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            value = app.FilesListBox.Value;

            % Get the current data.
            data = getappdata(app.BAR_App.TableFiles, 'data');

            % Get the current data type.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Get the list of objects and remove the informational ones.
            obj = fieldnames(data.(type).(value));
            obj(strcmp(obj, 'meta')) = [];
            obj(strcmp(obj, 'groups')) = [];

            % Set the list boxes to the unique values while adding an 'all' option to the top.
            app.ObjectsListBox.Items = [{'none'}; obj];

            app.ObjectsListBox.Value = {};

            if strcmp(app.SegmentonSelectionChangeSwitch.Value, 'Yes')
                app.SegmentSelectionButtonPushed
            end

        end

        % Value changed function: ObjectsListBox
        function ObjectsListBoxValueChanged(app, event)
            % createLists(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method will populate the various dropdown lists in the app. In the method a very long list will be made, and then only the unique
            %   ones will be used. This allows items that are present in some levels but not others to still be used.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            file = app.FilesListBox.Value;
            obj = app.ObjectsListBox.Value;

            if strcmp(obj, 'none')

                app.SignalsListBox.Items = {};

            else

                % Get the current data.
                data = getappdata(app.BAR_App.TableFiles, 'data');

                % Get the current data type.
                switch app.Switch.Value
                    case 'Raw'
                        type = 'raw';
                    case 'Processed'
                        type = 'pro';
                end

                % Get the list of signals
                sig = fieldnames(data.(type).(file).(obj).data);

                app.SignalsListBox.Items = [{'none'}; sig];

                if strcmp(app.SegmentonSelectionChangeSwitch.Value, 'Yes')
                    app.SegmentSelectionButtonPushed
                end

            end

        end

        % Value changed function: SignalsListBox
        function SignalsListBoxValueChanged(app, event)
            if strcmp(app.SegmentonSelectionChangeSwitch.Value, 'Yes')
                app.SegmentSelectionButtonPushed
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.DataSegmentationLabel = uilabel(app.UIFigure);
            app.DataSegmentationLabel.Text = 'Data Segmentation';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.AfterPanel = uipanel(app.UIFigure);
            app.AfterPanel.Title = 'After';
            app.SelectMethodDropDownLabel = uilabel(app.UIFigure);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.UIFigure);
            app.SelectMethodDropDown.Items = {};
            app.SelectMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SelectMethodDropDown.Value = {};
            app.SegmentallsaveandexitButton = uibutton(app.UIFigure, 'push');
            app.SegmentallsaveandexitButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentallsaveandexitButtonPushed, true);
            app.SegmentallsaveandexitButton.Text = 'Segment all, save and exit.';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Raw';
            app.BeforePanel = uipanel(app.UIFigure);
            app.BeforePanel.Title = 'Before';
            app.ExportFiguresSwitchLabel = uilabel(app.UIFigure);
            app.ExportFiguresSwitchLabel.Text = 'Export Figures';
            app.ExportFiguresSwitch = uiswitch(app.UIFigure, 'slider');
            app.ExportFiguresSwitch.Items = {'No', 'Yes'};
            app.ExportFiguresSwitch.Value = 'No';
            app.Version = uilabel(app.UIFigure);
            app.FilesListBoxLabel = uilabel(app.UIFigure);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.UIFigure);
            app.FilesListBox.Items = {};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.ObjectsLabel = uilabel(app.UIFigure);
            app.ObjectsLabel.Text = 'Objects';
            app.ObjectsListBox = uilistbox(app.UIFigure);
            app.ObjectsListBox.Items = {};
            app.ObjectsListBox.ValueChangedFcn = createCallbackFcn(app, @ObjectsListBoxValueChanged, true);
            app.ObjectsListBox.Value = {};
            app.SignalsListBoxLabel = uilabel(app.UIFigure);
            app.SignalsListBoxLabel.Text = 'Signals';
            app.SignalsListBox = uilistbox(app.UIFigure);
            app.SignalsListBox.Items = {};
            app.SignalsListBox.ValueChangedFcn = createCallbackFcn(app, @SignalsListBoxValueChanged, true);
            app.SignalsListBox.Value = {};
            app.SegmentSelectionButton = uibutton(app.UIFigure, 'push');
            app.SegmentSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentSelectionButtonPushed, true);
            app.SegmentSelectionButton.Text = 'Segment Selection';
            app.SegmentonSelectionChangeSwitchLabel = uilabel(app.UIFigure);
            app.SegmentonSelectionChangeSwitchLabel.Text = 'Segment on Selection Change';
            app.SegmentonSelectionChangeSwitch = uiswitch(app.UIFigure, 'slider');
            app.SegmentonSelectionChangeSwitch.Items = {'No', 'Yes'};
            app.SegmentonSelectionChangeSwitch.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Segment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end