classdef process_Surrogation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        ThielerTab                matlab.ui.container.Tab
        Algorithm2CheckBox        matlab.ui.control.CheckBox
        Algorithm1CheckBox        matlab.ui.control.CheckBox
        Algorithm0CheckBox        matlab.ui.control.CheckBox
        NumberofSurrogatestoCreateSpinner  matlab.ui.control.Spinner
        NumberofSurrogatestoCreateSpinnerLabel  matlab.ui.control.Label
        ProcessButton             matlab.ui.control.Button
        Label2                    matlab.ui.control.Label
        Version                   matlab.ui.control.Label
        SelectthesignalstoprocessListBox  matlab.ui.control.ListBox
        SelectthesignalstoprocessListBoxLabel  matlab.ui.control.Label
        Switch_2                  matlab.ui.control.Switch
        SaveResultsandExitButton  matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        Image                     matlab.ui.control.Image
        SurrogationLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        DAMVi_App % Main app object
        Data % Container for data from DAMVi
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of the error and organize the errors available.
            %         - newText, a string that may be inserted into the message.
            % Remarks
            % - This method will print messages to the log of the BAR App. The error codes are unique to each message so they can be found and 
            %   referenced easier.
            % - The method is public and is acessible outside of the BAR App. It is used in some of the example analysis and process scripts.
            % Future Work
            % - This log should be printed and saved to a file location each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text within the log.
            text = app.DAMVi_App.Log.Value;

            % Match the error code with the message and update the text for
            % the log.
            switch code
                case '001'
                    text{length(text) + 1, 1} = ['SUR-001 starting to create ' newText ' surrogates'];
                case '002'
                    text{length(text) + 1, 1} = 'SUR-002 surrogate creation complete';
                case '003'
                    text{length(text) + 1, 1} = ['SUR-003 analysis failed on: ' newText];
                case '004'
                    text{length(text) + 1, 1} = ['SUR-004 ' newText];
                case '005'
                    text{length(text) + 1, 1} = 'SUR-005 existing analysis, more than 10% have failed';
                case '006'
                    text{length(text) + 1, 1} = ['SUR-006 ' newText];
            end

            % Create a date-time for the message.
            text{end, 1} = [char(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z')) ' | ' text{end, 1}];
            % Add the message to the log.
            app.DAMVi_App.Log.Value = text;
            % Scroll down so the message is visible.
            scroll(app.DAMVi_App.Log,'bottom')
            % Make sure the app updates.
            drawnow
            % Write to the log file.
            writeLog(app.DAMVi_App, text{end, 1})
        end

        function updatelist(app)
            % updatelist(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the signal list within the app with the currently selected data type.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Find which data type was selected.
            switch app.Switch_2.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end
            % Initialize the list with an 'all' option.
            sigsAll = {'all'};
            % If the data type doesn't exist then exit.
            if ~isfield(app.Data, type)
                return
            end
            % Get the file names.
            files = fieldnames(app.Data.(type));
            % Iterate through the file names.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(app.Data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects
                for j = 1:length(objs)
                    % Append the signals to the list.
                    sigsAll = [sigsAll; fieldnames(app.Data.(type).(files{i}).(objs{j}).data)];
                end
            end
            % Set the unique signals to the list box.
            app.SelectthesignalstoprocessListBox.Items = unique(sigsAll);

        end

        function processData(app, surrogation)
            % processData(app, surrogation)
            % inputs  - app, required mlapp object.
            %         - surrogation, a string of the type of surrogation to perform.
            % Remarks
            % - This method starts the surrogation method. It pulls much of the inputs from the app interface. A different button is in each tab and 
            %   each maps to this analysis method.
            % - Since the method creates a bunch of new signals they are saved to the processed data. It is assumed that surrogates will be processed
            %   the same as the original so the original is included as the first dimension of the surrogate signal.
            % - The surrogation method and surrogate number are saved to the signal name.
            % Future Work
            % - A couple other methods could be added as options.
            % Jul 2023 - Created by Ben Senderling, bsender@bu.edu

            % Empty the current table.
            app.UITable.Data = [];

            % Get the selected level of data to process.
            switch app.Switch_2.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Initialize an index for the array.
            ind = 1;

            % Initialize a variable to count the fails.
            fails = 0;

            % Get the user selected signals.
            sigsSelected = app.SelectthesignalstoprocessListBox.Value;

            % This tag is used for a switch/case later.
            switch surrogation
                case 'theiler'
                    surrogation = 'Theiler';
            end

            printLog(app, '001', surrogation)

            % This section chooses the method based on which button was pressed.
            switch surrogation
                
                case {'Theiler'}
                    
                    % Get the field names of all the files that were loaded.
                    files = fieldnames(app.Data.(type));

                    for ind_file = 1:length(files)
                        % Get the fieldnames for all the signals that were contained within that file. This could be different foreach file so it is 
                        % within the loop.
                        objs = fieldnames(app.Data.(type).(files{ind_file}));
                        objs(strcmp(objs, 'groups')) = [];
                        objs(strcmp(objs, 'meta')) = [];
                        % Iterate through the objects.
                        for ind_obj = 1:length(objs)
                            % Get the user selected signals.
                            sigs = fieldnames(app.Data.(type).(files{ind_file}).(objs{ind_obj}).data);
                            % Iterate through the signals.
                            for ind_sig = 1:length(sigs)
                                % Check if 'all' or nothing was selected or the current signal matches the selection.
                                if any(contains(sigsSelected, 'all')) || isempty(sigsSelected) || any(strcmp(app.SelectthesignalstoprocessListBox.Value, sigs{ind_sig}))
                                    % Get the number of dimensions to use.
                                    m = size(app.Data.(type).(files{ind_file}).(objs{ind_obj}).data.(sigs{ind_sig}), 2);

                                    % Iterate through the dimensions.
                                    for ind_dim = 1:m

                                        % Create a string to use in the new signal name. Numbers up to 9,999 will all be four digits long. (Ex 0001)
                                        if ind_sig < 10
                                            dim_string = ['00' num2str(ind_dim)];
                                        elseif ind_dim < 100
                                            dim_string = ['0' num2str(ind_dim)];
                                        elseif ind_dim < 1000
                                            dim_string = num2str(ind_dim);
                                        else
                                            dim_string = num2str(ind_dim);
                                        end

                                        try

                                            for ind_surrogates = 1:app.NumberofSurrogatestoCreateSpinner.Value

                                                switch surrogation
                                                    case 'Theiler'
                                                        % Run algorithm 0.
                                                        if app.Algorithm0CheckBox.Value == 1
                                                            % If a surrogate has not been created yet put the original signal as the first dimension.
                                                            if ~(isfield(app.Data, 'pro') && isfield(app.Data.pro, files{ind_file}) && isfield(app.Data.pro.(files{ind_file}), objs{ind_obj}) && isfield(app.Data.pro.(files{ind_file}).(objs{ind_obj}).data, [sigs{ind_sig} '_' dim_string '_A0']))
                                                                app.Data.pro.(files{ind_file}).(objs{ind_obj}).data.([sigs{ind_sig} '_' dim_string '_A0'])(:, ind_surrogates) = app.Data.(type).(files{ind_file}).(objs{ind_obj}).data.(sigs{ind_sig})(:, ind_dim);
                                                            end
                                                            app.Data.pro.(files{ind_file}).(objs{ind_obj}).data.([sigs{ind_sig} '_' dim_string '_A0'])(:, ind_surrogates + 1) = Surr_Theiler(app.Data.(type).(files{ind_file}).(objs{ind_obj}).data.(sigs{ind_sig})(:, ind_dim), 0);
                                                        end
                                                        % Run algorithm 1
                                                        if app.Algorithm1CheckBox.Value == 1
                                                            % If a surrogate has not been created yet put the original signal as the first dimension.
                                                            if isfield(app.Data, 'pro') && isfield(app.Data.pro, files{ind_file}) && isfield(app.Data.pro.(files{ind_file}), objs{ind_obj}) && ~isfield(app.Data.pro.(files{ind_file}).(objs{ind_obj}).data, [sigs{ind_sig} '_' dim_string '_A1'])
                                                                app.Data.pro.(files{ind_file}).(objs{ind_obj}).data.([sigs{ind_sig} '_' dim_string '_A1'])(:, ind_surrogates) = app.Data.(type).(files{ind_file}).(objs{ind_obj}).data.(sigs{ind_sig})(:, ind_dim);
                                                            end
                                                            app.Data.pro.(files{ind_file}).(objs{ind_obj}).data.([sigs{ind_sig} '_' dim_string '_A1'])(:, ind_surrogates + 1) = Surr_Theiler(app.Data.(type).(files{ind_file}).(objs{ind_obj}).data.(sigs{ind_sig})(:, ind_dim), 1);
                                                        end
                                                        % Run algorithm 2
                                                        if app.Algorithm2CheckBox.Value == 1
                                                            % If a surrogate has not been created yet put the original signal as the first dimension.
                                                            if isfield(app.Data, 'pro') && isfield(app.Data.pro, files{ind_file}) && isfield(app.Data.pro.(files{ind_file}), objs{ind_obj}) && ~isfield(app.Data.pro.(files{ind_file}).(objs{ind_obj}).data, [sigs{ind_sig} '_' dim_string '_A2'])
                                                                app.Data.pro.(files{ind_file}).(objs{ind_obj}).data.([sigs{ind_sig} '_' dim_string '_A2'])(:, ind_surrogates) = app.Data.(type).(files{ind_file}).(objs{ind_obj}).data.(sigs{ind_sig})(:, ind_dim);
                                                            end
                                                            app.Data.pro.(files{ind_file}).(objs{ind_obj}).data.([sigs{ind_sig} '_' dim_string '_A2'])(:, ind_surrogates + 1) = Surr_Theiler(app.Data.(type).(files{ind_file}).(objs{ind_obj}).data.(sigs{ind_sig})(:, ind_dim), 2);
                                                        end

                                                        % Carry the sampling frequency over.
                                                        if isfield(app.Data.(type).(files{ind_file}).(objs{ind_obj}), 'freq') && ~isfield(app.Data.pro.(files{ind_file}).(objs{ind_obj}), 'freq')
                                                            app.Data.pro.(files{ind_file}).(objs{ind_obj}).freq = app.Data.raw.(files{ind_file}).(objs{ind_obj}).freq;
                                                        end

                                                end

                                            end

                                        catch ME
                                            % If there was an error the exception is caught and the fail count is incremented by 1.
                                            fails = fails + 1;

                                            % Print a message to the log if the load fails for any reason.
                                            printLog(app, '003', ['file: ' files{ind_file} ', object: ' objs{ind_obj} ', signal: ' sigs{ind_sig} ', dimension: ' ind_dim])

                                            % Get MATLAB's last error message and display it.
                                            printLog(app, '006', ME.message)
                                        end

                                        % If more than 10% of the time series fail stop the analysis.
                                        if fails/length(files) > 0.1
                                            printLog(app, '005')
                                            return
                                        end

                                    end
                                end
                            end
                        end

                        % Every 10 files print an update message.
                        if rem(ind_file, 10) == 0
                            printLog(app, '004', ['finished ' num2str(ind_file) ' of ' num2str(height(files)) ' files'])
                            % Sent the table structure to the module's table and set the column names to the fieldnames.
                            % app.UITable.Data = struct2table(dataTable);
                            % app.UITable.ColumnName = fieldnames(dataTable);
                        end
                    end

            end
            % Print a message complete message to the log.
            printLog(app, '002')
            % Save the data to the module.
            %setappdata(app.UITable, 'data', data)
            % Sent the table structure to the module's table and set the column names to the fieldnames.
            % app.UITable.Data = struct2table(dataTable);
            % app.UITable.ColumnName = fieldnames(dataTable);

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % StartupFcn(app, mainapp, data, analysis)
            % inputs  - app, required mlapp object.
            %         - mlapp, BAR App objects.
            %         - data, BAR App data structure.
            %         - analysis, string of the analysis name.
            % Remarks
            % - This is the startup method that initiallized the app.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.DAMVi_App = mainapp;
            % Save the data into the module.
            app.Data = data;
            % Initialize the signal list in the app.
            updatelist(app)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.DAMVi_App.Version.Text;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

            processData(app, 'theiler')

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app from the button press.
            % Remarks
            % - This method will send the data back to the BAR App and save
            %   it.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % The analysis name that will be used to save the data.
            analysis = 'Surrogation';
            % Save the data using a DAMVi_App method.
            analysisComplete(app.DAMVi_App, app.Data, analysis, 0)
            % Close the app.
            delete(app)
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            updatelist(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.SurrogationLabel = uilabel(app.UIFigure);
            app.SurrogationLabel.Text = 'Surrogation';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.Switch_2 = uiswitch(app.UIFigure, 'slider');
            app.Switch_2.Items = {'Raw', 'Processed'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Raw';
            app.SelectthesignalstoprocessListBoxLabel = uilabel(app.UIFigure);
            app.SelectthesignalstoprocessListBoxLabel.Text = 'Select the signals to process';
            app.SelectthesignalstoprocessListBox = uilistbox(app.UIFigure);
            app.SelectthesignalstoprocessListBox.Items = {};
            app.SelectthesignalstoprocessListBox.Multiselect = 'on';
            app.SelectthesignalstoprocessListBox.Value = {};
            app.Version = uilabel(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ThielerTab = uitab(app.TabGroup);
            app.ThielerTab.Title = 'Thieler';
            app.Label2 = uilabel(app.ThielerTab);
            app.Label2.Text = 'Check the algorithms to perform';
            app.ProcessButton = uibutton(app.ThielerTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.NumberofSurrogatestoCreateSpinnerLabel = uilabel(app.ThielerTab);
            app.NumberofSurrogatestoCreateSpinnerLabel.Text = 'Number of Surrogates to Create';
            app.NumberofSurrogatestoCreateSpinner = uispinner(app.ThielerTab);
            app.Algorithm0CheckBox = uicheckbox(app.ThielerTab);
            app.Algorithm0CheckBox.Text = 'Algorithm 0';
            app.Algorithm1CheckBox = uicheckbox(app.ThielerTab);
            app.Algorithm1CheckBox.Text = 'Algorithm 1';
            app.Algorithm2CheckBox = uicheckbox(app.ThielerTab);
            app.Algorithm2CheckBox.Text = 'Algorithm 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Surrogation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end