classdef process_Treatment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Version                    matlab.ui.control.Label
        SelectStepDropDown_2       matlab.ui.control.DropDown
        SelectStepDropDown_2Label  matlab.ui.control.Label
        RemoveStepButton           matlab.ui.control.Button
        AddStepButton              matlab.ui.control.Button
        SelectStepDropDown         matlab.ui.control.DropDown
        SelectStepDropDownLabel    matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        ProcessButton              matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        TreatmentLabel             matlab.ui.control.Label
    end


    properties (Access = private) 
        DAMVi_App % Main app object
        Data % Container for data from DAMVi
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of the error and organize the errors available.
            %         - newText, a string that may be inserted into the message.
            % Remarks
            % - This method will print messages to the log of the BAR App. The error codes are unique to each message so they can be found and 
            %   referenced easier.
            % - The method is public and is acessible outside of the BAR App. It is used in some of the example analysis and process scripts.
            % Future Work
            % - This log should be printed and saved to a file location each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Get the current text from the BAR App.
            text = app.DAMVi_App.Log.Value;
            % Match the error code with the message.
            switch code
                case '001'
                    text{length(text) + 1,1} = 'TRT-001 resample is not currently designed for non-integer resampling, rounding the sampling frequency to the nearest integer';
                case '002'
                    text{length(text) + 1,1} = ['TRT-002 add step: ' newText];
                case '003'
                    text{length(text) + 1,1} = ['TRT-003 removed step: ' newText];
                case '004'
                    text{length(text) + 1,1} = 'TRT-004 processing started';
                case '005'
                    text{length(text) + 1,1} = 'TRT-005 processing complete';
                case '006'
                    text{length(text) + 1,1} = ['TRT-006: ' newText];
            end

            % Create a date-time for the message.
            text{end, 1} = [char(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z')) ' | ' text{end, 1}];

            % Update the log.
            app.DAMVi_App.Log.Value = text;

            % Scroll to the bottom so the most recent message is visible.
            scroll(app.DAMVi_App.Log,'bottom')
            drawnow

            % Write to the log.
            writeLog(app.DAMVi_App, text{end, 1})

        end
        
        function createGlobalProperties(app)
            % createGlobalProperties(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method parses the data for sampling frequencies and collects them into a single object saved within the app. This is used by
            %   mini-modules to help in parameter selection.
            % - This method does not discriminate by data type. If different data have different sampling frequencies this treats them all the same.
            % Future Work
            % - Perhaps there is somewhere here or elsewhere in the app the limit which data is parsed. Maybe this could be done similar to the
            %   analysis modules where the signals to be analyzed can be selected, only here it would be the objects as they contain the frequency
            %   information.
            % - If other object properties are added they could be collected here as well.
            % Dec 2022 - Created by Ben Senderling, bms322@drexel.edu
            % Nov 2023 - Modified by Ben Senderling, bms322@drexel.edu
            %          - Add an initilization to the globalProperties object so it can be empty.

            % Get the data from the app.
            data = getappdata(app.UITable, 'data');

            % Get the files to iterate through.
            files = fieldnames(app.Data.raw);
            % Create an index used for the global parameters.
            ind = 0;
            % Create a flag that will help detect non-integer sampling frequencies.
            flag = 0;
            % Initialize the properties variable.
            globalProperties = struct;
            % Iterate through the files.
            for i = 1:length(files)
                % Get the object names.
                objs = fieldnames(app.Data.raw.(files{i}));
                % Iterate through the objects.
                for j = 1:length(objs)
                    % Only the objects with a sampling frequency will be used.
                    if isfield(app.Data.raw.(files{i}).(objs{j}), 'freq')
                        % Check if the sampling frequency is not an integer and change the flag.
                        if ~isinteger(app.Data.raw.(files{i}).(objs{j}).freq)
                            flag = 1;
                        end
                        % Add the sampling frequency to the global properties.
                        globalProperties.freq(ind + 1, 1) = round(app.Data.raw.(files{i}).(objs{j}).freq);
                        ind = ind + 1;
                    end
                end
            end

            % If the flag was made true print to the log a message saying it will be rounded in some treatment methods.
            if flag
                printLog(app, '001', [])
            end

            % Save the results to the app.
            setappdata(app.UITable, 'globalProperties', globalProperties)
            
        end

        function identifyTreatmentOptions(app)
            % identifyTreatmentOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Process folder for valid files that would be used to treat data within the app. These files need to have a
            %   name process_Treatment*.m. The results will be saved to the configuration file and made selectable in the app.
            % - The goal of this function was to make the addition of new functions easier.
            % - The idea of processing is to take an input and create a new ouput of similar size and scale.
            % Future Work
            % - None
            % Dec 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Dec 2025 - Modified by Ben Senderling, bensenderling@gmail.com
            %          - MATLAB no longer allows stanalone apps to be compiled with the feval function. Removed it, along with the code
            %            to find the treatment options. These are now hard-coded.
            
            % Load the existing configuration so it can be updated.
            config = loadConfig(app.DAMVi_App);

            % Empty the available treatment files from the saved configuration.
            %config.options.treatment = [];
            %ind = 1;

            %for j = 1:length(app.BAR_App.FilesProcess.Value)

                % Use the directory entered into the app to find the processing scripts.
            %    m_files = dir(app.BAR_App.FilesProcess.Value{j});

                % Look for files within the directory. If the file names contain the word 'Treatment' and a third part save them within the app and 
                % configuration.
            %    for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are currently being edited. There are not viable options.
            %        if contains(m_files(i).name, 'process') && contains(m_files(i).name, 'Treatment') && ~contains(m_files(i).name, 'asv')
            %            [~, name] = fileparts(m_files(i).name);
            %            parts = textscan(name, '%s%s%s', 'Delimiter', '_');
            %            if ~isempty(parts{3})
                            % The third part of the file name is used for the options.
            %                config.options.treatment{ind,1} = parts{3}{1};
            %                ind = ind + 1;
            %            end
            %        end
            %    end
            %end

            % If there are no available options or if the directory is misleading nothing will be found. The object in the app can't be set to empty.
            %if ~isempty(config.options.treatment)
                app.SelectStepDropDown.Items = config.options.treatment;
            %end

            % Save the configuration with the updated directory and or file extensions and formats.
            %saveConfig(app.BAR_App, config)
            
        end
    end
    
    methods (Access = public)
        
        function addStep(app, properties)
            % addStep(app, properties)
            % inputs  - app, required mlapp object.
            %         - properties, object from the mini-module with the properties structure for the treatment method.
            % Remarks
            % - This method adds a selected step to the table in the app. It displays basic information but not the properties of the treatment method
            %   as those are in a structure format.
            % Future Work
            % - The treatment properties could be converted to strings and additional displayed in the table.
            % - An export tool could be useful to save the treatment methods.
            % Dec 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Get the steps from the table.
            steps = app.UITable.Data;

            % Check if anything has been entered yet.
            if isempty(steps) || height(steps) == 0
                steps = struct;
                % If this is the first entry into the table.
                % The step number is used to identify repeated treatment steps.
                steps.Number = num2str(1);
                % The name of the treatment.
                steps.Treatment{1} = properties.type;
                % The various properties.
                steps.Parameters{1} = properties;
                % Send the data to the table.
                app.UITable.Data = struct2table(steps);
                % Update the column names.
                app.UITable.ColumnName = fieldnames(steps);
            else
                % If there are existing steps append the new step to the table.
                steps = [steps;{num2str(height(steps) + 1), properties.type, properties}];
                % Update the table.
                app.UITable.Data = steps;
            end

            % Update the dropdown used to remove steps with the new one.
            app.SelectStepDropDown_2.Items = join([steps.Number(:), steps.Treatment(:)]);

            % Print to the log a step has been added.
            printLog(app, '002', properties.type)

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, process)
            % StartupFcn(app, mainapp, data, process)
            % inputs  - app, required mlapp object.
            %         - mainapp, BAR_App object.
            %         - data, BAR App data structure.
            %         - process, a string with the processing name.
            % Remarks
            % - This is the mandetory startup method for the module.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bensenderling@gmail.com
            
            % Store main app in property for CloseRequestFcn to use
            app.DAMVi_App = mainapp;
            % Save the data to the app.
            % Save the data into the module.
            app.Data = data;
            % Find the mini-modules.
            identifyTreatmentOptions(app)
            % Identify the global property options from the data.
            createGlobalProperties(app)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.DAMVi_App.Version.Text;
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: AddStepButton
        function AddStepButtonPushed(app, event)
            % AddStepButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information about the app from the time of the button press.
            % Remarks
            % - This method runs a mini-module to allow the user to select treatment parameters and add it to the table of treatment steps.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Dec 2025 - Modified by Ben Senderling, bensenderling@gmail.com
            %          - Removed use of the feval function since MATLAB no longer allows code using it to be compiled as a standalone
            %            app. The treatment options are now hard-coded.

            % Get the global properties so it can be sent to the mini-module.
            globalProperties = getappdata(app.UITable, 'globalProperties');
            % Run the mini-module.
            switch app.SelectStepDropDown.Value
                case 'Add signals'
                    process_Treatment_AddSignals(app, globalProperties);
                case 'Low-pass Filter'
                    process_Treatment_LowPass(app, globalProperties);
                case 'Resample'
                    process_Treatment_Resample(app, globalProperties);
                case 'Residual analysis'
                    process_Treatment_ResidualAnalysis(app, globalProperties);
            end
            
        end

        % Button pushed function: RemoveStepButton
        function RemoveStepButtonPushed(app, event)
            % RemoveStepButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information about the app from the time of the button press.
            % Remarks
            % - This method removed the selected treatment step from the table.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bensenderling@gmail.com

            % Get the current steps from the table.
            steps = app.UITable.Data;
            % Get the name of the step to remove.
            step2remove = app.SelectStepDropDown_2.Value;
            % The name was concatenated before and we need its number.
            ind = strfind(step2remove, ' ');
            n = str2double(step2remove(1:ind(1) - 1));
            % Remove that number step from the table.
            steps(n,:) = [];
            % Re-order the step numbers.
            if n > 1
                steps.Number = cellstr(num2str([1:height(steps)]'));
            end
            % Send the steps back to the table.
            app.UITable.Data = steps;
            % Update the dropdown to remove steps with the new numbers.
            app.SelectStepDropDown_2.Items = join([steps.Number(:), steps.Treatment(:)]);
            % Print a message the step was removed.
            printLog(app, '003', step2remove)

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % ProcessButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information about the app from the time of the button press.
            % Remarks
            % - This method uses all the steps to run the various processing methods. It starts with the raw data which is then saved to the processed
            %   data after the first step. In the second and later steps the processed data is used and overwritten.
            % - Data is only treated if there is a sampling frequency in the object. This helps avoid processing some data that should not be treated.
            % Future Work
            % - If more steps are added these methods would need to be updated.
            % Dec 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Nov 2023 - Modified by Ben Senderling, bms322@drexel.edu
            %          - Changed how the sampling frequency is checked and added an add signals method.

            % Print a message the process is starting.
            printLog(app, '004')
            % Get the current steps from the app.
            steps = app.UITable.Data;
            % Get the global properties. This was made unused in preference for using the data directly for the parameters.
            globalProperties = getappdata(app.UITable, 'globalProperties');

            % The raw data will always be processed first.
            type = 'raw';

            % The sampling frequencies are transferred to the processed data first. In some steps they are overwritten and some steps they are not. It
            % has proven easier to transfer them all here and choose when to modify them later than to choice when and where to modify them later.
            % Iterate through the files
            % Get the file names.
            files = fieldnames(app.Data.raw);
            % Iterate through the files.
            for j = 1:length(files)
                % Get the object names and remove the informational ones.
                obj = fieldnames(app.Data.raw.(files{j}));
                obj(strcmp(obj, 'meta')) = [];
                obj(strcmp(obj, 'groups')) = [];
                % Iterate through the objects.
                for k = 1:length(obj)
                    % Transfer the sampling frequency if it is a field.
                    if isfield(app.Data.raw.(files{j}).(obj{k}), 'freq')
                        app.Data.pro.(files{j}).(obj{k}).freq = app.Data.raw.(files{j}).(obj{k}).freq;
                    end
                end
            end

            % Start iterating through the treatment steps.
            for i = 1:height(steps)
                % Iterate through the files.
                for j = 1:length(files)
                    % Get the object names and remove the informational ones.
                    obj = fieldnames(app.Data.(type).(files{j}));
                    obj(strcmp(obj, 'meta')) = [];
                    obj(strcmp(obj, 'groups')) = [];
                    % Iterate through the objects.
                    for k = 1:length(obj)
                        % Get the signal names.
                        sigs = fieldnames(app.Data.(type).(files{j}).(obj{k}).data);
                        % Iterate through the signals.
                        for ii = 1:length(sigs)
                            switch steps.Treatment{i}
                                case 'Low-pass Filter'
                                    % The data will only be treated if there is a sampling frequency.
                                    if isfield(app.Data.(type).(files{j}).(obj{k}), 'freq')
                                        % Determine the coefficients of the butterworth filter.
                                        [b, a] = butter(steps.Parameters{i}.order, steps.Parameters{i}.cf/(app.Data.(type).(files{j}).(obj{k}).freq/2));
                                        % Perform the filtering.
                                        app.Data.pro.(files{j}).(obj{k}).data.(sigs{ii}) = FiltFiltM(b, a, app.Data.(type).(files{j}).(obj{k}).data.(sigs{ii}), 1);
                                        app.Data.pro.(files{j}).(obj{k}).freq = app.Data.(type).(files{j}).(obj{k}).freq;
                                    end
                                case 'Resample'
                                    % The data will only be treated if there is a sampling frequency.
                                    if isfield(app.Data.(type).(files{j}).(obj{k}), 'freq')
                                        % Create a temporary variable.
                                        temp = app.Data.(type).(files{j}).(obj{k}).data.(sigs{ii});
                                        % remove the existing data so it can be overwritten with an array of a different size.
                                        app.Data.(type).(files{j}).(obj{k}).data.(sigs{ii}) = [];
                                        % Iterate through the columns of data.
                                        for jj = 1:size(temp, 2)
                                            % Calculate a baseline offset to help remove edge artifact from the resampling.
                                            shift = mean(temp(:, jj));
                                            % Perform the resampling.
                                            temp2 = resample(temp(:,jj) - shift, steps.Parameters{i}.freq, round(app.Data.(type).(files{j}).(obj{k}).freq));
                                            % Reassign the data back to the structure and remove the shift.
                                            app.Data.pro.(files{j}).(obj{k}).data.(sigs{ii})(:, jj) = temp2 + shift;
                                        end
                                    end
                                case 'Add Signals'
                                    if any(strcmp(sigs{ii}, steps.Parameters{i}.as))
                                        if isfield(data, 'pro') && isfield(app.Data.pro, files{j}) && isfield(app.Data.pro.(files{j}), obj{k})  && isfield(app.Data.pro.(files{j}).(obj{k}).data, strjoin(steps.Parameters{i}.as,'_'))
                                            app.Data.pro.(files{j}).(obj{k}).data.(strjoin(steps.Parameters{i}.as,'_')) = app.Data.pro.(files{j}).(obj{k}).data.(strjoin(steps.Parameters{i}.as,'_')) + app.Data.raw.(files{j}).(obj{k}).data.(sigs{ii});
                                        else
                                            app.Datap.ro.(files{j}).(obj{k}).data.(strjoin(steps.Parameters{i}.as,'_')) = app.Data.raw.(files{j}).(obj{k}).data.(sigs{ii});
                                        end
                                    end
                                case 'Residual Analysis'
                                    % The data will only be treated if there is a sampling frequency.
                                    if isfield(app.Data.(type).(files{j}).(obj{k}), 'freq')
                                        datafreq = app.Data.(type).(files{j}).(obj{k}).freq;
                                        startfreq = steps.Parameters{i}.startfreq;
                                        stepfreq = steps.Parameters{i}.step;
                                        maxfreq = steps.Parameters{i}.maxfreq;
                                        order = steps.Parameters{i}.order;
                                        plotoption = 0;
                                        for jj = 1:size(app.Data.(type).(files{j}).(obj{k}).data.(sigs{ii}), 2)
                                            try
                                                [smoothfreq, smoothdata, SNR] = smoothWinter(app.Data.(type).(files{j}).(obj{k}).data.(sigs{ii})(:, jj), datafreq, startfreq, stepfreq, maxfreq, order, plotoption);
                                                app.Data.pro.(files{j}).(obj{k}).data.(sigs{ii})(:, jj) = smoothdata;
                                                app.Data.pro.(files{j}).(obj{k}).freq = app.Data.(type).(files{j}).(obj{k}).freq;
                                                app.Data.ana.ResidualAnalysis.(files{j}).(obj{k}).data.(sigs{ii}).smoothfreq(1, jj) = smoothfreq;
                                                app.Data.ana.ResidualAnalysis.(files{j}).(obj{k}).data.(sigs{ii}).SNR(1, jj) = SNR;
                                            catch ME
                                                app.Data.pro.(files{j}).(obj{k}).data.(sigs{ii})(:, jj) = NaN*ones(length(app.Data.(type).(files{j}).(obj{k}).data.(sigs{ii})(:, jj)), 1);
                                                app.Data.pro.(files{j}).(obj{k}).freq = app.Data.(type).(files{j}).(obj{k}).freq;
                                                app.Data.ana.ResidualAnalysis.(files{j}).(obj{k}).data.(sigs{ii}).smoothfreq(1, jj) = NaN;
                                                app.Data.ana.ResidualAnalysis.(files{j}).(obj{k}).data.(sigs{ii}).SNR(1, jj) = NaN;

                                                % Print a message the step was removed.
                                                printLog(app, '006', ME.message)
                                            end
                                        end
                                    end
                            end
                        end
                        switch steps.Treatment{i}
                            case 'Resample'
                                % For resampling the sampling frequency needs to be updated with the new frequency.
                                app.Data.pro.(files{j}).(obj{k}).freq = steps.Parameters{i}.freq;
                        end
                    end
                end
                % Once the first step is complete change which level is being processed.
                if strcmp(type,'raw')
                    type = 'pro';
                end
            end

            % Send the data back to the BAR App.
            analysis = 'Treatment';
            analysisComplete(app.DAMVi_App, app.Data, analysis, 0)
            % Print a message the process has finished.
            printLog(app, '005')
            % Delete this app.
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.TreatmentLabel = uilabel(app.UIFigure);
            app.TreatmentLabel.Text = 'Treatment';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SelectStepDropDownLabel = uilabel(app.UIFigure);
            app.SelectStepDropDownLabel.Text = 'Select Step';
            app.SelectStepDropDown = uidropdown(app.UIFigure);
            app.SelectStepDropDown.Items = {};
            app.SelectStepDropDown.Value = {};
            app.AddStepButton = uibutton(app.UIFigure, 'push');
            app.AddStepButton.ButtonPushedFcn = createCallbackFcn(app, @AddStepButtonPushed, true);
            app.AddStepButton.Text = 'Add Step';
            app.RemoveStepButton = uibutton(app.UIFigure, 'push');
            app.RemoveStepButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveStepButtonPushed, true);
            app.RemoveStepButton.Text = 'Remove Step';
            app.SelectStepDropDown_2Label = uilabel(app.UIFigure);
            app.SelectStepDropDown_2Label.Text = 'Select Step';
            app.SelectStepDropDown_2 = uidropdown(app.UIFigure);
            app.SelectStepDropDown_2.Items = {};
            app.SelectStepDropDown_2.Value = {};
            app.Version = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Treatment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end