classdef process_Treatment_AddSignals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectthesignalstoaddListBox  matlab.ui.control.ListBox
        SelectthesignalstoaddListBoxLabel  matlab.ui.control.Label
        FinishedButton                matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        AddSignalsLabel               matlab.ui.control.Label
    end


    properties (Access = private) 
        process_Treatment   % Main app object
        Data % Description
    end
    
    methods (Access = private)

        function updatelist(app)
            % updatelist(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the signal list within the app with the currently selected data type.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bms322@drexel.edu
            
            % This is used in place of a switch as in other code.
            type = 'raw';

            % Initialize the list.
            sigsAll = {};

            % Get the file names.
            files = fieldnames(app.Data.(type));
            % Iterate through the file names.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(app.Data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects
                for j = 1:length(objs)
                    % Append the signals to the list.
                    sigsAll = [sigsAll; fieldnames(app.Data.(type).(files{i}).(objs{j}).data)];
                end
            end

            % Set the unique signals to the list box. Adding 'all' this way ensures it is at the top of the list. Before the list was initialized with
            % all. Unique would move it to the end of the list.
            sigsAll = ['all'; unique(sigsAll)];
            app.SelectthesignalstoaddListBox.Items = sigsAll;
            
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, globalProperties)
            % StartupFcn(app, mainapp, globalProperties)
            % inputs  - app, required mlapp object.
            %         - mainapp, BAR_App object.
            %         - globalProperties, structure with data characteristics of the data.
            % Remarks
            % - This is the mandetory startup method for the module.
            % - The globalProperties are used to set parameters within these mini-modules instead of passing the entire data structure.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bms322@drexel.edu

            app.Data = getappdata(mainapp.UITable, 'data');

            % Update the signal list.
            updatelist(app)
            
            % Store main app in property for CloseRequestFcn to use
            app.process_Treatment = mainapp;
                        
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            % FinishedButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from the button press.
            % Remarks
            % - This method takes the parameters set by the user and sends them back to the Treatment Module.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bms322@drexel.edu
            
            % Set the processing type.
            properties.type = 'Add Signals';
            % Set the cutoff frequency.
            properties.as = app.SelectthesignalstoaddListBox.Value;
            
            % Run the public method from the Treatment module to add the step.
            addStep(app.process_Treatment, properties)
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.AddSignalsLabel = uilabel(app.UIFigure);
            app.AddSignalsLabel.Text = 'Add Signals';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.SelectthesignalstoaddListBoxLabel = uilabel(app.UIFigure);
            app.SelectthesignalstoaddListBoxLabel.Text = 'Select the signals to add';
            app.SelectthesignalstoaddListBox = uilistbox(app.UIFigure);
            app.SelectthesignalstoaddListBox.Items = {};
            app.SelectthesignalstoaddListBox.Multiselect = 'on';
            app.SelectthesignalstoaddListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Treatment_AddSignals(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end