classdef process_Treatment_Resample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CurrentFrequencyEditField       matlab.ui.control.NumericEditField
        CurrentFrequencyEditFieldLabel  matlab.ui.control.Label
        TargetFrequencyHzSpinner        matlab.ui.control.Spinner
        TargetFrequencyHzSpinner_2Label  matlab.ui.control.Label
        FinishedButton                  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        ResampleLabel                   matlab.ui.control.Label
    end


    properties (Access = private) 
        process_Treatment   % Main app object
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, globalProperties)
            % StartupFcn(app, mainapp, globalProperties)
            % inputs  - app, required mlapp object.
            %         - mainapp, BAR_App object.
            %         - globalProperties, structure with data characteristics of the data.
            % Remarks
            % - This is the mandetory startup method for the module.
            % - The globalProperties are used to set parameters within these mini-modules instead of passing the entire data structure.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Store main app in property for CloseRequestFcn to use
            app.process_Treatment = mainapp;
            
            app.CurrentFrequencyEditField.Value = double(min(globalProperties.freq));
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            % FinishedButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from the button press.
            % Remarks
            % - This method takes the parameters set by the user and sends them back to the Treatment Module.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Set the processing type.
            properties.type = 'Resample';
            % Set the target resampling frequency.
            properties.freq = app.TargetFrequencyHzSpinner.Value;

            % Run the public method from the Treatment module to add the step.
            addStep(app.process_Treatment, properties)
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ResampleLabel = uilabel(app.UIFigure);
            app.ResampleLabel.Text = 'Resample';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.TargetFrequencyHzSpinner_2Label = uilabel(app.UIFigure);
            app.TargetFrequencyHzSpinner_2Label.Text = 'Target Frequency (Hz)';
            app.TargetFrequencyHzSpinner = uispinner(app.UIFigure);
            app.TargetFrequencyHzSpinner.Step = 0.01;
            app.CurrentFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFrequencyEditFieldLabel.Text = 'Current Frequency';
            app.CurrentFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentFrequencyEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Treatment_Resample(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end