classdef process_Treatment_ResidualAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FilterOrderSpinner              matlab.ui.control.Spinner
        FilterOrderSpinnerLabel         matlab.ui.control.Label
        MaximumFrequencyHzSpinner       matlab.ui.control.Spinner
        MaximumFrequencyHzSpinnerLabel  matlab.ui.control.Label
        StepsizeHzSpinner               matlab.ui.control.Spinner
        StepsizeHzSpinnerLabel          matlab.ui.control.Label
        CurrentFrequencyHzEditField     matlab.ui.control.NumericEditField
        CurrentFrequencyHzEditFieldLabel  matlab.ui.control.Label
        StartingFrequencyHzSpinner      matlab.ui.control.Spinner
        StartingFrequencyHzSpinnerLabel  matlab.ui.control.Label
        FinishedButton                  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        LowPassFilterusingResidualAnalysisLabel  matlab.ui.control.Label
    end


    properties (Access = private) 
        process_Treatment   % Main app object
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, globalProperties)
            % StartupFcn(app, mainapp, globalProperties)
            % inputs  - app, required mlapp object.
            %         - mainapp, BAR_App object.
            %         - globalProperties, structure with data characteristics of the data.
            % Remarks
            % - This is the mandetory startup method for the module.
            % - The globalProperties are used to set parameters within these mini-modules instead of passing the entire data structure.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Dec 2025 - Modified by Ben Senderling, bensenderling@gmail.com
            %          - Added maximum option when adapting for the Winter smoothing options.
            
            % Store main app in property for CloseRequestFcn to use
            app.process_Treatment = mainapp;
            % Set the current sampling frequncy so the user knows what it is.
            app.CurrentFrequencyHzEditField.Value = double(min(globalProperties.freq));
            % Set the limits for the lowest frequency.
            app.StartingFrequencyHzSpinner.Limits = [0, floor((double(min(globalProperties.freq))-1)/2)];
            % Set the limits for the increment of the sampling frequencies.
            app.StepsizeHzSpinner.Limits = [0.001, floor((double(min(globalProperties.freq))-1)/2)];
            % Set the limits for the maximum frequency.
            app.MaximumFrequencyHzSpinner.Limits = [1, floor((double(min(globalProperties.freq))-1)/2)];
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            % FinishedButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from the button press.
            % Remarks
            % - This method takes the parameters set by the user and sends them back to the Treatment Module.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bensenderling@gmail.com
            % Dec 2025 - Modified by Ben Senderling, bensenderling@gmail.com
            %          - Adapted for residual analysis.
            
            % Set the processing type.
            properties.type = 'Residual Analysis';
            % Set the starting frequency.
            properties.startfreq = app.StartingFrequencyHzSpinner.Value;
            % Set the stepsize.
            properties.step = app.StepsizeHzSpinner.Value;
            % Set the maximum frequency.
            properties.maxfreq = app.MaximumFrequencyHzSpinner.Value;
            % Set the filter order.
            properties.order = app.FilterOrderSpinner.Value;
            % Run the public method from the Treatment module to add the step.
            addStep(app.process_Treatment, properties)
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.LowPassFilterusingResidualAnalysisLabel = uilabel(app.UIFigure);
            app.LowPassFilterusingResidualAnalysisLabel.Text = {'Low Pass Filter using'; 'Residual Analysis'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            app.StartingFrequencyHzSpinnerLabel = uilabel(app.UIFigure);
            app.StartingFrequencyHzSpinnerLabel.Text = 'Starting Frequency (Hz)';
            app.StartingFrequencyHzSpinner = uispinner(app.UIFigure);
            app.StartingFrequencyHzSpinner.Limits = [1 Inf];
            app.StartingFrequencyHzSpinner.RoundFractionalValues = 'on';
            app.StartingFrequencyHzSpinner.Value = 1;
            app.CurrentFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFrequencyHzEditFieldLabel.Text = 'Current Frequency (Hz)';
            app.CurrentFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentFrequencyHzEditField.Editable = 'off';
            app.StepsizeHzSpinnerLabel = uilabel(app.UIFigure);
            app.StepsizeHzSpinnerLabel.Text = 'Stepsize (Hz)';
            app.StepsizeHzSpinner = uispinner(app.UIFigure);
            app.MaximumFrequencyHzSpinnerLabel = uilabel(app.UIFigure);
            app.MaximumFrequencyHzSpinnerLabel.Text = 'Maximum Frequency (Hz)';
            app.MaximumFrequencyHzSpinner = uispinner(app.UIFigure);
            app.FilterOrderSpinnerLabel = uilabel(app.UIFigure);
            app.FilterOrderSpinnerLabel.Text = 'Filter Order';
            app.FilterOrderSpinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Treatment_ResidualAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end