classdef quality_Threshold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        SliderMax  matlab.ui.control.Slider
        SliderMin  matlab.ui.control.Slider
        UsetheslidertoadjustthethresholdLabel  matlab.ui.control.Label
        UIAxes     matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        review_QC   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)


    end
    
    methods (Access = public)
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, qualityTable)
            % StartupFcn(app, qualityTable)
            % inputs  - app, required mlapp object.
            %         - mainapp, BAR_App object.
            %         - data, BAR App data structure.
            %         - process, a string with the processing name.
            % Remarks
            % - This is the mandetory startup method for the module.
            % Future Work
            % - None.
            % May 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Store main app in property for CloseRequestFcn to use
            app.review_QC = mainapp;

            % Save the quality table in the figure.
            setappdata(app.UIFigure, 'qualityTable', qualityTable)

            % Create an initial histogram.
            histogram(app.UIAxes, cell2mat(qualityTable.Value), 'FaceColor', 'k')

            % Get the bin edges using all the values.
            [~, edges] = histcounts(cell2mat(qualityTable.Value));
            % Set the slider limits to the min and max bin edges.
            app.SliderMax.Limits = [min(edges), max(edges)];
            % Make the major tick marks the same as the bin edges.
            app.SliderMax.MajorTicks = edges;
            % Create the first and last tick mark labels with the rest empty.
            app.SliderMax.MajorTickLabels = [{num2str(edges(1))}, repmat({''}, 1, length(edges) - 2), {num2str(edges(end))}];
            % Remove the minor tick marks.
            app.SliderMax.MinorTicks = [];
            % Set the slider limits to the min and max bin edges.
            app.SliderMin.Limits = [min(edges), max(edges)];
            % Make the major tick marks the same as the bin edges.
            app.SliderMin.MajorTicks = edges;
            % Create the first and last tick mark labels with the rest empty.
            app.SliderMin.MajorTickLabels = [{num2str(edges(1))}, repmat({''}, 1, length(edges) - 2), {num2str(edges(end))}];
            % Remove the minor tick marks.
            app.SliderMin.MinorTicks = [];
                       
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % DialogAppCloseRequest(app, event)
            % inputs  - app, required mlapp object.
            %         - event, contains information about the app during the button press.
            % Remarks
            % - When the app is closed this method sends the quality data back to the module. This is done before destroying the app.
            % Future Work
            % - None.
            % May 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the quality table from the figure.
            qualityTable = getappdata(app.UIFigure, 'qualityTable');

            % Any values less than the minimum slider are made a one.
            qualityTable.Fault(cell2mat(qualityTable.Value) < app.SliderMin.Value) = 1;
            % Any values more than the maximum slider are made a one.
            qualityTable.Fault(cell2mat(qualityTable.Value) > app.SliderMax.Value) = 1;
            % All faults of one are given a note describing where it is from.
            qualityTable.Notes(find(qualityTable.Fault)) = repmat({'threshold outlier'}, sum(qualityTable.Fault), 1);
            % Send the quality table back to the module using its public method.
            retrieveResults(app.review_QC, qualityTable);
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Value changed function: SliderMax, SliderMin
        function SliderValueChanged(app, event)
            % SliderValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, contains information about the app during the button press.
            % Remarks
            % - This method updates the histogram everytime a slider is moved. It displays three distributions depending on the slider values.
            % Future Work
            % - None.
            % May 2022 - Created by Ben Senderling, bsender@bu.eduqualityTable = getappdata(app.UIFigure, 'qualityTable');
            
            % Get the quality table from the figure.
            qualityTable = getappdata(app.UIFigure, 'qualityTable');

            % Convert all the values into a single numeric array.
            values = cell2mat(qualityTable.Value);

            % Get the edges of the bins.
            [~, edges] = histcounts(values);
            % If the minimum slider is less than the maximum slider.
            if app.SliderMin.Value < app.SliderMax.Value
                % Create a new edge array starting from the minimum slider and going to the maximum slider with the same inbetween edge values.
                edgeAll = [app.SliderMin.Value, edges(edges > app.SliderMin.Value & edges < app.SliderMax.Value), app.SliderMax.Value];
            else
                % Otherwise use the edges as is. This will be an empty distribution.
                edgeAll = edges;
            end
            % Create a histogram with the middle distribution.
            histogram(app.UIAxes, values(values >= app.SliderMin.Value & values <= app.SliderMax.Value), 'FaceColor', 'k', 'FaceAlpha', 0.75, 'BinEdges',edgeAll)
            hold(app.UIAxes, 'On')
            % Create edges using the minimum slider.
            edgesMin = [edges(edges < app.SliderMin.Value), app.SliderMin.Value];
            % Create a lower histogram.
            if ~isempty(values(values < app.SliderMin.Value))
                histogram(app.UIAxes, values(values < app.SliderMin.Value), 'FaceColor', 'r', 'FaceAlpha', 0.75, 'BinEdges',edgesMin)
            end
            % Create edges using the maximum slider.
            edgesMax = [app.SliderMax.Value, edges(edges > app.SliderMax.Value)];
            % Create an upper histogram.
            if ~isempty(values(values > app.SliderMax.Value))
                histogram(app.UIAxes, values(values > app.SliderMax.Value), 'FaceColor', 'b', 'FaceAlpha', 0.75, 'BinEdges',edgesMax)
            end
            hold(app.UIAxes, 'Off')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UsetheslidertoadjustthethresholdLabel = uilabel(app.UIFigure);
            app.UsetheslidertoadjustthethresholdLabel.Text = 'Use the slider to adjust the threshold. Close the mini-module to send the identified outlier back to the QC module.';
            app.SliderMin = uislider(app.UIFigure);
            app.SliderMin.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SliderMax = uislider(app.UIFigure);
            app.SliderMax.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quality_Threshold(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end