classdef review_FNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotselectdatadimensionListBox  matlab.ui.control.ListBox
        PlotselectdatadimensionListBoxLabel  matlab.ui.control.Label
        PlotselectsignalsListBox        matlab.ui.control.ListBox
        PlotselectsignalsListBoxLabel   matlab.ui.control.Label
        SelectmissingresultsCheckBox    matlab.ui.control.CheckBox
        PlotensembleCheckBox            matlab.ui.control.CheckBox
        Tree                            matlab.ui.container.CheckBoxTree
        Log                             matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        FalseNearestNeighborsReivewLabel  matlab.ui.control.Label
        FigureHist                      matlab.ui.control.UIAxes
        FigureResults                   matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['selection not valid to plot'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
        
        
        function UpdatePlot(app, dim)
            checkedNodes = app.Tree.CheckedNodes;

            cla(app.FigureResults)
            cla(app.FigureHist)
            
            data = getappdata(app.UIFigure, 'data');
            
            type = 'res';
            review = 'FNN';

            if isempty(dim)
                dim = app.PlotselectdatadimensionListBox.Value;
                if isempty(dim)
                    dim = 1;
                else
                    dim = arrayfun(@str2double, dim);
                end
            end

            plotData = {};
            for i = 1:length(dim)
                plotDim{i} = [];
            end
            
            ind = 1;

            for i = 1:length(checkedNodes)

                n = 0;
                S = checkedNodes(i);
                while isprop(S,'Parent')
                    n = n + 1;
                    S = S.Parent;
                end

                if n == 6

                    for j = 1:length(dim)
                        
                        dims = data.(type).(review).(checkedNodes(i).Parent.Parent.Text).(checkedNodes(i).Parent.Text).(checkedNodes(i).Text).dE{dim(j)};
                        if isnan(dims)
                            continue
                        end

                        plotData{j}(:, ind) = dims;
                        ind = ind + 1;

                        d = data.(type).(review).(checkedNodes(i).Parent.Parent.Text).(checkedNodes(i).Parent.Text).(checkedNodes(i).Text).dim{dim};
                        if isnan(d)
                            plotDim{j} = [plotDim{j}; [NaN, NaN]];
                        else
                            plotDim{j} = [plotDim{j}; [d, data.(type).(review).(checkedNodes(i).Parent.Parent.Text).(checkedNodes(i).Parent.Text).(checkedNodes(i).Text).dE{dim(j)}(d)]];
                        end

                    end

                end

            end

            if app.PlotensembleCheckBox.Value == 1 && ind > 1
                
                for i = 1:length(plotData)
                    fill(app.FigureResults, [1:size(plotData{i}, 1), size(plotData{i}, 1):-1:1]', [mean(plotData{i}, 2, 'omitnan') - std(plotData{i}, [], 2, 'omitnan'); flipud(mean(plotData{i}, 2, 'omitnan') + std(plotData{i}, [], 2, 'omitnan'))], [0.7, 0.7, 0.7], 'LineStyle', 'none')
                    if i == 1
                        hold(app.FigureResults, 'on')
                    end
                    plot(app.FigureResults, mean(plotData{i}, 2, 'omitnan'), 'k')
                    plot(app.FigureResults, mean(plotDim{i}(:,1), 'omitnan'), mean(plotDim{i}(:,2), 'omitnan'), 'ro')

                    if i == length(plotData)
                        hold(app.FigureResults, 'off')
                        axis(app.FigureResults, 'tight')
                    end
                end


            elseif  ind > 1

                for i = 1:length(plotData)

                    plot(app.FigureResults, plotData{i}, 'k')
                    if i ==1
                        hold(app.FigureResults, 'on')
                    end
                    plot(app.FigureResults, plotDim{i}(:,1), plotDim{i}(:,2), 'ro')
                    if i == length(plotData)
                        hold(app.FigureResults, 'off')
                        axis(app.FigureResults, 'tight')
                    end

                end

            end

            if ind > 1
                for i = 1:length(plotDim)
                    histogram(app.FigureHist, plotDim{i}(:,1), 'BinEdges', [0, 1:20], 'FaceAlpha', 0.5, 'EdgeAlpha', 0.5)
                    if i ==1
                        hold(app.FigureHist, 'on')
                    elseif i == length(plotDim)
                        hold(app.FigureHist, 'off')
                    end
                end
            end
            
        end
    end
    
    methods (Access = public)
        
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;

            config = loadConfig(app.BAR_App);
            
            setappdata(app.UIFigure, 'data', data)

            type = 'res';
            review = 'FNN';

            allSigs = {'none'};

            files = fieldnames(data.(type).(review));
            ind1 = 1;
            for i = 1:length(files)
                uitreenode(app.Tree, 'Text', files{i});
                objs = fieldnames(data.(type).(review).(files{i}));
                ind2 = 1;
                for j = 1:length(objs)
                    uitreenode(app.Tree.Children(ind1), 'Text', objs{j})
                    sigs = fieldnames(data.(type).(review).(files{i}).(objs{j}));
                    for k = 1:length(sigs)
                        uitreenode(app.Tree.Children(ind1).Children(ind2), 'Text', sigs{k});
                        allSigs = [allSigs; sigs{k}];
                    end
                    ind2 = ind2 + 1;
                end
                ind1 = ind1 + 1;
            end

            app.PlotselectdatadimensionListBox.Items = arrayfun(@num2str, (1:size(data.(type).(review).(files{i}).(objs{j}).(sigs{k}).dim, 2)), 'UniformOutput', 0);

            app.PlotselectsignalsListBox.Items = unique(allSigs);
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Callback function
        function SaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UIFigure, 'data');
            analysis = app.FalseNearestNeighborsReivewLabel.Text;
            
            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end

        % Value changed function: SelectmissingresultsCheckBox
        function SelectmissingresultsCheckBoxValueChanged(app, event)
            value = app.SelectmissingresultsCheckBox.Value;

            dim = app.PlotselectdatadimensionListBox.Value;
            if isempty(dim)
                dim = 1;
            else
                dim = str2double(cell2mat(dim));
            end

            if value

                data = getappdata(app.UIFigure, 'data');

                type = 'res';
                review = 'FNN';

                files = fieldnames(data.(type).(review));
                ind1 = 1;
                for i = 1:length(files)
                    objs = fieldnames(data.(type).(review).(files{i}));
                    ind2 = 1;
                    for j = 1:length(objs)
                        sigs = fieldnames(data.(type).(review).(files{i}).(objs{j}));
                        ind3 = 1;
                        for k = 1:length(sigs)
                            d = data.(type).(review).(files{i}).(objs{j}).(sigs{k}).dim{dim};
                            if isnan(d)
                                app.Tree.CheckedNodes = [app.Tree.CheckedNodes(:); app.Tree.Children(i).Children(j).Children(k)];
                                
                            end
                            ind3 = ind3 + 1;
                        end
                        ind2 = ind2 + 1;
                    end
                    ind1 = ind1 + 1;
                end

                UpdatePlot(app, [])

            end

        end

        % Value changed function: PlotselectsignalsListBox
        function PlotselectsignalsListBoxValueChanged(app, event)
            value1 = app.PlotselectsignalsListBox.Value;

            for ii = 1:length(value1)

                if strcmp(value1{ii}, 'none')
                    app.Tree.CheckedNodes = [];
                    UpdatePlot(app, [])
                    return
                end

                data = getappdata(app.UIFigure, 'data');

                type = 'res';
                review = 'FNN';

                files = fieldnames(data.(type).(review));
                for i = 1:length(files)
                    objs = fieldnames(data.(type).(review).(files{i}));
                    for j = 1:length(objs)
                        sigs = fieldnames(data.(type).(review).(files{i}).(objs{j}));
                        for k = 1:length(sigs)
                            if contains(value1{ii}, sigs{k})
                                app.Tree.CheckedNodes = [app.Tree.CheckedNodes(:); app.Tree.Children(i).Children(j).Children(k)];
                            end
                        end
                    end
                end

            end

            UpdatePlot(app, [])
            
        end

        % Callback function: PlotensembleCheckBox, 
        % PlotselectdatadimensionListBox, Tree
        function UpdatePlotCallback(app, event)
            UpdatePlot(app, [])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.FigureResults = uiaxes(app.UIFigure);
            xlabel(app.FigureResults, 'Embedding Dmension')
            ylabel(app.FigureResults, 'Portion FNN')
            app.FigureHist = uiaxes(app.UIFigure);
            xlabel(app.FigureHist, 'Embedding Dimension')
            ylabel(app.FigureHist, '# of Observations')
            zlabel(app.FigureHist, 'Z')
            app.FalseNearestNeighborsReivewLabel = uilabel(app.UIFigure);
            app.FalseNearestNeighborsReivewLabel.Text = 'False Nearest Neighbors Reivew';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.Log = uitextarea(app.UIFigure);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @UpdatePlotCallback, true);
            app.PlotensembleCheckBox = uicheckbox(app.UIFigure);
            app.PlotensembleCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotCallback, true);
            app.PlotensembleCheckBox.Text = 'Plot ensemble';
            app.SelectmissingresultsCheckBox = uicheckbox(app.UIFigure);
            app.SelectmissingresultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectmissingresultsCheckBoxValueChanged, true);
            app.SelectmissingresultsCheckBox.Text = 'Select missing results';
            app.PlotselectsignalsListBoxLabel = uilabel(app.UIFigure);
            app.PlotselectsignalsListBoxLabel.Text = 'Plot select signals';
            app.PlotselectsignalsListBox = uilistbox(app.UIFigure);
            app.PlotselectsignalsListBox.Items = {};
            app.PlotselectsignalsListBox.Multiselect = 'on';
            app.PlotselectsignalsListBox.ValueChangedFcn = createCallbackFcn(app, @PlotselectsignalsListBoxValueChanged, true);
            app.PlotselectsignalsListBox.Value = {};
            app.PlotselectdatadimensionListBoxLabel = uilabel(app.UIFigure);
            app.PlotselectdatadimensionListBoxLabel.Text = 'Plot select data dimension';
            app.PlotselectdatadimensionListBox = uilistbox(app.UIFigure);
            app.PlotselectdatadimensionListBox.Items = {};
            app.PlotselectdatadimensionListBox.Multiselect = 'on';
            app.PlotselectdatadimensionListBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotCallback, true);
            app.PlotselectdatadimensionListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = review_FNN(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end