classdef review_QC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DependenciesPanel              matlab.ui.container.Panel
        SignalswithinthesameobjectCheckBox  matlab.ui.control.CheckBox
        ManualQualityControlPanel      matlab.ui.container.Panel
        UpdateButton                   matlab.ui.control.Button
        NotesEditField                 matlab.ui.control.EditField
        NotesEditFieldLabel            matlab.ui.control.Label
        NumberofFaultsSpinner          matlab.ui.control.Spinner
        NumberofFaultsSpinnerLabel     matlab.ui.control.Label
        TableRowIndexSpinner           matlab.ui.control.Spinner
        TableRowIndexSpinnerLabel      matlab.ui.control.Label
        QuitButton                     matlab.ui.control.Button
        AdvancedTypesListBox           matlab.ui.control.ListBox
        AdvancedTypesListBoxLabel      matlab.ui.control.Label
        BasicTypesListBox              matlab.ui.control.ListBox
        BasicTypesListBoxLabel         matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        RunQualityControlMethodButton  matlab.ui.control.Button
        MeasuresListBox                matlab.ui.control.ListBox
        ObjectsLabel                   matlab.ui.control.Label
        SignalsListBox                 matlab.ui.control.ListBox
        SignalsListBox_2Label          matlab.ui.control.Label
        Version                        matlab.ui.control.Label
        SaveResultsButton              matlab.ui.control.Button
        SelectMethodDropDown           matlab.ui.control.DropDown
        SelectMethodDropDownLabel      matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        QualityControlLabel            matlab.ui.control.Label
    end


    properties (Access = private)
        DAMVi_App   % Main app object
    end

    properties (Access = public)
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of the error and organize the errors available.
            %         - newText, a string that may be inserted into the message.
            % Remarks
            % - This method will print messages to the log of the DAMVi App. The error codes are unique to each message so they can be found and
            %   referenced easier.
            % - The method is public and is acessible outside of the DAMVi App. It is used in some of the example analysis and process scripts.
            % Future Work
            % - This log should be printed and saved to a file location each time the DAMVi App is run.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text from the DAMVi App.
            text = app.DAMVi_App.Log.Value;
            % Match the error code with the message.
            switch code
                case '001'
                    text{length(text) + 1,1} = 'QAC-001 no data has been loaded';
                case '002'
                    text{length(text) + 1,1} = ['QAC-002 ' newText];
                case '003'
                    text{length(text) + 1,1} = ['QAC-003 unhandled exception in analysis: ' newText];
                case '004'
                    text{length(text) + 1,1} = ['QAC-004 updating selection: ' newText];
                case '005'
                    text{length(text) + 1,1} = 'QAC-004 updating complete';
            end
            % Create a date-time for the message.
            text{end, 1} = [char(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z')) ' | ' text{end, 1}];
            % Set the text of the log to the new text.
            app.DAMVi_App.Log.Value = text;
            % Scroll to the bottom of the log.
            scroll(app.DAMVi_App.Log,'bottom')
            % Make sure the display updates.
            drawnow
        end


        function identifyQualityControlOptions(app)
            % identifyQualityControlOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Review folder for valid files that would be used to perform quality control. These files need to have a name
            %   quality_*.m. The results will be saved to the configuration file and made selectable in the app.
            % - The goal of this function was to make the addition of new functions easier.
            % - The idea of processing is to take an input and create a new ouput of similar size and scale.
            % Future Work
            % - None
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Load the existing configuration so it can be updated.
            config = loadConfig(app.DAMVi_App);

            % Empty the available segment files from the saved configuration.
            config.options.quality = [];
            ind = 1;

            for j = 1:length(app.DAMVi_App.FilesReview.Value)
                % Use the directory entered into the app to find the processing scripts.
                m_files = dir(app.DAMVi_App.FilesReview.Value{j});

                % Look for files within the directory. If the file names contain the word 'process' save them within the app and configuration.
                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'quality') && ~contains(m_files(i).name, 'asv')

                        [~, name] = fileparts(m_files(i).name);
                        parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                        % The third part of the file name is used for the options.
                        config.options.quality{ind,1} = parts{2}{1};
                        ind = ind + 1;
                    end
                end
            end

            % If there are no quality files or if the directory is misleading because files have been moved or the app was moved nothing will be
            % found. The object in the app can't be set to empty.
            if ~isempty(config.options.quality)
                app.SelectMethodDropDown.Items = config.options.quality;
            end

            % Save the configuration with the updated directory and or file extensions and formats.
            saveConfig(app.DAMVi_App, config)

        end

        function createListBoxes(app)
            % createListBoxes(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method will populate the various list boxes in the app. In the method a very long list will be made, and then only the unique
            %   ones will be used. This allows items that are present in some levels but not others to still be used.
            % Future Work
            % - None.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data.
            data = getappdata(app.DAMVi_App.TableFiles, 'data');

            if isempty(data)
                printLog(app.DAMVi_App ,'001')
                delete(app)
                return
            end

            % Get the list of all the types available.
            types = fieldnames(data);

            % Create a list for basic types: raw and processed.
            if any(contains(types, 'raw')) && any(contains(types, 'pro'))
                basicList = {'raw'; 'pro'};
            elseif any(contains(types, 'raw'))
                basicList = {'raw'};
            elseif any(contains(types, 'pro'))
                basicList = {'pro'};
            else
                basicList = {''};
            end

            % Create a list for advanced types.
            if any(contains(types, 'ana')) && any(contains(types, 'res'))
                advanList = {'ana'; 'res'};
            elseif any(contains(types, 'ana'))
                advanList = {'ana'};
            elseif any(contains(types, 'res'))
                advanList = {'res'};
            else
                advanList = {''};
            end

            % Update the list boxes.
            app.BasicTypesListBox.Items = basicList;
            app.AdvancedTypesListBox.Items = advanList;

        end


        function updateManualQC(app, displayRow, displayColumn)
            % createListBoxes(app)
            % inputs  - app, required mlapp object.
            %         - displayRow, the row index currently selected in the table.
            %         - displayColumn, the column index currently selected in the table.
            % Remarks
            % - This method uses the indexes of the selected cell in the table to update the manual quality check panel.
            % Future Work
            % - None.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Set the spinner value to the row index.
            app.TableRowIndexSpinner.Value = displayRow;
            % Set the number of faults spinner to the current number of faults for that table row.
            app.NumberofFaultsSpinner.Value = app.UITable.Data.Fault(displayRow);
            % Check that notes exist.
            if any(strcmp(fieldnames(app.UITable.Data), 'Notes'))
                % If notes to exist put the ones for the current row in the edit field.
                app.NotesEditField.Value = app.UITable.Data.Notes{displayRow};
            end
        end

        function checkDependencies(app)
            % checkDependencies(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method applies the checked dependencies to the data in the table. It should be run after every quality check.
            % Future Work
            % - As more dependecies are added more code will be needed here.
            % - The module only shows the faults and notes for the current selection. So this should largely add additional faults and notes to empty
            %   arrays. But there may be some quirks that occur if those arrays do not start empty.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the table.
            qualityTable = app.UITable.Data;

            % Check if all signals within the same object should have the same faults.
            if app.SignalswithinthesameobjectCheckBox.Value == 1
                % Get the unique files present in the table.
                files = unique(qualityTable.File);
                % Iterate through the unique files.
                for ind_files = 1:length(files)
                    % Find all the unique object names within the current file name.
                    objs = unique(qualityTable.Object(strcmp(qualityTable.File, files{ind_files})));
                    % Iterate through the objects.
                    for ind_objs = 1:length(objs)
                        % Check if the notes for each row of the object have more than one unique value.
                        if length(unique(qualityTable.Notes(strcmp(qualityTable.File, files{ind_files}) & strcmp(qualityTable.Object, objs{ind_objs})))) ~= 1
                            % Get the original indexes of the current rows.
                            indexes = find(strcmp(qualityTable.File, files{ind_files}) & strcmp(qualityTable.Object, objs{ind_objs}));
                            % Get the relative index of the row that has the most notes.
                            [~, ind] = max(cellfun(@length, qualityTable.Notes(strcmp(qualityTable.File, files{ind_files}) & strcmp(qualityTable.Object, objs{ind_objs}))));
                            % Iterate through the current rows.
                            for ind_indexes = 1:length(indexes)
                                % Skip the one with the longest notes as it will be appended to the others.
                                if ind_indexes ~= ind
                                    % Add the new fault number to the row.
                                    qualityTable.Fault(indexes(ind_indexes)) = qualityTable.Fault(indexes(ind_indexes)) + qualityTable.Fault(indexes(ind));
                                    % Check if the notes are empty.
                                    if isempty(qualityTable.Notes{indexes(ind_indexes)})
                                        % If they are empty make the current row's notes the same as the index row.
                                        qualityTable.Notes{indexes(ind_indexes)} = qualityTable.Notes{indexes(ind)};
                                    else
                                        % If there are notes present append the new ones to it.
                                        qualityTable.Notes{indexes(ind_indexes)} = [qualityTable.Notes{indexes(ind_indexes)} ',' qualityTable.Notes{indexes(ind)}];
                                    end
                                end
                            end
                        end
                    end
                end

            end
            % Send the table back to be displayed in the app.
            app.UITable.Data = qualityTable;


        end
    end

    methods (Access = public)

        function retrieveResults(app, qualityTable)
            % retrieveResults(app, qualityTable)
            % inputs  - app, required mlapp object.
            %         - qualityTable, a table with faults and notes from a mini-module.
            % Remarks
            % - The module can use both m-files and mlapp-files. For the later the qualityTable cannot be returned as the output of a function. This
            %   public method allows the table to be brought back into this module.
            % Future Work
            % - None.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Display the results in the table and update the column headers.
            app.UITable.Data = qualityTable;
            app.UITable.ColumnName = fieldnames(qualityTable);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, process)
            % StartupFcn(app, mainapp, data, process)
            % inputs  - app, required mlapp object.
            %         - mainapp, DAMVi_App object.
            %         - data, DAMVi App data structure.
            %         - process, a string with the processing name.
            % Remarks
            % - This is the mandetory startup method for the module.
            % Future Work
            % - None.
            % May 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.DAMVi_App = mainapp;
            % Find the process_segment methods.
            identifyQualityControlOptions(app)
            % Populate the list boxes.
            createListBoxes(app)
            % Update the version number label with the label from the DAMVi App.
            app.Version.Text = app.DAMVi_App.Version.Text;

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            % SaveResultsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information about the app from the change in tree selection.
            % Remarks
            % - This method saves the data and sends it back to the DAMVi App. The quality measures need to be added to the DAMVi data structure. This
            %   requires a lot of conditions to account for different signals and measures have values but others not.
            % - If quality measures already exist the new ones are added or appended to them.
            % Future Work
            % - None.
            % May 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data.
            data = getappdata(app.DAMVi_App.TableFiles, 'data');
            % Get the quality data.
            qualityTable = app.UITable.Data;

            % Check if it was analysis or results data that was checked.
            if any(strcmp(fieldnames(qualityTable), 'Measure'))
                % Iterate through the rows of the quality table.
                for i = 1:height(qualityTable)
                    % If quality measures already exist.
                    if isfield(data, 'qua') && isfield(data.qua, qualityTable.Type{i}) &&...
                            isfield(data.qua.(qualityTable.Type{i}), qualityTable.Analysis{i}) &&...
                            isfield(data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}), qualityTable.File{i}) &&...
                            isfield(data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}), qualityTable.Object{i}) &&...
                            isfield(data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data, qualityTable.Signal{i}) &&...
                            isfield(data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}), qualityTable.Measure{i})
                        % If a fault already exists.
                        if isfield(data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).(qualityTable.Measure{i}), 'fault')
                            % Add the new value to the current value.
                            data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).(qualityTable.Measure{i}).fault = qualityTable.Fault(i) + ...
                                data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).(qualityTable.Measure{i}).fault;
                        else
                            % If a fault does not exist use the new value.
                            data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).(qualityTable.Measure{i}).fault = qualityTable.Fault(i);
                        end
                        % If notes already exist.
                        if isfield(data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).(qualityTable.Measure{i}), 'notes') &&...
                                ~isempty(data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).(qualityTable.Measure{i}).notes)
                            % Append the new notes to the existing notes.
                            data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).(qualityTable.Measure{i}).notes = [data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).(qualityTable.Measure{i}).notes,...
                                ',' qualityTable.Notes{i}];
                        else
                            % If no notes exist use the new ones.
                            data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).(qualityTable.Measure{i}).notes = qualityTable.Notes{i};
                        end
                    else
                        % If quality measures have not yet been created use the new values directly.
                        data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).(qualityTable.Measure{i}).fault = qualityTable.Fault(i);
                        data.qua.(qualityTable.Type{i}).(qualityTable.Analysis{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).(qualityTable.Measure{i}).notes = qualityTable.Notes{i};

                    end
                end
                % If it was raw or processed data that was checked.
            elseif any(strcmp(fieldnames(qualityTable), 'Signal')) && ~any(strcmp(fieldnames(qualityTable), 'Measure'))
                for i = 1:height(qualityTable)
                    % If quality measures already exist.
                    if isfield(data, 'qua') && isfield(data.qua, qualityTable.Type{i}) &&...
                            isfield(data.qua.(qualityTable.Type{i}), qualityTable.File{i}) &&...
                            isfield(data.qua.(qualityTable.Type{i}).(qualityTable.File{i}), qualityTable.Object{i}) &&...
                            isfield(data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data, qualityTable.Signal{i})
                        % If a fault already exists.
                        if isfield(data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}), 'fault')
                            % Add the new value to the current value.
                            data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).fault = qualityTable.Fault(i) + ...
                                data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).fault;
                        else
                            % If a fault does not exist use the new value.
                            data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).fault = qualityTable.Fault(i);
                        end
                        % If notes already exist.
                        if isfield(data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}), 'notes') &&...
                                ~isempty(data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).notes)
                            % Append the new notes to the existing notes.
                            data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).notes = [data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).notes,...
                                ',' qualityTable.Notes{i}];
                        else
                            % If no notes exist use the new ones.
                            data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).notes = qualityTable.Notes{i};
                        end
                    else
                        % If quality measures have not yet been created use the new values directly.
                        data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).fault = qualityTable.Fault(i);
                        data.qua.(qualityTable.Type{i}).(qualityTable.File{i}).(qualityTable.Object{i}).data.(qualityTable.Signal{i}).notes = qualityTable.Notes{i};

                    end
                end

            end

            % Send the data back to the DAMVi App and save it.
            analysisComplete(app.DAMVi_App, data, app.SelectMethodDropDown.Value, 1)

            % Tell the module to update the table with the new combined results. Separate callbacks are called depending on if it was raw/processed or
            % analysis/results data that was checked.
            if any(strcmp(fieldnames(qualityTable), 'Measure'))
                app.MeasuresListBoxValueChanged;
            elseif any(strcmp(fieldnames(qualityTable), 'Signal')) && ~any(strcmp(fieldnames(qualityTable), 'Measure'))
                app.SignalsListBoxValueChanged;
            end
        end

        % Button pushed function: RunQualityControlMethodButton
        function RunQualityControlMethodButtonPushed(app, event)
            % RunQualityControlMethodButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information about the app from the change in tree selection.
            % Remarks
            % - This method will arrange the data first and then will use the selected quality mini-module to detect faults in the values.
            % - Both m-files and mlapp-files can be used as mini-modules.
            % Future Work
            % - None.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % The data is pulled from the DAMVi app.
            qualityTable = app.UITable.Data;

            % The quality method will be found dynamically.

            % Find the quality function that matches the selected method.
            % Get the load functions for the first directory.
            filesReview = dir(app.DAMVi_App.FilesReview.Value{1});
            % Get the quality functions for the additional directories if there are more.
            if length(app.DAMVi_App.FilesReview.Value) > 1
                for i = 2:length(app.DAMVi_App.FilesReview.Value)
                    filesReview = [filesReview; dir(app.DAMVi_App.FilesReview.Value{i})];
                end
            end

            quality = app.SelectMethodDropDown.Value;
            % Find the index where the filename contains the target file extension and the equipment type and is not an asv-file.
            ind3 = find(contains({filesReview.name}', ['quality_' quality]) & ~contains({filesReview.name}', 'asv'), 1);

            % Exit if no file was found.
            if isempty(ind3)
                return
            end

            % Find the index of '.m' which will be present in all m- and mlapp-files.
            ind4 = strfind(filesReview(ind3).name, '.m');

            try
                % If the mini-module is an app run it as an app.
                if contains(filesReview(ind3).name, '.mlapp')
                    % For mlapp-files the qualityTable is returned to this module through a public method.
                    appQC = feval(filesReview(ind3).name(1:ind4 - 1), app, qualityTable);
                    % This wait will prevent the code further down from running untill the user is done interacting with the mini-module.
                    waitfor(appQC);

                elseif contains(filesReview(ind3).name, '.m')
                    % For m-files the qualityTable is returned directly.
                    qualityTable = feval(filesReview(ind3).name(1:ind4 - 1), qualityTable);

                    % Display the results in the table and update the column headers.
                    app.UITable.Data = qualityTable;
                    app.UITable.ColumnName = fieldnames(qualityTable);

                end
            catch ME
                % Print a message to the log if the load fails for any reason.
                printLog(app, '002', [quality ' failed'])

                % Get MATLAB's last error message and display it.
                printLog(app, '003', ME.message)
            end

            % Align the results with the selected dependencies.
            checkDependencies(app);

        end

        % Value changed function: BasicTypesListBox
        function BasicTypesListBoxValueChanged(app, event)
            % BasicTypesListBoxValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, contains information about the app from the button press.
            % Remarks
            % - This method will populate the signal list box with the available signal names from the selected basic type.
            % Future Work
            % - None.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Print a message that the list box is updating.
            printLog(app, '004', 'Signals')

            % Get the current data.
            data = getappdata(app.DAMVi_App.TableFiles, 'data');

            % Get the selected data type.
            type = app.BasicTypesListBox.Value;
            if ischar(type)
                type = {type};
            end

            % start the array with an 'all' option.
            sigAll = {'all'};
            % Iterate through all the types that were selected.
            for i = 1:length(type)
                % Get all the files names.
                files = fieldnames(data.(type{i}));
                % Iterate through the file names.
                for j = 1:length(files)
                    % Get the list of objects and remove the informational ones.
                    obj = fieldnames(data.(type{i}).(files{j}));
                    obj(strcmp(obj, 'meta')) = [];
                    obj(strcmp(obj, 'groups')) = [];
                    % Iterate through the objects.
                    for k = 1:length(obj)
                        % Get all the signal names.
                        sig = fieldnames(data.(type{i}).(files{j}).(obj{k}).data);
                        % Append the signal names to the existing array.
                        sigAll = [sigAll; sig];
                    end
                end
            end

            % Set the list boxes to the unique values while adding an 'all' option to the top.
            app.SignalsListBox.Items = unique(sigAll);

            % Print a message that the updating is complete.
            printLog(app, '005', 'Signals')

        end

        % Value changed function: AdvancedTypesListBox
        function AdvancedTypesListBoxValueChanged(app, event)
            % AdvancedTypesListBoxValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, contains information about the app from the button press.
            % Remarks
            % - This method will populate the measures list box with the available measures from the selected advanced type.
            % - All the values are added to a single column. This allows the other information to be treated as factors in outlier analyses.
            % Future Work
            % - None.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Print a message that the list box is updating.
            printLog(app, '004', 'Measures')

            % Get the current data.
            data = getappdata(app.DAMVi_App.TableFiles, 'data');

            % Get the selected data type.
            type = app.AdvancedTypesListBox.Value;
            if ischar(type)
                type = {type};
            end

            % start the array with an 'all' option.
            measAll = {'all'};
            % Iterate through all the types that were selected.
            for i = 1:length(type)
                % Get all the analyses used.
                ana = fieldnames(data.(type{i}));
                for j = 1:length(ana)
                    % Get all the files names.
                    files = fieldnames(data.(type{i}).(ana{j}));
                    % Iterate through the file names.
                    for k = 1:length(files)
                        % Get the list of objects and remove the informational ones.
                        obj = fieldnames(data.(type{i}).(ana{j}).(files{k}));
                        obj(strcmp(obj, 'meta')) = [];
                        obj(strcmp(obj, 'groups')) = [];
                        % Iterate through the objects.
                        for ii = 1:length(obj)
                            % Get all the signal names.
                            sig = fieldnames(data.(type{i}).(ana{j}).(files{k}).(obj{ii}).data);
                            for jj = 1:length(sig)
                                meas = fieldnames(data.(type{i}).(ana{j}).(files{k}).(obj{ii}).data.(sig{jj}));
                                % Append the signal names to the existing array.
                                measAll = [measAll; meas];
                            end
                        end
                    end
                end
            end

            % Set the list boxes to the unique values while adding an 'all' option to the top.
            app.MeasuresListBox.Items = unique(measAll);

            % Print a message that the updating is complete.
            printLog(app, '005', 'Measures')

        end

        % Value changed function: SignalsListBox
        function SignalsListBoxValueChanged(app, event)
            % SignalsListBoxValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, contains information about the app from the button press.
            % Remarks
            % - This method will use the selected signals to prepare an array for the quality method. The array will be displayed in the table.
            % - All the values are added to a single column. This allows the other information to be treated as factors in outlier analyses.
            % Future Work
            % - None.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Get the selected value from the user interface.
            value = app.SignalsListBox.Value;
            % If only one value was selected turn it into a cell array of one.
            if ischar(value)
                value = {value};
            end

            % Get the current data.
            data = getappdata(app.DAMVi_App.TableFiles, 'data');

            % Start an index for the table.
            ind = 1;
            % Create an empty structure for the table.
            qualityTable = struct;

            % Get the type that was selected.
            type = app.BasicTypesListBox.Value;
            % Iterate through the types.
            for i = 1:length(type)
                % Get the file names under that type.
                files = fieldnames(data.(type{i}));
                % Iterate through the files
                for j = 1:length(files)
                    % Get the object names for the current file and remove the informational ones.
                    obj = fieldnames(data.(type{i}).(files{j}));
                    obj(strcmp(obj, 'meta')) = [];
                    obj(strcmp(obj, 'groups')) = [];
                    % Iterate through the objects.
                    for k = 1:length(obj)
                        % Iterate through the selected signals.
                        for ii = 1:length(value)
                            % Check that the signal is present in the object.
                            if isfield(data.(type{i}).(files{j}).(obj{k}).data, value{ii})
                                % Create a row number for the user to use as reference. 'Row' cannot be used and is a reserved word.
                                qualityTable.RowNumber(ind, 1) = ind;
                                % Add the data type to the table.
                                qualityTable.Type{ind, 1} = type{i};
                                % Add the file name to the table.
                                qualityTable.File{ind, 1} = files{j};
                                % If group information is present under file then add them to the table.
                                if isfield(data.(type{i}).(files{j}), 'groups')
                                    qualityTable.FileGroups{ind, 1} = data.(type{i}).(files{j}).groups;
                                end
                                % Add the object name to the table.
                                qualityTable.Object{ind, 1} = obj{k};
                                % If group information is present for the object add it to the table.
                                if isfield(data.(type{i}).(files{j}).(obj{k}), 'groups')
                                    qualityTable.ObjectGroups{ind, 1} = data.(type{i}).(files{j}).(obj{k}).groups;
                                end
                                % Add the signal name to the table.
                                qualityTable.Signal{ind, 1} = value{ii};
                                % Add the value to a single column.
                                qualityTable.Value{ind, 1} = data.(type{i}).(files{j}).(obj{k}).data.(value{ii});
                                % If a quality fault is present add it to the table.
                                if isfield(data, 'qua') && isfield(data.qua, type{i}) &&...
                                        isfield(data.qua.(type{i}), files{j}) &&...
                                        isfield(data.qua.(type{i}).(files{j}), obj{k}) &&...
                                        isfield(data.qua.(type{i}).(files{j}).(obj{k}).data, value{ii}) &&...
                                        isfield(data.qua.(type{i}).(files{j}).(obj{k}).data.(value{ii}), 'fault')
                                    qualityTable.Fault(ind, 1) = data.qua.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{kk}).(value{ii}).fault;
                                else
                                    % If a quality fault is not present make it 0.
                                    qualityTable.Fault(ind, 1) = 0;
                                end
                                % If a quality measure is present add it to the table.
                                if isfield(data, 'qua') && isfield(data.qua, type{i}) &&...
                                        isfield(data.qua.(type{i}), files{j}) &&...
                                        isfield(data.qua.(type{i}).(files{j}), obj{k}) &&...
                                        isfield(data.qua.(type{i}).(files{j}).(obj{k}).data, value{ii}) &&...
                                        isfield(data.qua.(type{i}).(files{j}).(obj{k}).data.(sigs{k}).(value{ii}), 'notes')
                                    qualityTable.Notes{ind, 1} = data.qua.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{kk}).(value{ii}).notes;
                                else
                                    % If a quality measure is not present make it an empty string.
                                    qualityTable.Notes{ind, 1} = '';
                                end
                                % Add one index to the row count.
                                ind = ind + 1;
                            end
                        end
                    end
                end
            end

            % Convert the structure to a table and display it in the app.
            app.UITable.Data = struct2table(qualityTable);
            % Set the column headers using the fieldnames of the structure.
            app.UITable.ColumnName = fieldnames(qualityTable);
        end

        % Value changed function: MeasuresListBox
        function MeasuresListBoxValueChanged(app, event)
            % SignalsListBoxValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, contains information about the app from the button press.
            % Remarks
            % - This method will use the selected measures to prepare an array for the quality method. The array will be displayed in the table.
            % Future Work
            % - None.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Get the selected value from the user interface.
            value = app.MeasuresListBox.Value;
            % If only one value was selected turn it into a cell array of one.
            if ischar(value)
                value = {value};
            end

            % Get the current data.
            data = getappdata(app.DAMVi_App.TableFiles, 'data');

            % Start an index for the table.
            ind = 1;
            % Create an empty structure for the table.
            qualityTable = struct;

            % Get the type that was selected.
            type = app.AdvancedTypesListBox.Value;
            % Iterate through the types.
            for i = 1:length(type)
                % Get the analysis names used.
                ana = fieldnames(data.(type{i}));
                % Iterate through each analysis.
                for jj = 1:length(ana)
                    % Get the file names under that type.
                    files = fieldnames(data.(type{i}).(ana{jj}));
                    % Iterate through the files
                    for j = 1:length(files)
                        % Get the object names for the current file and remove the informational ones.
                        obj = fieldnames(data.(type{i}).(ana{jj}).(files{j}));
                        obj(strcmp(obj, 'meta')) = [];
                        obj(strcmp(obj, 'groups')) = [];
                        % Iterate through the objects.
                        for k = 1:length(obj)
                            % Get the signal names.
                            sigs = fieldnames(data.(type{i}).(ana{jj}).(files{j}).(obj{k}).data);
                            % Iterate through the signal names.
                            for kk = 1:length(sigs)
                                % Iterate through the selected measures.
                                for ii = 1:length(value)
                                    % check that the measures are present in the signal.
                                    if isfield(data.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{kk}), value{ii})
                                        m = size(data.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{kk}).(value{ii}), 2);
                                        for ind_m = 1:m
                                            % Create a row number for the user to use as reference. 'Row' cannot be used and is a reserved word.
                                            qualityTable.RowNumber(ind, 1) = ind;
                                            % Add the data type to the table.
                                            qualityTable.Type{ind, 1} = type{i};
                                            % Add the analysis name to the table.
                                            qualityTable.Analysis{ind, 1} = ana{jj};
                                            % Add the file name to the table.
                                            qualityTable.File{ind, 1} = files{j};
                                            % If group information is present under file then add them to the table.
                                            if isfield(data.(type{i}).(ana{jj}).(files{j}), 'groups')
                                                qualityTable.FileGroups{ind, 1} = data.(type{i}).(ana{jj}).(files{j}).groups;
                                            end
                                            % Add the object name to the table.
                                            qualityTable.Object{ind, 1} = obj{k};
                                            % If group information is present for the object add it to the table.
                                            if isfield(data.(type{i}).(ana{jj}).(files{j}).(obj{k}), 'groups')
                                                qualityTable.ObjectGroups{ind, 1} = data.(type{i}).(ana{jj}).(files{j}).(obj{k}).groups;
                                            end
                                            % Add the signal name to the table.
                                            qualityTable.Signal{ind, 1} = sigs{kk};
                                            % Add the measure name to the table.
                                            qualityTable.Measure{ind, 1} = value{ii};
                                            % Add the dimension to the table.
                                            qualityTable.Dimension{ind, 1} = ind_m;
                                            % Add the value to a single column.
                                            if iscell(data.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{kk}).(value{ii}))
                                                qualityTable.Value{ind, 1} = data.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{kk}).(value{ii}){1, ind_m};
                                            else
                                                qualityTable.Value{ind, 1} = data.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{kk}).(value{ii})(1, ind_m);
                                            end
                                            % If a quality fault is present add it to the table.
                                            if isfield(data, 'qua') && isfield(data.qua, type{i}) && isfield(data.qua.(type{i}), ana{jj}) &&...
                                                    isfield(data.qua.(type{i}).(ana{jj}), files{j}) &&...
                                                    isfield(data.qua.(type{i}).(ana{jj}).(files{j}), obj{k}) &&...
                                                    isfield(data.qua.(type{i}).(ana{jj}).(files{j}).(obj{k}).data, sigs{k}) &&...
                                                    isfield(data.qua.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{k}), value{ii}) &&...
                                                    isfield(data.qua.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{k}).(value{ii}), 'fault')
                                                qualityTable.Fault(ind, 1) = data.qua.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{kk}).(value{ii}).fault;
                                            else
                                                % If a quality fault is not present make it 0.
                                                qualityTable.Fault(ind, 1) = 0;
                                            end
                                            % If a quality measure is present add it to the table.
                                            if isfield(data, 'qua') && isfield(data.qua, type{i}) && isfield(data.qua.(type{i}), ana{jj}) &&...
                                                    isfield(data.qua.(type{i}).(ana{jj}), files{j}) &&...
                                                    isfield(data.qua.(type{i}).(ana{jj}).(files{j}), obj{k}) &&...
                                                    isfield(data.qua.(type{i}).(ana{jj}).(files{j}).(obj{k}).data, sigs{k}) &&...
                                                    isfield(data.qua.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{k}), value{ii}) &&...
                                                    isfield(data.qua.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{k}).(value{ii}), 'notes')
                                                qualityTable.Notes{ind, 1} = data.qua.(type{i}).(ana{jj}).(files{j}).(obj{k}).data.(sigs{kk}).(value{ii}).notes;
                                            else
                                                % If a quality measure is not present make it an empty string.
                                                qualityTable.Notes{ind, 1} = '';
                                            end
                                            % Add one to the row index.
                                            ind = ind + 1;
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
            end

            % Convert the structure to a table and display it in the app.
            app.UITable.Data = struct2table(qualityTable);
            % Set the column headers using the fieldnames of the structure.
            app.UITable.ColumnName = fieldnames(qualityTable);
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            % Delete the module.
            delete(app)
        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
            % UITableDoubleClicked(app, event)
            % inputs  - app, required mlapp object.
            %         - event, contains information about the app from the button press.
            % Remarks
            % - This method is used to pass the selected cells row and column indexes to the method that updates the manual quality panel.
            % Future Work
            % - None.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Get the row number.
            displayRow = event.InteractionInformation.DisplayRow;
            % Get the column number.
            displayColumn = event.InteractionInformation.DisplayColumn;
            % Send the indexes to a method that updates the manual quality panel.
            updateManualQC(app, displayRow, displayColumn)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % UpdateButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, contains information about the app from the button press.
            % Remarks
            % - This method uses the information in the manual quality panel to update the indicated row. This is the row set in the panel, not
            % necessarily the one that is selected.
            % Future Work
            % - None.
            % May 2023 - Created by Ben Senderling, bsender@bu.edu

            % Set the number of faults in the table to what in set in the panel.
            app.UITable.Data.Fault(app.TableRowIndexSpinner.Value) = app.NumberofFaultsSpinner.Value;
            % Change the notes in the table to what is entered in the panel.
            app.UITable.Data.Notes{app.TableRowIndexSpinner.Value} = app.NotesEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.QualityControlLabel = uilabel(app.UIFigure);
            app.QualityControlLabel.Text = 'Quality Control';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.SelectMethodDropDownLabel = uilabel(app.UIFigure);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.UIFigure);
            app.SelectMethodDropDown.Items = {};
            app.SelectMethodDropDown.Value = {};
            app.SaveResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.Version = uilabel(app.UIFigure);
            app.SignalsListBox_2Label = uilabel(app.UIFigure);
            app.SignalsListBox_2Label.Text = 'Signals';
            app.SignalsListBox = uilistbox(app.UIFigure);
            app.SignalsListBox.Items = {};
            app.SignalsListBox.Multiselect = 'on';
            app.SignalsListBox.ValueChangedFcn = createCallbackFcn(app, @SignalsListBoxValueChanged, true);
            app.SignalsListBox.Value = {};
            app.ObjectsLabel = uilabel(app.UIFigure);
            app.ObjectsLabel.Text = 'Measures';
            app.MeasuresListBox = uilistbox(app.UIFigure);
            app.MeasuresListBox.Items = {};
            app.MeasuresListBox.Multiselect = 'on';
            app.MeasuresListBox.ValueChangedFcn = createCallbackFcn(app, @MeasuresListBoxValueChanged, true);
            app.MeasuresListBox.Value = {};
            app.RunQualityControlMethodButton = uibutton(app.UIFigure, 'push');
            app.RunQualityControlMethodButton.ButtonPushedFcn = createCallbackFcn(app, @RunQualityControlMethodButtonPushed, true);
            app.RunQualityControlMethodButton.Text = 'Run Quality Control Method';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.BasicTypesListBoxLabel = uilabel(app.UIFigure);
            app.BasicTypesListBoxLabel.Text = 'Basic Types';
            app.BasicTypesListBox = uilistbox(app.UIFigure);
            app.BasicTypesListBox.Items = {};
            app.BasicTypesListBox.Multiselect = 'on';
            app.BasicTypesListBox.ValueChangedFcn = createCallbackFcn(app, @BasicTypesListBoxValueChanged, true);
            app.BasicTypesListBox.Value = {};
            app.AdvancedTypesListBoxLabel = uilabel(app.UIFigure);
            app.AdvancedTypesListBoxLabel.Text = 'Advanced Types';
            app.AdvancedTypesListBox = uilistbox(app.UIFigure);
            app.AdvancedTypesListBox.Items = {};
            app.AdvancedTypesListBox.Multiselect = 'on';
            app.AdvancedTypesListBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedTypesListBoxValueChanged, true);
            app.AdvancedTypesListBox.Value = {};
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ManualQualityControlPanel = uipanel(app.UIFigure);
            app.ManualQualityControlPanel.Title = 'Manual Quality Control';
            app.TableRowIndexSpinnerLabel = uilabel(app.ManualQualityControlPanel);
            app.TableRowIndexSpinnerLabel.Text = 'Table Row Index';
            app.TableRowIndexSpinner = uispinner(app.ManualQualityControlPanel);
            app.NumberofFaultsSpinnerLabel = uilabel(app.ManualQualityControlPanel);
            app.NumberofFaultsSpinnerLabel.Text = 'Number of  Faults';
            app.NumberofFaultsSpinner = uispinner(app.ManualQualityControlPanel);
            app.NotesEditFieldLabel = uilabel(app.ManualQualityControlPanel);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.NotesEditField = uieditfield(app.ManualQualityControlPanel, 'text');
            app.UpdateButton = uibutton(app.ManualQualityControlPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.DependenciesPanel = uipanel(app.UIFigure);
            app.DependenciesPanel.Title = 'Dependencies';
            app.SignalswithinthesameobjectCheckBox = uicheckbox(app.DependenciesPanel);
            app.SignalswithinthesameobjectCheckBox.Text = 'Signals within the same object';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = review_QC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end