classdef review_RAW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        v10Label                       matlab.ui.control.Label
        DataTypeDropDown               matlab.ui.control.DropDown
        DataTypeDropDownLabel          matlab.ui.control.Label
        GetadditionalaxesfromDropDown  matlab.ui.control.DropDown
        GetadditionalaxesfromDropDownLabel  matlab.ui.control.Label
        UpdatePlotButton               matlab.ui.control.Button
        MetaValueListBox               matlab.ui.control.ListBox
        MetaValueListBoxLabel          matlab.ui.control.Label
        MetaFieldListBox               matlab.ui.control.ListBox
        MetaFieldListBoxLabel          matlab.ui.control.Label
        DimensionListBox               matlab.ui.control.ListBox
        DimensionListBoxLabel          matlab.ui.control.Label
        SignalsListBox                 matlab.ui.control.ListBox
        SignalsListBoxLabel            matlab.ui.control.Label
        ObjectsListBox                 matlab.ui.control.ListBox
        ObjectsListBoxLabel            matlab.ui.control.Label
        FilesListBox                   matlab.ui.control.ListBox
        FilesListBoxLabel              matlab.ui.control.Label
        PlottypeDropDown               matlab.ui.control.DropDown
        PlottypeDropDownLabel          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        Log                            matlab.ui.control.TextArea
        Image                          matlab.ui.control.Image
        RawDataReivewLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        BAR_App   % Main app object
    end

    properties (Access = public)
    end

    methods (Access = private)

        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['selection not valid to plot'];
                case '002'
                    text{length(text) + 1,1} = ['updating selections'];
                case '003'
                    text{length(text) + 1,1} = ['update complete'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end

        function UpdatePlot(app, dim)

            % The data is pulled from the BAR app.
            data = getappdata(app.BAR_App.TableFiles, 'data');

            % Find if the Raw or Processed data should be displayed.
            switch app.DataTypeDropDown.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
                case 'Results'
                    type = 'res';
            end

            app.Panel.Children.delete

            % This may solve some pixilation problems on computers when
            % displaying images.
            if strcmp(app.PlottypeDropDown.Value, 'Image')
                opengl hardware
            end

            dim = arrayfun(@str2double, app.DimensionListBox.Value);
            if isempty(dim)
                dim = 1;
            end

            plotData = [];
            plotData{1} = [];
            ind_plot = 1;
            files = fieldnames(data.(type));
            for i = 1:length(files)
                if any(strcmp(app.FilesListBox.Value, files{i})) || isempty(app.FilesListBox.Value) || strcmp(app.FilesListBox.Value, 'all')

                    if ~isfield(data.(type).(files{i}), 'meta') || isempty(app.MetaFieldListBox.Value) || (isfield(data.(type).(files{i}).meta, app.MetaFieldListBox.Value{1}) || strcmp(app.MetaFieldListBox.Value{1}, 'none')) && (strcmp(data.(type).(files{i}).meta.(app.MetaFieldListBox.Value{1}), app.MetaValueListBox.Value) || strcmp(app.MetaValueListBox.Value, 'none'))

                        obj = fieldnames(data.(type).(files{i}));
                        obj(strcmp(obj, 'meta')) = [];
                        obj(strcmp(obj, 'groups')) = [];
                        for j = 1:length(obj)
                            if any(strcmp(app.ObjectsListBox.Value, obj{j})) || isempty(app.ObjectsListBox.Value) || any(strcmp(app.ObjectsListBox.Value, 'all'))
                                sig = fieldnames(data.(type).(files{i}).(obj{j}).data);
                                ind_plot = 1;
                                for k = 1:length(sig)
                                    if any(strcmp(app.SignalsListBox.Value, sig{k})) || isempty(app.SignalsListBox.Value)
                                        if size(plotData, 2) < ind_plot
                                            ind = 1;
                                        else
                                            ind = size(plotData{ind_plot}, 2) + 1;
                                        end
                                        newData = data.(type).(files{i}).(obj{j}).data.(sig{k})(:, dim);
                                        if size(plotData, 2) < ind_plot || isempty(plotData{ind_plot})
                                            plotData{ind_plot}(:, ind) = newData;
                                        elseif size(newData, 1) > size(plotData{ind_plot}, 1)
                                            plotData{ind_plot} = [plotData{ind_plot}; NaN*ones(size(newData, 1) - size(plotData{ind_plot}, 1), size(plotData{ind_plot}, 2))];
                                            plotData{ind_plot}(:, ind) = newData;
                                        elseif size(newData, 1) < size(plotData{ind_plot}, 1)
                                            plotData{ind_plot}(:, ind) = [newData; NaN*ones(size(plotData{ind_plot}, 1) - size(newData, 1), 1)];
                                        else
                                            plotData{ind_plot}(:, ind) = newData;
                                        end
                                        ind_plot = ind_plot + 1;
                                    end
                                end
                            end
                        end
                    end
                end

            end

            if isempty(plotData{1})
                return
            end

            H = figure('visible', 'off');
            
            switch app.PlottypeDropDown.Value
                case '1D'

                    ax = axes('Parent', H);
                    for i = 1:length(plotData)
                        plot(ax, plotData{i}, 'k');
                        if i == 1
                            hold('on')
                        end
                        if i == length(plotData)
                            hold('off')
                            axis('tight')
                        end
                    end
                    copyobj(ax, app.Panel)

                case '2D'

                        ax = axes('Parent', H);
                        plot(ax, plotData{1}, plotData{2}, 'k');
                        copyobj(ax, app.Panel)

                case '3D'

                        ax = axes('Parent', H);
                        plot3(ax, plotData{1}, plotData{2}, plotData{3}, 'k');
                        copyobj(ax, app.Panel)

                case 'Image'

                    if length(plotData) == 1
                        ax = axes('Parent', H);
                        imshow(plotData{1}, 'Border','tight', 'Interpolation', 'bilinear');
                        copyobj(ax, app.Panel)
                    else
                        N = ceil(length(plotData)/2);
                        for i = 1:length(plotData)
                            ax(i) = subplot(N,2,i);
                            imshow(plotData{i}, 'Border','tight', 'Interpolation', 'bilinear')
                            copyobj(ax(i), app.Panel)
                        end
                    end

                case 'Histogram'

                    if  ind > 1
                        ax = axes('Parent', H);
                        for i = 1:length(plotData)
                            histogram(ax, plotData{i});
                            if i == 1
                                hold('on')
                            end
                            if i == length(plotData)
                                hold('off')
                                axis('tight')
                            end
                        end
                        copyobj(ax, app.Panel)
                    end

            end

        end

        function createNodeTree(app)

            app.Tree.Children.delete;

            data = getappdata(app.BAR_App.TableFiles, 'data');

            switch app.DataTypeDropDown.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
                case 'Results'
                    type = 'res';
            end

            allSigs = {'none'};
            allObjs = {'none'};

            files = fieldnames(data.(type));
            ind1 = 1;
            for i = 1:length(files)
                uitreenode(app.Tree, 'Text', files{i});
                objs = fieldnames(data.(type).(files{i}));
                allObjs = [allObjs; objs];
                ind2 = 1;
                obj(strcmp(obj, 'meta')) = [];
                obj(strcmp(obj, 'groups')) = [];
                for j = 1:length(objs)
                    uitreenode(app.Tree.Children(ind1), 'Text', objs{j});
                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                    for k = 1:length(sigs)
                        uitreenode(app.Tree.Children(ind1).Children(ind2), 'Text', sigs{k});
                        allSigs = [allSigs; sigs{k}];
                    end
                    ind2 = ind2 + 1;
                end
                ind1 = ind1 + 1;
            end

            if numel(size(data.(type).(files{i}).(objs{j}).data.(sigs{k}))) == 2
                app.PlotselectdatadimensionListBox.Items = arrayfun(@num2str, (1:size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2)), 'UniformOutput', 0);
            else
                app.PlotselectdatadimensionListBox.Items = {'1'};
            end

            app.PlotselectedsignalsListBox.Items = unique(allSigs);
            app.PlotselectedobjectsListBox.Items = unique(allObjs);
        end
        
        function createLists(app)
            
            data = getappdata(app.BAR_App.TableFiles, 'data');
            switch app.DataTypeDropDown.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
                case 'Results'
                    type = 'res';
            end

            % Create list for Files.
            files = fieldnames(data.(type));
            app.FilesListBox.Items = [{'all'}; files];

            % Create list for Objects, Signals and Dimensions.
            objList = [];
            sigList = [];
            d = [];
            for i = 1:length(files)
                obj = fieldnames(data.(type).(files{i}));
                obj(strcmp(obj, 'meta')) = [];
                obj(strcmp(obj, 'groups')) = [];
                objList = [objList; obj];
                for j = 1:length(obj)
                    sig = fieldnames(data.(type).(files{i}).(obj{j}).data);
                    sigList = [sigList; sig];
                    for k = 1:length(sig)
                        d = [d; (1:size(data.(type).(files{i}).(obj{j}).data.(sig{k}), 2))'];
                    end
                end
            end
            app.ObjectsListBox.Items = [{'all'}; unique(objList)];
            app.SignalsListBox.Items = [{'all'}; unique(sigList)];
            app.DimensionListBox.Items = [{'all'}; cellstr(num2str(unique(d)))];

            % Identify the fields for meta data.
            metaListFields = [];
            for i = 1:length(files)
                if isfield(data.(type).(files{i}), 'meta')
                    metaListFields = [metaListFields; fieldnames(data.(type).(files{i}).meta)];
                end
            end
            metaListFields = unique(metaListFields);
            app.MetaFieldListBox.Items = [{'none'}; metaListFields];

        end
    end

    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;

            config = loadConfig(app.BAR_App);

            app.PlottypeDropDown.Items = config.figures(1:2:end);

            createLists(app)

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Callback function
        function SaveResultsandExitButtonPushed(app, event)

            delete(app)
        end

        % Value changed function: MetaFieldListBox
        function MetaFieldListBoxValueChanged(app, event)
            value = app.MetaFieldListBox.Value;
            
            data = getappdata(app.BAR_App.TableFiles, 'data');
            switch app.DataTypeDropDown.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
                case 'Results'
                    type = 'res';
            end

            metaListValues = [];
            files = fieldnames(data.(type));
            for i = 1:length(files)
                if isfield(data.(type).(files{i}).meta, value{1})
                    metaListValues = [metaListValues; {data.(type).(files{i}).meta.(value{1})}];
                end
            end
            try
                app.MetaValueListBox.Items = unique(metaListValues);
            catch
                app.MetaValueListBox.Items = {};
            end
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            UpdatePlot(app, [])
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            value = app.DataTypeDropDown.Value;
            createLists(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.RawDataReivewLabel = uilabel(app.UIFigure);
            app.RawDataReivewLabel.Text = 'Raw Data Reivew';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.Log = uitextarea(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.PlottypeDropDownLabel = uilabel(app.UIFigure);
            app.PlottypeDropDownLabel.Text = 'Plot type';
            app.PlottypeDropDown = uidropdown(app.UIFigure);
            app.PlottypeDropDown.Items = {};
            app.PlottypeDropDown.Value = {};
            app.FilesListBoxLabel = uilabel(app.UIFigure);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.UIFigure);
            app.FilesListBox.Items = {};
            app.FilesListBox.Multiselect = 'on';
            app.FilesListBox.Value = {};
            app.ObjectsListBoxLabel = uilabel(app.UIFigure);
            app.ObjectsListBoxLabel.Text = 'Objects';
            app.ObjectsListBox = uilistbox(app.UIFigure);
            app.ObjectsListBox.Items = {};
            app.ObjectsListBox.Multiselect = 'on';
            app.ObjectsListBox.Value = {};
            app.SignalsListBoxLabel = uilabel(app.UIFigure);
            app.SignalsListBoxLabel.Text = 'Signals';
            app.SignalsListBox = uilistbox(app.UIFigure);
            app.SignalsListBox.Items = {};
            app.SignalsListBox.Multiselect = 'on';
            app.SignalsListBox.Value = {};
            app.DimensionListBoxLabel = uilabel(app.UIFigure);
            app.DimensionListBoxLabel.Text = 'Dimension';
            app.DimensionListBox = uilistbox(app.UIFigure);
            app.DimensionListBox.Items = {};
            app.DimensionListBox.Multiselect = 'on';
            app.DimensionListBox.Value = {};
            app.MetaFieldListBoxLabel = uilabel(app.UIFigure);
            app.MetaFieldListBoxLabel.Text = 'Meta Field';
            app.MetaFieldListBox = uilistbox(app.UIFigure);
            app.MetaFieldListBox.Items = {};
            app.MetaFieldListBox.Multiselect = 'on';
            app.MetaFieldListBox.ValueChangedFcn = createCallbackFcn(app, @MetaFieldListBoxValueChanged, true);
            app.MetaFieldListBox.Value = {};
            app.MetaValueListBoxLabel = uilabel(app.UIFigure);
            app.MetaValueListBoxLabel.Text = 'Meta Value';
            app.MetaValueListBox = uilistbox(app.UIFigure);
            app.MetaValueListBox.Items = {};
            app.MetaValueListBox.Multiselect = 'on';
            app.MetaValueListBox.Value = {};
            app.UpdatePlotButton = uibutton(app.UIFigure, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.GetadditionalaxesfromDropDownLabel = uilabel(app.UIFigure);
            app.GetadditionalaxesfromDropDownLabel.Text = 'Get additional axes from:';
            app.GetadditionalaxesfromDropDown = uidropdown(app.UIFigure);
            app.GetadditionalaxesfromDropDown.Items = {'Files', 'Objects', 'Signals', 'Dimension'};
            app.GetadditionalaxesfromDropDown.Value = 'Files';
            app.DataTypeDropDownLabel = uilabel(app.UIFigure);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.UIFigure);
            app.DataTypeDropDown.Items = {'Raw', 'Processed', 'Results'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'Raw';
            app.v10Label = uilabel(app.UIFigure);
            app.v10Label.Text = 'v1.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = review_RAW(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end