classdef review_RQA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FindGroupsButton                matlab.ui.control.Button
        Groups                          matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        RecurrencePlotValues            matlab.ui.control.TextArea
        Switch                          matlab.ui.control.Switch
        Tree                            matlab.ui.container.Tree
        RecurrencePlotPanel             matlab.ui.container.Panel
        PlotselectdatadimensionListBox  matlab.ui.control.ListBox
        PlotselectdatadimensionListBoxLabel  matlab.ui.control.Label
        SelectmissingresultsCheckBox    matlab.ui.control.CheckBox
        Log                             matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        RecurrenceQuantificationReviewLabel  matlab.ui.control.Label
        FigureHist                      matlab.ui.control.UIAxes
        FigureResults                   matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['selection not valid to plot'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
        

        function updatePlot(app, dim)
            selectedNode = app.Tree.SelectedNodes;

            app.RecurrencePlotPanel.Children.delete;

            data = getappdata(app.UIFigure, 'data');

            type = 'res';
            review = 'RQA';

            if isempty(dim)
                dim = app.PlotselectdatadimensionListBox.Value;
                if isempty(dim)
                    dim = 1;
                else
                    dim = arrayfun(@str2double, dim);
                end
            end


            n = 0;
            S = selectedNode;
            while isprop(S,'Parent')
                n = n + 1;
                S = S.Parent;
            end

            if n == 6

                DIM = data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.DIM;
                dimEmb = data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.EMB;
                tau = data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.DEL;
                radius = data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.RADIUS;
                norm = data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.NORM;
                zScore = data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.ZSCORE;
                typeRQA = 'RQA';%data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.RADIUS;
                dataPlot = data.processed.(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).data.(selectedNode.Text)(:,dim);%data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.DATA;

                if strcmp(app.Switch.Value, 'Binary')
                    rp = data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).rp{dim};
                else
                    rp = data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).rp{dim};
                end

                H = figure('visible', 'off');

                a1 = axes('Parent',H,'Position', [0 0 1 1], 'Visible', 'off');
                ax(1) = axes('Parent',H,'Position',[.375 .35 .58 .6], 'FontSize', 8);
                imagesc(ax(1),rp);
                colormap(gray);
                xlabel('X(i)','Interpreter','none', 'FontSize', 10);
                ylabel('Y(j)','Interpreter','none', 'FontSize', 10);
                set(gca,'XTick',[ ]);
                set(gca,'YTick',[ ]);
                switch upper(typeRQA)
                    case {'RQA','MDRQA','MD','MULTI'}
                        ax(2) = axes('Parent',H,'Position',[.375 .1 .58 .15], 'FontSize', 8);
                        plot(ax(2), 1:length(dataPlot(:,1)), dataPlot(:,1), 'k-');
                        xlim([1 length(rp(:,1))]);
                        ax(3) = axes('Parent',H,'Position',[.09 .35 .15 .6], 'FontSize', 8);
                        plot(ax(3), flip(dataPlot(:,1)), 1:length(dataPlot(:,1)), 'k-');
                        ylim([1 length(rp(:,1))]);
                        set(ax(3),'Ydir','reverse');
                    case {'CRQA','CROSS'}
                        ax(2) = axes('Parent',H,'Position',[.375 .1 .58 .15], 'FontSize', 8);
                        plot(ax(2), 1:length(dataPlot(:,1)), dataPlot(:,1), 'k-');
                        xlim([1 length(dataPlot(:,1))]);
                        ax(3) = axes('Parent',H,'Position',[.09 .35 .15 .6], 'FontSize', 8);
                        plot(ax(3), flip(dataPlot(:,2)), 1:length(dataPlot(:,2)), 'k-');
                        ylim([1 length(dataPlot(:,1))]);
                        set(ax(3),'Ydir','reverse');
                    case {'JRQA','JOINT'}
                        for i = 1:c
                            ax(2) = axes('Parent',H,'Position',[.375 .1 .58 .15], 'FontSize', 8);
                            plot(ax(2), 1:length(dataPlot(:,1)), dataPlot(:,i),'k-');
                            xlim([1 length(dataPlot(:,1))]);
                            ax(3) = axes('Parent',H,'Position',[.09 .35 .15 .6], 'FontSize', 8);
                            plot(ax(3), flip(dataPlot(:,1)), 1:length(data(:,i)),'k-');
                            ylim([1 length(dataPlot(:,1))]);
                            set(ax(3),'Ydir','reverse');
                        end
                end
                linkaxes(ax([1,2]),'x');
                linkaxes(ax([1,3]),'y');

                str = {['DIM = ', sprintf('%i\t',DIM), ' EMB = ',sprintf('%i\t',dimEmb), ' DEL = ', sprintf('%i\t',tau), ' RAD = ', sprintf('%.2f\t',radius), ' NORM = ', norm, sprintf('\t'), ' ZSCORE = ', sprintf('%i',zScore)];...
                    ['%REC = ', sprintf('%.2f\t\t',data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.REC)...
                    'MaxL = ', sprintf('%.0f\t\t',data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.MaxL)...
                    '%LAM = ', sprintf('%.2f\t\t',data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.LAM)...
                    'EntrW = ', sprintf('%.2f',data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.EntrW)];
                    ['%DET = ', sprintf('%.2f\t',data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.DET)...
                    'MeanL = ', sprintf('%.2f\t\t',data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.MeanL)...
                    'MaxV = ', sprintf('%.0f',data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.MaxV)];...
                    [sprintf('\t\t\t\t') 'EntrL = ', sprintf('%.2f\t\t',data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.EntrL)...
                    'MeanV = ', sprintf('%.2f',data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.MeanV)];...
                    [sprintf('\t\t\t\t\t\t\t\t') 'EntrV = ', sprintf('%.2f',data.(type).(review).(selectedNode.Parent.Parent.Text).(selectedNode.Parent.Text).(selectedNode.Text).results{dim}.EntrV)]...
                    };
                app.RecurrencePlotValues.Value = str;

                for i = 1:3
                    copyobj(ax(i), app.RecurrencePlotPanel)
                end


            end

        end
        
        
        function updateHist(app, dataGrouped)
            
            groups = fieldnames(dataGrouped);
            for i = 1:length(groups)
                histogram(app.FigureHist, dataGrouped.(groups{i}), 'FaceAlpha', 0.5, 'EdgeAlpha', 0.5)
                if i ==1
                    hold(app.FigureHist, 'on')
                elseif i == length(groups)
                    hold(app.FigureHist, 'off')
                end
            end
            legend(groups)
        end
    end
    
    methods (Access = public)
        
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;

            config = loadConfig(app.BAR_App);
            
            setappdata(app.UIFigure, 'data', data)

            type = 'res';
            review = 'RQA';

            files = fieldnames(data.(type).(review));
            ind1 = 1;
            for i = 1:length(files)
                uitreenode(app.Tree, 'Text', files{i});
                objs = fieldnames(data.(type).(review).(files{i}));
                ind2 = 1;
                for j = 1:length(objs)
                    uitreenode(app.Tree.Children(ind1), 'Text', objs{j})
                    sigs = fieldnames(data.(type).(review).(files{i}).(objs{j}));
                    for k = 1:length(sigs)
                        uitreenode(app.Tree.Children(ind1).Children(ind2), 'Text', sigs{k});
                    end
                    ind2 = ind2 + 1;
                end
                ind1 = ind1 + 1;
            end

            app.PlotselectdatadimensionListBox.Items = arrayfun(@num2str, (1:size(data.(type).(review).(files{i}).(objs{j}).(sigs{k}).results, 2)), 'UniformOutput', 0);
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Callback function
        function SaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UIFigure, 'data');
            analysis = app.RecurrenceQuantificationReviewLabel.Text;
            
            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end

        % Value changed function: SelectmissingresultsCheckBox
        function SelectmissingresultsCheckBoxValueChanged(app, event)
            value = app.SelectmissingresultsCheckBox.Value;

            dim = app.PlotselectdatadimensionListBox.Value;
            if isempty(dim)
                dim = 1;
            else
                dim = str2double(cell2mat(dim));
            end

            if value

                data = getappdata(app.UIFigure, 'data');

                type = 'res';
                review = 'FNN';

                files = fieldnames(data.(type).(review));
                ind1 = 1;
                for i = 1:length(files)
                    objs = fieldnames(data.(type).(review).(files{i}));
                    ind2 = 1;
                    for j = 1:length(objs)
                        sigs = fieldnames(data.(type).(review).(files{i}).(objs{j}));
                        ind3 = 1;
                        for k = 1:length(sigs)
                            d = data.(type).(review).(files{i}).(objs{j}).(sigs{k}).dim{dim};
                            if isnan(d)
                                app.Tree.CheckedNodes = [app.Tree.CheckedNodes(:); app.Tree.Children(i).Children(j).Children(k)];
                                
                            end
                            ind3 = ind3 + 1;
                        end
                        ind2 = ind2 + 1;
                    end
                    ind1 = ind1 + 1;
                end

                updatePlot(app, [])

            end

        end

        % Callback function: PlotselectdatadimensionListBox, Tree
        function UpdatePlotCallback(app, event)
            updatePlot(app, [])
        end

        % Button pushed function: FindGroupsButton
        function FindGroupsButtonPushed(app, event)
            
            data = getappdata(app.UIFigure, 'data');

            type = 'res';
            review = 'RQA';

            groups = app.Groups.Value;
            groups = textscan(groups, '%s', 'Delimiter', ',');
            groups = groups{1};

            for jj = 1:length(groups)
                ind1 = 1;
                files = fieldnames(data.(type).(review));
                for i = 1:length(files)
                    objs = fieldnames(data.(type).(review).(files{i}));
                    for j = 1:length(objs)
                        sigs = fieldnames(data.(type).(review).(files{i}).(objs{j}));
                        for k = 1:length(sigs)
                            for ii = 1:size(data.(type).(review).(files{i}).(objs{j}).(sigs{k}).results, 2)
                                if contains(objs{j}, groups{jj})
                                    if isfield(data.(type).(review).(files{i}).(objs{j}).(sigs{k}).results{ii}, 'MaxL')
                                        dataGrouped.(groups{jj})(ind1,1) = data.(type).(review).(files{i}).(objs{j}).(sigs{k}).results{ii}.MaxL;
                                        ind1 = ind1 + 1;
                                    end
                                end
                            end
                        end
                    end
                end
            end

            updateHist(app, dataGrouped)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.FigureResults = uiaxes(app.UIFigure);
            app.FigureHist = uiaxes(app.UIFigure);
            ylabel(app.FigureHist, '# of Observations')
            zlabel(app.FigureHist, 'Z')
            app.RecurrenceQuantificationReviewLabel = uilabel(app.UIFigure);
            app.RecurrenceQuantificationReviewLabel.Text = 'Recurrence Quantification Review';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.Log = uitextarea(app.UIFigure);
            app.SelectmissingresultsCheckBox = uicheckbox(app.UIFigure);
            app.SelectmissingresultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectmissingresultsCheckBoxValueChanged, true);
            app.SelectmissingresultsCheckBox.Text = 'Select missing results';
            app.PlotselectdatadimensionListBoxLabel = uilabel(app.UIFigure);
            app.PlotselectdatadimensionListBoxLabel.Text = 'Plot select data dimension';
            app.PlotselectdatadimensionListBox = uilistbox(app.UIFigure);
            app.PlotselectdatadimensionListBox.Items = {};
            app.PlotselectdatadimensionListBox.Multiselect = 'on';
            app.PlotselectdatadimensionListBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotCallback, true);
            app.PlotselectdatadimensionListBox.Value = {};
            app.RecurrencePlotPanel = uipanel(app.UIFigure);
            app.RecurrencePlotPanel.Title = 'Recurrence Plot';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @UpdatePlotCallback, true);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Binary', 'Heatmap'};
            app.Switch.Value = 'Binary';
            app.RecurrencePlotValues = uitextarea(app.UIFigure);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Enter tags that should be used to assign groups. Separate multiple values by commas.';
            app.Groups = uieditfield(app.UIFigure, 'text');
            app.FindGroupsButton = uibutton(app.UIFigure, 'push');
            app.FindGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @FindGroupsButtonPushed, true);
            app.FindGroupsButton.Text = 'Find Groups';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = review_RQA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end